classdef TrackEditor < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        LocusTrackEditorUIFigure        matlab.ui.Figure
        CloseButton                     matlab.ui.control.Button
        RemoveEditsButton               matlab.ui.control.Button
        ChangeUnitspixelsmicronsButton  matlab.ui.control.StateButton
        SaveCloseButton                 matlab.ui.control.Button
        TrackSelectorDropDown           matlab.ui.control.DropDown
        SelectTrackLabel                matlab.ui.control.Label
        UITable                         matlab.ui.control.Table
    end

    
    properties (Access = private)
        UneditedTrackData = [];
        CallingAppHandles = [];
        TrackData = [];
        PixelSize = [];
        ZStep     = [];        
    end
    
    methods (Access = private)
        
        
            %|||||||||||||||||||||||||||||||||||||||||||||||||||||||||||||||||||||||||||||||||||||||||||||||||||    
            function UpdateTable(app)
    
                time = app.TrackData{1}(:,1); 
                track = app.TrackSelectorDropDown.Value;
                % TableData = app.UITable.Data;
                
                if app.ChangeUnitspixelsmicronsButton.Value
                    X = app.PixelSize*( app.TrackData{track}(:,2) - 1 );
                    Y = app.PixelSize*( app.TrackData{track}(:,3) - 1 );
                    Z = app.ZStep*    ( app.TrackData{track}(:,4) - 1 );
                    ColumnNames = {'Timepoint',['X (',char(956),'m)'],['Y (',char(956),'m)'],['Z (',char(956),'m)']};
                else
                    X = app.TrackData{track}(:,2); 
                    Y = app.TrackData{track}(:,3); 
                    Z = app.TrackData{track}(:,4); 
                    ColumnNames = {'Timepoint','X (pixels)', 'Y (pixels)', 'Z (z-step)'};
                end 
                
                T = table(time,X,Y,Z,'VariableNames',ColumnNames);
                
                app.UITable.Data = T;
                app.UITable.ColumnName = ColumnNames;
                app.UITable.ColumnEditable = [false,true,true,true];
                
            end
            %||||||||||||||||||||||||||||||||||||||||||||||||||||||||||||||||||||||||||||||||||||||||||||||||||||  
    
    
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, MainAppHandles, TrackData, TrackInfo, PixelSize, ZStep)
            
            app.CallingAppHandles = MainAppHandles;
            app.CallingAppHandles.TrackEditorButton.Enable = false;
            
            app.TrackData = TrackData;
            app.UneditedTrackData = TrackData;
            app.PixelSize = PixelSize;
            app.ZStep     = ZStep;
            
            app.TrackSelectorDropDown.Items =               TrackInfo.names;
            app.TrackSelectorDropDown.ItemsData = (1:length(TrackInfo.names))';
            app.TrackSelectorDropDown.Value = 1;
                        
            UpdateTable(app)
            
        end

        % Cell edit callback: UITable
        function UITableCellEdit(app, event)
            
            indices = event.Indices;
            col = indices(1,2);
            row = indices(1,1);
            newData = event.NewData;
            track = app.TrackSelectorDropDown.Value;
            
            if isequal(1, app.ChangeUnitspixelsmicronsButton.Value) 
                % If "Change Units" button is pressed convert new value back to Pixel (or Z-Step) units.
                if isequal(col,4)
                    app.TrackData{track}(row,col) = newData/app.ZStep + 1;
                else
                    app.TrackData{track}(row,col) = newData/app.PixelSize + 1;
                end
            else
                app.TrackData{track}(row,col) = newData;
            end
            
            
        end

        % Value changed function: ChangeUnitspixelsmicronsButton
        function ChangeUnitspixelsmicronsButtonValueChanged(app, event)
            
            value = app.ChangeUnitspixelsmicronsButton.Value;
            UpdateTable(app)
            
        end

        % Value changed function: TrackSelectorDropDown
        function TrackSelectorDropDownValueChanged(app, event)
            
            %value = app.TrackSelectorDropDown.Value;
            UpdateTable(app)


        end

        % Button pushed function: RemoveEditsButton
        function RemoveEditsButtonPushed(app, event)
            
            message   = sprintf('Warning! \n Are you sure you want to remove edits?');
            selection = uiconfirm(app.LocusTrackEditorUIFigure,message,...
                'Confirm removing edits','Icon','warning','Options',{'Yes','No'},...
                'DefaultOption',2,'CancelOption',2);
            
            if strcmp(selection,'Yes')
                app.TrackData = app.UneditedTrackData;   
            end
            
        end

        % Button pushed function: SaveCloseButton
        function SaveCloseButtonPushed(app, event)
            
            message   = sprintf('Warning! \n Are you sure you want overwrite track data with these edits?');
            selection = uiconfirm(app.LocusTrackEditorUIFigure,message,...
                        'Confirm overwrite','Icon','warning','Options',{'Yes','No'},'DefaultOption',2,'CancelOption',2);
            
            if strcmp(selection,'Yes')
                % Enable the Plot Opions button in main app
                app.CallingAppHandles.TrackEditorButton.Enable = 'on';
                app.CallingAppHandles.DATA1 = app.TrackData;
                
                % Delete the dialog box 
                delete(app)
            end
            
        end

        % Button pushed function: CloseButton
        function CloseButtonPushed(app, event)
                       
            %app.CallingAppHandle.OptionsButton.Enable = 'on';
            app.CallingAppHandles.DATA1 = [];
            delete(app)
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.LocusTrackEditorUIFigure = uifigure('Visible', 'off');
            app.LocusTrackEditorUIFigure.Name = 'Locus: Track Editor';
            app.UITable = uitable(app.LocusTrackEditorUIFigure);
            app.UITable.ColumnName = {'Column 1'; 'Column 2'; 'Column 3'; 'Column 4'};
            app.UITable.RowName = {};
            app.UITable.ColumnEditable = true;
            app.UITable.CellEditCallback = createCallbackFcn(app, @UITableCellEdit, true);
            app.SelectTrackLabel = uilabel(app.LocusTrackEditorUIFigure);
            app.SelectTrackLabel.Text = 'Select Track:';
            app.TrackSelectorDropDown = uidropdown(app.LocusTrackEditorUIFigure);
            app.TrackSelectorDropDown.ValueChangedFcn = createCallbackFcn(app, @TrackSelectorDropDownValueChanged, true);
            app.SaveCloseButton = uibutton(app.LocusTrackEditorUIFigure, 'push');
            app.SaveCloseButton.ButtonPushedFcn = createCallbackFcn(app, @SaveCloseButtonPushed, true);
            app.SaveCloseButton.Text = {'Save/Close'; ''};
            app.ChangeUnitspixelsmicronsButton = uibutton(app.LocusTrackEditorUIFigure, 'state');
            app.ChangeUnitspixelsmicronsButton.ValueChangedFcn = createCallbackFcn(app, @ChangeUnitspixelsmicronsButtonValueChanged, true);
            app.ChangeUnitspixelsmicronsButton.Text = 'Change Units (pixels/microns)';
            app.RemoveEditsButton = uibutton(app.LocusTrackEditorUIFigure, 'push');
            app.RemoveEditsButton.ButtonPushedFcn = createCallbackFcn(app, @RemoveEditsButtonPushed, true);
            app.RemoveEditsButton.Text = 'Remove Edits';
            app.CloseButton = uibutton(app.LocusTrackEditorUIFigure, 'push');
            app.CloseButton.ButtonPushedFcn = createCallbackFcn(app, @CloseButtonPushed, true);
            app.CloseButton.Text = {'Close'; ''};
            % Show the figure after all components are created
            app.LocusTrackEditorUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = TrackEditor(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.LocusTrackEditorUIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.LocusTrackEditorUIFigure)
        end
    end
end