classdef locus < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        LocusUIFigure                   matlab.ui.Figure
        TabGroup                        matlab.ui.container.TabGroup
        DataTab                         matlab.ui.container.Tab
        SavelocusfilePanel              matlab.ui.container.Panel
        LastSavedDisplay                matlab.ui.control.Label
        SaveLocusFileButton             matlab.ui.control.Button
        SaveAsLocusFileButton           matlab.ui.control.Button
        LoadedLocusFileDisplay          matlab.ui.control.TextArea
        ShortcutsButton                 matlab.ui.control.Button
        Load_Locus_File_Button          matlab.ui.control.Button
        AboutButton                     matlab.ui.control.Button
        DatavolumesubregionsizePanel    matlab.ui.container.Panel
        InputvaluesusingkeypadtoshortcutconflictsLabel  matlab.ui.control.Label
        ZSubRegionField                 matlab.ui.control.NumericEditField
        ZvoxelsLabel                    matlab.ui.control.Label
        ColsSubRegionField              matlab.ui.control.NumericEditField
        ColumnsvoxelsLabel              matlab.ui.control.Label
        RowsSubRegionField              matlab.ui.control.NumericEditField
        RowsvoxelsLabel                 matlab.ui.control.Label
        DatameasurementsPanel           matlab.ui.container.Panel
        TimeStepField                   matlab.ui.control.NumericEditField
        TimeStepsecondsLabel            matlab.ui.control.Label
        ZStepField                      matlab.ui.control.NumericEditField
        ZstepmicronsLabel               matlab.ui.control.Label
        PixelSizeField                  matlab.ui.control.NumericEditField
        PixelSizemicronsEditFieldLabel  matlab.ui.control.Label
        RGBZtimeseriesdirectoryPanel    matlab.ui.container.Panel
        ChangeImageDirectoryButton      matlab.ui.control.Button
        SelectFolderButton              matlab.ui.control.Button
        RGBZTDirectoryDisplay           matlab.ui.control.TextArea
        LongtrackercelltrackfilesPanel  matlab.ui.container.Panel
        CellTracksListBox1              matlab.ui.control.ListBox
        RemoveTrackButton               matlab.ui.control.Button
        AddTrackButton                  matlab.ui.control.Button
        SingleCellRenderingTab          matlab.ui.container.Tab
        DataPlaneManipulationPanel      matlab.ui.container.Panel
        DataPlaneColormapDropDown       matlab.ui.control.DropDown
        DataplancolormapLabel           matlab.ui.control.Label
        Panel_12                        matlab.ui.container.Panel
        DataPlaneChannel                matlab.ui.control.DropDown
        DataPlaneToggleButton           matlab.ui.control.StateButton
        DataPlaneSizeInput              matlab.ui.control.NumericEditField
        SizeumEditFieldLabel            matlab.ui.control.Label
        Panel_17                        matlab.ui.container.Panel
        IntersectionLineVisibilityButton  matlab.ui.control.StateButton
        IntersectionColorDisplay        matlab.ui.container.Panel
        IntersectionlinecolorButton     matlab.ui.control.Button
        TranslationRotationTabGroup     matlab.ui.container.TabGroup
        EllipsoidTranslationRotationTab  matlab.ui.container.Tab
        AutoUpdateViewCheckBox          matlab.ui.control.CheckBox
        AutoUpdateDataPlanePositionCheckBox  matlab.ui.control.CheckBox
        DefaultViewR3                   matlab.ui.control.CheckBox
        DefaultViewR2                   matlab.ui.control.CheckBox
        DefaultViewR1                   matlab.ui.control.CheckBox
        EllipsoidFitAlertsDisplay       matlab.ui.control.Label
        ELamp3                          matlab.ui.control.Lamp
        ELamp2                          matlab.ui.control.Lamp
        ELamp1                          matlab.ui.control.Lamp
        EllipsoidTranslationZero        matlab.ui.control.Button
        EllipsoidRotationZero           matlab.ui.control.Button
        EllipsoidTranslationDisplay     matlab.ui.control.EditField
        TranslationEditFieldLabel       matlab.ui.control.Label
        EllipsoidTranslationSlider      matlab.ui.control.Slider
        EllipsoidRotationDisplay        matlab.ui.control.NumericEditField
        RotationLabel                   matlab.ui.control.Label
        EllipsoidRotationKnob           matlab.ui.control.Knob
        Er1ViewButton                   matlab.ui.control.Button
        Er2ViewButton                   matlab.ui.control.Button
        Er3ViewButton                   matlab.ui.control.Button
        GeneralTranslationRotationTab   matlab.ui.container.Tab
        RotationPanel                   matlab.ui.container.Panel
        PANslider                       matlab.ui.control.Slider
        TILTslider                      matlab.ui.control.Slider
        Tilt90Button                    matlab.ui.control.Button
        Tilt45Button                    matlab.ui.control.Button
        Pan90Button                     matlab.ui.control.Button
        Pan45Button                     matlab.ui.control.Button
        PanZeroButton                   matlab.ui.control.Button
        TiltZeroButton                  matlab.ui.control.Button
        TILTeditField                   matlab.ui.control.NumericEditField
        TILTLabel                       matlab.ui.control.Label
        PANeditField                    matlab.ui.control.NumericEditField
        PANEditFieldLabel               matlab.ui.control.Label
        TranslationPanel                matlab.ui.container.Panel
        StepSizeLabel                   matlab.ui.control.Label
        StepSizeSpinner                 matlab.ui.control.Spinner
        MaxDistLabel                    matlab.ui.control.Label
        MaxDistSpinner                  matlab.ui.control.Spinner
        ZSliderZeroButton               matlab.ui.control.Button
        YSliderZeroButton               matlab.ui.control.Button
        XSliderZeroButton               matlab.ui.control.Button
        ZSliderSpinner                  matlab.ui.control.Spinner
        ZSpinnerLabel                   matlab.ui.control.Label
        YSliderSpinner                  matlab.ui.control.Spinner
        YSpinnerLabel                   matlab.ui.control.Label
        XSliderSpinner                  matlab.ui.control.Spinner
        XSpinnerLabel                   matlab.ui.control.Label
        ZSlider                         matlab.ui.control.Slider
        YSlider                         matlab.ui.control.Slider
        XSlider                         matlab.ui.control.Slider
        Panel_10                        matlab.ui.container.Panel
        FrangiLamp                      matlab.ui.control.Lamp
        FrangiFilterSwitch              matlab.ui.control.Switch
        FrangiFilterLabel               matlab.ui.control.Label
        Panel_9                         matlab.ui.container.Panel
        AdaptHistEqLamp                 matlab.ui.control.Lamp
        AdaptHistEqSwitch               matlab.ui.control.Switch
        AdaptHistEqSwitchLabel          matlab.ui.control.Label
        Panel_11                        matlab.ui.container.Panel
        SigmaSpinner                    matlab.ui.control.Spinner
        sigmaSpinnerLabel               matlab.ui.control.Label
        GaussFiltLamp                   matlab.ui.control.Lamp
        GaussianFiltSwitch              matlab.ui.control.Switch
        GaussianFiltSwitchLabel         matlab.ui.control.Label
        CurrentTimepointAndTrackPanel   matlab.ui.container.Panel
        TimePointSpinner                matlab.ui.control.Spinner
        CurrentTrackDropDown            matlab.ui.control.DropDown
        CurrentTrackLabel               matlab.ui.control.Label
        AutoRenderAllCellsButton        matlab.ui.control.Button
        OPENRenderingFigureButton       matlab.ui.control.Button
        CellRenderingandTrackPointControlPanel  matlab.ui.container.Panel
        CellVisibilityButton            matlab.ui.control.StateButton
        TrackOffsetPanel                matlab.ui.container.Panel
        TrackEditorButton               matlab.ui.control.Button
        RenderingSummaryButton          matlab.ui.control.Button
        TrackPointResetButton           matlab.ui.control.Button
        TrackPointOffset_Z              matlab.ui.control.Spinner
        ZLabel                          matlab.ui.control.Label
        TrackPointOffset_Y              matlab.ui.control.Spinner
        YLabel                          matlab.ui.control.Label
        TrackPointOffset_X              matlab.ui.control.Spinner
        XSpinnerLabel_2                 matlab.ui.control.Label
        RenderingsmoothingPanel         matlab.ui.container.Panel
        UseSmoothedForMeasurementsCheckBox  matlab.ui.control.CheckBox
        SegSmoothSigmaSpinner           matlab.ui.control.Spinner
        sigmaSpinnerLabel_2             matlab.ui.control.Label
        RenderingSmoothingOnCheckBox    matlab.ui.control.CheckBox
        RenderingParametersPanel        matlab.ui.container.Panel
        setdefaultsButton               matlab.ui.control.Button
        defaultvaluesButton             matlab.ui.control.Button
        mLabel_2                        matlab.ui.control.Label
        mLabel                          matlab.ui.control.Label
        PadSpinner                      matlab.ui.control.Spinner
        PadSpinnerLabel                 matlab.ui.control.Label
        ExtendedMinSpinner              matlab.ui.control.Spinner
        ExtendedMinimumLabel            matlab.ui.control.Label
        ColorsPanel                     matlab.ui.container.Panel
        MeshTransparencySpinner         matlab.ui.control.Spinner
        TransparencySpinner_2Label      matlab.ui.control.Label
        FaceTransparencySpinner         matlab.ui.control.Spinner
        TransparencyLabel               matlab.ui.control.Label
        MeshColorDisplay                matlab.ui.container.Panel
        MeshColorButton                 matlab.ui.control.Button
        FaceColorDisplay                matlab.ui.container.Panel
        FaceColorButton                 matlab.ui.control.Button
        SeparationLineBox               matlab.ui.container.Panel
        SeparationLineSTARTButton       matlab.ui.control.StateButton
        CLEARSepButton                  matlab.ui.control.Button
        UpdateRenderingButton           matlab.ui.control.Button
        ShowTrackPointButton            matlab.ui.control.StateButton
        ConnectionLinePanel             matlab.ui.container.Panel
        ConnectionLineSTARTButton       matlab.ui.control.StateButton
        clearButton                     matlab.ui.control.Button
        SaveLocusFileButton2            matlab.ui.control.Button
        MultiCellAnalysisTab            matlab.ui.container.Tab
        ClosestPointofApproachCalculationPanel  matlab.ui.container.Panel
        TrackBStatsButton               matlab.ui.control.Button
        TrackAStatsButton               matlab.ui.control.Button
        XaxisTimeunitsDropDown          matlab.ui.control.DropDown
        XaxisTimeunitsDropDownLabel     matlab.ui.control.Label
        ExportasExcelfileButton         matlab.ui.control.Button
        SelectTrackBDropDown            matlab.ui.control.DropDown
        SelectTrackBDropDownLabel       matlab.ui.control.Label
        SelectTrackADropDown            matlab.ui.control.DropDown
        SelectTrackADropDownLabel       matlab.ui.control.Label
        CalculateandPlotClosestApproachButton  matlab.ui.control.Button
        PlotCellRenderingsOverAllTimePointsPanel  matlab.ui.container.Panel
        CurrentVelocityRangeDisplay     matlab.ui.control.Label
        VelocityFilteringAndRangeButton  matlab.ui.control.Button
        ColormapLabel_2                 matlab.ui.control.Label
        ColormaptypeLabel               matlab.ui.control.Label
        ColormapTypeDropDown            matlab.ui.control.DropDown
        MultiCellColormapDropDown       matlab.ui.control.DropDown
        Panel_24                        matlab.ui.container.Panel
        FramespersecondEditField        matlab.ui.control.NumericEditField
        FPSLabel                        matlab.ui.control.Label
        VisualPropertiesTabGroup        matlab.ui.container.TabGroup
        AlltimeptsTab                   matlab.ui.container.Tab
        AllCellMeshUseColormapCheck     matlab.ui.control.CheckBox
        AllCellSurfaceUseColormapCheck  matlab.ui.control.CheckBox
        AllCellSurfaceTransparencySpinner  matlab.ui.control.Spinner
        AllCellMeshCheckBox             matlab.ui.control.CheckBox
        AllCellSurfaceCheckBox          matlab.ui.control.CheckBox
        TransparencyLabel3              matlab.ui.control.Label
        AllCellMeshTransparencySpinner  matlab.ui.control.Spinner
        AllCellMeshColorDisplay         matlab.ui.container.Panel
        AllCellMeshColorButton          matlab.ui.control.Button
        TransparencyLabel2              matlab.ui.control.Label
        AllCellSurfaceColorDisplay      matlab.ui.container.Panel
        AllCellSurfaceColorButton       matlab.ui.control.Button
        TrackTab                        matlab.ui.container.Tab
        LineWidthDropDown               matlab.ui.control.DropDown
        LinewidthDropDownLabel          matlab.ui.control.Label
        TrackLineUseColormapCheck       matlab.ui.control.CheckBox
        SphericalmarkeristrackstartingpointLabel  matlab.ui.control.Label
        nPtsLabel                       matlab.ui.control.Label
        TrackSmoothingNPointsDropDown   matlab.ui.control.DropDown
        TrackSmoothingCheck             matlab.ui.control.CheckBox
        TrackLineCheckBox               matlab.ui.control.CheckBox
        TransparencyLabel4              matlab.ui.control.Label
        TrackLineTransparencySpinner    matlab.ui.control.Spinner
        TrackLineColorDisplay           matlab.ui.container.Panel
        TrackLineColorButton            matlab.ui.control.Button
        VectorsTab                      matlab.ui.container.Tab
        AllVectorUseColormapCheck       matlab.ui.control.CheckBox
        VectorLengthEditField           matlab.ui.control.NumericEditField
        VectorlengthLabel               matlab.ui.control.Label
        VectorRadiusEditField           matlab.ui.control.NumericEditField
        VectorradiusLabel               matlab.ui.control.Label
        AllVectorCheckBox               matlab.ui.control.CheckBox
        TransparencyLabel5_2            matlab.ui.control.Label
        AllVectorTransparencySpinner    matlab.ui.control.Spinner
        AllVectorColorDisplay           matlab.ui.container.Panel
        AllVectorColorButton            matlab.ui.control.Button
        DataPlaneTab                    matlab.ui.container.Tab
        DataPlaneImageSaturation        matlab.ui.control.CheckBox
        DataPlaneElevationEditField     matlab.ui.control.NumericEditField
        ElevationEditFieldLabel         matlab.ui.control.Label
        DataPlaneAzimuthOffsetInput     matlab.ui.control.NumericEditField
        AzimuthoffsetLabel              matlab.ui.control.Label
        ColormapLabel                   matlab.ui.control.Label
        MovieDataPlaneColormapDropDown  matlab.ui.control.DropDown
        OverlayDataPlaneCheck           matlab.ui.control.CheckBox
        MovieTab                        matlab.ui.container.Tab
        MovieVectorUseColormapCheck     matlab.ui.control.CheckBox
        MovieCentroidUseColormapCheck   matlab.ui.control.CheckBox
        MovieCellMeshUseColormapCheck   matlab.ui.control.CheckBox
        MovieCellSurfaceUseColormapCheck  matlab.ui.control.CheckBox
        MovieVectorColorCheckBox        matlab.ui.control.CheckBox
        TransparencyLabel5_3            matlab.ui.control.Label
        MovieVectorTransparencySpinner  matlab.ui.control.Spinner
        MovieVectorColorDisplay         matlab.ui.container.Panel
        VectorColorButton               matlab.ui.control.Button
        MovieCentroidCheckBox           matlab.ui.control.CheckBox
        TransparencyLabel7              matlab.ui.control.Label
        MovieCentroidTransparencySpinner  matlab.ui.control.Spinner
        MovieCentroidColorDisplay       matlab.ui.container.Panel
        MovieCentroidColorButton        matlab.ui.control.Button
        MovieCellMeshCheckBox           matlab.ui.control.CheckBox
        MovieCellSurfaceCheckBox        matlab.ui.control.CheckBox
        TransparencyLabel6              matlab.ui.control.Label
        MovieCellMeshTransparencySpinner  matlab.ui.control.Spinner
        MovieCellMeshColorDisplay       matlab.ui.container.Panel
        MovieCellMeshColorButton        matlab.ui.control.Button
        TransparencyLabel5              matlab.ui.control.Label
        MovieCellSurfaceTransparencySpinner  matlab.ui.control.Spinner
        MovieCellSurfaceColorDisplay    matlab.ui.container.Panel
        MovieCellSurfaceColorButton     matlab.ui.control.Button
        Panel_23                        matlab.ui.container.Panel
        GoToEndViewButton               matlab.ui.control.Button
        GoToStartViewButton             matlab.ui.control.Button
        RotationDropDown                matlab.ui.control.DropDown
        RotationLabel_2                 matlab.ui.control.Label
        SetEndAngleDisplay              matlab.ui.control.EditField
        SetStartAngleDisplay            matlab.ui.control.EditField
        SetEndAngleButton               matlab.ui.control.Button
        SetStartAngleButton             matlab.ui.control.Button
        Panel_21                        matlab.ui.container.Panel
        ShowSmoothedRenderingCheck      matlab.ui.control.CheckBox
        DownTrackButton                 matlab.ui.control.Button
        UpTrackButton                   matlab.ui.control.Button
        ExportFluoRenderFormatedTrack   matlab.ui.control.Button
        UnselectAllTrackButton          matlab.ui.control.Button
        ShowTrackCheckBox               matlab.ui.control.CheckBox
        SelectTrackDropDown             matlab.ui.control.DropDown
        SelectTrackDropDownLabel        matlab.ui.control.Label
        Panel_20                        matlab.ui.container.Panel
        RecordMovieButton               matlab.ui.control.Button
        StopMovieButton                 matlab.ui.control.StateButton
        UpdateCompositePlotButton       matlab.ui.control.Button
        Panel_19                        matlab.ui.container.Panel
        TextAxesColorDisplay            matlab.ui.container.Panel
        TextAxesColorButton             matlab.ui.control.Button
        MultiTrackBackgroundColorDisplay  matlab.ui.container.Panel
        MultitrackBackgroundColorButton  matlab.ui.control.Button
        Panel_18                        matlab.ui.container.Panel
        EndingTimePoint                 matlab.ui.control.NumericEditField
        MakeDropDownLabel_6             matlab.ui.control.Label
        MakeDropDownLabel_5             matlab.ui.control.Label
        TimePointIncrement              matlab.ui.control.NumericEditField
        StartingTimePoint               matlab.ui.control.NumericEditField
        MakeDropDownLabel_2             matlab.ui.control.Label
        AxesRangePanel                  matlab.ui.container.Panel
        AutoAxisLimitsCheckBox          matlab.ui.control.CheckBox
        updateplotButton                matlab.ui.control.Button
        ZminZmaxEditField               matlab.ui.control.EditField
        ZminZmaxEditFieldLabel          matlab.ui.control.Label
        YminYmaxEditField               matlab.ui.control.EditField
        YminYmaxEditFieldLabel          matlab.ui.control.Label
        XminXmaxEditField               matlab.ui.control.EditField
        XminXmaxLabel                   matlab.ui.control.Label
    end

    
    properties (Access = private)
            
            LocusFileName = '';
            LocusFileDirectory = '';
            LoadDataCheckList = false(8,1);
            RGBZT_Directory = []; % RGBZ timeseries location
            RGBZT_ImageFileNames = [];
            LocusFileSaveDirectory = [];
            CellTrackFilesInfo = []; % 
            CellTrackData = cell(0,1);
            CellTrackOffset = cell(0,1);
            CellRenderingsDirectory = [];
            DeconvolvedImageFilesDirectory = 'Deconvolved_Image_Files';
            %DATA5d = cell(0,1); % Subregion Raw data storage
            %PROC5d = cell(0,1); % Subregion Processed data storage
            IND    = cell(0,1); % The indices that the Subregion represents in the original data file
            TimePointData = []; % Store Time Points Properties and Rendering Data
            CurrentDataVolumeForTimeT = [];
            TrackEditorHandle = -1;
            RenderingSummaryHandle = -1;
            X  = [];
            Y  = [];
            Z  = [];
%             SubVol_nR = [];
%             SubVol_nC = [];
%             SubVol_nZ = [];
            dp = [];
            dz = [];
            dt = [];
            zDepth  = [];   % Z-depth 
            cWidth  = [];   % Full width of image
            rHeight = [];   % Full height of image
            nTime   = []; 
            SingleCellFigureHandle = -1;
            SingleCellAxisHandle = -1;
            SingleCellRenderingHandle = -1;
            AxesToolBarHandle = -1;
            CellRenderings = [];
            DataPlaneSize = 40; % defualt length and width of data plane
            DataPlaneHandle = [];
            DataPlaneStartArray = [];
            DataPlaneCurrentArray = [];
            CurrentTrackPoint = [];    
            CurrentTrack = 1;
            CurrentTimePoint = 1;
            TrackPointHandle = -1;
            TempConnectionLine = NaN(2,3);
            ConnectionLineHandles = -1;
            TempSeparationLine = zeros(0,3);
            SeparationLineHandles = -1;
            RenderingTextHandle  = -1;
            TimepointTextHandle  = -1;
            TimepointTextHandle2 = -1;
            PreviousPoint   = NaN(1,2);
            EllipsoidAxes   = NaN(3,3); % each axis is a column vector
            EllipsoidCenter = NaN(3,1);
            ViewButtonPressed = 1;
            FaceColor           = [0.3,0.75,0.93];
            FaceTransparency    = 0.5;
            MeshColor           = [0,0,0];
            MeshTransparency    = 0.5;
            DefaultRenderingValues = [];
            TrackPlottingProperties = cell(0,1); 
            TrackFigureProperties = [];
            TrackAndMovieFigureHandle = -1;
            TrackAndMovieAxesHandle = -1;
            CellPlaneIntersectionLineHandle = -1;
            CellPlaneIntersectionColor = [1,1,1];
            VelocityColormapRange = [15,25];
            TrackVelocities = [];
            VelocityUnits = 2;
            nVelocitySmoothingPoints = 21;
            FramesPerSecond = 5;
            MovieIntersectionLine = gobjects(0,1);
            MovieObjectHandles  = gobjects(0,1);
            TimeTextHandle = gobjects(0,1);
    end 
    
    %------------------------------------------------------------------------------------------------
    properties (Access = public)
            DATA1 = []; % Generic public data containers for passing data between apps
            DATA2 = [];
            DATA3 = [];
    end
    %------------------------------------------------------------------------------------------------
    
        
    methods (Access = private)
        
    %--------------------------------------------------------------------------------------------------------------------------------
            function FileNames = MakeSureImageFilesAreSortedByTimestamp(app,FileNames)

                        % DotIdx = cellfun(@(x) find(isstrprop(x,'punct'),1,'last'),FileNames,'UniformOutput',false);  % get index of last '.' in filename
                        % 
                        % NumStartStop = @(x,idx) [ strfind([0,isstrprop(x(1:idx),'digit')],[0,1])',...  % Define function that gets starting indices of numeric regions in filename
                        %                           strfind([isstrprop(x(1:idx),'digit'),0],[1,0])' ];   % Define function that gets stopping indices of numeric regions in filename
                        % 
                        % NumericIDs = cellfun(NumStartStop,FileNames,DotIdx,'UniformOutput',false); % In each cell: Start (column 1) and Stop (column 2) of numeric regions in the file name  
                        % 
                        % % Make sure file names are sorted according to TimeStamp: This is a probelm if numeric timestamp width changes (no zero padding)
                        % TimeStamps = cellfun(@(x,idx) str2double(x(idx(end,1):idx(end,2))), FileNames, NumericIDs);
                        % [~,SortIdx] = sortrows(TimeStamps);
                        % FileNames = FileNames(SortIdx,1);

                        if numel(FileNames) > 1
                                [nRows,nCols] = size(FileNames);
                                if nCols > nRows
                                    FileNames = FileNames'; % If needed, convert to column vector of cells for processing
                                end
                
                                % Find numeric pattern in filename that represents time
                                    pat = digitsPattern;
                                    % Grab all the numeric patterns in the file name
                                    NumPatterns = cellfun(@str2double,extract(FileNames,pat)); 
                                    [nR,nC] = size(NumPatterns);
                                    % Assume that column related to time is the only column with all unique values.
                                    % So the difference of values in that column should all be one and others zero.
                                    C = mat2cell( NumPatterns ,nR,ones(1,nC) );
                                    TimeColumnIdx = find( logical(  cellfun(@length, cellfun(@unique,C,'UniformOutput',false) ) - 1 )    );
                                    if numel(TimeColumnIdx) == 1
                                        % Now sort TimeColumnIdx and apply sorting order to filenames
                                        [~,sIdx] = sort(NumPatterns(:,TimeColumnIdx));
                                        FileNames = FileNames(sIdx,1);
                                    elseif isempty(TimeColumnIdx)
                                        error('No varying numeric region found in file names.')
                                    else
                                        error('Files names must not contain more than one varying numeric region.')
                                    end
                
                                if nCols > nRows
                                    FileNames = FileNames'; % If needed, convert back to row vector if that is how it was input
                                end    
                        else
                                disp('Files variable must have a 1D length of at least 2')
                        end
                        

            end

    %--------------------------------------------------------------------------------------------------------------------------------        
            function SendRenderingSummaryToSummaryWindow(app)
                
                    if isgraphics(app.RenderingSummaryHandle)
                        [nTracks,~] = size(app.TimePointData.SurfaceRenderings);
                        DATA2 = cell(nTracks,1);
                        StartEndText = cell(nTracks,1);
                        Missing = cell(nTracks,1);
                        %-----------------------------------------------------------------------------------------------------
                        for T = 1:nTracks 
                                % Look at all the timepoints for each track and see if renderings exist
                                status  = ~cellfun(@isempty,app.TimePointData.SurfaceRenderings{T});
                                MissingIdx = find(~status);      % Find all the timepoints that have not been rendered
                                Start = find(status,1,'first');  % Find the first rendered timepooint (if there is one)
                                %-----------------------------------
                                if isempty(Start)
                                    Start = [];
                                    End   = [];
                                    MissingIdx = [];
                                    StartEndText{T,1} = '';
                                else  
                                    End = find( status,1,'last' ); % Find last timepoint
                                    MissingIdx( MissingIdx<Start | MissingIdx>End ) = []; % Remove missing indices of timepoints that are before the first and after the last timepoints with renderings.
                                    StartEndText{T,1} = [ sprintf('%d',Start), ' - ', sprintf('%d',End) ];
                                end
                                %-----------------------------------
                                if ~isempty(MissingIdx)
                                    MissingText  = sprintf('%d,',MissingIdx');
                                    Missing{T,1} = MissingText(1:end-1);
                                else
                                    Missing{T,1} = '';
                                end
                                %-----------------------------------
                        end
                        %-----------------------------------------------------------------------------------------------------
                        T = table( app.CellTrackFilesInfo.names, StartEndText, Missing, 'VariableNames', app.RenderingSummaryHandle.ColumnName);
                        app.RenderingSummaryHandle.Data = T;
                        app.RenderingSummaryHandle.ColumnEditable = [false,false,false];
                        
                    else
                        app.RenderingSummaryHandle = -1;
                    end
            end
            
            %--------------------------------------------------------------------------------------------------------------------------------
            function app = ApplyDeconvolution(app)
                   
                    % Create Progress bar ------------------------------------------------------------
                    d = uiprogressdlg(app.LocusUIFigure,'Title','Processing Image Data','Message','','Cancelable','on');
                    d.Value = 0; 
                    %---------------------------------------------------------------------------------
                    % Create estimated Point Spread Functions
                    PSF1 = customgauss([51,51], 8,  8,0,0,1,[0,0]); % For XY planes
                    PSF1 = PSF1./sum(PSF1(:));                      
                    %---------------------------------------------------------------------------------
                    
                    [files, ~] = files_and_folders(app, app.RGBZT_Directory);
                    nTime = size(files,1);
                    info = imfinfo(fullfile(app.RGBZT_Directory,files{1}),'tif');
                    nC = info(1).Width;
                    nR = info(1).Height;
                    nZ = length(info);
                    tic
                    for t = 1:nTime %#ok<*ADPROP> 
                            d.Value = t/nTime; 
                            d.Message = ['Applying Blind Deconvolution for t = ',num2str(t),' of ',num2str(nTime)];
                            PROC4 = zeros(nR,nC,3,nZ,'uint8');
                            
                            for ch = 1:2
                                    ProcImVol = zeros([nR,nC,nZ],'uint8');
                                    for z = 1:nZ
                                        %========================================================================================
                                            ImRGB = imread(fullfile(app.RGBZT_Directory,files{t,1}),'Index',z);
                                            disp(z)
                                            if d.CancelRequested; break; end
                                            IM = squeeze(ImRGB(:,:,ch));  % Vin = Membrane and Nuclear channels
                                        % Pad image with flipped copies of itself. This will help reduce edge effects during deconvolution
                                            IMA = flipud(IM);
                                            IMB = fliplr(IM);
                                            IMC = flipud(fliplr(IM));                         %#ok<FLUDLR> 
                                            IMpad = [ [IMC, IMA, IMC];...
                                                      [IMB,  IM, IMB];...
                                                      [IMC, IMA, IMC] ];
                                        % Now grab a subset of that padded image to speed up processing (1/3 all around original image)
                                            nRframe = round(nR/3);
                                            nCframe = round(nC/3);
                                            IMframed = IMpad(nR-nRframe+1:(2*nR+nRframe),nC-nCframe+1:(2*nC+nCframe));  
                                        % Blur framed image around the edge to further reduce doncvolution aritifacts    
                                            IMframed = edgetaper(IMframed,PSF1);
                                        % Apply Blind deconvolution     
                                            [J1,~] = deconvblind(IMframed,PSF1,10);
                                        % If it's the membrane channel, apply adaptivehistogram to flatten image  
                                            if ch == 1
                                                J1 = adapthisteq(J1,'NumTiles',[16,16]);
                                            end
                                            J1 = imadjust(J1);
                                        % Now grab center of framed image (remove padding/framing)    
                                            J1 = J1(nRframe+1:end-nRframe,nCframe+1:end-nCframe);
                                            ProcImVol(:,:,z) = J1;
                                            
                                            if d.CancelRequested; break; end
                                        %========================================================================================
                                    end
                                    
                                    % Apply a 3D erosion -----------------------------------
                                    ProcImVol = imerode(ProcImVol,strel('sphere',1));
                                    %-------------------------------------------------------
                                    for  z = 1:nZ
                                       PROC4(:,:,ch,z) = ProcImVol(:,:,z);
                                    end
                            end
                            
                            % Create Deconvolved Directory if it doesn't already exist ------------------------------------------
                            if ~isfolder( fullfile(app.RGBZT_Directory, app.DeconvolvedImageFilesDirectory) )
                                [status,~] = mkdir(fullfile(app.RGBZT_Directory, app.DeconvolvedImageFilesDirectory ));  
                                if ~status; disp('Not able to create Deconvolved_Image_Files directory'); end
                            end
                            
                            % Check if Deconvolution directory exists 
                            ProcImageFileName = fullfile(app.RGBZT_Directory, app.DeconvolvedImageFilesDirectory, ['DIF_',files{t,1}] );
                            if isfile(ProcImageFileName)
                                delete(ProcImageFileName)
                            end
                            
                            % Write each image to a multi-tiff file -------------------------------------------------------------
                            for z = 1:nZ
                                ProcImage = squeeze(PROC4(:,:,:,z));
                                imwrite( ProcImage , ProcImageFileName, 'WriteMode', 'append')
                            end
                            
                            if d.CancelRequested; break; end
                    end
                    toc
                    close(d)
            end


%-------------------------------------------------------------------------------------------------------------------------- 
function PTS = PlaneAndSurfaceIntersectionLine(app,Faces,Vertices,b,v)
    
            % ------------------------------------------------------------------------------------------------------------
            % PTS = PlaneAndSurfaceIntersectionLine(Faces,Vertices,b,v)
            %    
            %   Faces = nx3 array of triangle point indices for triangulated surface
            %   Vertices = mx3 array of x,y,z points that are used to define a triangulated surface
            %   b = a point, [px,py,pz], that lies in the plane, P. Size: 1x3
            %   v = a vector, [vx,vy,vz], normal to the plane, P. Size 1x3
            %   PTS = array of point pairs defining the intersection line segments of all triangle-plane intersection
            %         (To )
            %   If DoSort = false, plot line segments using: plot3(PTS(:,:,1),PTS(:,:,2),PTS(:,:,3)
            %   If DoSort = true,  plot line using: plot3(PTS(:,1),PTS(:,2),PTS(:,3))   
            %
            %           
            % Keith R. Carney 8/16/2024
            % keith.carney@hci.utah.edu
            % Huntsman Cancer Institute
            % Department of Human Genetics 
            % University of Utah
            % Salt Lake City, UT
            % ------------------------------------------------------------------------------------------------------------
        
                [vR,vC] = size(v); if vR>vC; v = v'; end
                [bR,bC] = size(b); if bR>bC; b = b'; end

            % Grab the three corner points for all the triangles

                p1 = Vertices(Faces(:,1),:);  
                p2 = Vertices(Faces(:,2),:); 
                p3 = Vertices(Faces(:,3),:);  

            % For each side/segment of the triangles, find its intersection point with the plane if it exists.
                
                [Q1,T1,AppendPTS1a] = CalculateLineSegmentIntersectionPoints(app,p1,p2,v,b); % p1 and p2 are end points for side 1 of the triangles  
                % If an intersection point of side 1 and the plane exists (non-NaN rows of Q1), check if the lines segment of Q1 and p3 lie in the plane
                AppendPTS1b = CheckIfIntersectionPointAndOpposingPointLieInPlane(app,Q1(T1,:),p3(T1,:),v,b);

                [Q2,T2,AppendPTS2a] = CalculateLineSegmentIntersectionPoints(app,p2,p3,v,b); % p2 and p3 are end points for side 2 of the triangles
                % If an intersection point of side 2 and the plane exists (non-NaN rows of Q2), check if the lines segment of Q2 and p1 lie in the plane
                AppendPTS2b = CheckIfIntersectionPointAndOpposingPointLieInPlane(app,Q2(T2,:),p1(T2,:),v,b);

                [Q3,T3,AppendPTS3a] = CalculateLineSegmentIntersectionPoints(app,p3,p1,v,b); % p1 and p3 are end points for side 3 of the triangles
                % If an intersection point of side 3 and the plane exists (non-NaN rows of Q2), check if the lines segment of Q2 and p1 lie in the plane
                AppendPTS3b = CheckIfIntersectionPointAndOpposingPointLieInPlane(app,Q3(T3,:),p2(T3,:),v,b);
        
            % Making the assumption that if any of the triangles intersect the plane at least two of 
            % the line segments must intersect too, or share a point that is in the plane.
            % Find all the triangles with two segments that intersect the triangle (TA,TB, and TC).

                TA = ~(T1&T2); 
                QA = cat(3,Q1,Q2); 
                QA(TA,:,:) = [];     % remove all pairs of points (line segments) that do not intersect the plane
            
                TB = ~(T2&T3);
                QB = cat(3,Q2,Q3); 
                QB(TB,:,:) = [];     % remove all pairs of points (line segments) that do not intersect the plane
            
                TC = ~(T3&T1);
                QC = cat(3,Q3,Q1); 
                QC(TC,:,:) = [];     % remove all pairs of points (line segments) that do not intersect the plane
            
            % From the three combination tests, concatenate all the segment pair intersection points    
                Q = cat(1,QA,QB,QC);      

            % Now add all the appended points of segments that lie in the plane:   
                AppendPTS = cat(1,AppendPTS1a,AppendPTS2a,AppendPTS3a,...
                                  AppendPTS1b,AppendPTS2b,AppendPTS3b);
       

            %----------------------------------------------------------------------------------
                Xseg =  cat(1, squeeze(Q(:,1,:)), squeeze(AppendPTS(:,1,:)) );
                Yseg =  cat(1, squeeze(Q(:,2,:)), squeeze(AppendPTS(:,2,:)) );
                Zseg =  cat(1, squeeze(Q(:,3,:)), squeeze(AppendPTS(:,3,:)) );
                
                                  
                PTS = cat(3,Xseg',Yseg',Zseg'); 
                % to plot line segments use: plot3(PTS(:,:,1),PTS(:,:,2),PTS(:,:,3))  
end


%--------------------------------------------------------------------------------------------------------------------------------
%--------------------------------------------------------------------------------------------------------------------------------
function [Q,TEST,AppendPTS] = CalculateLineSegmentIntersectionPoints(app,p,q,v,b)

            % [Q,TEST,AppendPTS] = CalculateLineSegmentIntersectionPoints(p,q,V)
            %
            % p and q are the x,y,z coordinates of the two end points of a line segment. 
            % Both have size [n,3], where n is the # of line segments.
            % V = a vector, [vx,vy,vz], normal to the plane, P. Size 1x3
            % b = point on plane [x,y,z]
            % Q = line segment and plane intersection points
            % TEST = logica array. True if line segment intersects with plane.
            % AppendPTS = If the line segment end points, p and q, do not intersect but lie in the plane, include them and we will append the points in the calling function.
            % Function applies Line-Plane Intersection Method: https://en.wikipedia.org/wiki/Line%E2%80%93plane_intersection
            % 
            % Keith R. Carney 8/16/2024
                
                % p = [p; [rand(4,2),b(3)*ones(4,1)] ];
                % q = [q; [rand(4,2),b(3)*ones(4,1)] ];
                
                nSegments = size(p,1);
                V = repmat(v,nSegments,1); 
                M = (p-q)./vecnorm(p-q,2,2); % Normalized vector in direction of line segment
                B = repmat(b,nSegments,1);
                
                TEST1a = dot(M,V,2) == 0; % If M dot V = 0, line and plane are parallel. 
                TEST1b = dot( (B-p), V, 2) == 0; % if (B-p) dot V = 0, line segment is in plane
                TEST1  = TEST1a & TEST1b; % Find segments parallel to and in plane. Append these segment's end points and append to the end of the PTS variable in calling function
                if any(TEST1)
                    AppendPTS = cat(3,p(TEST1,:),q(TEST1,:)); % size AppendPTS: [nSegments, dimension(x,y,z), (p=1 and q=2)]
                else
                    AppendPTS = zeros(0,3,2);
                end

                % Now calculate intersection points for all line segments that are not parallel to plane
                S = NaN(nSegments,1); % Preallocate space for Scalar computation (only compute for TEST2 = true)
                Q = NaN(nSegments,3); % Preallocate space for line and plane intersection point 
                T = ~ TEST1a; % For M dot V not equal to 0
                S(T,1) = dot( B(T,:)-p(T,:), V(T,:), 2) ./ dot(M(T,:),V(T,:),2);
                Q(T,:) = p(T,:) + M(T,:).*repmat(S(T,1),1,3);
        
                 % Test to make sure intersection point is between the line segment's end points, if not set it to NaN
                d1 = vecnorm([p(:,1)-q(:,1),p(:,2)-q(:,2),p(:,3)-q(:,3)]')';    
                d2 = vecnorm([p(:,1)-Q(:,1),p(:,2)-Q(:,2),p(:,3)-Q(:,3)]')';   
                d3 = vecnorm([q(:,1)-Q(:,1),q(:,2)-Q(:,2),q(:,3)-Q(:,3)]')';   
        
                Test = false(nSegments,1);
                Test(d2 >= d1 | d3 >= d1) = true;
                Q(Test,:) = NaN;

                TEST = ~any(isnan(Q),2);                
end

%--------------------------------------------------------------------------------------------------------------------------------
%--------------------------------------------------------------------------------------------------------------------------------
function AppendPTS = CheckIfIntersectionPointAndOpposingPointLieInPlane(app,Q,p,v,b)
                
                nSegments = size(p,1);
                V = repmat(v,nSegments,1); 
                M = (Q-p)./vecnorm(Q-p,2,2); % Normalized vector in direction of line segment
                B = repmat(b,nSegments,1);
                
                TEST1a = dot(M,V,2) == 0; % If M dot V = 0, line and plane are parallel. 
                TEST1b = dot( (B-p), V, 2) == 0; % if (B-p) dot V = 0, line segment is in plane
                TEST1  = TEST1a & TEST1b; % Find segments parallel to and in plane. Append these segment's end points and append to the end of the PTS variable in calling function
                if any(TEST1)
                    AppendPTS = cat(3,Q(TEST1,:),p(TEST1,:)); % size AppendPTS: [nSegments, dimension(x,y,z), (p=1 and q=2)]
                else
                    AppendPTS = zeros(0,3,2);
                end

end

%----------------------------------------------------------------------------------------------------------------------------


  % %-------------------------------------------------------------------------------------------------------------------------- 
  % %-------------------------------------------------------------------------------------------------------------------------- 
  % % https://www.mathworks.com/matlabcentral/fileexchange/22377-intersectplanesurf
  % % Cite As: Mehmet OZTURK (2022). intersectPlaneSurf (https://www.mathworks.com/matlabcentral/fileexchange/22377-intersectplanesurf), MATLAB Central File Exchange. Retrieved February 3, 2022.
  % 
  %       function lin = intersectPlaneSurf(app,fv,p0,n)
  % 
  %               % Intersection of a plane with an arbitrary surface data. The surface must
  %               % be in the form of faces and vertices strcture such as patch function and
  %               % the faces of the surface must be defined with triangles. fv = 
  %               % fv =
  %               %        faces: [number of faces x 3 double]
  %               %     vertices: [number of vertices x 3 double]
  %               % "p0" and "n" is used to define the plane. "p0" is a point that lies on
  %               % the plane and "n" is the normal vector of the plane.
  %               %
  %               % The function actually calculates intersection segments of triangels of
  %               % the surface with the plane and finally all the calculated segments is 
  %               % linked to each other to form a continuous intersection region
  %               % Mehmet ÖZTÜRK 2011
  %               % % EXAMPLE
  %               % % create some surface data
  %               % [exx1 eyy1 ezz1]=sphere(100);
  %               % fv1 = surf2patch(exx1,eyy1,ezz1,'triangles');
  %               % [exx2 eyy2 ezz2]=cylinder(0:3,100);
  %               % fv2 = surf2patch(exx2,eyy2,ezz2-1.25,'triangles');% convert patches to triangels
  %               % 
  %               % fv.faces=[fv1.faces; fv2.faces+length(fv1.vertices)];
  %               % fv.vertices=[fv1.vertices; fv2.vertices];
  %               % 
  %               % p0=[1 0 0]; v=[1 1 1]; % a plane that parallel to the xy plane and passes the point [0 0 0]
  %               % v=v./norm(v);
  %               % 
  %               % intersectPlaneSurf(fv,p0,n)
  %               % input argument checking
  % 
  %               if nargin==0
  %                   error('ÝntersectPlaneSurf: not enough input arguments')
  %               elseif nargin==1
  %                   if size(fv.faces,1)~=3
  %                       error('ÝntersectPlaneSurf: surface data must be defined with triangels')
  %                   end
  %                   error('ÝntersectPlaneSurf: The plane must be defined with a point p0 and a normal vector of the plane n')
  %               elseif nargin==2
  %                   error('ÝntersectPlaneSurf: The plane must be defined with 2 arguemnts')
  %               end
  % 
  %               % normalized the normal vector of the plane if necessary
  %               if norm(n)~=1
  %                   n=n./norm(n);
  %               end
  % 
  %               % allocate memory for coordinates of the segments
  %               segment_start=nan(3,round(length(fv.faces)/2));
  %               segment_finish=segment_start;
  %               count=1; % counter for segments
  %               thr=1e-9; % threshold value for zero checking
  %               for s=1:size(fv.faces,1)
  %                   % define vertices of the triangle
  %                   P1=fv.vertices(fv.faces(s,1),:)';
  %                   P2=fv.vertices(fv.faces(s,2),:)';
  %                   P3=fv.vertices(fv.faces(s,3),:)';
  % 
  %                   % is there any intersection?
  %                   [ num_int, pi ] = IntersectPlaneTriangle( n, p0, P1, P2, P3);
  %                   % IntersectPlaneTriangle function returns (first outout argument) 0 if there is no intersection
  %                   % and 2 if there is intersection segment. The second output is the
  %                   % start and end point coordinates (3x2 matrix) of intersection segment if any
  %                   % Check for intersection and also a test for seperate points (only one intersection)
  %                   if num_int==2 && sqrt(sum((pi(:,1)-pi(:,2)).^2,1))>thr
  %                       segment_start(:,count)=pi(:,1);
  %                       segment_finish(:,count)=pi(:,2);
  %                       count=count+1;
  %                   end
  %               end
  % 
  %               segment_start(:,all(isnan(segment_start),1))=[]; % remove unused memory portions
  %               segment_finish(:,all(isnan(segment_finish),1))=[]; % remove unused memory portions
  %               % Begin to classify segments
  %               % Classification process is based on euclidean distances of segments
  %               nol=1; % initial number of line
  %               while ~isempty(segment_start)
  %                   lin{nol}=[segment_start(:,1) segment_finish(:,1)]; % start from the first segment remained
  %                   if sqrt(sum((lin{nol}(:,end)-lin{nol}(:,end-1)).^2,1))<=thr % they are the same points?
  %                       lin{nol}(:,end)=[]; % if so remove one
  %                   end
  %                   segment_start(:,1)=[]; % remove also used segments
  %                   segment_finish(:,1)=[];
  % 
  %                   while ~isempty(segment_start) % start to search nearby segments
  %                       distEnd2Start=sqrt(sum(bsxfun(@minus,lin{nol}(:,end),segment_start).^2,1));
  %                       distEnd2End=sqrt(sum(bsxfun(@minus,lin{nol}(:,end),segment_finish).^2,1));
  %                       distStart2Start=sqrt(sum(bsxfun(@minus,lin{nol}(:,1),segment_start).^2,1));
  %                       distStart2End=sqrt(sum(bsxfun(@minus,lin{nol}(:,1),segment_finish).^2,1));
  % 
  %                       distMat=[distEnd2Start;distEnd2End;distStart2Start;distStart2End];
  % 
  %                       [row col]=find(distMat<=thr);
  %                       if ~isempty(row)
  %                           row=row(1); col=col(1);
  %                           switch row
  %                               case 1
  %                                   lin{nol}=[lin{nol} segment_finish(:,col)];
  %                                   if sqrt(sum((lin{nol}(:,end)-lin{nol}(:,end-1)).^2,1))<=thr
  %                                       lin{nol}(:,end)=[];
  %                                   end
  %                                   segment_start(:,col)=[];
  %                                   segment_finish(:,col)=[];
  %                               case 2
  %                                   lin{nol}=[lin{nol} segment_start(:,col)];
  %                                   if sqrt(sum((lin{nol}(:,end)-lin{nol}(:,end-1)).^2,1))<=thr
  %                                       lin{nol}(:,end)=[];
  %                                   end
  %                                   segment_start(:,col)=[];
  %                                   segment_finish(:,col)=[];
  %                               case 3
  %                                   lin{nol}=[segment_finish(:,col) lin{nol}];
  %                                   if sqrt(sum((lin{nol}(:,1)-lin{nol}(:,2)).^2,1))<=thr
  %                                       lin{nol}(:,1)=[];
  %                                   end
  %                                   segment_start(:,col)=[];
  %                                   segment_finish(:,col)=[];
  %                               case 4
  %                                   lin{nol}=[segment_start(:,col) lin{nol}];
  %                                   if sqrt(sum((lin{nol}(:,1)-lin{nol}(:,2)).^2,1))<=thr
  %                                       lin{nol}(:,1)=[];
  %                                   end
  %                                   segment_start(:,col)=[];
  %                                   segment_finish(:,col)=[];
  %                           end
  %                       else
  %                           nol=nol+1;
  %                           break
  %                       end
  %                   end
  %               end
  % 
  %               if nargout==0 % if no output is wanted, then visualize the final situation :)
  %                   fig=patch(fv);
  %                   set(fig,'FaceColor',[.8 .8 .8],'EdgeColor','none');
  %                   camlight,lighting gouraud
  %                   plane=createPlane(p0,n(:).'); % createPlane function si from geom3d toolbox
  %                   hold on, hpl=drawPlane3d(plane,'b','FaceAlpha', 0.7); % and also drawPlane3d
  %                   set(hpl,'FaceAlpha',0.7)
  %                   daspect([1 1 1]); axis('vis3d')
  %                   set(gcf,'Renderer','OpenGL')
  %                   for m=1:size(lin,2)
  %                       hold on,plot3(lin{m}(1,:),lin{m}(2,:),lin{m}(3,:),'k','Linewidth',2)
  %                   end
  %                   hold off
  %               end          
  %       end
            
 %--------------------------------------------------------------------------------------------------------------------------            
 %-------------------------------------------------------------------------------------------------------------------------- 
 % FUNCTION FROM MATLAB FILE EXCHANGE BY: Brett Shoelson
 % https://www.mathworks.com/matlabcentral/fileexchange/36953-patchline
 % Cite As: Brett Shoelson (2022). Patchline (https://www.mathworks.com/matlabcentral/fileexchange/36953-patchline), MATLAB Central File Exchange. Retrieved February 3, 2022.
                
               function p = patchline(app,xs,ys,varargin)
                        % Plot lines as patches (efficiently)
                        %
                        % SYNTAX:
                        %     patchline(xs,ys)
                        %     patchline(xs,ys,zs,...)
                        %     patchline(xs,ys,zs,'PropertyName',propertyvalue,...)
                        %     p = patchline(...)
                        %
                        % PROPERTIES: 
                        %     Accepts all parameter-values accepted by PATCH.
                        % 
                        % DESCRIPTION:
                        %     p = patchline(xs,ys,zs,'PropertyName',propertyvalue,...)
                        %         Takes a vector of x-values (xs) and a same-sized
                        %         vector of y-values (ys). z-values (zs) are
                        %         supported, but optional; if specified, zs must
                        %         occupy the third input position. Takes all P-V
                        %         pairs supported by PATCH. Returns in p the handle
                        %         to the resulting patch object.
                        %         
                        % NOTES:
                        %     Note that we are drawing 0-thickness patches here,
                        %     represented only by their edges. FACE PROPERTIES WILL
                        %     NOT NOTICEABLY AFFECT THESE OBJECTS! (Modify the
                        %     properties of the edges instead.)
                        %
                        %     LINUX (UNIX) USERS: One test-user found that this code
                        %     worked well on his Windows machine, but crashed his
                        %     Linux box. We traced the problem to an openGL issue;
                        %     the problem can be fixed by calling 'opengl software'
                        %     in your <http://www.mathworks.com/help/techdoc/ref/startup.html startup.m>.
                        %     (That command is valid at startup, but not at runtime,
                        %     on a unix machine.)
                        %
                        % EXAMPLES:
                        %%% Example 1:
                        %
                        % n = 10;
                        % xs = rand(n,1);
                        % ys = rand(n,1);
                        % zs = rand(n,1)*3;
                        % plot3(xs,ys,zs,'r.')
                        % xlabel('x');ylabel('y');zlabel('z');
                        % p  = patchline(xs,ys,zs,'linestyle','--','edgecolor','g',...
                        %     'linewidth',3,'edgealpha',0.2);
                        %
                        %%% Example 2: (Note "hold on" not necessary here!)
                        %
                        % t = 0:pi/64:4*pi;
                        % p(1) = patchline(t,sin(t),'edgecolor','b','linewidth',2,'edgealpha',0.5);
                        % p(2) = patchline(t,cos(t),'edgecolor','r','linewidth',2,'edgealpha',0.5);
                        % l = legend('sine(t)','cosine(t)');
                        % tmp = sort(findobj(l,'type','patch'));
                        % for ii = 1:numel(tmp)
                        %     set(tmp(ii),'facecolor',get(p(ii),'edgecolor'),'facealpha',get(p(ii),'edgealpha'),'edgecolor','none')
                        % end
                        %
                        %%% Example 3 (requires Image Processing Toolbox):
                        %%%   (NOTE that this is NOT the same as showing a transparent image on 
                        %%%         of the existing image. (That functionality is
                        %%%         available using showMaskAsOverlay or imoverlay).
                        %%%         Instead, patchline plots transparent lines over
                        %%%         the image.)
                        %
                        % img = imread('rice.png');
                        % imshow(img)
                        % img = imtophat(img,strel('disk',15));
                        % grains = im2bw(img,graythresh(img));
                        % grains = bwareaopen(grains,10);
                        % edges = edge(grains,'canny');
                        % boundaries = bwboundaries(edges,'noholes');
                        % cmap = jet(numel(boundaries));
                        % ind = randperm(numel(boundaries));
                        % for ii = 1:numel(boundaries)
                        % patchline(boundaries{ii}(:,2),boundaries{ii}(:,1),...
                        %     'edgealpha',0.2,'edgecolor',cmap(ind(ii),:),'linewidth',3);
                        % end
                        %
                        % Written by Brett Shoelson, PhD
                        % brett.shoelson@mathworks.com
                        % 5/31/2012
                        % 
                        % Revisions:
                        % 6/26 Improved rice.png example, modified FEX image.
                        % 11/30/30/2020 Fixed an issue on line 113 if varargin is empty.
                        %
                        % Copyright 2012 MathWorks, Inc.
                        %
                        % See also: patch, line, plot
                        [zs,PVs] = parseInputs(app,varargin{:});
                        if rem(numel(PVs),2) ~= 0
                            % Odd number of inputs!
                            error('patchline: Parameter-Values must be entered in valid pairs')
                        end
                        % Facecolor = 'k' is (essentially) ignored here, but syntactically necessary
                        if isempty(zs)
                            p = patch([xs(:);NaN],[ys(:);NaN],'k');
                        else
                            p = patch([xs(:);NaN],[ys(:);NaN],[zs(:);NaN],'k');
                        end
                        % Apply PV pairs
                        for ii = 1:2:numel(PVs)
                            set(p,PVs{ii},PVs{ii+1})
                        end
                        if nargout == 0
                            clear p
                        end
                  end
                  
                  function [zs,PVs] = parseInputs(app,varargin)
                        if ~isempty(varargin) && isnumeric(varargin{1})
                            zs = varargin{1};
                            PVs = varargin(2:end);
                        else
                            PVs = varargin;
                            zs = [];
                        end              
                  end


 %-------------------------------------------------------------------------------------------------------------------------- 
 %--------------------------------------------------------------------------------------------------------------------------                   
 % https://www.mathworks.com/matlabcentral/fileexchange/37856-inpolyhedron-are-points-inside-a-triangulated-volume?s_tid=srchtitle                
 % Cite As: Sven (2022). inpolyhedron - are points inside a triangulated volume? (https://www.mathworks.com/matlabcentral/fileexchange/37856-inpolyhedron-are-points-inside-a-triangulated-volume), MATLAB Central File Exchange. Retrieved February 3, 2022.                 
                  
           function IN = inpolyhedron(app,varargin)
                %INPOLYHEDRON  Tests if points are inside a 3D triangulated (faces/vertices) surface
                %
                %   IN = INPOLYHEDRON(FV,QPTS) tests if the query points (QPTS) are inside
                %   the patch/surface/polyhedron defined by FV (a structure with fields
                %   'vertices' and 'faces'). QPTS is an N-by-3 set of XYZ coordinates. IN
                %   is an N-by-1 logical vector which will be TRUE for each query point
                %   inside the surface. By convention, surface normals point OUT from the
                %   object (see FLIPNORMALS option below if to reverse this convention).
                %
                %   INPOLYHEDRON(FACES,VERTICES,...) takes faces/vertices separately, rather than in
                %   an FV structure.
                %
                %   IN = INPOLYHEDRON(..., X, Y, Z) voxelises a mask of 3D gridded query points
                %   rather than an N-by-3 array of points. X, Y, and Z coordinates of the grid
                %   supplied in XVEC, YVEC, and ZVEC respectively. IN will return as a 3D logical
                %   volume with SIZE(IN) = [LENGTH(YVEC) LENGTH(XVEC) LENGTH(ZVEC)], equivalent to
                %   syntax used by MESHGRID. INPOLYHEDRON handles this input faster and with a lower 
                %   memory footprint than using MESHGRID to make full X, Y, Z query points matrices.
                %
                %   INPOLYHEDRON(...,'PropertyName',VALUE,'PropertyName',VALUE,...) tests query
                %   points using the following optional property values:
                %
                %   TOL           - Tolerance on the tests for "inside" the surface. You can think of
                %   tol as the distance a point may possibly lie above/below the surface, and still
                %   be perceived as on the surface. Due to numerical rounding nothing can ever be
                %   done exactly here. Defaults to ZERO. Note that in the current implementation TOL
                %   only affects points lying above/below a surface triangle (in the Z-direction).
                %   Points coincident with a vertex in the XY plane are considered INside the surface.
                %   More formal rules can be implemented with input/feedback from users.
                %
                %   GRIDSIZE      - Internally, INPOLYHEDRON uses a divide-and-conquer algorithm to
                %   split all faces into a chessboard-like grid of GRIDSIZE-by-GRIDSIZE regions.
                %   Performance will be a tradeoff between a small GRIDSIZE (few iterations, more
                %   data per iteration) and a large GRIDSIZE (many iterations of small data
                %   calculations). The sweet-spot has been experimentally determined (on a win64
                %   system) to be correlated with the number of faces/vertices. You can overwrite
                %   this automatically computed choice by specifying a GRIDSIZE parameter.
                %
                %   FACENORMALS   - By default, the normals to the FACE triangles are computed as the
                %   cross-product of the first two triangle edges. You may optionally specify face
                %   normals here if they have been pre-computed.
                %
                %   FLIPNORMALS   - (Defaults FALSE). To match a wider convention, triangle
                %   face normals are presumed to point OUT from the object's surface. If
                %   your surface normals are defined pointing IN, then you should set the
                %   FLIPNORMALS option to TRUE to use the reverse of this convention.
                %
                %   Example:
                %       tmpvol = zeros(20,20,20);       % Empty voxel volume
                %       tmpvol(5:15,8:12,8:12) = 1;     % Turn some voxels on
                %       tmpvol(8:12,5:15,8:12) = 1;
                %       tmpvol(8:12,8:12,5:15) = 1;
                %       fv = isosurface(tmpvol, 0.99);  % Create the patch object
                %       fv.faces = fliplr(fv.faces);    % Ensure normals point OUT
                %       % Test SCATTERED query points
                %       pts = rand(200,3)*12 + 4;       % Make some query points
                %       in = inpolyhedron(fv, pts);     % Test which are inside the patch
                %       figure, hold on, view(3)        % Display the result
                %       patch(fv,'FaceColor','g','FaceAlpha',0.2)
                %       plot3(pts(in,1),pts(in,2),pts(in,3),'bo','MarkerFaceColor','b')
                %       plot3(pts(~in,1),pts(~in,2),pts(~in,3),'ro'), axis image
                %       % Test STRUCTURED GRID of query points
                %       gridLocs = 3:2.1:19;
                %       [x,y,z] = meshgrid(gridLocs,gridLocs,gridLocs);
                %       in = inpolyhedron(fv, gridLocs,gridLocs,gridLocs);
                %       figure, hold on, view(3)        % Display the result
                %       patch(fv,'FaceColor','g','FaceAlpha',0.2)
                %       plot3(x(in), y(in), z(in),'bo','MarkerFaceColor','b')
                %       plot3(x(~in),y(~in),z(~in),'ro'), axis image
                %
                % See also: UNIFYMESHNORMALS (on the <a href="http://www.mathworks.com/matlabcentral/fileexchange/loadFile.do?objectId=43013">file exchange</a>)
                % TODO-list
                % - Optmise overall memory footprint. (need examples with MEM errors)
                % - Implement an "ignore these" step to speed up calculations for:
                %     * Query points outside the convex hull of the faces/vertices input
                % - Get a better/best gridSize calculation. User feedback?
                % - Detect cases where X-rays or Y-rays would be better than Z-rays?
                %
                %   Author: Sven Holcombe
                % - 10 Jun 2012: Version 1.0
                % - 28 Aug 2012: Version 1.1 - Speedup using accumarray
                % - 07 Nov 2012: Version 2.0 - BEHAVIOUR CHANGE
                %                Query points coincident with a VERTEX are now IN an XY triangle
                % - 18 Aug 2013: Version 2.1 - Gridded query point handling with low memory footprint.
                % - 10 Sep 2013: Version 3.0 - BEHAVIOUR CHANGE 
                %                NEW CONVENTION ADOPTED to expect face normals pointing IN
                %                Vertically oriented faces are now ignored. Speeds up
                %                computation and fixes bug where presence of vertical faces
                %                produced NaN distance from a query pt to facet, making all
                %                query points under facet erroneously NOT IN polyhedron.
                % - 25 Sep 2013: Version 3.1 - Dropped nested unique call which was made
                %                mostly redundant via v2.1 gridded point handling. Also
                %                refreshed grid size selection via optimisation.
                % - 25 Feb 2014: Version 3.2 - Fixed indeterminate behaviour for query 
                %                points *exactly* in line with an "overhanging" vertex.
                % - 11 Nov 2016: Version 3.3 - Used quoted semicolons ':' inside function 
                %                handle calls to conform with new 2015b interpreter
                %%
                % FACETS is an unpacked arrangement of faces/vertices. It is [3-by-3-by-N],
                % with 3 1-by-3 XYZ coordinates of N faces.
                [facets, qPts, options] = parseInputs2(app,varargin{:});
                numFaces = size(facets,3);
                if ~options.griddedInput            % SCATTERED QUERY POINTS
                    numQPoints = size(qPts,1);
                else                                % STRUCTURED QUERY POINTS
                    numQPoints = prod(cellfun(@numel,qPts(1:2)));
                end
                % Precompute 3d normals to all facets (triangles). Do this via the cross
                % product of the first edge vector with the second. Normalise the result.
                allEdgeVecs = facets([2 3 1],:,:) - facets(:,:,:);
                if isempty(options.facenormals)
                    allFacetNormals =  bsxfun(@times, allEdgeVecs(1,[2 3 1],:), allEdgeVecs(2,[3 1 2],:)) - ...
                        bsxfun(@times, allEdgeVecs(2,[2 3 1],:), allEdgeVecs(1,[3 1 2],:));
                    allFacetNormals = bsxfun(@rdivide, allFacetNormals, sqrt(sum(allFacetNormals.^2,2)));
                else
                    allFacetNormals = permute(options.facenormals,[3 2 1]);
                end
                if options.flipnormals
                    allFacetNormals = -allFacetNormals;
                end
                % We use a Z-ray intersection so we don't even need to consider facets that
                % are purely vertically oriented (have zero Z-component).
                isFacetUseful = allFacetNormals(:,3,:) ~= 0;
                %% Setup grid referencing system
                % Function speed can be thought of as a function of grid size. A small number of grid
                % squares means iterating over fewer regions (good) but with more faces/qPts to
                % consider each time (bad). For any given mesh/queryPt configuration, there will be a
                % sweet spot that minimises computation time. There will also be a constraint from
                % memory available - low grid sizes means considering many queryPt/faces at once,
                % which will require a larger memory footprint. Here we will let the user specify
                % gridsize directly, or we will estimate the optimum size based on prior testing.
                if ~isempty(options.gridsize)
                    gridSize = options.gridsize;
                else
                    % Coefficients (with 95% confidence bounds):
                    p00 =         -47;    p10 =       12.83;    p01 =       20.89;
                    p20 =      0.7578;    p11 =      -6.511;    p02 =      -2.586;
                    p30 =     -0.1802;    p21 =      0.2085;    p12 =      0.7521;
                    p03 =     0.09984;    p40 =    0.005815;    p31 =    0.007775;
                    p22 =    -0.02129;    p13 =    -0.02309;
                    GSfit = @(x,y)p00 + p10*x + p01*y + p20*x^2 + p11*x*y + p02*y^2 + p30*x^3 + p21*x^2*y + p12*x*y^2 + p03*y^3 + p40*x^4 + p31*x^3*y + p22*x^2*y^2 + p13*x*y^3;
                    gridSize = min(150 ,max(1, ceil(GSfit(log(numQPoints),log(numFaces)))));
                    if isnan(gridSize), gridSize = 1; end
                end
                %% Find candidate qPts -> triangles pairs
                % We have a large set of query points. For each query point, find potential
                % triangles that would be pierced by vertical rays through the qPt. First,
                % a simple filter by XY bounding box
                % Calculate the bounding box of each facet
                minFacetCoords = permute(min(facets(:,1:2,:),[],1),[3 2 1]);
                maxFacetCoords = permute(max(facets(:,1:2,:),[],1),[3 2 1]);
                % Set rescale values to rescale all vertices between 0(-eps) and 1(+eps)
                scalingOffsetsXY = min(minFacetCoords,[],1) - eps;
                scalingRangeXY = max(maxFacetCoords,[],1) - scalingOffsetsXY + 2*eps;
                % Based on scaled min/max facet coords, get the [lowX lowY highX highY] "grid" index
                % of all faces
                lowToHighGridIdxs = floor(bsxfun(@rdivide, ...
                    bsxfun(@minus, ... % Use min/max coordinates of each facet (+/- the tolerance)
                    [minFacetCoords-options.tol maxFacetCoords+options.tol],...
                    [scalingOffsetsXY scalingOffsetsXY]),...
                    [scalingRangeXY scalingRangeXY]) * gridSize) + 1;
                % Build a grid of cells. In each cell, place the facet indices that encroach into
                % that grid region. Similarly, each query point will be assigned to a grid region.
                % Note that query points will be assigned only one grid region, facets can cover many
                % regions. Furthermore, we will add a tolerance to facet region assignment to ensure
                % a query point will be compared to facets even if it falls only on the edge of a
                % facet's bounding box, rather than inside it.
                cells = cell(gridSize);
                [unqLHgrids,~,facetInds] = unique(lowToHighGridIdxs,'rows');
                tmpInds = accumarray(facetInds(isFacetUseful),find(isFacetUseful),[size(unqLHgrids,1),1],@(x){x});
                for xi = 1:gridSize
                    xyMinMask = xi >= unqLHgrids(:,1) & xi <= unqLHgrids(:,3);
                    for yi = 1:gridSize
                        cells{yi,xi} = cat(1,tmpInds{xyMinMask & yi >= unqLHgrids(:,2) & yi <= unqLHgrids(:,4)});
                        % The above line (with accumarray) is faster with equiv results than:
                        % % cells{yi,xi} = find(ismember(facetInds, xyInds));
                    end
                end
                % With large number of facets, memory may be important:
                clear lowToHightGridIdxs LHgrids facetInds tmpInds xyMinMask minFacetCoords maxFacetCoords
                %% Compute edge unit vectors and dot products
                % Precompute the 2d unit vectors making up each facet's edges in the XY plane.
                allEdgeUVecs = bsxfun(@rdivide, allEdgeVecs(:,1:2,:), sqrt(sum(allEdgeVecs(:,1:2,:).^2,2)));
                % Precompute the inner product between edgeA.edgeC, edgeB.edgeA, edgeC.edgeB
                allEdgeEdgeDotPs = sum(allEdgeUVecs .* -allEdgeUVecs([3 1 2],:,:),2) - 1e-9;
                %% Gather XY query locations
                % Since query points are most likely given as a (3D) grid of query locations, we only
                % need to consider the unique XY locations when asking which facets a vertical ray
                % through an XY location would pierce.
                if ~options.griddedInput            % SCATTERED QUERY POINTS
                    qPtsXY = @(varargin)qPts(:,1:2);
                    qPtsXYZViaUnqIndice = @(ind)qPts(ind,:);
                    outPxIndsViaUnqIndiceMask = @(ind,mask)ind(mask);
                    outputSize = [size(qPts,1),1];
                    reshapeINfcn = @(INMASK)INMASK;
                    minFacetDistanceFcn = @minFacetToQptDistance;
                else                                % STRUCTURED QUERY POINTS
                    [xmat,ymat] = meshgrid(qPts{1:2});
                    qPtsXY = [xmat(:) ymat(:)];
                    % A standard set of Z locations will be shifted around by different
                    % unqQpts XY coordinates.
                    zCoords = qPts{3}(:) * [0 0 1];
                    qPtsXYZViaUnqIndice = @(ind)bsxfun(@plus, zCoords, [qPtsXY(ind,:) 0]);
                    % From a given indice and mask, we will turn on/off the IN points under
                    % that indice based on the mask. The easiest calculation is to setup
                    % the IN matrix as a numZpts-by-numUnqPts mask. At the end, we must
                    % unpack/reshape this 2D mask to a full 3D logical mask
                    numZpts = size(zCoords,1);
                    baseZinds = 1:numZpts;
                    outPxIndsViaUnqIndiceMask = @(ind,mask)(ind-1)*numZpts + baseZinds(mask);
                    outputSize = [numZpts, size(qPtsXY,1)];
                    reshapeINfcn = @(INMASK)reshape(INMASK', cellfun(@numel, qPts([2 1 3])));
                    minFacetDistanceFcn = @minFacetToQptsDistance;
                end
                % Start with every query point NOT inside the polyhedron. We will
                % iteratively find those query points that ARE inside.
                IN = false(outputSize);
                % Determine with grids each query point falls into.
                qPtGridXY = floor(bsxfun(@rdivide, bsxfun(@minus, qPtsXY(':',':'), scalingOffsetsXY),...
                    scalingRangeXY) * gridSize) + 1;
                [unqQgridXY,~,qPtGridInds] = unique(qPtGridXY,'rows');
                % We need only consider grid indices within those already set up
                ptsToConsidMask = ~any(qPtGridXY<1 | qPtGridXY>gridSize, 2);
                if ~any(ptsToConsidMask)
                    IN = reshapeINfcn(IN);
                    return;
                end
                % Build the reference list
                cellQptContents = accumarray(qPtGridInds(ptsToConsidMask),find(ptsToConsidMask), [],@(x){x});
                gridsToCheck = unqQgridXY(~any(unqQgridXY<1 | unqQgridXY>gridSize, 2),:);
                cellQptContents(cellfun('isempty',cellQptContents)) = [];
                gridIndsToCheck = sub2ind(size(cells), gridsToCheck(:,2), gridsToCheck(:,1));
                % For ease of multiplication, reshape qPt XY coords to [1-by-2-by-1-by-N]
                qPtsXY = permute(qPtsXY(':',':'),[4 2 3 1]);
                % There will be some grid indices with query points but without facets.
                emptyMask = cellfun('isempty',cells(gridIndsToCheck))';
                for i = find(~emptyMask)
                    % We get all the facet coordinates (ie, triangle vertices) of triangles
                    % that intrude into this grid location. The size is [3-by-2-by-N], for
                    % the [3vertices-by-XY-by-Ntriangles]
                    allFacetInds = cells{gridIndsToCheck(i)};
                    candVerts = facets(:,1:2,allFacetInds);
                    % We need the XY coordinates of query points falling into this grid.
                    allqPtInds = cellQptContents{i};
                    queryPtsXY = qPtsXY(:,:,:,allqPtInds);
                    % Get unit vectors pointing from each triangle vertex to my query point(s)
                    vert2ptVecs = bsxfun(@minus, queryPtsXY, candVerts);
                    vert2ptUVecs = bsxfun(@rdivide, vert2ptVecs, sqrt(sum(vert2ptVecs.^2,2)));
                    % Get unit vectors pointing around each triangle (along edge A, edge B, edge C)
                    edgeUVecs = allEdgeUVecs(:,:,allFacetInds);
                    % Get the inner product between edgeA.edgeC, edgeB.edgeA, edgeC.edgeB
                    edgeEdgeDotPs = allEdgeEdgeDotPs(:,:,allFacetInds);
                    % Get inner products between each edge unit vec and the UVs from qPt to vertex
                    edgeQPntDotPs = sum(bsxfun(@times, edgeUVecs, vert2ptUVecs),2);
                    qPntEdgeDotPs = sum(bsxfun(@times,vert2ptUVecs, -edgeUVecs([3 1 2],:,:)),2);
                    % If both inner products 2 edges to the query point are greater than the inner
                    % product between the two edges themselves, the query point is between the V
                    % shape made by the two edges. If this is true for all 3 edge pair, the query
                    % point is inside the triangle.
                    resultIN = all(bsxfun(@gt, edgeQPntDotPs, edgeEdgeDotPs) & bsxfun(@gt, qPntEdgeDotPs, edgeEdgeDotPs),1);
                    resultONVERTEX = any(any(isnan(vert2ptUVecs),2),1);
                    result = resultIN | resultONVERTEX;
                    qPtHitsTriangles = any(result,3);
                    % If NONE of the query points pierce ANY triangles, we can skip forward
                    if ~any(qPtHitsTriangles), continue, end
                    % In the next step, we'll need to know the indices of ALL the query points at
                    % each of the distinct XY coordinates. Let's get their indices into "qPts" as a
                    % cell of length M, where M is the number of unique XY points we had found.
                    for ptNo = find(qPtHitsTriangles(:))'
                        % Which facets does it pierce?
                        piercedFacetInds = allFacetInds(result(1,1,:,ptNo));
                        
                        % Get the 1-by-3-by-N set of triangle normals that this qPt pierces       
                        piercedTriNorms = allFacetNormals(:,:,piercedFacetInds);
                        
                        % Pick the first vertex as the "origin" of a plane through the facet. Get the
                        % vectors from each query point to each facet origin
                        facetToQptVectors = bsxfun(@minus, ...
                            qPtsXYZViaUnqIndice(allqPtInds(ptNo)),...
                            facets(1,:,piercedFacetInds));
                        
                        % Calculate how far you need to go up/down to pierce the facet's plane.
                        % Positive direction means "inside" the facet, negative direction means
                        % outside.
                        facetToQptDists = bsxfun(@rdivide, ...
                            sum(bsxfun(@times,piercedTriNorms,facetToQptVectors),2), ...
                            abs(piercedTriNorms(:,3,:)));
                        
                        % Since it's possible for two triangles sharing the same vertex to
                        % be the same distance away, I want to sum up all the distances of
                        % triangles that are closest to the query point. Simple case: The
                        % closest triangle is unique Edge case: The closest triangle is one
                        % of many the same distance and direction away. Tricky case: The
                        % closes triangle has another triangle the equivalent distance
                        % but facing the opposite direction
                        IN( outPxIndsViaUnqIndiceMask(allqPtInds(ptNo), ...
                            minFacetDistanceFcn(app,facetToQptDists)<options.tol...
                            )) = true;
                    end
                end
                % If they provided X,Y,Z vectors of query points, our output is currently a
                % 2D mask and must be reshaped to [LEN(Y) LEN(X) LEN(Z)].
                IN = reshapeINfcn(IN);
                % Called subfunctions
                % vertices = [
                %     0.9046    0.1355   -0.0900
                %     0.8999    0.3836   -0.0914
                %     1.0572    0.2964   -0.0907
                %     0.8735    0.1423   -0.1166
                %     0.8685    0.4027   -0.1180
                %     1.0337    0.3112   -0.1173
                %     0.9358    0.1287   -0.0634
                %     0.9313    0.3644   -0.0647
                %     1.0808    0.2816   -0.0641
                % ];
                % faces = [
                %      1     2     5
                %      1     5     4
                %      2     3     6
                %      2     6     5
                %      3     1     4
                %      3     4     6
                %      6     4     5
                %      2     1     8
                %      8     1     7
                %      3     2     9
                %      9     2     8
                %      1     3     7
                %      7     3     9
                %      7     9     8
                % ];
                % point = [vertices(3,1),vertices(3,2),1.5];
                
           end
           
                %----------------------------------------------------------------------------------------
                function closestTriDistance = minFacetToQptDistance(app,facetToQptDists)
                    % FacetToQptDists is a 1pt-by-1-by-Nfacets array of how far you need to go
                    % up/down to pierce each facet's plane. If the Qpt was directly over an
                    % "overhang" vertex, then two facets with opposite orientation will be
                    % equally distant from the Qpt, with one distance positive and one
                    % negative. In such cases, it is impossible for the Qpt to actually be
                    % "inside" this pair of facets, so their distance is updated to Inf.
                    [~,minInd] = min(abs(facetToQptDists),[],3);
                    while any( abs(facetToQptDists + facetToQptDists(minInd)) < 1e-15 )
                        % Since the above comparison is made every time, but the below variable
                        % setting is done only in the rare case that a query point coincides
                        % with an overhang vertex, it is more efficient to re-compute the
                        % equality when it's true, rather than store the result every time.
                        facetToQptDists( abs(facetToQptDists) - abs(facetToQptDists(minInd)) < 1e-15) = inf;
                        if ~any(isfinite(facetToQptDists))
                            break;
                        end
                        [~,minInd] = min(abs(facetToQptDists),[],3);
                    end
                    closestTriDistance = facetToQptDists(minInd);
                
                end
                
                %----------------------------------------------------------------------------------------
                function closestTriDistance = minFacetToQptsDistance(app,facetToQptDists)
                    % As above, but facetToQptDists is an Mpts-by-1-by-Nfacets array.
                    % The multi-point version is a little more tricky. While below is quite a
                    % bit slower when the while loop is entered, it is very rarely entered and
                    % very fast to make just the initial comparison.
                    [minVals,minInds] = min(abs(facetToQptDists),[],3);
                    while any(...
                            any(abs(bsxfun(@plus,minVals,facetToQptDists))<1e-15,3) & ...
                            any(abs(bsxfun(@minus,minVals,facetToQptDists))<1e-15,3))
                        maskP = abs(bsxfun(@plus,minVals,facetToQptDists))<1e-15;
                        maskN = abs(bsxfun(@minus,minVals,facetToQptDists))<1e-15;
                        mustAlterMask = any(maskP,3) & any(maskN,3);
                        for i = find(mustAlterMask)'
                            facetToQptDists(i,:,maskP(i,:,:) | maskN(i,:,:)) = inf;
                        end
                        [newMv,newMinInds] = min(abs(facetToQptDists(mustAlterMask,:,:)),[],3);
                        minInds(mustAlterMask) = newMinInds(:);
                        minVals(mustAlterMask) = newMv(:);
                    end
                    % Below is a tiny speedup on basically a sub2ind call.
                    closestTriDistance = facetToQptDists((minInds-1)*size(facetToQptDists,1) + (1:size(facetToQptDists,1))');
                    % Input handling subfunctions
                end
                
                %----------------------------------------------------------------------------------------
                function [facets, qPts, options] = parseInputs2(app,varargin)
                    
                    % Gather FACES and VERTICES
                    if isstruct(varargin{1})                        % inpolyhedron(FVstruct, ...)
                        if ~all(isfield(varargin{1},{'vertices','faces'}))
                            error( 'Structure FV must have "faces" and "vertices" fields' );
                        end
                        faces = varargin{1}.faces;
                        vertices = varargin{1}.vertices;
                        varargin(1) = []; % Chomp off the faces/vertices
                        
                    else                                            % inpolyhedron(FACES, VERTICES, ...)
                        faces = varargin{1};
                        vertices = varargin{2};
                        varargin(1:2) = []; % Chomp off the faces/vertices
                    end
                    % Unpack the faces/vertices into [3-by-3-by-N] facets. It's better to
                    % perform this now and have FACETS only in memory in the main program,
                    % rather than FACETS, FACES and VERTICES
                    facets = vertices';
                    facets = permute(reshape(facets(:,faces'), 3, 3, []),[2 1 3]);
                    % Extract query points
                    if length(varargin)<2 || ischar(varargin{2})    % inpolyhedron(F, V, [x(:) y(:) z(:)], ...)
                        qPts = varargin{1};
                        varargin(1) = []; % Chomp off the query points
                    else                                            % inpolyhedron(F, V, xVec, yVec, zVec, ...)
                        qPts = varargin(1:3);
                        % Chomp off the query points and tell the world that it's gridded input.
                        varargin(1:3) = [];
                        varargin = [varargin {'griddedInput',true}];
                    end
                        
                    % Extract configurable options
                    options = parseOptions(app,varargin{:});
                    % Check if face normals are unified
                    if options.testNormals
                        options.normalsAreUnified = checkNormalUnification(faces);
                    end
                end
                
                %----------------------------------------------------------------------------------------
                function options = parseOptions(app,varargin)
                    IP = inputParser;
                    if verLessThan('matlab', 'R2013b')
                        fcn = 'addParamValue';
                    else
                        fcn = 'addParameter';
                    end
                    IP.(fcn)('gridsize',[], @(x)isscalar(x) && isnumeric(x))
                    IP.(fcn)('tol', 0, @(x)isscalar(x) && isnumeric(x))
                    IP.(fcn)('tol_ang', 1e-5, @(x)isscalar(x) && isnumeric(x))
                    IP.(fcn)('facenormals',[]);
                    IP.(fcn)('flipnormals',false);
                    IP.(fcn)('griddedInput',false);
                    IP.(fcn)('testNormals',false);
                    IP.parse(varargin{:});
                    options = IP.Results;         
                end
                
                
  %--------------------------------------------------------------------------------------------------------------------------                 
  %--------------------------------------------------------------------------------------------------------------------------                  
  %-------------------------------------------------------------------------------------------------------------------------- 
  % FUNCTION FROM MATLAB FILE EXCHANGE BY: J. OLE KAVEN 
  % https://www.mathworks.com/matlabcentral/fileexchange/24943-two-functions-for-smoothing-denoising-of-triangular-meshes?s_tid=srchtitle
  
                function xyzn = lpflow_trismooth(app,xyz,t)
                            % Laplace flow mesh smoothing for vertex ring
                            % 
                            % Reference:    1) Zhang and Hamza, (2006) Vertex-based anisotropic smoothing of
                            %               3D mesh data, IEEE CCECE
                            % Acknowledgement:
                            %               Q. Fang: iso2mesh (http://iso2mesh.sf.net)
                            %
                            % Input:        xyz <nx3> vertex coordinates
                            %               t <mx3> triangulation index array
                            % Output:       xyzn <nx3> updates vertex coordinates
                            % Version:      1
                            % JOK 300709
                        
                            % I/O check: 
%                             if nargin~=2
%                                 error('Wrong # of input')
%                             end
%                             if nargout ~= 1
%                                 error('Output is a single array, wrong designation!')
%                             end
                            nt= size(t);
                            if nt(2)~=3
                                error('Triangle element matrix should be mx3!')
                            end
                            mp = size(xyz);
                            if mp(2)~=3
                                error('Vertices should be nx3!')
                            end
                        
                            % Initialize etc.
                            k=0;
                            [conn,connnum,count]=neighborelem(app,t,max(max(t)));
                            xyzn = xyz;
                            while k<length(xyz)
                                k=k+1;
                                % Find vertex neighborhood
                                indt01=conn{k}; % Element indices
                                indv01 = t(indt01,:);
                                indv01 = unique(indv01(:));
                                vdist = xyz(indv01,:)-repmat(xyz(k,:),length(indv01),1);
                                dist = sqrt(sum(vdist.*vdist,2));
                                indaux1 = find(dist==0);
                                vdist(indaux1,:) =[];
                                d=length(vdist); % Cardinality of vertex
                                if isempty(dist)
                                    xyzn(k,:)  = NaN;
                                end
                                vcorr = sum(vdist/d,1);
                                xyzn(k,:) = xyz(k,:)+vcorr;
                            end
                        
                     end % lpflow_smooth_v01


                     % SUBFUNCTION of lpflow_trismooth (above) ------------------------------------------------------------------
                     function [conn,connnum,count]=neighborelem(app,elem,nn)
                            % [conn,connnum,count]=neighborelem(elem,nn)
                            %
                            % neighborelem: create node neighbor list from a mesh
                            %
                            % author: fangq (fangq<at> nmr.mgh.harvard.edu)
                            % date: 2007/11/21
                            %
                            % parameters:
                            %    elem:  element table of a mesh
                            %    nn  :  total node number of the mesh
                            %    conn:  output, a cell structure of length nn, conn{n}
                            %           contains a list of all neighboring elem ID for node n
                            %    connnum: vector of length nn, denotes the neighbor number of each node
                            %    count: total neighbor numbers
                            %
                            % -- this function is part of iso2mesh toolbox (http://iso2mesh.sf.net)
                            %
                        
                            conn=cell(nn,1);
                            dim=size(elem);
                            for i=1:dim(1)
                              for j=1:dim(2)
                                conn{elem(i,j)}=[conn{elem(i,j)},i];
                              end
                            end
                            count=0;
                            connnum=zeros(1,nn);
                            for i=1:nn
                                conn{i}=sort(conn{i});
                                connnum(i)=length(conn{i});
                                count=count+connnum(i);
                            end
                     end % neighborelem


  %--------------------------------------------------------------------------------------------------------------------------                 
  %--------------------------------------------------------------------------------------------------------------------------                  
  % https://www.mathworks.com/matlabcentral/fileexchange/48725-exact-minimum-bounding-spheres-and-circles?s_tid=srchtitle          
  % Cite As: Anton Semechko (2022). Exact minimum bounding spheres and circles (https://github.com/AntonSemechko/Bounding-Spheres-And-Circles), GitHub. Retrieved February 3, 2022.                   
                     

            function [R,C,Xb] = ExactMinBoundSphere3D(app,X)
                
                % Compute exact minimum bounding sphere of a 3D point cloud (or a 
                % triangular surface mesh) using Welzl's algorithm. 
                %
                %   - X     : M-by-3 list of point co-ordinates or a triangular surface 
                %             mesh specified as a TriRep object.
                %   - R     : radius of the sphere.
                %   - C     : 1-by-3 vector specifying the centroid of the sphere.
                %   - Xb    : subset of X, listing K-by-3 list of point coordinates from 
                %             which R and C were computed. See function titled 
                %             'FitSphere2Points' for more info.
                %
                % REREFERENCES:
                % [1] Welzl, E. (1991), 'Smallest enclosing disks (balls and ellipsoids)',
                %     Lecture Notes in Computer Science, Vol. 555, pp. 359-370
                %
                % AUTHOR: Anton Semechko (a.semechko@gmail.com)
                % DATE: Dec.2014
                %
            
            
                if isobject(X), X=X.X; end
                if size(X,2)~=3
                    error('This function only works for 3D data')
                end
                if sum(isnan(X(:)) | isinf(X(:)))>0
                    error('Point data contains NaN or Inf entries. Remove them and try again.')
                end
            
                % Get the convex hull of the point set
                F=convhulln(X);
                F=unique(F(:));
                X=X(F,:);
            
                % Randomly permute the point set
                idx=randperm(size(X,1));
                X=X(idx(:),:);
            
                % Get the minimum bounding sphere
                if size(X,1)<=4
                    [R,C]=FitSphere2Points(app,X); 
                    Xb=X;
                    return
                end
            
                if size(X,1)<1E3
                    try
            
                        % Center and radius of the sphere
                        [R,C]=B_MinSphere(app,X,[]);
            
                        % Coordiantes of the points used to compute parameters of the 
                        % minimum bounding sphere
                        D=sum(bsxfun(@minus,X,C).^2,2);
                        [D,idx]=sort(abs(D-R^2));
                        Xb=X(idx(1:4),:);
                        D=D(1:4);
                        Xb=Xb(D<1E-6,:);
                        [~,idx]=sort(Xb(:,1));
                        Xb=Xb(idx,:);
                        return
                    catch
                    end
                end
            
                % If we got to this point, then the recursion depth limit was reached. So 
                % need to break-up the the data into smaller sets and then recombine the 
                % results.
                M=size(X,1);
                dM=min(floor(M/4),300);
                res=mod(M,dM);
                n=ceil(M/dM);  
                idx=dM*ones(1,n);
                if res>0
                    idx(end)=res;
                end
            
                if res<=0.25*dM 
                    idx(n-1)=idx(n-1)+idx(n);
                    idx(n)=[];
                    n=n-1;
                end
            
                X=mat2cell(X,idx,3);
                Xb=[];
                for i=1:n
            
                    % Center and radius of the sphere
                    [R,C,Xi]=B_MinSphere(app,[Xb;X{i}],[]);    
            
                    % 40 points closest to the sphere
                    if i<1
                        D=abs(sum(bsxfun(@minus,Xi,C).^2,2)-R^2);
                    else
                        D=abs(sqrt(sum(bsxfun(@minus,Xi,C).^2,2))-R);
                    end
                    [D,idx]=sort(D);
                    Xb=Xi(idx(1:40),:);
            
                end
                D=D(1:4);
                Xb=Xb(D/R*100<1E-3,:);
                [~,idx]=sort(Xb(:,1));
                Xb=Xb(idx,:);
            end

            %----------------------------------------------------------------------------------------
            function [R,C,P]=B_MinSphere(app,P,B)
                
                if size(B,1)==4 || isempty(P)
                    [R,C]=FitSphere2Points(app,B); % fit sphere to boundary points
                    return
                end
                % Remove the last (i.e., end) point, p, from the list
                P_new=P;
                P_new(end,:)=[];
                p=P(end,:);
                    
                % Check if p is on or inside the bounding sphere. If not, it must be
                % part of the new boundary.
                [R,C,P_new]=B_MinSphere(app,P_new,B); 
                if isnan(R) || isinf(R) || R<=eps
                    chk=true;
                else
                    chk=norm(p-C)>(R+eps);
                end
                
                if chk
                    B=[p;B];
                    [R,C]=B_MinSphere(app,P_new,B);
                    P=[p;P_new];
                end
            end
            

            %----------------------------------------------------------------------------------------
            function [R,C]=FitSphere2Points(app,X)
                    % Fit a sphere to a set of 2, 3, or at most 4 points in 3D space. Note that
                    % point configurations with 3 collinear or 4 coplanar points do not have 
                    % well-defined solutions (i.e., they lie on spheres with infinite radius).
                    %
                    %   - X     : M-by-3 array of point coordinates, where M<=4.
                    %   - R     : radius of the sphere. R=Inf when the sphere is undefined, as 
                    %             specified above.
                    %   - C     : 1-by-3 vector specifying the centroid of the sphere. 
                    %             C=nan(1,3) when the sphere is undefined, as specified above.
                    %
                    % AUTHOR: Anton Semechko (a.semechko@gmail.com)
                    % DATE: Dec.2014
                    %
                    
                    
                    N=size(X,1);
                    if N>4 
                        error('Input must a N-by-3 array of point coordinates, with N<=4')
                    end
                    
                    % Empty set
                    if isempty(X)
                        C=nan(1,3);
                        R=nan; 
                        return
                    end
                    
                    % A single point
                    if N==1
                        C=X;
                        R=0;
                        return
                    end
                    
                    % Line segment
                    if N==2
                        C=mean(X,1);
                        R=norm(X(2,:)-X(1,:))/2;
                        return
                    end
                    
                    % Remove duplicate vertices, if there are any
                    D=bsxfun(@minus,permute(X,[1 3 2]),permute(X,[3 1 2]));
                    D=sqrt(sum(D.^2,3));
                    D(1:(N+1):end)=Inf;
                    chk=D<=1E-12;
                    if sum(chk(:))>0
                        for i=1:N
                            if size(X,1)<=i, break; end
                            idx=chk(i,:);
                            idx(1:i)=false;
                            idx=find(idx);
                            chk(idx,:)=[];
                            chk(:,idx)=[];
                            X(idx,:)=[];
                        end
                        [R,C]=FitSphere2Points(app,X);
                        return
                    end
                    
                    
                    % Three unique, though possibly collinear points
                    tol=1E-2; % collinearity/co-planarity threshold (in degrees)
                    if N==3
                       
                        % Check for collinearity
                        D12=X(2,:)-X(1,:); D12=D12/norm(D12);
                        D13=X(3,:)-X(1,:); D13=D13/norm(D13);
                    
                        chk=abs(D12*D13(:));
                        chk(chk>1)=1;
                        if acos(chk)/pi*180<tol
                            R=inf;
                            C=nan(1,3);
                            return
                        end
                        
                        % Make plane formed by the points parallel with the xy-plane  
                        n=cross(D13,D12);
                        n=n/norm(n);
                        r=cross(n,[0 0 1]); r=acos(n(3))*r/norm(r); % Euler rotation vector
                        Rmat=expm([0 -r(3) r(2); r(3) 0 -r(1); -r(2) r(1) 0]);
                        Xr=(Rmat*X')'; 
                            
                        % Circle centroid
                        x=Xr(:,1:2);
                        A=2*bsxfun(@minus,x(2:3,:),x(1,:));
                        b=sum(bsxfun(@minus,x(2:3,:).^2,x(1,:).^2),2);
                        C=(A\b)';
                        
                        % Circle radius
                        R=sqrt(sum((x(1,:)-C).^2,2));     
                        
                        % Rotate centroid back into the original frame of reference
                        C(3)=mean(Xr(:,3));
                        C=(Rmat'*C(:))';
                        return
                    end
                    
                    
                    % If we got to this point then we have 4 unique, though possibly co-linear
                    % or co-planar points. 
                    
                    % Check if the the points are co-linear
                    D12=X(2,:)-X(1,:); D12=D12/norm(D12);
                    D13=X(3,:)-X(1,:); D13=D13/norm(D13);
                    D14=X(4,:)-X(1,:); D14=D14/norm(D14);
                    
                    chk1=abs(D12*D13(:));
                    chk1(chk1>1)=1;
                    chk2=abs(D12*D14(:));
                    chk2(chk2>1)=1;
                    
                    if acos(chk1)/pi*180<tol || acos(chk2)/pi*180<tol
                        R=inf;
                        C=nan(1,3);
                        return
                    end
                    
                    % Check if the the points are co-planar
                    n1=cross(D12,D13); n1=norm(n1);
                    n2=cross(D12,D14); n2=norm(n2);
                    
                    chk=abs(n1*n2(:));
                    chk(chk>1)=1;
                    if acos(chk)/pi*180<tol
                        R=inf;
                        C=nan(1,3);
                        return
                    end
                    
                    % Centroid of the sphere
                    A=2*bsxfun(@minus,X(2:end,:),X(1,:));
                    b=sum(bsxfun(@minus,X(2:end,:).^2,X(1,:).^2),2);
                    C=(A\b)';
                    
                    % Radius of the sphere
                    R=sqrt(sum((X(1,:)-C).^2,2));          
             end


             %-----------------------------------------------------------------------------------------------   
             function [R,C,Xb] = ExactMinBoundCircle(app,X)
            
                    % Compute exact minimum bounding circle of a 2D point cloud using Welzl's 
                    % algorithm. 
                    %
                    %   - X     : M-by-2 list of point co-ordinates, where M is the total
                    %             number of points.
                    %   - R     : radius of the circle.
                    %   - C     : 1-by-2 vector specifying the centroid of the circle.
                    %   - Xb    : subset of X, listing K-by-2 list of point coordinates from 
                    %             which R and C were computed. See function titled 
                    %             'FitCirc2Points' for more info.
                    %
                    % REREFERENCES:
                    % [1] Welzl, E. (1991), 'Smallest enclosing disks (balls and ellipsoids)',
                    %     Lecture Notes in Computer Science, Vol. 555, pp. 359-370
                    %
                    % AUTHOR: Anton Semechko (a.semechko@gmail.com)
                    % DATE: Dec.2014
                    %
                    
                    if isobject(X), X=X.X; end
                    if size(X,2)~=2
                        error('This function only works for 2D data')
                    end
                    if sum(isnan(X(:)) | isinf(X(:)))>0
                        error('Point data contains NaN or Inf entries. Remove them and try again.')
                    end
                    % Get the convex hull of the point set
                    F=convhulln(X);
                    F=unique(F(:));
                    X=X(F,:);
                    % Randomly permute the point set
                    idx=randperm(size(X,1));
                    X=X(idx(:),:);
                    % Get the minimum bounding circle
                    if size(X,1)<=3
                        [R,C]=FitCirc2Points(app,X); 
                        Xb=X;
                        return
                    end
                    
                    if size(X,1)<1E3
                        try
                            % Center and radius of the circle
                            [R,C]=B_MinCircle(app,X,[]);
                            % Coordiantes of the points used to compute parameters of the 
                            % minimum bounding circle
                            D=sum(bsxfun(@minus,X,C).^2,2);
                            [D,idx]=sort(abs(D-R^2));
                            Xb=X(idx(1:4),:);
                            D=D(1:4);
                            Xb=Xb(D<1E-6,:);
                            [~,idx]=sort(Xb(:,1));
                            Xb=Xb(idx,:);
                            return
                        catch
                        end
                    end
                
                    % If we got to this point, then the recursion depth limit was reached. So 
                    % need to break-up the the data into smaller sets and then recombine the 
                    % results.
                    M=size(X,1);
                    dM=min(floor(M/4),300);
                    res=mod(M,dM);
                    n=ceil(M/dM);  
                    idx=dM*ones(1,n);
                    if res>0
                        idx(end)=res;
                    end
                
                    if res<=0.25*dM 
                        idx(n-1)=idx(n-1)+idx(n);
                        idx(n)=[];
                        n=n-1;
                    end
                    X=mat2cell(X,idx,2);
                    Xb=[];
                    for i=1:n
                        % Center and radius of the circle
                        [R,C,Xi]=B_MinCircle(app,[Xb;X{i}],[]);    
                        % 40 points closest to the circle
                        if i<1
                            D=abs(sum(bsxfun(@minus,Xi,C).^2,2)-R^2);
                        else
                            D=abs(sqrt(sum(bsxfun(@minus,Xi,C).^2,2))-R);
                        end
                        [D,idx]=sort(D);
                        Xb=Xi(idx(1:40),:);
                    end
                
                    D=D(1:3);
                    Xb=Xb(D/R*100<1E-3,:);
                    [~,idx]=sort(Xb(:,1));
                    Xb=Xb(idx,:);
             end
             
             %----------------------------------------------------------------------------------------
            function [R,C,P]=B_MinCircle(app,P,B)
                
                if size(B,1)==3 || isempty(P)
                    [R,C]=FitCircle2Points(app,B); % fit circle to boundary points
                    return
                end
        
                % Remove the last (i.e., end) point, p, from the list
                P_new=P;
                P_new(end,:)=[];
                p=P(end,:);
        
                % Check if p is on or inside the bounding circle. If not, it must be
                % part of the new boundary.
                [R,C,P_new]=B_MinCircle(app,P_new,B); 
                if isnan(R) || isinf(R) || R<=eps
                    chk=true;
                else
                    chk=norm(p-C)>(R+eps);
                end
        
                if chk
                    B=[p;B];
                    [R,C]=B_MinCircle(app,P_new,B);
                    P=[p;P_new];
                end
                
            end
          
               
            %------------------------------------------------------------------------------------------------
            function [R,C] = FitCircle2Points(app,X)
                    % Fit a circle to a set of 2 or at most 3 points in 3D space. Note that
                    % point configurations with 3 collinear points do not have well-defined 
                    % solutions (i.e., they lie on circles with infinite radius).
                    %
                    %   - X     : M-by-2 array of point coordinates, where M<=3.
                    %   - R     : radius of the circle. R=Inf when the circle is undefined, as 
                    %             specified above.
                    %   - C     : 1-by-2 vector specifying the centroid of the circle. 
                    %             C=nan(1,2) when the circle is undefined, as specified above.
                    %
                    % AUTHOR: Anton Semechko (a.semechko@gmail.com)
                    % DATE: Dec.2014
                    %
                    N=size(X,1);
                    if N>3
                        error('Input must a N-by-2 array of point coordinates, with N<=3')
                    end
                    % Empty set
                    if isempty(X)
                        C=nan(1,2);
                        R=nan; 
                        return
                    end
                    % A single point
                    if N==1
                        C=X;
                        R=0;
                        return
                    end
                    % Line segment
                    if N==2
                        C=mean(X,1);
                        R=norm(X(2,:)-X(1,:))/2;
                        return
                    end
                    % Remove duplicate vertices, if there are any
                    D=bsxfun(@minus,permute(X,[1 3 2]),permute(X,[3 1 2]));
                    D=sqrt(sum(D.^2,3));
                    D(1:(N+1):end)=Inf;
                    chk=D<=1E-12;
                    if sum(chk(:))>0
                        for i=1:(N-1)
                            if size(X,1)<=i, break; end
                            idx=chk(i,:);
                            idx(1:i)=false;
                            idx=find(idx);
                            chk(idx,:)=[];
                            chk(:,idx)=[];
                            X(idx,:)=[];
                        end
                        [R,C]=FitCirc2Points(X);
                        return
                    end
                    % Three unique, though possibly collinear points
                    tol=1E-2; % collinearity/co-planarity threshold (in degrees)
                
                    % Check for collinearity
                    D12=X(2,:)-X(1,:); D12=D12/norm(D12);
                    D13=X(3,:)-X(1,:); D13=D13/norm(D13);
                    chk=abs(D12*D13(:));
                    chk(chk>1)=1;
                    if acos(chk)/pi*180<tol
                        R=inf;
                        C=nan(1,2);
                        return
                    end
                    % Circle centroid
                    A=2*bsxfun(@minus,X(2:3,:),X(1,:));
                    b=sum(bsxfun(@minus,X(2:3,:).^2,X(1,:).^2),2);
                    C=(A\b)';
                    % Circle radius
                    R=sqrt(sum((X(1,:)-C).^2,2));
                
                end
%--------------------------------------------------------------------------------------------------------------------------                
%--------------------------------------------------------------------------------------------------------------------------                
%--------------------------------------------------------------------------------------------------------------------------                
 
%--------------------------------------------------------------------------------------------------------------------------       
                function PROCESS_DATA_FOR_ADDED_TRACK(app,tracknum,TrackName,nTracksReadIn,NewTrackNum)
                    
                    
%                     % Read in first RGB timepoint to get full image volume size -------------------------------    
%                             [files, ~] = files_and_folders(app, app.RGBZT_Directory);
%                             info = imfinfo( fullfile(app.RGBZT_Directory, files{1,1}) );
%                             
%                             app.zDepth  = size(info,1);       % Z-depth
%                             app.cWidth  = info(1,1).Width;    % Full width of image
%                             app.rHeight = info(1,1).Height;   % Full height of image
%                             %app.nTime   = size(files,1);      % Number of time points % Gets read in at Select Folder callback
%                             
%                             app.dp = app.PixelSizeField.Value; % pixel size  (microns)
%                             app.dz = app.ZStepField.Value; % z-step size (microns)
%                             app.dt = app.TimeStepField.Value;   % min
%                             
%                             [app.X, app.Y, app.Z] = meshgrid( app.dp.*[0:(app.cWidth -1)],...
%                                                               app.dp.*[0:(app.rHeight-1)],...
%                                                               app.dz.*[0:(app.zDepth -1)] ); 
%                                                           
%                         % Specify sub-region size -----------------------------------------------------------------
%                             app.SubVol_nR = app.RowsSubRegionField.Value;  % 51;
%                             app.SubVol_nC = app.ColsSubRegionField.Value;  % 61;
%                             app.SubVol_nZ = app.ZSubRegionField.Value;     % 31;   
%                         
%                         % Read Image Data for Track ----------------------------------------------------------
%                              if size(app.DATA5d,1) < tracknum;  app.DATA5d = [app.DATA5d; cell(1)];    end
%                              if size(app.IND,1)    < tracknum;     app.IND = [app.IND;    cell(1)];    end
%                              
%                              [app.DATA5d{tracknum,1}, app.IND{tracknum,1}] = ReadSubRegionsOverTime(app,            app.RGBZT_Directory,   TrackName,        app.CellTrackData{tracknum}(:,2:4),...
%                                                                                                     app.SubVol_nR,  app.SubVol_nC,         app.SubVol_nZ,    app.dp,         app.dz, ...
%                                                                                                     app.rHeight,    app.cWidth,            app.zDepth,       app.nTime); 
%                         
%                         % Image Data Deconvolution Pre-processing -------------------------------------------------
%                               app = Locus_2D_PreProcessing(app,tracknum,nTracksReadIn,NewTrackNum); % Creates app.PROC5d 
%                               % app.PROC5d{tracknum,1} = NaN(size(app.DATA5d{tracknum,1}));
%                               
%                         % Create default Time Point Data ----------------------------------------------------------
%                               %app.TimePointData.Properties{tracknum,1} = [ (0:(app.nTime-1))'.*app.dt/60 , zeros(app.nTime,5)];
%                               app.TimePointData.Renderings{tracknum,1} = cell(app.nTime,1);  
%                               app.TimePointData.RenderingSettings{tracknum,1}.Pad = zeros(app.nTime,1);
%                               app.TimePointData.RenderingSettings{tracknum,1}.ContractBias = zeros(app.nTime,1);
%                               %app.TimePointData.RenderingSettings{tracknum,1}.SmoothFactor = zeros(app.nTime,1);
%                               app.TimePointData.RenderingSettings{tracknum,1}.ExtendedMin  = zeros(app.nTime,1);
%                               app.TimePointData.RenderingSettings{tracknum,1}.nIterations = zeros(app.nTime,1);
%                               app.TimePointData.RenderingSettings{tracknum,1}.DataSource  = zeros(app.nTime,1);
%                               app.TimePointData.RenderingSettings{tracknum,1}.ConnectionLines = cell(app.nTime,1);
%                               app.TimePointData.RenderingSettings{tracknum,1}.SeparationLines = cell(app.nTime,1);
%                               
%                               app.TimePointData.Measurements{tracknum,1}.Volume    = zeros(app.nTime,1);
%                               app.TimePointData.Measurements{tracknum,1}.MBSD      = zeros(app.nTime,1);
%                               app.TimePointData.Measurements{tracknum,1}.MBCD      = zeros(app.nTime,1);
%                               app.TimePointData.Measurements{tracknum,1}.Centroid  = zeros(app.nTime,1);
%                               app.TimePointData.Measurements{tracknum,1}.MBCD      = zeros(app.nTime,1);  
%                               
%                         % Initialize Data Plane Coordinates -------------------------------------------------------
%                              W = 40;  % Width  of plane in microns
%                              L = 40;  % Height of plane in microns
%                 
%                              % Create Cross-section plane, width w and height h, centered at [0,0] 
%                              [xp,yp,zp] = meshgrid( linspace( -W/2, W/2, ceil(W/app.dp) ),...  
%                                                     linspace( -L/2, L/2, ceil(L/app.dp) ), 0);  
%                                                  
%                              app.DataPlaneStartArray.xp = xp;
%                              app.DataPlaneStartArray.yp = yp;
%                              app.DataPlaneStartArray.zp = zp;
%                  
%                              app.SaveAsLocusFileButton.Enable = true;
                        
                end


%------------------------------------------------------------------------------------------------------------------------                  
                function colors = turbo(app)
                
                    colors = turbo;                
                end

%------------------------------------------------------------------------------------------------------------------------    
                function colors = parula(app)
                
                    colors = parula;                
                end

%------------------------------------------------------------------------------------------------------------------------    
                function cm_data = thermal(app)              
                
                
                cm_data = [[ 0.01555601, 0.13824425, 0.20181089];
                           [ 0.01620184, 0.14105074, 0.20897651];
                           [ 0.01685649, 0.14382701, 0.21623868];
                           [ 0.0175264 , 0.14657173, 0.2235997 ];
                           [ 0.01821872, 0.14928346, 0.23106187];
                           [ 0.01894138, 0.15196073, 0.23862748];
                           [ 0.01969968, 0.15460145, 0.24630497];
                           [ 0.02050332, 0.15720378, 0.25409711];
                           [ 0.02136721, 0.15976645, 0.26199915];
                           [ 0.02230341, 0.16228755, 0.27001321];
                           [ 0.0233252 , 0.16476505, 0.27814139];
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                           [ 0.90904184, 0.98215741, 0.35550781]];
                
                end


%------------------------------------------------------------------------------------------------------------------------    
                function cm_data = haline(app)                              
                            
                 cm_data = [[ 0.16295295, 0.09521592, 0.42257292];
                            [ 0.16481011, 0.09635116, 0.43184597];
                            [ 0.16661617, 0.09744967, 0.44120648];
                            [ 0.16836624, 0.09851521, 0.4506511 ];
                            [ 0.17005471, 0.09955275, 0.46017511];
                            [ 0.17167508, 0.10056873, 0.46977222];
                            [ 0.17321987, 0.10157136, 0.47943423];
                            [ 0.17468043, 0.10257097, 0.48915068];
                            [ 0.17604337, 0.10356584, 0.4989416 ];
                            [ 0.17729823, 0.10458025, 0.50877159];
                            [ 0.1784323 , 0.10563803, 0.51861083];
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                            [ 0.99408058, 0.93672758, 0.60267   ]];
                
                end


%------------------------------------------------------------------------------------------------------------------------    

                function cm_data = wolfram(app)                    
                    
                    cm_data =  [[ 0.21176, 0.36078, 0.60392 ];
                                [ 0.21738, 0.36319, 0.60207 ];
                                [ 0.22300, 0.36560, 0.60022 ];
                                [ 0.22862, 0.36801, 0.59836 ];
                                [ 0.23424, 0.37042, 0.59651 ];
                                [ 0.23986, 0.37283, 0.59466 ];
                                [ 0.24548, 0.37524, 0.59281 ];
                                [ 0.25110, 0.37764, 0.59095 ];
                                [ 0.25672, 0.38005, 0.58910 ];
                                [ 0.26234, 0.38246, 0.58725 ];
                                [ 0.26796, 0.38487, 0.58539 ];
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                                [ 1.00000, 0.97647, 0.77255 ]];
                    
                end

%------------------------------------------------------------------------------------------------------------------------    

                function TrackVelocity = CalculateTrackVelocity(app,track,Xtrack,Ytrack,Ztrack)

                        %track = app.CelltrackDropDown.Value;

%                         tStart = app.StartingTimePoint.Value;
%                         tEnd   = app.EndingTimePoint.Value;
%                         tIncrement = 1; % app.LTapp.TimePointIncrement.Value;
                        TimeVec = (1:1:size(Xtrack,1))';

                        x = fillmissing( Xtrack(:,track),'linear','MaxGap',2);
                        y = fillmissing( Ytrack(:,track),'linear','MaxGap',2);
                        z = fillmissing( Ztrack(:,track),'linear','MaxGap',2);
                        
                        speed = sqrt( (x(2:end,1)-x(1:end-1,1)).^2 + (y(2:end,1)-y(1:end-1,1)).^2 + (z(2:end,1)-z(1:end-1,1)).^2  )./app.dt;
        
                        Sidx = find( ~isnan(speed),1,'first');
                        Eidx = find( ~isnan(speed),1,'last'); 
        
                        if  isempty(Sidx) || isempty(Eidx)
                            FilteredSpeed = zeros(size(x));
                        else
                            speed = interp1( TimeVec(Sidx:Eidx)+0.5, speed(Sidx:Eidx,1), TimeVec, 'linear', 'extrap'); % since we have one less velocity value than time point. 
                            nPoints = app.nVelocitySmoothingPoints;
                            FilteredSpeed = NaN(size(speed));
                            FilteredSpeed(Sidx:Eidx,1) = smoothdata(speed(Sidx:Eidx,1),"gaussian",nPoints);
                            FilteredSpeed(isnan(x)) = NaN;
                        end        

                        if app.VelocityUnits == 1
                            SF = 60; % Conversion factor for microns/min
                        else
                            SF = 1000; % conversion factor for nm/s
                        end

                        TrackVelocity = SF*FilteredSpeed;

                end


%------------------------------------------------------------------------------------------------------------------------    

                function [nTracks,TrackData,TrackNames] = ReadInLongtrackerTrackFile(app,Path,FileName)
                  
                            % Column Headers:   SetNumber	TimePoint	CellNumber	X (pixels)	Y (pixels)	Z (integer)
                             M = readmatrix( fullfile(Path,FileName),'NumHeaderLines',1 );
                             
                             SetNums = M(:,1);
                             UniqueSetNums = unique(SetNums);
                             nSets = length(UniqueSetNums);
                             CellNum = M(:,3);
                             
                             TrackData = cell(0,1);
                             TrackNames = cell(0,1);
                             nTracks = 0;
                             
                             for S = 1:nSets
                                     idx1 = eq(SetNums,UniqueSetNums(S));
                                     UniqueCellNum = unique(CellNum(idx1));
                                     nCells = length(UniqueCellNum);
                                     for C = 1:nCells
                                         nTracks = nTracks + 1;
                                         idx2 = eq(SetNums,UniqueSetNums(S)) & eq(CellNum,UniqueCellNum(C));
                                         [~,name,~] = fileparts(FileName);
                                         TrackNames{nTracks,1} = [name,'_s',num2str(UniqueSetNums(S)),'_c',num2str(UniqueCellNum(C))];
                                         TXYZvalues = [ M(idx2,2), M(idx2,4:6) ]; % [Time, XYZ] from text file
                                        
                                         TXYZ = nan(app.nTime,4);
                                         TXYZ(:,1) = (1:app.nTime)';
                                         for n = 1:size(TXYZvalues,1)
                                            idx = find(TXYZ(:,1)==TXYZvalues(n,1)); 
                                            TXYZ(idx,:) = TXYZvalues(n,:);
                                         end
                                        
                                         TrackData{nTracks,1} = TXYZ; % [Time, XYZ]
                                     end
                             end

                end
                
%------------------------------------------------------------------------------------------------------------------------      
               function [row,col,page] = FindIndicesOfXYZ(app,x,y,z,X,Y,Z)

                        % ---------------------------------------------
                        %
                        % [row,col,page] = FindIndicesOfXYZ(x,y,z,X,Y,Z)
                        %
                        % x,y,z = coordinate of location
                        % X,Y,Z = mesh arrays of the gridded volume
                        % row,col,z = indices of closest point in gridded volumes
                        %
                        % KRC 4/30/2020
                        %----------------------------------------------
                    
                        [~,r] = min( abs(squeeze(Y(:,1,1))  - y) ); 
                        row  = r(1); 
                                
                        [~,c] = min( abs(squeeze(X(1,:,1))' - x) );
                        col  = c(1);
                                
                        [~,p] = min( abs(squeeze(Z(1,1,:))  - z) );
                        page = p(1);
                        
                end  
              

%------------------------------------------------------------------------------------------------------------------------     
                function Color = GetTimeOrVelocityColor(app,ColorMap,Range,Value) 
                    
                        [nR,~] = size(ColorMap);
                        [n,~] = discretize( Value, linspace(Range(1),Range(2),nR));
                        if isnan(n)
                            if Value <= Range(1)
                                Color = ColorMap(1,:);
                            elseif Value >= Range(2)
                                Color = ColorMap(end,:);
                            else
                                Color = [0,0,0];
                            end
                        else
                            Color = ColorMap(n,:);
                        end
                        
                
                end

%------------------------------------------------------------------------------------------------------------------------     
                function TF = AnyColormapsChecked(app)
                        
                         TF1 = app.AllCellSurfaceUseColormapCheck.Value;
                         TF2 = app.AllCellMeshUseColormapCheck.Value;
                         TF3 = app.TrackLineUseColormapCheck.Value;
                         TF4 = app.AllVectorUseColormapCheck.Value;
                         TF5 = app.MovieCellSurfaceUseColormapCheck.Value;
                         TF6 = app.MovieCellMeshUseColormapCheck.Value;
                         TF7 = app.MovieCentroidUseColormapCheck.Value;
                         TF8 = app.MovieVectorUseColormapCheck.Value;

                         if any([TF1,TF2,TF3,TF4,TF5,TF6,TF7,TF8])
                             TF = true;
                         else
                             TF = false;
                         end
                end

%------------------------------------------------------------------------------------------------------------------------     
                function W = AdaptHistEq3D(app,V)


                        [nR,nC,nZ] = size(V);
                        W = zeros(nR,nC,nZ,'single');
                        %-----------------------------------------------------------------------------
                        for r = 1:nR    
                            W(r,:,:) = adapthisteq(squeeze(V(r,:,:)));
                        end
                    
                end
                
%------------------------------------------------------------------------------------------------------------------------                        
                function UpdateCellRendering(app) 
                    
                    if isgraphics(app.SingleCellRenderingHandle)
                        delete(app.SingleCellRenderingHandle)
                    end
                    
                    t = app.CurrentTimePoint;
                    track = app.CurrentTrack;
                    ch = app.DataPlaneChannel.Value;
                    
                    % Grab X,Y, and Z coordinates for subregion at timepoint t. -------------------------
                        axis(app.SingleCellAxisHandle,'equal')
                        axis(app.SingleCellAxisHandle,...
                              [ min(app.X(1, app.IND{track,1}(1,2,t):app.IND{track,1}(2,2,t),1)),...
                                max(app.X(1, app.IND{track,1}(1,2,t):app.IND{track,1}(2,2,t),1)),...
                                min(app.Y(app.IND{track,1}(1,1,t):app.IND{track,1}(2,1,t),1,1)),...
                                max(app.Y(app.IND{track,1}(1,1,t):app.IND{track,1}(2,1,t),1,1)),...
                                min(app.Z(1,1,app.IND{track,1}(1,3,t):app.IND{track,1}(2,3,t))),...
                                max(app.Z(1,1,app.IND{track,1}(1,3,t):app.IND{track,1}(2,3,t))) ] );
                        
                    % -----------------------------------------------------------------------------------
                    V = app.CurrentDataVolumeForTimeT;
                    V = squeeze(V(:,:,1,:)); % Always ise channel one for rendering.
                    
%                     if isequal(1,app.DataSourceDropDown.Value)
%                         V = single(squeeze(app.DATA5d{track,1}(ch,:,:,:,t))); % Raw Membrane channel 3D data for timepoint t   
%                         V = V/max(V(:)); % XYZ @ time t
%                     else
%                         V = single(squeeze(app.PROC5d{track,1}(ch,:,:,:,t))); % Processed Membrane channel 3D data for timepoint t   
%                         V = V/max(V(:)); % XYZ @ time t
%                         % 3d Adaptive Contrast ----------
%                         % V = AdaptHistEq3D(app,V);
%                         %--------------------------------
%                     end
                    
                    % Grab Subregion X,Y, and Z coordinates for this timepoint (IND = INDICES) ----------------------------------------------------------------------------
                         Xsr = app.X(app.IND{track}(1,1,t):app.IND{track}(2,1,t), ...
                               app.IND{track}(1,2,t):app.IND{track}(2,2,t), ...
                               app.IND{track}(1,3,t):app.IND{track}(2,3,t));
                         Ysr = app.Y(app.IND{track}(1,1,t):app.IND{track}(2,1,t), ...
                               app.IND{track}(1,2,t):app.IND{track}(2,2,t), ...
                               app.IND{track}(1,3,t):app.IND{track}(2,3,t));
                         Zsr = app.Z(app.IND{track}(1,1,t):app.IND{track}(2,1,t), ...
                               app.IND{track}(1,2,t):app.IND{track}(2,2,t), ...
                               app.IND{track}(1,3,t):app.IND{track}(2,3,t));               
                         
                         % Create volume with linear indices as the actual values so that later as 
                         % the volume is cropped again we can easily calculate the linear indices of 
                         % the cell mask as if it was in the original uncropped volume
                         LImask    = zeros( size(app.X) );
                         LImask(:) = 1:numel(LImask);
                         LImask = LImask(app.IND{track}(1,1,t):app.IND{track}(2,1,t), ...
                                         app.IND{track}(1,2,t):app.IND{track}(2,2,t), ...
                                         app.IND{track}(1,3,t):app.IND{track}(2,3,t));   

                     % Seed point location (microns) ----------------------------------------------------------------------------------------------------------------------                 
                         sp = [ app.CurrentTrackPoint.x + app.CellTrackOffset{app.CurrentTrack}(app.CurrentTimePoint,1),...
                                app.CurrentTrackPoint.y + app.CellTrackOffset{app.CurrentTrack}(app.CurrentTimePoint,2),...
                                app.CurrentTrackPoint.z + app.CellTrackOffset{app.CurrentTrack}(app.CurrentTimePoint,3) ];  
                   
                    % Grab subregion --------------------------------------------------------------------------------------------------------------------------------------
                        % Grabbing subregion of voxels for rendering speeds up calculations A LOT
                        pad = app.PadSpinner.Value; % pad size around track point (microns)
                        %pad = app.TimePointData.RenderingSettings{track,1}.Pad(t,1);
                            
                        limits = [ sp(1)-pad, sp(1)+pad, sp(2)-pad, sp(2)+pad, sp(3)-pad, sp(3)+pad ];
                        limits(limits < 0) = 0;
                        [Xsub,Ysub,Zsub,Vsub] = subvolume(Xsr,Ysr,Zsr,V,limits); 
                        [~,~,~,LImask] = subvolume(Xsr,Ysr,Zsr,LImask,limits); 

                        % Calculate row, column, page, indices of seed point inside SubVolume 
                        % based on X,Y,Z to set mask starting point
                        [~,rowsub] = min( abs(squeeze(Ysub(:,1,1)) - sp(2)) ); 
                        [~,colsub] = min( abs(squeeze(Xsub(1,:,1))'- sp(1)) ); 
                        [~,pagsub] = min( abs(squeeze(Zsub(1,1,:)) - sp(3)) ); 

                    % Rendering algorithms --------------------------------------------------------------------------------------------------------------------------------             
                        % ActiveContourRenderingMethod(app, track, t, Vsub, Xsub, Ysub, Zsub, sp, rowsub(1), colsub(1), pagsub(1) ); % NOT AS GOOD AS WatershedRenderingMethod but interesting method to know about
                        WatershedRenderingMethod(app, track, t, Vsub, Xsub, Ysub, Zsub, LImask, rowsub(1),colsub(1),pagsub(1) ); % DEFAULT                    
                      
                end


%------------------------------------------------------------------------------------------------------------------------    
%         function ActiveContourRenderingMethod(app,track,t,Vsub,Xsub,Ysub,Zsub,LImask,sp,rowsub,colsub,pagsub)
% 
%                     mask_init = zeros(size(Vsub),'double'); % Initialize mask
%                     mask_init(rowsub,colsub,pagsub) = 1;    % Create mask starting point
%                     mask_init = imdilate(mask_init,strel('square',2));
% %                     tic
% %                     Processed = FindCellSurfaces(app,Vsub);
% %                     toc
%                     method = 'Chan-Vese'; % 'edge'; % 
%                     if isequal(app.GaussianFiltSwitch.Value,1)
%                         sig = app.SigmaSpinner.Value;
%                         Vsub = imgaussfilt3(Vsub, [sig,sig,sig*(app.dp/app.dz)]);
%                     end
% 
%                     J = activecontour(1-Vsub, mask_init, num_it,method, 'SmoothFactor',0);
% 
%                     % Seperate attached objects and appply separation and connection lines --------------------------------------------------------------------------
%                         % Apply Separation Line-----------------------------------------------------
%                         if ~isempty(app.TimePointData.RenderingSettings{track,1}.SeparationLines{t,1})
%                             separationLines = app.TimePointData.RenderingSettings{track,1}.SeparationLines{t,1};
%                             J = ApplySeparationLines(app,J,separationLines,Xsub,Ysub,Zsub,app.dp,app.dz,2,false);
%                         end
% 
%                         % Insert "-Inf" in regional minimums of the distance transform to help eliminate segmenting with the watershed function
%                         DT = -bwdist(~J);
%                         EM = imextendedmin(DT,app.ExtendedMinSpinner.Value);
%                         DT(EM) = -Inf;
% 
%                         % Apply Connection Line ----------------------------------------------------
%                         if ~isempty(app.TimePointData.RenderingSettings{track,1}.ConnectionLines{t,1})
%                             connectLines = app.TimePointData.RenderingSettings{track,1}.ConnectionLines{t,1};
%                             DT = ApplyConnectionLines(app,DT,connectLines,Xsub,Ysub,Zsub,app.dp,app.dz,2);
%                         end
% 
%                         % Apply watershed function (in this case the watershed function is making a cut at the pinchpoint of a connected object) --------------------
%                         BW2 = logical(watershed(DT));
%                         J = J.*bwselect3(BW2, rowsub, colsub, pagsub);
%                         J = bwmorph3(J,'clean');
%                         J = bwmorph3(J,'fill');
%                         % J = imclose(J,strel('sphere',1));
%                     %-----------------------------------------------------------------------------------------------
%                     % Dilate object
% 
%                     sigma = 0.65;
%                     J = imgaussfilt3(double(J),[sigma,sigma,(app.dp/app.dz)*sigma]);
%                     IS = isosurface(Xsub+app.dp/2, Ysub+app.dp/2, Zsub+app.dz/2, double(J), 0.1); % Recenter IsoSurface by adding dp and dz to coords
%                     IS = reducepatch(IS,0.3);
% 
%                     if ~isempty(IS.vertices)
%                         % https://www.mathworks.com/matlabcentral/fileexchange/24943-two-functions-for-smoothing-denoising-of-triangular-meshes?s_tid=srchtitle
%                             IS.vertices = lpflow_trismooth(app,IS.vertices,IS.faces); 
% 
%                         % Create solid representation with tetrahedrons ---------------------------------------------------
%                             DT1 = delaunayTriangulation(IS.vertices(:,1),IS.vertices(:,2),IS.vertices(:,3));  % Create Delaunay Triangulation from isosurface points
%                             T = DT1.ConnectivityList;  % Grab connectivity list
%                             % triangulation creates concave solids, so we need to test which tetrahedrons are outside the isosurface and remove them then recreate the triangulation
%                             [Circumcenters,~] = circumcenter(DT1);  % Calculate circumcenters of tetrahedrons
%                             IN = inpolyhedron(app,IS.faces,IS.vertices,Circumcenters,'flipnormals',true);  % Test if curcumceneters are inside or outside isosurface
%                             T(~IN,:) = [];  % Remove tetrhedrons outside isosurface
%                             DT2 = triangulation(T,IS.vertices);  % Re-define solid triangulation without the tetrahedrons that are outside the isosurface
%                             % Visualize DT2 using: tetramesh(DT2,'FaceAlpha',0.3);    
% 
%                         % Calculate Cell Volume ---------------------------------------------------------------------------    
%                             % https://en.wikipedia.org/wiki/Tetrahedron V = |(a.(b x c))|/6 for d = [0,0,0]
% 
%                             a = IS.vertices( DT2.ConnectivityList(:,1), :);
%                             b = IS.vertices( DT2.ConnectivityList(:,2), :);
%                             c = IS.vertices( DT2.ConnectivityList(:,3), :);
%                             d = IS.vertices( DT2.ConnectivityList(:,4), :);      
% 
%                             % CellVolume1 = (app.dp^2)*app.dz*sum(J(:));        % Volume Method1: sum voxels in J
%                             % disp(CellVolume1)
%                             TetraVolumes = abs(dot(a-d,cross(b-d,c-d,2),2))./6; % Volume Method2: sum tetrahedron volumes in DT2
%                             CellVolume   = sum(TetraVolumes);
%                         % Calculate Minimum Bounding Sphere Diameter -----------------------------------------------------
%                             [R1,~,~] = ExactMinBoundSphere3D(app,IS.vertices);   
%                             MBSD = 2*R1;
%                         % Center of Mass of the Rendering ----------------------------------------------------------------
%                             Centroids = mean(cat(3,a,b,c,d),3);
%                             CellCentroid = sum( [TetraVolumes.*Centroids(:,1),...
%                                                  TetraVolumes.*Centroids(:,2),...
%                                                  TetraVolumes.*Centroids(:,3)]./CellVolume, 1 );
%                         % Calculate Minimum Bounding Circle diameter of cross-section through centroid perpendicular to R1 of ellipsoid fit
%                             [~,~,ev,~,~] = ellipsoid_fit(app, IS.vertices, '');
%                             cLine = intersectPlaneSurf(app,IS,CellCentroid,ev(:,1));  % Be sure to include mex file: IntersectPlaneTriangle.mex
%                             XYZmbcdline = cLine{1}';
%                             Rot1 = RotationMatrix3D(app,ev(:,1),[0,0,1]);
% %                           % Rotate Intersection Points to XY plane so that MinBoundingCircle can be fit
%                             M1 = Rot1*[ XYZmbcdline(:,1)' - CellCentroid(1) ;...
%                                         XYZmbcdline(:,2)' - CellCentroid(2) ;...
%                                         XYZmbcdline(:,3)' - CellCentroid(3)  ];
%                             [R2,~,~] = ExactMinBoundCircle(app,[ M1(1,:)', M1(2,:)' ]); 
%                             MBCD = 2*R2;
%                             %[R2,~,~] = ExactMinBoundSphere3D(app,XYZmbcdline);   
%                             %MBCD = 2*R2; %CalculateMBCDIntersectingPlane(app,IS,CellCentroid,ev(:,1),1);  
% 
%                         % Update timepoint text on figure window ---------------------------------------------------------
%                             if isgraphics(app.TimepointTextHandle)
%                                  delete(app.TimepointTextHandle)
%                             end
%                             app.TimepointTextHandle = axes(app.SingleCellFigureHandle,'Position',[0.02,0.96,0.01,0.01],'Visible','off','XLim',[0,1],'YLim',[0,1]);
%                             TX = text(app.TimepointTextHandle,0,0, ['timepoint = ',num2str(app.TimePointSpinner.Value),...
%                                                                     pad('volume = ',19,'left'), sprintf('%0.0f',CellVolume),' \mum^3'],...
%                                                                     'HorizontalAlignment','left','FontWeight','bold','FontSize',14,'Color',[1,0,0]);   
%                             drawnow
%                         %----------------------------------------------------------------------------------------------  
% 
% 
% 
%                         % Save/Overwrite Rendering data and settings from this time point ----------------------------------
% 
%                             app.TimePointData.SurfaceRenderings{track,1}{t,1}              = IS;  
%                             app.TimePointData.SolidRenderings{track,1}{t,1}                = DT2;
%                             app.TimePointData.RenderingSettings{track,1}.Pad(t,1)          = app.PadSpinner.Value;
%                             app.TimePointData.RenderingSettings{track,1}.ExtendedMin(t,1)  = app.ExtendedMinSpinner.Value;
%                             app.TimePointData.RenderingSettings{track,1}.DataSource(t,1)   = app.DataSourceDropDown.Value;
% 
%                         % Save/Overwrite Cell Measurements -----------------------------------------------------------------    
%                             app.TimePointData.Measurements{track,1}.Volume(t,1)    = CellVolume;  
%                             app.TimePointData.Measurements{track,1}.MBSD(t,1)      = MBSD;   
%                             app.TimePointData.Measurements{track,1}.MBCD(t,1)      = MBCD; 
%                             app.TimePointData.Measurements{track,1}.Centroid(t,:)  = CellCentroid; 
% 
% 
%                             % Create Rendered cell surface in figure window -------------------------------------------------
%                                 app.SingleCellRenderingHandle = patch(app.SingleCellAxisHandle, IS);
% 
%                                 app.SingleCellRenderingHandle.FaceColor = app.FaceColor;
%                                 app.SingleCellRenderingHandle.FaceAlpha = app.FaceTransparency;  
%                                 app.SingleCellRenderingHandle.EdgeColor = app.MeshColor;  % [0.8,0.8,0.8];
%                                 app.SingleCellRenderingHandle.EdgeAlpha = app.MeshTransparency;
% 
%                                 app.CellVisibilityButton.Text = 'Cell Visibility - ON';
%                                 hold(app.SingleCellAxisHandle,'on')
%                                        plot3(app.SingleCellAxisHandle, CellCentroid(1), CellCentroid(2), CellCentroid(3),'.','MarkerSize',5)
%                                 hold(app.SingleCellAxisHandle,'off')
% 
%                                 R3 = app.DataPlaneSizeInput.Value/2+10;
%                                 axis(app.SingleCellAxisHandle,'equal')
%                                 axis(app.SingleCellAxisHandle, [app.CurrentTrackPoint.x - R3, app.CurrentTrackPoint.x + R3,...
%                                                                 app.CurrentTrackPoint.y - R3, app.CurrentTrackPoint.y + R3,...
%                                                                 app.CurrentTrackPoint.z - R3, app.CurrentTrackPoint.z + R3] );
%                     end
% 
%         end

           
 %------------------------------------------------------------------------------------------------------------------------              
        function WatershedRenderingMethod(app,track,t,Vsub,Xsub,Ysub,Zsub,LImask,rowsub,colsub,pagsub)

                    
                    Vsub = AdaptHistEq3D(app,Vsub);

                    [row,col,nZ] = size(Vsub);      
                    sigma = 1;  % app.SigmaSpinner.Value;
                    V = imgaussfilt3(Vsub,[sigma,sigma,(app.dp/app.dz)*sigma]); % Apply light 3D smoothing
                    if isequal(app.ExtendedMinSpinner.Value,0)
                        BWem = false(size(V));
                    else
                        %EM = app.TimePointData.RenderingSettings{track,1}.ExtendedMin(t,1);   
                        EM = app.ExtendedMinSpinner.Value;
                        BWem = imextendedmin(V,EM);  % Find extended minimums with a depth of 0.2 (data is normalized)
                    end
                    V(BWem) = -Inf;               % Set extended minimum regions to -Inf to help prevent over segmentation
                    
                    FM1 = false(row,col,nZ);      % Preallocate space for doing a 2D watershed in each of the 3 dimensions
                    FM2 = false(row,col,nZ);     
                    FM3 = false(row,col,nZ);
                    
                    % Calculate 2D watershed in each 3D dimension --------------------------------
                    for r = 1:row    
                        FM1(r,:,:) = ~logical( watershed(squeeze(V(r,:,:))) );
                    end
                    for c = 1:col
                        FM2(:,c,:) = ~logical( watershed(squeeze(V(:,c,:))) );
                    end
                    for p = 1:nZ
                        FM3(:,:,p) = ~logical( watershed(squeeze(V(:,:,p))) );
                    end
                    %------------------------------------------------------------------------------
                    
                    Processed = FM1 + FM2 + FM3;      % add up all watersheds from each dimension
                    Processed(Processed < 2) = 0;     % keep boundaries where at least two dimensions agreed
                    BW2 = logical(Processed);         % Return remaining boundary to logical array
                    
                    % Apply Separation Line-----------------------------------------------------
                    if ~isempty(app.TimePointData.RenderingSettings{track,1}.SeparationLines{t,1})
                        separationLines = app.TimePointData.RenderingSettings{track,1}.SeparationLines{t,1};
                        BW3 = ApplySeparationLines(app, BW2, separationLines, Xsub, Ysub, Zsub, app.dp, app.dz, 2, true);
                    else
                        BW3 = false(size(BW2));
                    end
                    
                    BW2(BW3) = true;              % add new separation line barriers to first watershed segmentation
                    BWem(BW3) = false;             % Split minima regions with separation line
                    DT = -bwdist(BW2);            % Calculate distance transform
                    DT(BWem) = -Inf;               % Set original extended minima to -Inf in distance transform 
                    
                    % Apply Connection Line ----------------------------------------------------
                    if ~isempty(app.TimePointData.RenderingSettings{track,1}.ConnectionLines{t,1})
                        connectLines = app.TimePointData.RenderingSettings{track,1}.ConnectionLines{t,1};
                        DT = ApplyConnectionLines(app, DT, connectLines, Xsub, Ysub, Zsub, app.dp, app.dz, 1);
                    end
                    
                    % Apply watershed function (in this case the watershed function is making a cut at the pinchpoint of a connected object) --------------------
                    S = logical(watershed(DT));       % Now segment DT using 3D watershed to remove attached cells at pinch points
                    % J = bwselect3(S, rowsub, colsub, pagsub, 6); % Keep only the object that overlaps with the track point
                    % The "bwselect3 function doesn't always seem to work, or recognize the overalaying point. So I used "bwlabeln" instead.
                    J = bwlabeln(logical(S),6);
                    J( J~=J(rowsub,colsub,pagsub) ) = 0; % set objects not equal to the value that overlaps with the track point to zero
                    J( J==J(rowsub,colsub,pagsub) ) = 1; % Set the object that overlaps with the trackpoint to 1.
                    
                    Jraw = logical(J);

                    % Calculate linear indices of masked volume in un-cropped volume before applying any smoothing etc.
                    % (This is used to export data to Fluorender)
                        FullMask = false( size(app.X) ); % Create blank 3D mask of UnCropped volume
                        FullMask( LImask(:) ) = Jraw(:); % Put subvolue mask back into it's original location in the uncropped volume mask
                        FullVolumeLinearIndices = find(FullMask); % Grab the linear indices
                    %-----------------------------------------------------------------------------------------------
                   

                    % Compute rendered surface for SMOOTHED segmentation -----------------------------------------------------------------------------
                        sigma = app.SegSmoothSigmaSpinner.Value; %if app.RenderingSmoothingOnCheckBox.Value   
                        Js = imgaussfilt3(double(J), [sigma,sigma,(app.dp/app.dz)*sigma] );
                        
                        [Faces1s,Vertices1s]   = isosurface(Xsub, Ysub, Zsub, double(Js), 0.3); % Recenter IsoSurface by adding dp and dz to coords
                        [Faces2s,Vertices2s,~] = isocaps( Xsub, Ysub, Zsub, double(Js), 0.3); % Add isocaps if the cell borders the edge of the data
                        if isempty(Vertices1s) % If there are no isocaps than no need to combine anything
                            Faces3s = Faces1s;
                            Vertices3s = Vertices1s;
                        else  % Combine Isocaps and Isosurface data into one surface
                            Faces3s = [ Faces1s ; Faces2s + size(Vertices1s(:,1),1) ];   
                            Vertices3s = [Vertices1s ; Vertices2s];
                        end
                        ISsmooth = reducepatch(Faces3s,Vertices3s,0.3); % <----- This is the final smoothed isosurface that is rendered !!! 


                    % Compute rendered surface for RAW segmentation -----------------------------------------------------------------------------
                        [Faces1,Vertices1]   = isosurface(Xsub, Ysub, Zsub, double(J), 0.3); % Recenter IsoSurface by adding dp and dz to coords
                        [Faces2,Vertices2,~] = isocaps( Xsub, Ysub, Zsub, double(J), 0.3); % Add isocaps if the cell borders the edge of the data
                        if isempty(Vertices1) % If there are no isocaps than no need to combine anything
                            Faces3 = Faces1;
                            Vertices3 = Vertices1;
                        else  % Combine Isocaps and Isosurface data into one surface
                            Faces3 = [ Faces1 ; Faces2 + size(Vertices1(:,1),1) ];   
                            Vertices3 = [Vertices1 ; Vertices2];
                        end
                        ISraw = reducepatch(Faces3,Vertices3,0.3); % <----- This is the final raw isosurface that is rendered !!! 

                     
                    if ~isempty(ISraw.vertices) % If rendering exists (or worked) then take measurements and record

                            % https://www.mathworks.com/matlabcentral/fileexchange/24943-two-functions-for-smoothing-denoising-of-triangular-meshes?s_tid=srchtitle
                            % IS.vertices = lpflow_trismooth(app,IS.vertices,IS.faces); 
                        
                            % Create solid RAW representation with tetrahedrons ---------------------------------------------------
                                DT1 = delaunayTriangulation(ISraw.vertices(:,1),ISraw.vertices(:,2),ISraw.vertices(:,3));  % Create Delaunay Triangulation from isosurface points
                                T = DT1.ConnectivityList;  % Grab connectivity list
                                % triangulation creates concave solids, so we need to test which tetrahedrons are outside the isosurface and remove them then recreate the triangulation
                                [Circumcenters,~] = circumcenter(DT1);  % Calculate circumcenters of tetrahedrons
                                IN = inpolyhedron(app,ISraw.faces,ISraw.vertices,Circumcenters,'flipnormals',true);  % Test if curcumceneters are inside or outside isosurface
                                T(~IN,:) = [];  % Remove tetrhedrons outside isosurface
                                DT2 = triangulation(T,ISraw.vertices);  % Re-define solid triangulation without the tetrahedrons that are outside the isosurface
                                % Visualize DT2 using: tetramesh(DT2,'FaceAlpha',0.3);    
                            
                            % Create solid SMOOTH representation with tetrahedrons ---------------------------------------------------
                                DT1smooth = delaunayTriangulation(ISsmooth.vertices(:,1),ISsmooth.vertices(:,2),ISsmooth.vertices(:,3));  % Create Delaunay Triangulation from isosurface points
                                Ts = DT1smooth.ConnectivityList;  % Grab connectivity list
                                % triangulation creates concave solids, so we need to test which tetrahedrons are outside the isosurface and remove them then recreate the triangulation
                                [Circumcenters2,~] = circumcenter(DT1smooth);  % Calculate circumcenters of tetrahedrons
                                INs = inpolyhedron(app,ISsmooth.faces,ISsmooth.vertices,Circumcenters2,'flipnormals',true);  % Test if curcumceneters are inside or outside isosurface
                                Ts(~INs,:) = [];  % Remove tetrhedrons outside isosurface
                                DT2smooth = triangulation(Ts,ISsmooth.vertices);  % Re-define solid triangulation without the tetrahedrons that are outside the isosurface
                                % Visualize DT2 using: tetramesh(DT2,'FaceAlpha',0.3);      

                            % Select which rendering to use to make measurements
                            if app.UseSmoothedForMeasurementsCheckBox.Value
                                IS = ISsmooth;
                                DT = DT2smooth;
                            else
                                IS = ISraw;
                                DT = DT2;
                            end

                            % Calculate Cell Volume --------------------------------------------------------------------------    
                            % https://en.wikipedia.org/wiki/Tetrahedron V = |(a.(b x c))|/6 for d = [0,0,0]
                                
                            a = IS.vertices( DT.ConnectivityList(:,1), :);
                            b = IS.vertices( DT.ConnectivityList(:,2), :);
                            c = IS.vertices( DT.ConnectivityList(:,3), :);
                            d = IS.vertices( DT.ConnectivityList(:,4), :);   
                            
                            TetraVolumes = abs(dot(a-d,cross(b-d,c-d,2),2))./6; % Volume Method1: sum tetrahedron volumes in DT2
                            CellVolume   = sum(TetraVolumes);
                            % CellVolume2  = (app.dp^2)*app.dz*sum(J(:)); % Volume Method2: sum voxels in J 

                            % Calculate Minimum Bounding Sphere Diameter -----------------------------------------------------
                            [R1,~,~] = ExactMinBoundSphere3D(app,IS.vertices);   
                            MBSD = 2*R1;

                            % Center of Mass of the Rendering ----------------------------------------------------------------
                            Centroids = mean(cat(3,a,b,c,d),3);
                            CellCentroid = sum( [TetraVolumes.*Centroids(:,1),...
                                                 TetraVolumes.*Centroids(:,2),...
                                                 TetraVolumes.*Centroids(:,3)]./CellVolume, 1 );
                            % Calculate Minimum Bounding Circle diameter of cross-section through centroid perpendicular to R1 of ellipsoid fit
                            if size(IS.vertices,1) > 9
                                    [~,~,ev,~,~] = ellipsoid_fit(app, IS.vertices, '');       % Calculate best ellipsoid fit, and the three defining vectors: ev !!!!
                                    % cLine = intersectPlaneSurf(app,IS,CellCentroid,ev(:,1));  % Be sure to include mex file: IntersectPlaneTriangle.mex                                    
                                    XYZ = PlaneAndSurfaceIntersectionLine(app,IS.faces,IS.vertices,CellCentroid,ev(:,1));
                                    % XYZmbcdline = cLine{1}';
                                    XYZmbcdline = [reshape(XYZ(:,:,1),[],1),reshape(XYZ(:,:,2),[],1),reshape(XYZ(:,:,3),[],1)]; % Note: these points are not unique or sorted
                                    Rot1 = RotationMatrix3D(app,ev(:,1),[0,0,1]);
                                    % Rotate Intersection Points to XY plane so that MinBoundingCircle can be fit
                                    M1 = Rot1*[ XYZmbcdline(:,1)' - CellCentroid(1) ;...
                                                XYZmbcdline(:,2)' - CellCentroid(2) ;...
                                                XYZmbcdline(:,3)' - CellCentroid(3)  ];
                                    [R2,~,~] = ExactMinBoundCircle(app,[ M1(1,:)', M1(2,:)' ]); 
                                    MBCD = 2*R2;
                            else
                                    ev = nan(3,3);
                                    MBCD = nan;
                            end
                            
                            % Update timepoint text on figure window -----------------------------------------------------------
                            if isgraphics(app.TimepointTextHandle)
                                 delete(app.TimepointTextHandle)
                            end

                            app.TimepointTextHandle = axes(app.SingleCellFigureHandle,'Position',[0.02,1,0.01,0.01],'Visible','off','XLim',[0,1],'YLim',[0,1]);

                            TX1 = text(app.TimepointTextHandle,0,0,['Timepoint = ',num2str(app.TimePointSpinner.Value),'\color{black}', pad('volume = ',19,'left'),...
                                                                    sprintf('%0.0f',CellVolume),' \mum^3',...
                                                                    '         track position [x,y,z] (\mum) = ',...
                                                                     ' [  ',sprintf('%0.2f',app.CurrentTrackPoint.x),...
                                                                     ',  ', sprintf('%0.2f',app.CurrentTrackPoint.y),...
                                                                     ',  ', sprintf('%0.2f',app.CurrentTrackPoint.z),'  ]'],...
                                                                    'VerticalAlignment','top','HorizontalAlignment','left','FontWeight','bold','FontSize',11,'Color',[1,0,0]); 
                            drawnow 
                        
                            % Save/Overwrite Rendering data and settings from this time point ----------------------------------
                            app.TimePointData.SurfaceRenderings{track,1}{t,1}                       = ISraw;  
                            app.TimePointData.SolidRenderings{track,1}{t,1}                         = DT2;
                            app.TimePointData.SmoothedSurfaceRenderings{track,1}{t,1}               = ISsmooth; 
                            app.TimePointData.SmoothedSolidRenderings  {track,1}{t,1}               = DT2smooth; 
                            app.TimePointData.LinearIndexMaskInUnCroppedVolume{track,1}{t,1}        = FullVolumeLinearIndices;
                            app.TimePointData.RenderingSettings{track,1}.Pad(t,1)                   = app.PadSpinner.Value;
                            app.TimePointData.RenderingSettings{track,1}.ExtendedMin(t,1)           = app.ExtendedMinSpinner.Value;
                            app.TimePointData.RenderingSettings{track,1}.SmoothSegmentationSigma    = app.SegSmoothSigmaSpinner.Value;
                            %app.TimePointData.RenderingSettings{track,1}.DataSource(t,1)   = app.DataSourceDropDown.Value;
                        
                            % Save/Overwrite Cell Measurements -----------------------------------------------------------------    
                            app.TimePointData.Measurements{track,1}.Volume(t,1)    = CellVolume;  
                            app.TimePointData.Measurements{track,1}.MBSD(t,1)      = MBSD;   
                            app.TimePointData.Measurements{track,1}.MBCD(t,1)      = MBCD; 
                            app.TimePointData.Measurements{track,1}.Centroid(t,:)  = CellCentroid; 
                            app.TimePointData.Measurements{track,1}.EllipsoidFitVectors(t,:,:) = ev; % Record longaxis of ellipsoid fit

                            % Create Rendered cell surface in figure window -------------------------------------------------
                                % Select which version to render
                                if app.RenderingSmoothingOnCheckBox.Value
                                    ISS = ISsmooth;
                                else
                                    ISS = ISraw;
                                end
                                app.SingleCellRenderingHandle = patch(app.SingleCellAxisHandle, ISS);
    
                                app.SingleCellRenderingHandle.FaceColor = app.FaceColor;
                                app.SingleCellRenderingHandle.FaceAlpha = app.FaceTransparency;  
                                app.SingleCellRenderingHandle.EdgeColor = app.MeshColor;  % [0.8,0.8,0.8];
                                app.SingleCellRenderingHandle.EdgeAlpha = app.MeshTransparency;
                                
                                app.CellVisibilityButton.Text = 'Cell Visibility - ON';
                                % hold(app.SingleCellAxisHandle,'on')
                                %     plot3(app.SingleCellAxisHandle, CellCentroid(1), CellCentroid(2), CellCentroid(3),'.','MarkerSize',5)
                                % hold(app.SingleCellAxisHandle,'off')
                                
                                R3 = app.DataPlaneSizeInput.Value/2 + 10;
                                axis(app.SingleCellAxisHandle,'equal')
                                axis(app.SingleCellAxisHandle, [ app.CurrentTrackPoint.x - R3,  app.CurrentTrackPoint.x + R3,...
                                                                 app.CurrentTrackPoint.y - R3,  app.CurrentTrackPoint.y + R3,...
                                                                 app.CurrentTrackPoint.z - R3,  app.CurrentTrackPoint.z + R3 ] );


                    else  % IF RENDERING FAILS:
                            % Save/Overwrite Rendering data and settings from this time point ----------------------------------
                            app.TimePointData.SurfaceRenderings{track,1}{t,1}                 = [];  
                            app.TimePointData.SolidRenderings{track,1}{t,1}                   = [];
                            app.TimePointData.LinearIndexMaskInUnCroppedVolume{track,1}{t,1}  = [];
                            app.TimePointData.SmoothedSurfaceRenderings{track,1}{t,1}         = [];  
                            app.TimePointData.SmoothedSolidRenderings  {track,1}{t,1}         = []; 
                            app.TimePointData.RenderingSettings{track,1}.Pad(t,1)                = app.PadSpinner.Value;
                            app.TimePointData.RenderingSettings{track,1}.ExtendedMin(t,1)        = app.ExtendedMinSpinner.Value;
                            app.TimePointData.RenderingSettings{track,1}.SmoothSegmentationSigma = app.SegSmoothSigmaSpinner.Value;
                            %app.TimePointData.RenderingSettings{track,1}.DataSource(t,1)      = app.DataSourceDropDown.Value;
                        
                            % Save/Overwrite Cell Measurements -----------------------------------------------------------------    
                            app.TimePointData.Measurements{track,1}.Volume(t,1)    = nan;  
                            app.TimePointData.Measurements{track,1}.MBSD(t,1)      = nan;   
                            app.TimePointData.Measurements{track,1}.MBCD(t,1)      = nan; 
                            app.TimePointData.Measurements{track,1}.Centroid(t,:)  = nan; 
                    end
        end
           

%--------------------------------------------------------------------------------------------------------------------------------------
             function LineMaskIdx = GetLinearIndicesForLongAxisLine(app,track,t,L)
                    
                    % L = Long axis line length (microns)
%                     X =  LocusData.X;
%                     Y =  LocusData.Y;
%                     Z =  LocusData.Z;
                    
%                     t = 58; % Specify timepoint
                    idx = app.TimePointData.LinearIndexMaskInUnCroppedVolume{track,1}{t,1}; % Garb linear indices of 3D solid cell mask
                    V   = app.TimePointData.Measurements{track,1}.EllipsoidFitVectors(t,:,1); % Grab long axis vector

                    if ~isempty(idx) & ~isempty(V) & ~any(isnan(V))
                            V   = app.TimePointData.Measurements{track,1}.EllipsoidFitVectors(t,:,1); % Grab long axis vector
        %                     Mask1 = zeros(size(X),'uint8'); % Create 3D cell rendering mask
        %                     Mask1(idx) = 1;
                            [r,c,p] = ind2sub(size(app.X),idx);
                            
                            R = round(mean(r(:)));
                            C = round(mean(c(:)));
                            P = round(mean(p(:)));
                            
                            Xc = app.X(R,C,P);
                            Yc = app.Y(R,C,P);
                            Zc = app.Z(R,C,P);
                            
                            % Create mask for longaxis vector mask
                            Mask2 = false(size(app.X));
                            % L = 35; % Half length
        
                            L1 = [Xc+L*V(1)/2, Yc+L*V(2)/2, Zc+L*V(3)/2]; % Find end point1 of line representing longaxis in X,Y,z
                            L2 = [Xc-L*V(1)/2, Yc-L*V(2)/2, Zc-L*V(3)/2]; % Find end point2 of line representing longaxis in x,y,z
                            
                            [~,Cidx1] = min( abs(squeeze(app.X(1,:,1)) - L1(1)) ); % Find index in X array that corresponds to this end point
                            Cidx1 = Cidx1(1); % incase the point lies inbetween two points just take one of them
                            [~,Ridx1] = min( abs(squeeze(app.Y(:,1,1)) - L1(2)) );
                            Ridx1 = Ridx1(1);
                            [~,Pidx1] = min( abs(squeeze(app.Z(1,1,:)) - L1(3)) );
                            Pidx1 = Pidx1(1);
                            
                            [~,Cidx2] = min( abs(squeeze(app.X(1,:,1)) - L2(1)) ); % Find index in X array that corresponds to this end point
                            Cidx1 = Cidx1(1); % incase the point lies inbetween two points just take one of them
                            [~,Ridx2] = min( abs(squeeze(app.Y(:,1,1)) - L2(2)) );
                            Ridx1 = Ridx1(1);
                            [~,Pidx2] = min( abs(squeeze(app.Z(1,1,:)) - L2(3)) );
                            Pidx2 = Pidx2(1);
                            
                            MaxPts =  sqrt( (Cidx2-Cidx1)^2 + (Ridx2-Ridx1)^2 + (Pidx2-Pidx1)^2 );
                            IdxPts =  1:1:MaxPts;
                            LineRpts = round(Ridx1 + IdxPts*( (Ridx2-Ridx1)/MaxPts ) );
                            LineCpts = round(Cidx1 + IdxPts*( (Cidx2-Cidx1)/MaxPts ) );
                            LinePpts = round(Pidx1 + IdxPts*( (Pidx2-Pidx1)/MaxPts ) );
                            
                            LineMaskIdx = unique( sub2ind(size(app.X),LineRpts,LineCpts,LinePpts) );
                            MLI = numel(app.X); % Maximum linear index
                            LineMaskIdx(LineMaskIdx < 1 | LineMaskIdx > MLI) = []; % remove indices outlide of Mask indices
                            Mask2(LineMaskIdx) = true;
                            Mask2 = imdilate(Mask2,strel('sphere',1));
                            LineMaskIdx = find(Mask2);

                    else

                            LineMaskIdx = [];

                    end

            end

%--------------------------------------------------------------------------------------------------------------------------------------           
           function Processed = FindCellSurfaces(app,V)

                    [row,col,page] = size(V);
                    FM1 = zeros(row,col,page);
                    FM2 = zeros(row,col,page);
                    FM3 = zeros(row,col,page);
                    
                    % V = imdilate(V,strel('sphere',2));
                    
                    SS = 0.001;
                    for r = 1:row    
                        FM1(r,:,:) = fibermetric(squeeze(V(r,:,:)),'thickness',[1,2],'StructureSensitivity',SS);
                        FM1(r,:,:) = bwmorph(squeeze(FM1(r,:,:)),'bridge');  
                        FM1(r,:,:) = bwareaopen(squeeze(FM1(r,:,:)),6);
                    end
                    for c = 1:col
                        FM2(:,c,:) = fibermetric(squeeze(V(:,c,:)),'thickness',[1,2],'StructureSensitivity',SS); 
                        FM2(:,c,:) = bwmorph(squeeze(FM2(:,c,:)),'bridge'); 
                        FM2(:,c,:) = bwareaopen(squeeze(FM2(:,c,:)),6); 
                    end
                    for p = 1:page
                        FM3(:,:,p) = fibermetric(squeeze(V(:,:,p)),'thickness',[1,2],'StructureSensitivity',SS);  
                        FM3(:,:,p) = bwmorph(squeeze(FM3(:,:,p)),'bridge'); 
                        FM3(:,:,p) = bwareaopen(squeeze(FM3(:,:,p)),6); 
                    end
                
                    Processed = (FM1+FM2+FM3)./3;
                    
                    L = logical(Processed);
                
                    L2 = bwareaopen(L,50);
                    L3 = ~bwareaopen(L2,10);
                    L4 = bwmorph3(L3,'majority');
                    DW = bwdist(L4);
                    
                     for r = 1:row    
                        FM1(r,:,:) = fibermetric(squeeze(DW(r,:,:)),'thickness',[1],'StructureSensitivity',SS);
                    end
                    for c = 1:col
                        FM2(:,c,:) = fibermetric(squeeze(DW(:,c,:)),'thickness',[1],'StructureSensitivity',SS); 
                    end
                    for p = 1:page
                        FM3(:,:,p) = fibermetric(squeeze(DW(:,:,p)),'thickness',[1],'StructureSensitivity',SS); 
                    end
                    
                    Processed = (FM1+FM2+FM3)./3;
           end

           
%--------------------------------------------------------------------------------------------------------------------------------------
    function DT = ApplyConnectionLines(app,DT,connectionLines,Xsub,Ysub,Zsub,dp,dz,LineDilation)
    
            if ~isempty(connectionLines)
                nL = size(connectionLines,3); % Count number of lines
                for k = 1:nL
                    cL = squeeze(connectionLines(:,:,k));
                    % [row,col,z] = FindIndicesOfXYZ(x,y,z,X,Y,Z); [x,y,z] = point location, X,Y,& Z = 3D mesh grids
                    [r1,c1,p1] = FindIndicesOfXYZ(app,cL(1,1),cL(1,2),cL(1,3),Xsub,Ysub,Zsub);
                    [r2,c2,p2] = FindIndicesOfXYZ(app,cL(2,1),cL(2,2),cL(2,3),Xsub,Ysub,Zsub);
        
                    % The dimension with the longest distance
                    n = max( [abs(r2-r1)+1; abs(c2-c1)+1; abs(p2-p1)+1; 1] );
        
                    r = round(linspace(r1,r2,n))';
                    c = round(linspace(c1,c2,n))';
                    p = round(linspace(p1,p2,n))';
        
                    ind = unique(sub2ind(size(DT),r,c,p));
        
                    B = zeros(size(DT));
                    B(ind) = 1;
                    
                    % Thicken connection line
                    B = imboxfilt3(B);
                    B(B >= 1/26) = 1;
                    B(B  < 1/26) = 0;
                    
                    %B = imdilate(B,strel('sphere',LineDilation));
                    % ind = find(B);
                    % DT(ind) = -Inf;
                    DT(B==1) = -Inf;
                end
            end
            
    end

    
%--------------------------------------------------------------------------------------------------------------------------------------
    function B = ApplySeparationLines(app,J,separationLines,Xsub,Ysub,Zsub,dp,dz,LineDilation,SetValue)
    
    % The variable serparationLines contains two sub cell arrays for each separation line.
    % The first sub cell contains the actual separation line points, and the second sub cell contains a unit vector perpendicular to the plane it was drawn on
    
            B = false(size(J));
            if ~isempty(separationLines)
                nL = size(separationLines,2); % Count number of lines
                
                for k = 1:nL
                        SLpts  = separationLines{1,k}{1}; % grab serparation line points (units of microns).
                        SLppv1 = separationLines{2,k}{1}; % grab the perpendicular-to-plane-vector that the separation line was drawn on (units are microns).
                        SLppv2 = [ SLppv1(1)*app.dp, SLppv1(2)*app.dp, SLppv1(3)*app.dz ]; % convert to pixel/z-step units
                        SLppv2 = SLppv2./vecnorm(SLppv2); % re-normalize
                        
                        R = []; 
                        C = []; 
                        P = [];
                        
                        for m = 1:(size(SLpts,1)-1) % Create pixel line one point pair at a time
                            [r1,c1,p1] = FindIndicesOfXYZ(app, SLpts(m  ,1), SLpts(m  ,2), SLpts(m  ,3), Xsub, Ysub, Zsub);
                            [r2,c2,p2] = FindIndicesOfXYZ(app, SLpts(m+1,1), SLpts(m+1,2), SLpts(m+1,3), Xsub, Ysub, Zsub);
                      
                            % Find the dimension with the longest distance
                            N = max( [abs(r2-r1)+1; abs(c2-c1)+1; abs(p2-p1)+1; 1] );
                                % Interpolate between each component of segment of the separation line to make sure we have
                                % at least one point overlapping each voxel along the way    
                                r = round( linspace(r1,r2,N) )';
                                c = round( linspace(c1,c2,N) )';
                                p = round( linspace(p1,p2,N) )';
                                % Accumulate all the interpolated points for each segment of the separation line and user 
                                % this later to turn the interpolated line into an interpolated ribbon
                                R = [R;r]; 
                                C = [C;c];
                                P = [P;p];          
                        end
                        ind = unique( sub2ind(size(J),R,C,P) ); % remove duplicate points
                        [R,C,P] = ind2sub(size(J),ind);
                        % Interpolate line into ribbon
                        Rr = [];
                        Cr = [];
                        Pr = [];
                        RW = 20; % Ribbon height in microns
                        for n = 1:length(R)
                            [r1,c1,p1] = FindIndicesOfXYZ(app, Xsub(R(n),C(n),P(n))-RW*SLppv1(1), Ysub(R(n),C(n),P(n))-RW*SLppv1(2), Zsub(R(n),C(n),P(n))-RW*SLppv1(3), Xsub, Ysub, Zsub);
                            [r2,c2,p2] = FindIndicesOfXYZ(app, Xsub(R(n),C(n),P(n))+RW*SLppv1(1), Ysub(R(n),C(n),P(n))+RW*SLppv1(2), Zsub(R(n),C(n),P(n))+RW*SLppv1(3), Xsub, Ysub, Zsub);
                            N = max( [abs(r2-r1)+1; abs(c2-c1)+1; abs(p2-p1)+1; 1] );
                            r = round( linspace(r1,r2,N) )';
                            c = round( linspace(c1,c2,N) )';
                            p = round( linspace(p1,p2,N) )';
                            
                            Rr = [Rr;r];
                            Cr = [Cr;c];
                            Pr = [Pr;p];                        
                        end
                        ind2 = unique( sub2ind(size(J),Rr,Cr,Pr) );
                        B(ind2) = true; 
                end
                D = B;
                B = imdilate(B,strel('sphere',1)); % dilate line if necessary
%                 To visualize ribbon

                %D = imdilate(D,strel('cube',1)); % dilate line if necessary
                % hold on
                % p = patch(app.SingleCellAxisHandle, isosurface(Xsub,Ysub,Zsub,double(B),0.1) );
                % p.FaceColor = [0.5,0.5,0.5];
                % p.FaceAlpha = 0.5;
                % p.EdgeColor = 'none';
                % drawnow
            end
    end


%--------------------------------------------------------------------------------------------------------------------------------------
    function SeedPointRenderingMethod(app,SeedPoint,Vol,X,Y,Z)
                 
                        %[nR,nC,nZ] = size(Vol);
                        %SeedPoint = [48,49,15];
                        
                        r = 40;
                        
                        %[Vert,~] = spheretri(500);
                        [xp,yp,zp] = EquallyDistributePoints(500);
                        xp = r*xp; % r*Vert(:,1);
                        yp = r*yp; % r*Vert(:,2);
                        zp = r*zp; % r*Vert(:,3);
                        
                        N = length(xp);
                        nI = 100; % Number of interpolation points --------
                        XP = zeros(N,nI);
                        YP = zeros(N,nI);
                        ZP = zeros(N,nI);
                        
                        for n = 1:N
                            XP(n,:) = linspace(0,xp(n,1),nI);
                            YP(n,:) = linspace(0,yp(n,1),nI);
                            ZP(n,:) = linspace(0,zp(n,1),nI);
                        end
                        
                        % Sample region centered on nuclear position
                        h1 = waitbar(0);
                        Q1 = zeros(size(XP));
                        
                        % Interpolate along vectors centered at seed point -------------------------------------------------------------
                            for m = 1:N
                                Q1(m,:) = interp3(X,Y,Z, double(Vol), XP(m,:) + SeedPoint(1,1),...
                                                                      YP(m,:) + SeedPoint(1,2),...
                                                                      ZP(m,:) + SeedPoint(1,3), 'nearest');
                                waitbar(m/N,h1)
                                drawnow
                            end
                            try; close(h1); end
                    
                        % plot vector interpolations -----------------------------------------------------------------------------------
                            IntPts = NaN(N,3);
                            for m = 1:N
                                %[pks,locs,w,p] = findpeaks( smooth(Q1(m,:),5,'sgolay',3) );
                                [pks,locs,w,p] = findpeaks(Q1(m,:));
                                idx = find(pks > 0.05);
                                if ~isempty(pks) && ~isempty(idx)
                                    IntPts(m,:) = [ XP( m,locs(idx(1)) ) + SeedPoint(1,1),...
                                                    YP( m,locs(idx(1)) ) + SeedPoint(1,2),...
                                                    ZP( m,locs(idx(1)) ) + SeedPoint(1,3)  ];
                                    % IntensityValue(m,:) = pks(idx(1));
                                    % DistFromSeedPoint(m,:) = locs(idx(1));
                                end
                            end 
                    
                        %------------------------------------------------------------------------------------    
                        plot3(IntPts(:,1),IntPts(:,2),IntPts(:,3),'.',SeedPoint(1),SeedPoint(2),SeedPoint(3),'.r');  
                        grid on;  camproj('perspective')    
                        xlabel('X');, ylabel('Y'); zlabel('Z')    
                        
                        % Check for NaN and Inf values ----------------------------------------
                           ptCloud  = pointCloud(IntPts);  
                           [ptCloud,indices] = removeInvalidPoints(ptCloud);
                           nPts = ptCloud.Count;
                        %----------------------------------------------------------------------
                        tic
                        K = [10,10,40];
                        for n = 1:2
                            sPoints = NaN(nPts,3); % Smooothed Points
                            for q = 1:nPts
                                [ind3,dists] = findNearestNeighbors(ptCloud, ptCloud.Location(q,:) ,K(n));
                                sPoints(q,:) = [mean(ptCloud.Location(ind3,1)), mean(ptCloud.Location(ind3,2)), mean(ptCloud.Location(ind3,3))];
                            end
                            ptCloud = pointCloud(sPoints);
                        end
                        toc
                    %     [C,v] = convexHull(DT);
                        figure(4); clf
                       % hold on
                        %plot3(IntPts(:,1),IntPts(:,2),IntPts(:,3),'.b',SeedPoint(1),SeedPoint(2),SeedPoint(3),'.r');     
                        plot3(sPoints(:,1),sPoints(:,2),sPoints(:,3),'.r');  grid on;  camproj('perspective')  ; axis equal  
                        xlabel('X'); ylabel('Y'); zlabel('Z')                        
            end


%---------------------------------------------------------------------------------------------------------------------
            function  app = InitializeControlValues(app)
                
                    app.CurrentTrackDropDown.Value = 1;
                    
                    app.TrackPointOffset_X.Value = 0;
                    app.TrackPointOffset_Y.Value = 0;
                    app.TrackPointOffset_Z.Value = 0;
                    
                    app.PadSpinner.Value = 21;
                    %app.SmoothFactorSpinner.Value = 0;
                    app.ExtendedMinSpinner.Value = 0.1;
                    
                    app.XSlider.Value = 0;
                    app.YSlider.Value = 0;
                    app.ZSlider.Value = 0;
                    app.XSliderSpinner.Value = 0;
                    app.YSliderSpinner.Value = 0;
                    app.ZSliderSpinner.Value = 0;
                    
                    app.MaxDistSpinner.Value = 20;
                    app.StepSizeSpinner.Value = 1;
                    app.TILTslider.Value = 0;
                    app.PANslider.Value = 0;
                    app.TILTeditField.Value = 0;
                    app.PANeditField.Value = 0;
                    
                    %app.DataSourceDropDown.Value = 1;
                    %app.DataPlaneChannel.Value = 1;
                    
                    app.GaussianFiltSwitch.Value = 0;
                    app.GaussFiltLamp.Color =  [0.8,0.8,0.8];
                    app.SigmaSpinner.Value = 1.0;
                    app.FrangiFilterSwitch.Value = 0;
                    app.FrangiLamp.Color =  [0.8,0.8,0.8];
                    app.AdaptHistEqSwitch.Value = 0;
                    app.AdaptHistEqLamp.Color = [0.8,0.8,0.8];
                
            end
                
 %------------------------------------------------------------------------------------------------------------------------               
            function  app = PopulateTimePointProperties(app)
                
               % Default population ----------------------------------------------------------------------                 
               %    app.TimePointData.Properties = [ (0:(app.nTime-1))'.*app.dt/60 , zeros(app.nTime,5)];
               %    app.TimePointData.Renderings = cell(app.nTime,1);  
               %------------------------------------------------------------------------------------------
                                  
               Rendering = [{'empty'},{'notsaved'},{'saved'}];
               Visibility = [{'yes'},{'no'}];
               Fit = [{'off'},{'on'}];
               TrackPt = [{'original'},{'moved'}];
               track = app.CurrentTrack;
               ITEMS = cell(app.nTime,1);
              
               for t = 1:app.nTime
                    Index = sprintf('% 5s',  sprintf('%04u',t) );
                    Time  = sprintf('% 10s', sprintf('%7.2f',app.TimePointData.Properties{track,1}(t,1)) );
                    Saved = sprintf('% 8s',  Rendering{1, app.TimePointData.Properties{track,1}(t,2)+1} );
                    Vis   = sprintf('% 6s',  Visibility{1,app.TimePointData.Properties{track,1}(t,3)+1} );
                    FitEq = sprintf('% 8s',  Fit{1,app.TimePointData.Properties{track,1}(t,4)+1} );
                    Vol   = sprintf('% 9s',  sprintf('%u',   app.TimePointData.Properties{track,1}(t,5)) ); 
                    Track = sprintf('% 12s', TrackPt{1,app.TimePointData.Properties{track,1}(t,4)+1} ); 
                    
                    ITEMS{t,1} = [Index, Time, Saved, Vis, FitEq, Vol, Track];
               end  
              
               app.TimePointListBox.Items = ITEMS;
               app.TimePointListBox.ItemsData = 1:app.nTime;
               app.TimePointListBox.Value = 1;
            

            end

 %------------------------------------------------------------------------------------------------------------------------       
                function  [RotationMatrix, RotationAngle] = RotationMatrix3D(app,U,V)
        
                        % -------------------------------------------------------------------
                        % Creates 3D rotation matrix 
                        %
                        % U = [Ux Uy Uz]; Starting position vector
                        % V = [Vx Vy Vz]; Ending position vector
                        %
                        % KRC 11/2/18
                        %
                        % For explanation of calculation, see Euler-Rodriques formula
                        % https://en.wikipedia.org/wiki/Euler-Rodrigues_formula
                        % https://en.wikipedia.org/wiki/Rodrigues%27_rotation_formula
                        % --------------------------------------------------------------------
                
                         K = cross(U,V); % Calculate rotation axis vector K using cross product of U and V
                         mK = norm(K);  % Calulate vector magnitudes
                         mU = norm(U);
                         mV = norm(V);
                         
                         if isequal(mK,0)
                            t = 0;
                            k = [0,0,0];     
                         else
                             k = K./mK;     % Create unit vector
                             
                             if sign(dot(U,V)) >= 0
                                 t = asin(mK/(mU*mV)); % calculate rotation angle theta
                             else
                                 t = pi - asin(mK/(mU*mV)); % calculate rotation angle theta
                             end
                         end
                         
                        RotationAngle = t*180/pi;
                         
                        a = cos(t/2);
                        b = k(1)*sin(t/2);
                        c = k(2)*sin(t/2);
                        d = k(3)*sin(t/2);
                        
                        RotationMatrix = [ [ (a^2+ b^2-c^2-d^2)  ,     2*(b*c-a*d)     ,      2*(b*d+a*c)    ];... 
                                           [    2*(b*c+a*d)      ,  (a^2+c^2-b^2-d^2)  ,      2*(c*d-a*b)    ];... 
                                           [    2*(b*d-a*c)      ,     2*(c*d+a*b)     ,  (a^2+d^2-b^2-c^2)  ] ];
                end
                

  %------------------------------------------------------------------------------------------------------------------------       
                function  [RotationMatrix] = RotationMatrixAroundVector(app,V,theta)
        
                        % -------------------------------------------------------------------
                        % Creates 3D rotation matrix around a vector
                        %
                        % 
                        % V = [Vx Vy Vz]; Ending position vector
                        %
                        % KRC 4/23/21
                        %
                        % For explanation of calculation, see Euler-Rodriques formula
                        % https://mathworld.wolfram.com/RodriguesRotationFormula.html
                        % 
                        % --------------------------------------------------------------------
                         
                         mV = norm(V);
                         V = V./mV;
                         Vx = V(1); 
                         Vy = V(2);
                         Vz = V(3);
                         
                         t = theta*(pi/180);
                         
                        RotationMatrix = [ [  cos(t)    + (Vx^2)*(1-cos(t)) ,  Vx*Vy*(1-cos(t)) - Vz*sin(t)  ,  Vy*sin(t) +  Vx*Vz*(1-cos(t))  ];... 
                                           [  Vz*sin(t) +  Vx*Vy*(1-cos(t))  ,  cos(t)   + (Vy^2)*(1-cos(t)) , -Vx*sin(t) +  Vy*Vz*(1-cos(t))  ];... 
                                           [ -Vy*sin(t) +  Vx*Vz*(1-cos(t))  ,  Vx*sin(t) + Vy*Vz*(1-cos(t)) ,  cos(t)    + (Vz^2)*(1-cos(t)) ] ];
                end
                             

 %------------------------------------------------------------------------------------------------------------------------
                function [files, folders] = files_and_folders(app, directory)
                    
                        %  ______________________________________________
                        %  Grab and sort files and folders from directory
                        %
                        %  [files, folders] = files_and_folders(directory)
                        %
                           
                        DirInfo = dir(directory); % struct array with fields: name, date, bytes, isdir, datenum
                        DirInfoCell = struct2cell(DirInfo); % ([name date bytes isdir datenum],N)
                        names = DirInfoCell(1,:)'; % Grab Names only
                        designation = DirInfoCell(5,:)'; % TRUE = Folder/Dir, FALSE = File
                        
                        % List names of hidden files and folders that are operating system specific (PC and MAC)
                        flag1 =  strcmp('.',names);
                        flag2 =  strcmp('..',names);
                        flag3 =  strcmp('Thumbs.db',names);
                        flag4 =  strcmp('.DS_Store',names);
                        flag5 =  strcmp('._.DS_Dtore',names);
                        ind = all(~[flag1, flag2, flag3, flag4, flag5],2);
                        
                        % Use logical Indexing to grab only names and designations that are non-operating system specific
                        FilteredNames = names(ind,1); 
                        FilteredDesignation = cell2mat(designation(ind,1)); % Convert this cell array to matrix array for logical indexing below
                        
                        % Now sort into File and Folder cell arrays
                        files  = FilteredNames(~FilteredDesignation);
                        folders = FilteredNames(FilteredDesignation);
                end
                

 %------------------------------------------------------------------------------------------------------------------------
%              function ImVolEq = adapthisteq3D(app,ImVol)
%                  
%                  [nR,nC,nZ] = size(ImVol);
%                  ImVolEq1 = zeros(nR,nC,nZ,'single');
%                  ImVolEq2 = zeros(nR,nC,nZ,'single');
%                  ImVolEq3 = zeros(nR,nC,nZ,'single');
%                  
%                  for z = 1:nZ; ImVolEq1(:,:,z) = adapthisteq(ImVol(:,:,z)); end
%                  for r = 1:nR; ImVolEq2(r,:,:) = adapthisteq(squeeze(ImVol(r,:,:))); end
%                  for c = 1:nC; ImVolEq3(:,c,:) = adapthisteq(squeeze(ImVol(:,c,:))); end
%                  
%                  ImVolEq = cat(4,ImVolEq1,ImVolEq2,ImVolEq3);
%                  ImVolEq = max(ImVolEq,[],4);
%              end


 %------------------------------------------------------------------------------------------------------------------------
             function [DATA5d, IND] = ReadSubRegionsOverTime(app, DirRGB, TrackName, Track, nR, nC, nZ, dp, dz, rHeight, cWidth, zDepth, nT)
                
                    % [DATA5d, IND] = ReadSubRegionsOverTime(app, DirRGB, Track, nR, nC, nZ, dp, dz, rHeight, cWidth, zDepth, nT)
                    %
                    % DirRGB = Directory of RGB timeseries (same files used as input to longtracker)
                    % Track = Exported X,Y,Z coordinates (pixel units) of one tracked cell over time
                    % nR = number of rows in subregion that is extracted from full scan region
                    % nC = number of columns in subregion that is extracted from full scan region
                    % nZ = number of z slices in subregion that is extracted from full scan region
                    % dp = pixel size (microns)
                    % dz = z-step size (microns)
                    % rHeight = number of rows in full 3d confocal image
                    % cWidth  = number of cols in full 3d confocal image
                    % zDepth  = number of z slices in full 3d confocal image
                    % nT = total number of time points in data set
                                        
                    ImSaturate = @(Image,percent) imadjust(Image ,stretchlim(Image,[percent/100 (100-percent)/100]));
                    [files, ~] = files_and_folders(app, DirRGB);
                
                    % Make sure nR,nC,nZ are odd ----------------------------
                        nR = 2*fix(nR/2) + 1;
                        nC = 2*fix(nC/2) + 1;
                        nZ = 2*fix(nZ/2) + 1;
                    %--------------------------------------------------------
                    
                    ypad = floor( nR/2 ); % Find a row region +/- ypix wide
                    xpad = floor( nC/2 ); % Find a column region +/- xpix wide
                    zpad = floor( nZ/2 ); % Find a z region +/- zpix wide
                    
                    DATA5d  = zeros(3,nR,nC,nZ,nT, 'uint8'); % Channels, row, col, z-slice, time
                    IND = zeros(2,3,nT, 'uint16'); 
                    
                    FH = figure;
                    G = groot;
                    SS = G.ScreenSize(1,3:4);
                    Fwidth = 1200; Fheight = 650;
                    FH.Position = [ (SS(1,1)-Fwidth)/2, (SS(1,2)-Fheight)/2, Fwidth, Fheight ];
                    AH = axes(FH);
                    AH.Units = 'normalized';
                    AH.Position = [0.0825, 0.2292, 0.7500, 0.7692]; % [100,150,900,500];
                    set(AH,'CameraViewAngleMode','manual','CameraViewAngle',6,'BoxStyle','full')
                    camproj(AH,'perspective')
                   
                    view(AH,30,15) 
                    % Create %Ram axes ----------------------
                        AH2 = axes(FH);
                        AH2.Units = 'normalized';
                        AH2.Position = [0.9408, 0.0754, 0.0167, 0.8308]; % [1130,50,20,540];
                        xticks(AH2,[])
                        yticklabels(AH2,'')
                        axis(AH2,[0,1,0,100])
                        ylabel(AH2,'Percentage of RAM used','FontSize',11)
                        box(AH2,'on')
                    
                    % Create %Timepoints axes ---------------
                        AH3 = axes(FH);
                        AH3.Units = 'normalized';
                        AH3.Position = [0.0825, 0.0754, 0.7917, 0.0308]; % [100,50,950,20];
                        yticks(AH3,[])
                        xticks(AH3,[])
                        axis(AH3,[0,nT,0,1])
                        title(AH3,['Reading timepoint = ',num2str(0,'%d'),' of ',num2str(nT)],'FontSize',11)
                        box(AH3,'on')
                    %----------------------------------------
                    
                    hold(AH,'on') 
                        % Track, at this point, is in units of pixels
                        plot3(AH, Track(:,1), Track(:,2), Track(:,3), '-r');
                        %plot3(AH, Track(:,1), Track(:,2), Track(:,3), '.k', 'MarkerSize',3);
                        axis(AH,'equal')
                        grid(AH,'on')
                        box(AH,'on') 
                      
                        axis(AH, [1,cWidth,1,rHeight,1,zDepth])
                    
                        xlabel(AH,'Width'); 
                        ylabel(AH,'Height'); 
                        zlabel(AH,'Z slices')
                        camproj(AH,'perspective')
                    
%                         L = waitbar(0); 
%                         L.Units = 'pixels';
%                         LP = L.Position;
%                         L.Position(1,2) = FH.Position(1,2)-150;
                        
                        for t = 1:nT 
                            % Convert Track position back to matrix index value -----------------------------------
                                Ci = round(Track(t,1)); % Column index for the tracks X-coordinate   
                                Ri = round(Track(t,2)); % Row index for the tracks Y-coordinate           
                                Zi = round(Track(t,3)); % Z-slice for the tracks Z-coordinate
                            % Get Start and End indices for sub-region --------------------------------------------
                                Rs = Ri-ypad;   Re = Ri+ypad;
                                    if Rs < 1;              Rs = 1;                Re = 1+2*ypad; 
                                    elseif Re > rHeight;    Rs = rHeight-2*ypad;   Re = rHeight;  
                                    end
                                Cs = Ci-xpad;   Ce = Ci+xpad;
                                    if Cs < 1;              Cs = 1;                Ce = 1+2*xpad; 
                                    elseif Ce > cWidth;     Cs = cWidth-2*xpad;    Ce = cWidth; 
                                    end
                                Zs = Zi-zpad;   Ze = Zi+zpad;
                                    if Zs < 1;              Zs = 1;                Ze = 1+2*zpad; 
                                    elseif Ze > zDepth;     Zs = zDepth-2*zpad;    Ze = zDepth; 
                                    end
                                    
                                if ~isequal(t,1); delete (PH1); delete(PH2); delete(TH); delete(PH3); delete(PH4); end
                                % Plot position centered region -----------------------------------------------
                                hold(AH,'on')
                                
                                PH1 = plot3(AH,[Ce;Ce;Ce;Ce;Cs;Cs;Cs;Cs;Ce;Ce;Cs;Cs;Cs;Cs;Ce;Ce;Ce],...
                                               [Re;Re;Rs;Rs;Rs;Rs;Re;Re;Re;Re;Re;Re;Rs;Rs;Rs;Rs;Re],...
                                               [Zs;Ze;Ze;Zs;Zs;Ze;Ze;Zs;Zs;Ze;Ze;Zs;Zs;Ze;Ze;Zs;Zs],'-b');
                                            
                                PH2 = plot3(AH,Track(t,1),Track(t,2),Track(t,3),'.b','MarkerSize',8);      
                                
                                hold(AH,'off')
                                
                                TH = title(AH,['Loading subvolume of imaging volume for Track: ', TrackName]);
                                TH.Interpreter = "none";
                                
                                view(AH,30+t/3,15) % Rotate axes as it plots
                              
                                % ----------------------------------------------------------------------------- 
                                
                                IND(:,:,t) = [[Rs, Cs, Zs];... 
                                              [Re, Ce, Ze]]; 
                
                                %     Example of usage (IND = INDICES)
                                %     Xsr = X(IND(1,1,t):IND(2,1,t), IND(1,2,t):IND(2,2,t), IND(1,3,t):IND(2,3,t));
                                %     Ysr = Y(IND(1,1,t):IND(2,1,t), IND(1,2,t):IND(2,2,t), IND(1,3,t):IND(2,3,t));
                                %     Zsr = Z(IND(1,1,t):IND(2,1,t), IND(1,2,t):IND(2,2,t), IND(1,3,t):IND(2,3,t));               
                                %----------------------------------------------------------------------------------
                                
                                for z = Zs:Ze
                                    RGB  =  imread(fullfile(DirRGB,files{t,1}),'Index',z,'PixelRegion',{[Rs,Re],[Cs,Ce]});
                                    DATA5d(:,:,:,z-Zs+1,t) = shiftdim(RGB,2); % 5D array: Channel,Row,Column,Z,Time
                                end
                                
                                % Plot RAM used so far ------------------------------------------------------------
                                    [~,sys] = memory;
                                    usedmemory  = sys.PhysicalMemory.Total - sys.PhysicalMemory.Available;
                                    totalmemory = sys.PhysicalMemory.Total;
                                % Update YData in plot ------------------------------------------------------------
                                    y = (usedmemory/totalmemory)*100;
                                    PH3 = patch(AH2,[0;1;1;0],[0;0;y;y],'red','EdgeColor','none');
                                    title(AH2,[num2str(y,'%3.3f'),'%'],'FontSize',12)
                                % Plot Waitbar--------------------------------------------------------------------- 
                                    PH4 = patch(AH3,[0;t;t;0],[0;0;1;1],'red','EdgeColor','none');
                                    title(AH3,['Timepoint = ',num2str(t,'%d'),' of ',num2str(nT)])
                                %---------------------------------------------------------------------------------- 
                                drawnow  
                        end
                        
                        try close(L); end
                        try close(FH); end
             end
             

%------------------------------------------------------------------------------------------------------------------------
        function DATA4 = ReadFullImageDataAtTimeT(app,t,source)

                        if source == 1
                            ImageFilesDirectory = app.RGBZT_Directory;
                        elseif source == 2
                            ImageFilesDirectory = fullfile(app.RGBZT_Directory,app.DeconvolvedImageFilesDirectory);
                        end
                         
                        [files, ~] = files_and_folders(app, ImageFilesDirectory);
                        DATA4 = zeros(app.rHeight,app.cWidth,3,app.zDepth,'uint8'); % Channels, row, col, z-slice, time
                        index = 0;
                        for z = 1:app.zDepth
                            index = index + 1;
                            DATA4(:,:,:,index) = imread(fullfile(ImageFilesDirectory,files{t,1}),'Index',z);
                        end
            
        end
%------------------------------------------------------------------------------------------------------------------------

        function [DATA4, IND] = ReadSubRegionForTimeT(app,t,track,source)
                
                    % [DATA5d, IND] = ReadSubRegionForTimeT(app,t,track,source)
                    %
                    % DirRGB = Directory of RGB timeseries (same files used as input to longtracker)
                    % Track = Exported X,Y,Z coordinates (pixel units) of one tracked cell over time
                    % rHeight = number of rows in full 3d confocal image
                    % cWidth  = number of cols in full 3d confocal image
                    % zDepth  = number of z slices in full 3d confocal image
                    % nT = total number of time points in data set
%                                         
%                     ImSaturate = @(Image,percent) imadjust(Image ,stretchlim(Image,[percent/100 (100-percent)/100]));
                    
                    Track = app.CellTrackData{track,1};
                    
                    if ~isnan(Track(t,2))
                             % if source == 1
                             %     ImageFilesDirectory = app.RGBZT_Directory;
                             % elseif source == 2
                             %     ImageFilesDirectory = fullfile(app.RGBZT_Directory,app.DeconvolvedImageFilesDirectory);
                             % end

                             ImageFilesDirectory = app.RGBZT_Directory;

                             nR = app.RowsSubRegionField.Value;
                             nC = app.ColsSubRegionField.Value;
                             nZ = app.ZSubRegionField.Value;

                            % Make sure nR,nC,nZ are odd ----------------------------
                                nR = 2*fix(nR/2) + 1;
                                nC = 2*fix(nC/2) + 1;
                                nZ = 2*fix(nZ/2) + 1;
                            %--------------------------------------------------------
                            
                                ypad = floor( nR/2 ); % Find a row region +/- ypix wide
                                xpad = floor( nC/2 ); % Find a column region +/- xpix wide
                                zpad = floor( nZ/2 ); % Find a z region +/- zpix wide
                            
                            % Find timepoint index corresponding to time t
                                %tidx = find(Track(:,1) == t);
                            
                            % Convert Track position back to matrix index value -----------------------------------
                                Ci = round(Track(t,2)); % Column index for the tracks X-coordinate   
                                Ri = round(Track(t,3)); % Row index for the tracks Y-coordinate           
                                Zi = round(Track(t,4)); % Z-slice for the tracks Z-coordinate
                                
                            % Get Start and End indices for sub-region --------------------------------------------
                                Rs = Ri-ypad;   
                                Re = Ri+ypad;
                                    if Rs < 1                  
                                        Rs = 1;                     Re = 1+2*ypad; 
                                    elseif Re > app.rHeight    
                                        Rs = app.rHeight-2*ypad;    Re = app.rHeight;  
                                    end
                                    
                                Cs = Ci-xpad;   
                                Ce = Ci+xpad;
                                    if Cs < 1                 
                                        Cs = 1;                    Ce = 1+2*xpad; 
                                    elseif Ce > app.cWidth    
                                        Cs = app.cWidth-2*xpad;    Ce = app.cWidth; 
                                    end
                                    
                                Zs = Zi-zpad;   
                                Ze = Zi+zpad;
                                    if Zs < 1
                                        Zs = 1;                    Ze = 1+2*zpad; 
                                    elseif Ze > app.zDepth     
                                        Zs = app.zDepth-2*zpad;    Ze = app.zDepth; 
                                    end                          
                              
                            % ----------------------------------------------------------------------------- 
                                
                                IND = [[Rs, Cs, Zs]; [Re, Ce, Ze]]; 
                
                                %     Example of usage (IND = INDICES)
                                %     Xsr = X(IND(1,1):IND(2,1), IND(1,2):IND(2,2), IND(1,3):IND(2,3));
                                %     Ysr = Y(IND(1,1):IND(2,1), IND(1,2):IND(2,2), IND(1,3):IND(2,3));
                                %     Zsr = Z(IND(1,1):IND(2,1), IND(1,2):IND(2,2), IND(1,3):IND(2,3));               
                            %------------------------------------------------------------------------------
                                
                                DATA4 = zeros(nR,nC,3,nZ,'uint8'); % Channels, row, col, z-slice, time
                                index = 0;
                                
                                info = imfinfo(fullfile(ImageFilesDirectory,app.RGBZT_ImageFileNames{t,1}));
                                nZ = size(info,1);

                                for z = Zs:Ze
                                        index = index + 1;
                                        ImageData = imread(fullfile(ImageFilesDirectory,app.RGBZT_ImageFileNames{t,1}),'Index',z,'PixelRegion',{[Rs,Re],[Cs,Ce]});
                                        try 
                                            DATA4(:,:,:,index) = ImageData;
                                        catch
                                            if size(ImageData,3) ~= 3
                                                ErrorTxt = [{'Image data appears to be grayscale: Cannot load file.'}, {'Image files at each timepoint need to be RGB Z-stack tifs.'}];
                                                errordlg(ErrorTxt,'Wrong image format.')
                                            else
                                                ErrorTxt = [{ ['Expecting image array size to be: ',sprintf( [repmat('%d x ',1,length(size(DATA4))-1),'%d,'], size(DATA4) )] },...
                                                            { ['but reading: ',sprintf( '%d x %d x %d x %d', [Re-Rs+1, Ce-Cs+1, size(ImageData,3), Ze-Zs+1 ] )] }];
                                                errordlg(ErrorTxt,'Wrong image format.')
                                            end
                                            break
                                        end
                                end
                                
                                DATA4 = single(DATA4);
                                Ch1Values = DATA4(:,:,1,:);
                                DATA4(:,:,1,:) = DATA4(:,:,1,:)./max(Ch1Values(:));
                                Ch2Values = DATA4(:,:,2,:);
                                DATA4(:,:,2,:) = DATA4(:,:,2,:)./max(Ch2Values(:));                                  
                                
                    else
                        
                            DATA4 = [];
                            IND  = nan(2,3);
                    end
                
             end

             
 %------------------------------------------------------------------------------------------------------------------------            
             function DATA = ReadAllDataForTimeT(app,ch,t)
                
                    % DATA = ReadAllDataForTimeT(app,ch,t)
%                  ImSaturate = @(Image,percent) imadjust(Image ,stretchlim(Image,[percent/100 (100-percent)/100]));
                     
                    ImageFilesDirectory = app.RGBZT_Directory;
                    [files, ~] = files_and_folders(app, ImageFilesDirectory);
                    
                    [nR,nC,nZ] = size(app.X);
                    
                    data = zeros(nR,nC,3,nZ,'uint8'); % Channels, row, col, z-slice, time

                    for z = 1:nZ
                        data(:,:,:,z) = imread( fullfile( ImageFilesDirectory, files{t,1} ), 'Index', z);
                    end
                    
                    data = single(data);
                    DATA = squeeze(data(:,:,1,:));
                    DATA = DATA./max(DATA(:));
                    
             end             
             

%------------------------------------------------------------------------------------------------------------------------
            function EnableAndDisablePlaneControls(app,action)
                
                % action = 'enable' or 'disable'
                
                switch lower(action)
                    case 'enable';  status = true;  status2 = 'on';
                    case 'disable'; status = false; status2 = 'off';
                end
                
                app.XSlider.Enable = status;
                app.YSlider.Enable = status;
                app.ZSlider.Enable = status;
                app.XSliderSpinner.Enable = status;
                app.YSliderSpinner.Enable = status;
                app.ZSliderSpinner.Enable = status;
                
                app.PANslider.Enable = status;
                app.TILTslider.Enable = status;
                app.PANeditField.Enable = status;
                app.TILTeditField.Enable = status;
                app.Er3ViewButton.Enable = status;
                app.Er2ViewButton.Enable = status;
                app.Er1ViewButton.Enable = status;
                
                app.FrangiFilterSwitch.Enable = status;
                app.FrangiLamp.Enable = status;
                
                app.GaussianFiltSwitch.Enable = status;
                app.GaussFiltLamp.Enable = status;
                app.SigmaSpinner.Enable = status;
                    
                app.FrangiFilterSwitch.Enable = status;
                app.FrangiLamp.Enable = status;
                
                app.AdaptHistEqSwitch.Enable = status;
                app.AdaptHistEqLamp.Enable = status;
                
                app.TiltZeroButton.Enable = status;
                app.PanZeroButton.Enable = status;
                app.Tilt45Button.Enable = status;
                app.Pan45Button.Enable = status;
                app.Pan90Button.Enable = status;
                app.Tilt90Button.Enable = status;
                app.SigmaSpinner.Enable = status;
                
                app.IntersectionLineVisibilityButton.Enable = status;
                app.IntersectionlinecolorButton.Enable = status;
                app.IntersectionColorDisplay.Enable = status2;
                app.DataPlaneSizeInput.Enable = status;
                
                app.XSliderZeroButton.Enable = status;
                app.YSliderZeroButton.Enable = status;
                app.ZSliderZeroButton.Enable = status;
               
                app.MaxDistSpinner.Enable = status;
                app.StepSizeSpinner.Enable = status;
                
                app.DataPlaneChannel.Enable = status;
                app.EllipsoidRotationKnob.Enable = status;
                app.EllipsoidRotationDisplay.Enable = status;
                app.EllipsoidRotationZero.Enable = status;
                app.EllipsoidTranslationZero.Enable = status;
                app.EllipsoidTranslationDisplay.Enable = status;
                app.EllipsoidTranslationSlider.Enable = status;
                app.DataPlaneColormapDropDown.Enable = status;
                
                app.ELamp1.Enable = status;
                app.ELamp2.Enable = status;
                app.ELamp3.Enable = status;
                app.EllipsoidFitAlertsDisplay.Enable = status;
                %-----------------------------------------
                app.XSlider.Value = 0;
                app.XSliderSpinner.Value = 0;
                app.YSlider.Value = 0;
                app.YSliderSpinner.Value = 0;
                app.ZSlider.Value = 0;
                app.ZSliderSpinner.Value = 0;
                 
                app.TILTslider.Value = 0;
                app.TILTeditField.Value = 0;
                app.PANslider.Value = 0;
                app.PANeditField.Value = 0;  
                app.GaussianFiltSwitch.ItemsData = [0,1];
                app.GaussianFiltSwitch.Value = 0;

                app.GaussFiltLamp.Color = [0.8,0.8,0.8];
                app.FrangiFilterSwitch.ItemsData = [0,1];
                app.FrangiFilterSwitch.Value = 0;
                app.FrangiLamp.Color = [0.8,0.8,0.8];
                app.AdaptHistEqSwitch.ItemsData = [0,1];
                app.AdaptHistEqSwitch.Value = 0;
                app.AdaptHistEqLamp.Color = [0.8,0.8,0.8]; 
             
                app.DefaultViewR1.Enable = status;
                app.DefaultViewR2.Enable = status;
                app.DefaultViewR3.Enable = status;
            end
            

%------------------------------------------------------------------------------------------------------------------------
            function EnableAndDisableRenderControls(app,action)
                
                % action = 'enable' or 'disable'
                
                switch lower(action)
                    case 'enable';  status = true; status2 = 'on';
                    case 'disable'; status = false; status2 = 'off';
                end  
                
                app.UpdateRenderingButton.Enable = status;
                app.PadSpinner.Enable = status;
                %app.ContractionBiasSpinner.Enable = status2;
                %app.SmoothFactorSpinner.Enable = status;
                app.ExtendedMinSpinner.Enable  = status;
                app.RenderingSmoothingOnCheckBox.Enable = status;

                app.ConnectionLineSTARTButton.Enable  = status;
                app.ConnectionLineSTARTButton.Value   = 0;
                app.clearButton.Enable  = status;
                
                app.CellVisibilityButton.Enable = status2;
                app.ShowTrackPointButton.Enable = status2;
                
                app.TrackPointOffset_X.Enable = status2;
                app.TrackPointOffset_Y.Enable = status2;
                app.TrackPointOffset_Z.Enable = status2;
                app.TrackPointResetButton.Enable   = status2;
                %app.SaveLocusFileButton2.Enable = status2;
                
                app.setdefaultsButton.Enable = status2;
                app.defaultvaluesButton.Enable = status2;
                app.SeparationLineSTARTButton.Enable = status2;
                app.SeparationLineSTARTButton.Value  = 0;
                app.CLEARSepButton.Enable = status2;
                app.FaceColorButton.Enable = status2;
                 
                app.FaceTransparencySpinner.Enable = status2;
                app.MeshColorButton.Enable = status2;
                 
                app.MeshTransparencySpinner.Enable = status2;
                app.FaceColorDisplay.Enable = status2;
                app.MeshColorDisplay.Enable = status2;
                app.RenderingSmoothingOnCheckBox.Enable = status2;
                app.RenderingSummaryButton.Enable = status2;
                % app.RenderingSummaryButton2.Enable = status2;
                app.ShowSmoothedRenderingCheck.Enable = status2;
                app.TrackEditorButton.Enable = status2; 
                app.setdefaultsButton.Enable = status2;
                app.defaultvaluesButton.Enable = status2;

                app.RenderingSmoothingOnCheckBox.Enable = status2;
                app.SegSmoothSigmaSpinner.Enable = status2;
                app.UseSmoothedForMeasurementsCheckBox.Enable = status2;

                app.AutoRenderAllCellsButton.Enable = status;
        end
        

%----------------------------------------------------------------------------------------------------------------------
        function [pX,pY,pZ] = CreatePlaneOutline(app,xp,yp,zp)

            pX = [xp(1,1); xp(end,1); xp(end,end); xp(1,end); xp(1,1)];
            pY = [yp(1,1); yp(end,1); yp(end,end); yp(1,end); yp(1,1)];
            pZ = [zp(1,1); zp(end,1); zp(end,end); zp(1,end); zp(1,1)]; % Plane Outline Coords
            
        end

        
%------------------------------------------------------------------------------------------------------------------------
        function PanTiltTranslatePlane(app)
            
                % Tilt and Rotate data plane based on TILT and PAN knobs
                
                [ni,nj] = size(app.DataPlaneStartArray.xp); 
            
            % 3D Plane Tilt ---------------------------------------------------------
                alpha = app.TILTslider.Value;
                TiltVec = [sind(alpha),0,cosd(alpha)];
                
                % 3D Rotation Matrix
                [R1,~] = RotationMatrix3D(app, [0,0,1], TiltVec); 

                M1 = R1*[ app.DataPlaneStartArray.xp(:)' ; ...
                          app.DataPlaneStartArray.yp(:)' ; ...
                          app.DataPlaneStartArray.zp(:)' ];

                xp = reshape( M1(1,:), ni, nj);
                yp = reshape( M1(2,:), ni, nj);
                zp = reshape( M1(3,:), ni, nj);
                    
            % 2D Plane Rotation -----------------------------------------------------
                theta = app.PANslider.Value;
                
                R2 = [ [ cosd(theta) , -sind(theta) , 0] ; ... % 2D Rotation Matrix
                       [ sind(theta) ,  cosd(theta) , 0] ; ... 
                       [           0 ,            0 , 1] ];

                M2 = R2*[ xp(:)' ; ...
                          yp(:)' ; ...
                          zp(:)' ]; 
    
                xp = reshape(M2(1,:),ni,nj);
                yp = reshape(M2(2,:),ni,nj);
                zp = reshape(M2(3,:),ni,nj);  
                
            % 3D Plane Translation --------------------------------------------------  
                xp = xp + app.XSlider.Value + app.CurrentTrackPoint.x;
                yp = yp + app.YSlider.Value + app.CurrentTrackPoint.y;
                zp = zp + app.ZSlider.Value + app.CurrentTrackPoint.z;
                
            %------------------------------------------------------------------------   
                app.DataPlaneCurrentArray.xp = xp;
                app.DataPlaneCurrentArray.yp = yp;
                app.DataPlaneCurrentArray.zp = zp;
                
        end
        

%------------------------------------------------------------------------------------------------------------------------
        function PanTiltTranslatePlane2(app,EllipsoidAxes,EllipsCenter,d)
            
            % Tilt data plane in perpendicular to specified ellipsoid axis
                [ni,nj] = size(app.DataPlaneStartArray.xp); 
                TiltVec = EllipsoidAxes(:,1)';
                
                % 2D Plane Rotation -----------------------------------------------------
                %if isequal(app.ViewButtonPressed,2)
                    %theta = atan2d( EllipsoidAxes(2,1), EllipsoidAxes(1,1));
                %else
                    %theta = atan2d(-EllipsoidAxes(2,1), EllipsoidAxes(1,1));
                    theta = 0;
                %end
                
                R3 = [ [ cosd(theta) , -sind(theta) , 0]; ... % 2D Rotation Matrix
                       [ sind(theta) ,  cosd(theta) , 0]; ... 
                       [           0 ,            0 , 1] ];
                
            % 3D Plane Tilt ---------------------------------------------------------
                % 3D Rotation Matrix (Rodqigues formula)
                [R1,~] = RotationMatrix3D(app, [0,0,1], TiltVec); 
                % [R1] = RotationMatrixAroundVector(app,TiltVec,RotationAngle);

                M1 = R1*(R3*[app.DataPlaneStartArray.xp(:)';...
                             app.DataPlaneStartArray.yp(:)';...
                             app.DataPlaneStartArray.zp(:)' ]);

                
                    xp = reshape( M1(1,:), ni, nj);
                    yp = reshape( M1(2,:), ni, nj);
                    zp = reshape( M1(3,:), ni, nj); 
 
                    % 3D Plane Translation --------------------------------------------------  
                    xp = xp + EllipsCenter(1,1); % app.XSlider.Value + app.CurrentTrackPoint.x;
                    yp = yp + EllipsCenter(2,1); % app.YSlider.Value + app.CurrentTrackPoint.y;
                    zp = zp + EllipsCenter(3,1); % app.ZSlider.Value + app.CurrentTrackPoint.z;
                    
                    % Add translation from "Translation Along R1 vector" --------------------  
                    % app.EllipsoidTranslationSlider.Value = 0;
                    xp = xp + TiltVec(1)*d;
                    yp = yp + TiltVec(2)*d;
                    zp = zp + TiltVec(3)*d;

            %------------------------------------------------------------------------
                if app.AutoUpdateDataPlanePositionCheckBox.Value 
                    app.DataPlaneCurrentArray.xp = xp;
                    app.DataPlaneCurrentArray.yp = yp;
                    app.DataPlaneCurrentArray.zp = zp;
                end

        end  

    
%------------------------------------------------------------------------------------------------------------------------
        
        function PanTiltTranslatePlane3(app,EAxis,EllipsCenter,RotationAngle,d)
            
            % Tilt data plane in perpendicular to specified ellipsoid axis
                [ni,nj] = size(app.DataPlaneStartArray.xp); 
                if app.ViewButtonPressed == 2
                    TiltVec = EAxis(:,2)';
                else
                    TiltVec = EAxis(:,3)';
                end
                % 2D Plane Rotation -----------------------------------------------------
                %if isequal(app.ViewButtonPressed,2)
                    % u = EAxis(:,1);
                    % v = [EAxis(1:2,1);0];
                    % CosTheta = max(min(dot(u,v)/(norm(u)*norm(v)),1),-1);
                    % theta1 = real(acosd(CosTheta));
                    % theta2 = atan2d( EAxis(1,1), EAxis(2,1));
                    % theta = -theta1 + theta2;
                %else
                    %theta = atan2d(-EAxis(2,1), EAxis(1,1));
                    theta = 0;
                %end
                
                R3 = [ [ cosd(theta) , -sind(theta) , 0] ; ... % 2D Rotation Matrix
                       [ sind(theta) ,  cosd(theta) , 0] ; ... 
                       [           0 ,            0 , 1] ];

                 
            % 3D Plane Tilt ---------------------------------------------------------
                
                % 3D Rotation Matrix (Rodqigues formula)
                if isequal(app.ViewButtonPressed,2)
                    EA = EAxis(:,2);
                else
                    EA = EAxis(:,3);
                end
                
                [R1,~] = RotationMatrix3D(app, [0,0,1], EA ); 
                [R2]   = RotationMatrixAroundVector(app,app.EllipsoidAxes(:,1),RotationAngle);

                M1 = R2*(R1*(R3*[ app.DataPlaneStartArray.xp(:)' ;...
                                  app.DataPlaneStartArray.yp(:)' ;...
                                  app.DataPlaneStartArray.zp(:)' ] ));

                xp = reshape( M1(1,:), ni, nj);
                yp = reshape( M1(2,:), ni, nj);
                zp = reshape( M1(3,:), ni, nj);
                
            % 3D Plane Translation --------------------------------------------------  
                xp = xp + EllipsCenter(1,1); %app.XSlider.Value + app.CurrentTrackPoint.x;
                yp = yp + EllipsCenter(2,1); %app.YSlider.Value + app.CurrentTrackPoint.y;
                zp = zp + EllipsCenter(3,1); %app.ZSlider.Value + app.CurrentTrackPoint.z;
                
            %------------------------------------------------------------------------   
                xp = xp + TiltVec(1)*d;
                yp = yp + TiltVec(2)*d;
                zp = zp + TiltVec(3)*d;

                if app.AutoUpdateDataPlanePositionCheckBox.Value 
                    app.DataPlaneCurrentArray.xp = xp;
                    app.DataPlaneCurrentArray.yp = yp;
                    app.DataPlaneCurrentArray.zp = zp;
                end
                
        end 
        
        
%-----------------------------------------------------------------------------------------------------------------------
%         function UpdateSingleCellRendering(app,t,track,AxisHandle)
%                 
%                 SigmaZ = @(xSigma,dx,dz) xSigma*(dx/dz);
%                 
%                 % Update 'rendering' text in figure ------------------------------------------------------------------
%                 AH3 = axes(app.SingleCellFigureHandle,'Position',[0.03,0.96,0.01,0.01],'Visible','off','XLim',[0,1],'YLim',[0,1]);
%                 TX = text(AH3,0,0,'Rendering...','FontWeight','bold','FontSize',14,'Color',[1,0,0]);   
%                 drawnow
%                 %-----------------------------------------------------------------------------------------
%                 
%                 Xsr = app.X( app.IND{track,1}(1,1,t):app.IND{track,1}(2,1,t), app.IND{track,1}(1,2,t):app.IND{track,1}(2,2,t), app.IND{track,1}(1,3,t):app.IND{track,1}(2,3,t) );
%                 Ysr = app.Y( app.IND{track,1}(1,1,t):app.IND{track,1}(2,1,t), app.IND{track,1}(1,2,t):app.IND{track,1}(2,2,t), app.IND{track,1}(1,3,t):app.IND{track,1}(2,3,t) );
%                 Zsr = app.Z( app.IND{track,1}(1,1,t):app.IND{track,1}(2,1,t), app.IND{track,1}(1,2,t):app.IND{track,1}(2,2,t), app.IND{track,1}(1,3,t):app.IND{track,1}(2,3,t) );
%                                   
%                 if ~isempty(app.SingleCellRenderingHandle)
%                     if isgraphics(app.SingleCellRenderingHandle)
%                         delete(app.SingleCellRenderingHandle)
%                         app.SingleCellRenderingHandle = -1;
%                     end
%                 end
%                 %----------------------------------------------------------------------
%                 Membrane = single(squeeze( app.DATA5d{track,1}(1,:,:,:,t) )); 
%                 Nuclear  = single(squeeze( app.DATA5d{track,1}(2,:,:,:,t) )); % Nuclear channel 3D data for timepoint t
%                 
% %                 if isequal(app.GaussianFiltSwitch.Value,1)
% %                     sig = app.SigmaSpinner.Value;
% %                     mem1 = imgaussfilt3( mem1, [sig,sig,SigmaZ(sig, app.dp, app.dz)] );
% %                     Membrane = imgaussfilt3( Membrane, [sig,sig,SigmaZ(sig, app.dp, app.dz)] );
% %                 end
%                 
%                 % Create cell surface rendering ---------------------------------------
%                 %Membrane = imerode(Membrane,strel('sphere',2));
%                 [WSz,WSr,WSc] = LocusMembrane2DImageProcessing(app,Membrane);
%                 app.SingleCellRenderingHandle = UpdateCellRendering(app, Membrane, WSz, WSr, WSc, Xsr, Ysr, Zsr,...
%                                                                     app.CurrentTrackPoint, app.dp, app.dz,...
%                                                                     app.SigmaSpinner.Value, AxisHandle);
%                 
%                 % Check to see if lighting needs to be added to axes ------------------
%                 ObjHandles = findall(app.SingleCellFigureHandle,'Type','light');
%                 if isempty(ObjHandles)
%                     camlight(app.SingleCellAxisHandle,0,-89.5)
%                     camlight(app.SingleCellAxisHandle,0, 89.5)
%                 end                                       
%                 %---------------------------------------------------------------------                                       
%                                                        
%                 % Remove 'RENDERING' text from figure ----
%                 delete(AH3)
%                 %-----------------------------------------
%         end
        
%------------------------------------------------------------------------------------------------------------------------        
        
%------------------------------------------------------------------------------------------------------------------------
        function UpdateDataPlane(app)
              
                set(app.EllipsoidTranslationSlider,'BusyAction','Cancel')
                set(app.EllipsoidRotationKnob,'BusyAction','Cancel')
                set(app.XSlider,'BusyAction','Cancel')
                set(app.YSlider,'BusyAction','Cancel')
                set(app.ZSlider,'BusyAction','Cancel')
                set(app.PANslider,'BusyAction','Cancel')
                set(app.TILTslider,'BusyAction','Cancel')

            
                SigmaZ = @(xSigma,dx,dz) xSigma*(dx/dz);

                if isgraphics(app.CellPlaneIntersectionLineHandle)
                    delete(app.CellPlaneIntersectionLineHandle)
                end
                
                % Remove connection lines plotted in the figure -----------
                    h = findobj(app.SingleCellAxisHandle,'Type','line');
                    if ~isempty(h)
                        delete(h);
                    end
                %-----------------------------------------------------------
                
                t  = app.CurrentTimePoint;
                track = app.CurrentTrack;
                ch = app.DataPlaneChannel.Value;
%                 source = app.DataSourceDropDown.Value;
%                 [V, IND] = ReadSubRegionForTimeT(app,t,track,source); 
                V = app.CurrentDataVolumeForTimeT;
                V = squeeze(V(:,:,ch,:));

                
                if isequal(app.GaussianFiltSwitch.Value,1)
                    sig = app.SigmaSpinner.Value;
                    V = imgaussfilt3(V, [sig,sig,SigmaZ(sig, app.dp, app.dz)]);
                end
              
                                  
                % Grab X,Y, and Z coordinates for subregion at timepoint t. -------------------------
                    Xsr = app.X(  app.IND{track}(1,1,t):app.IND{track}(2,1,t), app.IND{track}(1,2,t):app.IND{track}(2,2,t), app.IND{track}(1,3,t):app.IND{track}(2,3,t)  );
                    Ysr = app.Y(  app.IND{track}(1,1,t):app.IND{track}(2,1,t), app.IND{track}(1,2,t):app.IND{track}(2,2,t), app.IND{track}(1,3,t):app.IND{track}(2,3,t)  );
                    Zsr = app.Z(  app.IND{track}(1,1,t):app.IND{track}(2,1,t), app.IND{track}(1,2,t):app.IND{track}(2,2,t), app.IND{track}(1,3,t):app.IND{track}(2,3,t)  );
                % -----------------------------------------------------------------------------------
                
                % Erase graphics from previous timepoint --------------------------------------
                if ~isempty(app.DataPlaneHandle.main) 
                    if isgraphics(app.DataPlaneHandle.main)     
                        delete(app.DataPlaneHandle.main)  
                    end
                end  
                
                if ~isempty(app.DataPlaneHandle.outline)
                    if isgraphics(app.DataPlaneHandle.outline)
                        delete(app.DataPlaneHandle.outline)
                    end
                end
                
                %-------------------------------------------------------------------------------
                    

                hold(app.SingleCellAxisHandle,'on');
                % CREATE DATA PLANE INTERPOLATED SLICE ||||||||||||||||||||||||||||||||||||||||||||||||||||||||||||||||||||||||||||||||||||||||||||||||||||||||||| 
                        app.DataPlaneHandle.main = slice(app.SingleCellAxisHandle, Xsr, Ysr, Zsr, V, ...
                                                                                   app.DataPlaneCurrentArray.xp,...
                                                                                   app.DataPlaneCurrentArray.yp,...
                                                                                   app.DataPlaneCurrentArray.zp,'linear');
                % ||||||||||||||||||||||||||||||||||||||||||||||||||||||||||||||||||||||||||||||||||||||||||||||||||||||||||||||||||||||||||||||||||||||||||||||||


                    % Define Button Down Function for Data Plane ----------------
                    function SliceButtonDownFcn(~,eventData,app)
                                tt = app.CurrentTimePoint;
                                TTr = app.CurrentTrack;
                                % LINE CONNECTION SECTION -------------------------------------------------------------------------
                                if isequal(app.ConnectionLineSTARTButton.Value,1)
                                        if isnan(app.TempConnectionLine(1,1)) % If space (1,1) is a NaN then we're still picking the first point
                                            %rotate3d(app.SingleCellAxisHandle,'off')
                                            pt1 = eventData.IntersectionPoint; % Grab the user-selected point
                                            hold(app.SingleCellAxisHandle,'on')
                                            plot3(app.SingleCellAxisHandle, pt1(1), pt1(2), pt1(3), 'o','Color','r','MarkerSize',3,'MarkerFaceColor','r')
                                            hold(app.SingleCellAxisHandle,'off')
                                            app.TempConnectionLine(1,:) = [pt1(1),pt1(2),pt1(3)]; % Hold this point temporarily while the user picks the second point
                                        else  % isnan(app.TempConnectionLine(2,1)) 
                                            pt1 = app.TempConnectionLine(1,:); 
                                            pt2 = eventData.IntersectionPoint;  % Grab second point
                                            hold(app.SingleCellAxisHandle,'on')
                                            plot3(app.SingleCellAxisHandle, [pt1(1);pt2(1)], [pt1(2);pt2(2)], [pt1(3);pt2(3)], '-o','Color','r','MarkerSize',3,'MarkerFaceColor','r')
                                            hold(app.SingleCellAxisHandle,'off')
                                            app.TempConnectionLine(2,:) = pt2; 
                                            app.ConnectionLineSTARTButton.Value = 0; % Turn off Line connection start button
                                            app.ConnectionLineSTARTButton.BackgroundColor = [0.94,0.94,0.94];
                                            %rotate3d(app.SingleCellAxisHandle,'on')
                                            app.TimePointData.RenderingSettings{TTr,1}.ConnectionLines{tt,1} = ...  % Record connection line in TimePointData
                                                            cat(3, app.TimePointData.RenderingSettings{TTr,1}.ConnectionLines{tt,1}, app.TempConnectionLine);
                                            app.TempConnectionLine = NaN(2,3); %  Reset TempConnectionLine variable
                                            if isgraphics(app.SingleCellRenderingHandle)  
                                                app.CellVisibilityButton.Text = 'Cell Visibility - ON';
                                                app.SingleCellRenderingHandle.Visible = 'on';
                                            end
                                        end
                                        
                                % LINE SEPARATION SECTION -------------------------------------------------------------------------        
                                elseif isequal(app.SeparationLineSTARTButton.Value,1)
                                        if isequal(eventData.Button,1) % Left Button was clicked (select multiple points with left button)
                                            %rotate3d(app.SingleCellAxisHandle,'off')
                                            pt1 = eventData.IntersectionPoint; % Grab the user-selected point
                                            app.TempSeparationLine = [app.TempSeparationLine; pt1]; % Record point in temp array
                                            hold(app.SingleCellAxisHandle,'on')
                                            plot3(app.SingleCellAxisHandle, app.TempSeparationLine(:,1), app.TempSeparationLine(:,2), app.TempSeparationLine(:,3),...
                                                                            'o','MarkerEdgeColor',[0,0,0],'MarkerSize',3,'MarkerFaceColor',app.CellPlaneIntersectionColor)
                                            hold(app.SingleCellAxisHandle,'off')
                                        elseif isequal(eventData.Button,3) % Right Button was clicked (pick last point with rigth button)
                                            pt1 = eventData.IntersectionPoint;  % Grab Nth point
                                            app.TempSeparationLine = [app.TempSeparationLine; pt1]; % Record point in temp array
                                            hold(app.SingleCellAxisHandle,'on')
                                            plot3(app.SingleCellAxisHandle, app.TempSeparationLine(:,1), app.TempSeparationLine(:,2), app.TempSeparationLine(:,3),...
                                                                            'o-','Color',app.CellPlaneIntersectionColor,'MarkerEdgeColor',[0,0,0],'MarkerSize',3,...
                                                                            'MarkerFaceColor',app.CellPlaneIntersectionColor)
                                            hold(app.SingleCellAxisHandle,'off')
                                            app.SeparationLineSTARTButton.Value = 0; % Turn off Line connection start button
                                            app.SeparationLineSTARTButton.BackgroundColor = [0.94,0.94,0.94];
                                            
                                             % Fit equation of a plane to current data plane [x,y,z] points -------------
                                             % and record vector perpendicular to plane ---------------------------------
                                                XP = app.DataPlaneCurrentArray.xp;
                                                YP = app.DataPlaneCurrentArray.yp;
                                                ZP = app.DataPlaneCurrentArray.zp;
                                                
                                                V1 = [ XP(end,1)-XP(1,1), YP(end,1)-YP(1,1), ZP(end,1)-ZP(1,1)];
                                                V2 = [ XP(1,end)-XP(1,1), YP(1,end)-YP(1,1), ZP(1,end)-ZP(1,1)];

                                                PlaneVector = cross(V1,V2); % Calculate vector normal to plane by taking cross product of edge vector
                                                PlaneVector = PlaneVector./vecnorm(PlaneVector); % Normalized plane vector
                                            %--------------------------------------------------------------------------
                                            
                                            app.TimePointData.RenderingSettings{TTr,1}.SeparationLines{tt,1} = ...       % Record connection line in TimePointData
                                                                                                    [ app.TimePointData.RenderingSettings{TTr,1}.SeparationLines{tt,1},...
                                                                                                    { {app.TempSeparationLine};{PlaneVector} }  ];

                                            app.TempSeparationLine = zeros(0,3); %  Reset TempConnectionLine variable
                                            if isgraphics(app.SingleCellRenderingHandle)  
                                                app.CellVisibilityButton.Text = 'Cell Visibility - ON';
                                                app.SingleCellRenderingHandle.Visible = 'on';
                                            end
                                        end
                                end
                            
                          end
                    %||||||||||||||||||||||||||||||||||||||||||||||||||||||||||||||||||||||||||||||||||||||||||||||||||||||||||||||||||||||||||||||||||||  
                             
                    x = app.DataPlaneCurrentArray.xp;
                    y = app.DataPlaneCurrentArray.yp;
                    z = app.DataPlaneCurrentArray.zp;

                    set(app.DataPlaneHandle.main,'ButtonDownFcn',{@SliceButtonDownFcn,app})
                    app.DataPlaneHandle.main.CData( isnan(app.DataPlaneHandle.main.CData) ) = 0; % Change NaNs to zero    

                    % [Rdp,Cdp] = size(app.DataPlaneHandle.main.CData);
                    % [Xdp,Ydp] = meshgrid(1:Cdp,1:Rdp);
                    % Xc = mean(Xdp(:));
                    % Yc = mean(Ydp(:));
                    % app.DataPlaneHandle.main.CData( sqrt((Xdp-Xc).^2 + (Ydp-Yc).^2) > Rdp/2) = NaN;
                    % app.DataPlaneHandle.main.CData( sqrt((Xdp-Xc).^2 + (Ydp-Yc).^2) > (Rdp/2-1) & sqrt((Xdp-Xc).^2 + (Ydp-Yc).^2) <= (Rdp/2)) = 0;

                    % Apply Objectness filter (Frangi Filter) ---------------------------------                             
                        if app.FrangiFilterSwitch.Value  
                            J = fibermetric(app.DataPlaneHandle.main.CData,'thickness',[1,2],'StructureSensitivity',0.01);    % StructureSensitivityDefault = 0.1
                            app.DataPlaneHandle.main.CData = J/max(J(:)); 
                        end
                    % Apply Adaptive Histogram Equalization function --------------------------  
                        if isequal(app.AdaptHistEqSwitch.Value,1)
                            app.DataPlaneHandle.main.CData = adapthisteq(app.DataPlaneHandle.main.CData,'NumTiles',[8,8],'ClipLimit',0.01);    
                        end
                    %--------------------------------------------------------------------------
                        
                    clim(app.SingleCellAxisHandle, [0,1])

                    app.DataPlaneHandle.main.EdgeColor = 'none';
                    app.DataPlaneHandle.main.AmbientStrength  = 1;
                    app.DataPlaneHandle.main.DiffuseStrength  = 0;
                    app.DataPlaneHandle.main.SpecularStrength = 0;   
                              
                    axis(app.SingleCellAxisHandle, [ 5*ceil(min(Xsr(:))/5), 5*floor(max(Xsr(:))/5),...
                                                     5*ceil(min(Ysr(:))/5), 5*floor(max(Ysr(:))/5),...
                                                     5*ceil(min(Zsr(:))/5), 5*floor(max(Zsr(:))/5) ])
                    
                % Create Outline of volume crossection plane ------------------------------------------------  
                    [pX,pY,pZ] = CreatePlaneOutline(app, app.DataPlaneCurrentArray.xp,...
                                                         app.DataPlaneCurrentArray.yp,...
                                                         app.DataPlaneCurrentArray.zp);  


                    app.DataPlaneHandle.outline = plot3(app.SingleCellAxisHandle,pX,pY,pZ,'-k','LineWidth',2);
                    hold(app.SingleCellAxisHandle,'off') 

                    %Wolfram = customColormap({[54,92,154]./255,[236,170,94]./255,[255,249,197]./255},'nPoints',256);
                    ColorMapNames = {'Parula','Thermal','Haline','Wolfram','Turbo'};

                    switch app.DataPlaneColormapDropDown.Value
                        case 1; ColorMap = parula(256);
                        case 2; ColorMap = thermal(app);
                        case 3; ColorMap = haline(app);
                        case 4; ColorMap = wolfram(app); % customColormap({[54,92,154]./255,[236,170,94]./255,[255,249,197]./255},'nPoints',256); % wolfram_cm;
                        case 5; ColorMap = turbo(256);
                        
                    end                    
                    
                    colormap(app.SingleCellAxisHandle, ColorMap  )  % thermal_cm, haline_cm
              
                % Create Intersection of plane and rendered cell --------------------------------------------------------
                    if app.RenderingSmoothingOnCheckBox.Value
                        CellSurface = app.TimePointData.SmoothedSurfaceRenderings{track}{t};                    
                    else
                        CellSurface = app.TimePointData.SurfaceRenderings{track}{t};
                    end

                    if isempty(CellSurface)
                        CellSurface = app.TimePointData.SurfaceRenderings{track}{t};
                        disp('Smoothed rendering does not exist: Update rendering')
                    end
                    
                    XYZ = CalculatePlaneAndCellIntersectionLine(app,x,y,z,CellSurface);

                    if ~isempty(XYZ) & ~app.IntersectionLineVisibilityButton.Value
                        hold(app.SingleCellAxisHandle,'on')

                        app.CellPlaneIntersectionLineHandle = plot3(app.SingleCellAxisHandle,XYZ(:,:,1),XYZ(:,:,2),XYZ(:,:,3),...
                                                                    '-','LineWidth',3,'Color', app.CellPlaneIntersectionColor);
                        hold(app.SingleCellAxisHandle,'off')
                    end
                   
                % -------------------------------------------------------------------------------------------------------    

                    axis(app.SingleCellAxisHandle,'equal')
                    axis(app.SingleCellAxisHandle,...
                          [ min( app.X( 1,  app.IND{track,1}(1,2,t):app.IND{track,1}(2,2,t), 1) ),...
                            max( app.X( 1,  app.IND{track,1}(1,2,t):app.IND{track,1}(2,2,t), 1) ),...
                            min( app.Y( app.IND{track,1}(1,1,t):app.IND{track,1}(2,1,t), 1, 1 ) ),...
                            max( app.Y( app.IND{track,1}(1,1,t):app.IND{track,1}(2,1,t), 1, 1 ) ),...
                            min( app.Z( 1, 1, app.IND{track,1}(1,3,t):app.IND{track,1}(2,3,t) ) ),...
                            max( app.Z( 1, 1, app.IND{track,1}(1,3,t):app.IND{track,1}(2,3,t) ) ) ]);

                    drawnow limitrate
        end
        
                
%------------------------------------------------------------------------------------------------------------------------
        function  XYZnew = CalculatePlaneAndCellIntersectionLine(app,x,y,z,CellSurface)  

                    
                    p1 = [x(1,1), y(1,1), z(1,1)]; % Corner 1 of Data Plane
                    p2 = [x(1,end), y(1,end), z(1,end)]; % Corner 2 of Data Plane
                    p3 = [x(end,1), y(end,1), z(end,1)]; % Corner 3 of Data Plane
                    N = cross(p2-p1, p3-p1); % Create vectors p1-p1 and p3-p1. They're cross-product is the vector perpendicular to the data plane
                    n = N./norm(N); % Normalize perpendicular vector
                    
                    try
                        XYZnew = PlaneAndSurfaceIntersectionLine(app,CellSurface.faces,CellSurface.vertices,p1,n);                        
                        % lin = intersectPlaneSurf(app,CellSurface,p1,n);  % Be sure to include mex file: IntersectPlaneTriangle.mex
                        % XYZnew = lin{1}';
                    catch
                        disp('Intersection line failed')
                        XYZnew = [];
                    end
                    
        end
%------------------------------------------------------------------------------------------------------------------------        
        function UpdateFigureFunctions(app,Action)
% 
%                  % Define Rotation Button Down Function for Data Plane ----------------
%                  function DataPlaneMotionButtonDownFcn(source,eventData,app)
%                         % eventData.Source.SelectionType
%                         disp(['Button Pressed: ' num2str(eventData.Source.CurrentPoint)])
%                  end
%                     
%                  
%                  switch Action
%                      case 'Asign'
%                          set(app.SingleCellFigureHandle,'WindowButtonMotionFcn',{@DataPlaneMotionButtonDownFcn,app}) 
%                      case 'Remove'
%                          set(app.SingleCellFigureHandle,'WindowButtonMotionFcn',[]) 
%                  end
      
                    
        end
        
%------------------------------------------------------------------------------------------------------------------------

        function UpdateConnectionLines(app)
            
                t = app.CurrentTimePoint;
                track = app.CurrentTrack;
                
                if ~isempty(app.TimePointData.RenderingSettings{track,1}.ConnectionLines{t,1})
                    nLines = size(app.TimePointData.RenderingSettings{track,1}.ConnectionLines{t,1}, 3);  
                    app.ConnectionLineHandles = gobjects(nLines,1);
                    
                    for k = 1:nLines
                            p1 = app.TimePointData.RenderingSettings{track,1}.ConnectionLines{t,1}(1,:,k);
                            p2 = app.TimePointData.RenderingSettings{track,1}.ConnectionLines{t,1}(2,:,k);
                            hold(app.SingleCellAxisHandle,'on')
                            
                            app.ConnectionLineHandles(k,1) = ...
                                plot3(app.SingleCellAxisHandle, [p1(1,1);p2(1,1)], [p1(1,2);p2(1,2)], [p1(1,3);p2(1,3)], ...
                                '-o','Color','r','MarkerSize',3,'MarkerFaceColor','r');
                            
                            hold(app.SingleCellAxisHandle,'off')
                    end
                end
            
        end
        
%------------------------------------------------------------------------------------------------------------------------
        function UpdateSeparationLines(app)
            
                t = app.CurrentTimePoint;
                track = app.CurrentTrack;
                
                if ~isempty(app.TimePointData.RenderingSettings{track,1}.SeparationLines{t,1})
                    nLines = size(app.TimePointData.RenderingSettings{track,1}.SeparationLines{t,1},2);  
                    app.SeparationLineHandles = gobjects(nLines,1);
                    for k = 1:nLines
                            pts = app.TimePointData.RenderingSettings{track,1}.SeparationLines{t,1}{1,k}{1};
                            hold(app.SingleCellAxisHandle,'on')
                            app.SeparationLineHandles(k,1) = ...
                                plot3(app.SingleCellAxisHandle, pts(:,1), pts(:,2), pts(:,3), ...
                                '-o','Color','k','MarkerSize',3,'MarkerFaceColor',app.CellPlaneIntersectionColor);
                            hold(app.SingleCellAxisHandle,'off')
                    end
                end
            
        end
        
%------------------------------------------------------------------------------------------------------------------------        
        function [ center, radii, evecs, v, chi2 ] = ellipsoid_fit(app, X, equals )
                    %
                % Fit an ellispoid/sphere/paraboloid/hyperboloid to a set of xyz data points:
                %
                %   [center, radii, evecs, pars ] = ellipsoid_fit( X )
                %   [center, radii, evecs, pars ] = ellipsoid_fit( [x y z] );
                %   [center, radii, evecs, pars ] = ellipsoid_fit( X, 1 );
                %   [center, radii, evecs, pars ] = ellipsoid_fit( X, 2, 'xz' );
                %   [center, radii, evecs, pars ] = ellipsoid_fit( X, 3 );
                %
                % Parameters:
                % * X, [x y z]   - Cartesian data, n x 3 matrix or three n x 1 vectors
                % * flag         - '' or empty fits an arbitrary ellipsoid (default),
                %                - 'xy' fits a spheroid with x- and y- radii equal
                %                - 'xz' fits a spheroid with x- and z- radii equal
                %                - 'xyz' fits a sphere
                %                - '0' fits an ellipsoid with its axes aligned along [x y z] axes
                %                - '0xy' the same with x- and y- radii equal
                %                - '0xz' the same with x- and z- radii equal
                %
                % Output:
                % * center    -  ellispoid or other conic center coordinates [xc; yc; zc]
                % * radii     -  ellipsoid or other conic radii [a; b; c]
                % * evecs     -  the radii directions as columns of the 3x3 matrix
                % * v         -  the 10 parameters describing the ellipsoid / conic algebraically: 
                %                Ax^2 + By^2 + Cz^2 + 2Dxy + 2Exz + 2Fyz + 2Gx + 2Hy + 2Iz + J = 0
                % * chi2      -  residual sum of squared errors (chi^2e), this chi2 is in th 
                %                coordinate frame in which the ellipsoid is a unit sphere.
                %
                % Author:
                % Yury Petrov, Oculus VR
                % Date:
                % September, 2015
                %
        
                narginchk( 1, 3 );  % check input arguments
                if nargin == 1
                    equals = ''; % no constraints by default
                end
        
                if size( X, 2 ) ~= 3
                    error( 'Input data must have three columns!' );
                else
                    x = X( :, 1 );
                    y = X( :, 2 );
                    z = X( :, 3 );
                end
        
                % need nine or more data points
                if length( x ) < 9 && strcmp( equals, '' ) 
                   error( 'Must have at least 9 points to fit a unique ellipsoid' );
                end
                if length( x ) < 8 && ( strcmp( equals, 'xy' ) || strcmp( equals, 'xz' ) )
                   error( 'Must have at least 8 points to fit a unique ellipsoid with two equal radii' );
                end
                if length( x ) < 6 && strcmp( equals, '0' )
                   error( 'Must have at least 6 points to fit a unique oriented ellipsoid' );
                end
                if length( x ) < 5 && ( strcmp( equals, '0xy' ) || strcmp( equals, '0xz' ) )
                   error( 'Must have at least 5 points to fit a unique oriented ellipsoid with two equal radii' );
                end
                if length( x ) < 4 && strcmp( equals, 'xyz' );
                   error( 'Must have at least 4 points to fit a unique sphere' );
                end
        
                % fit ellipsoid in the form Ax^2 + By^2 + Cz^2 + 2Dxy + 2Exz + 2Fyz + 2Gx +
                % 2Hy + 2Iz + J = 0 and A + B + C = 3 constraint removing one extra
                % parameter
                if strcmp( equals, '' )
                    D = [ x .* x + y .* y - 2 * z .* z, ...
                        x .* x + z .* z - 2 * y .* y, ...
                        2 * x .* y, ...
                        2 * x .* z, ...
                        2 * y .* z, ...
                        2 * x, ...
                        2 * y, ...
                        2 * z, ...
                        1 + 0 * x ];  % ndatapoints x 9 ellipsoid parameters
                elseif strcmp( equals, 'xy' )
                    D = [ x .* x + y .* y - 2 * z .* z, ...
                        2 * x .* y, ...
                        2 * x .* z, ...
                        2 * y .* z, ...
                        2 * x, ...
                        2 * y, ...
                        2 * z, ...
                        1 + 0 * x ];  % ndatapoints x 8 ellipsoid parameters
                elseif strcmp( equals, 'xz' )
                    D = [ x .* x + z .* z - 2 * y .* y, ...
                        2 * x .* y, ...
                        2 * x .* z, ...
                        2 * y .* z, ...
                        2 * x, ...
                        2 * y, ...
                        2 * z, ...
                        1 + 0 * x ];  % ndatapoints x 8 ellipsoid parameters
                    % fit ellipsoid in the form Ax^2 + By^2 + Cz^2 + 2Gx + 2Hy + 2Iz = 1
                elseif strcmp( equals, '0' )
                    D = [ x .* x + y .* y - 2 * z .* z, ...
                          x .* x + z .* z - 2 * y .* y, ...
                          2 * x, ...
                          2 * y, ... 
                          2 * z, ... 
                          1 + 0 * x ];  % ndatapoints x 6 ellipsoid parameters
                    % fit ellipsoid in the form Ax^2 + By^2 + Cz^2 + 2Gx + 2Hy + 2Iz = 1,
                    % where A = B or B = C or A = C
                elseif strcmp( equals, '0xy' )
                    D = [ x .* x + y .* y - 2 * z .* z, ...
                          2 * x, ...
                          2 * y, ... 
                          2 * z, ... 
                          1 + 0 * x ];  % ndatapoints x 5 ellipsoid parameters
                elseif strcmp( equals, '0xz' )
                    D = [ x .* x + z .* z - 2 * y .* y, ...
                          2 * x, ...
                          2 * y, ... 
                          2 * z, ... 
                          1 + 0 * x ];  % ndatapoints x 5 ellipsoid parameters
                     % fit sphere in the form A(x^2 + y^2 + z^2) + 2Gx + 2Hy + 2Iz = 1
                elseif strcmp( equals, 'xyz' )
                    D = [ 2 * x, ...
                          2 * y, ... 
                          2 * z, ... 
                          1 + 0 * x ];  % ndatapoints x 4 ellipsoid parameters
                else
                    error( [ 'Unknown parameter value ' equals '!' ] );
                end
        
                % solve the normal system of equations
                d2 = x .* x + y .* y + z .* z; % the RHS of the llsq problem (y's)
                
                warning off
                u = ( D' * D ) \ ( D' * d2 );  % solution to the normal equations
                % find the residual sum of errors
                % chi2 = sum( ( 1 - ( D * u ) ./ d2 ).^2 ); 
                % this chi2 is in the coordinate frame in which the ellipsoid is a unit sphere.
        
                % find the ellipsoid parameters
                % convert back to the conventional algebraic form
                if strcmp( equals, '' )
                    v(1) = u(1) +     u(2) - 1;
                    v(2) = u(1) - 2 * u(2) - 1;
                    v(3) = u(2) - 2 * u(1) - 1;
                    v( 4 : 10 ) = u( 3 : 9 );
                elseif strcmp( equals, 'xy' )
                    v(1) = u(1) - 1;
                    v(2) = u(1) - 1;
                    v(3) = -2 * u(1) - 1;
                    v( 4 : 10 ) = u( 2 : 8 );
                elseif strcmp( equals, 'xz' )
                    v(1) = u(1) - 1;
                    v(2) = -2 * u(1) - 1;
                    v(3) = u(1) - 1;
                    v( 4 : 10 ) = u( 2 : 8 );
                elseif strcmp( equals, '0' )
                    v(1) = u(1) +     u(2) - 1;
                    v(2) = u(1) - 2 * u(2) - 1;
                    v(3) = u(2) - 2 * u(1) - 1;
                    v = [ v(1) v(2) v(3) 0 0 0 u( 3 : 6 )' ];
                elseif strcmp( equals, '0xy' )
                    v(1) = u(1) - 1;
                    v(2) = u(1) - 1;
                    v(3) = -2 * u(1) - 1;
                    v = [ v(1) v(2) v(3) 0 0 0 u( 2 : 5 )' ];
                elseif strcmp( equals, '0xz' )
                    v(1) = u(1) - 1;
                    v(2) = -2 * u(1) - 1;
                    v(3) = u(1) - 1;
                    v = [ v(1) v(2) v(3) 0 0 0 u( 2 : 5 )' ];
                elseif strcmp( equals, 'xyz' )
                    v = [ -1 -1 -1 0 0 0 u( 1 : 4 )' ];
                end
                v = v';
        
                % form the algebraic form of the ellipsoid
                A = [ v(1) v(4) v(5) v(7); ...
                      v(4) v(2) v(6) v(8); ...
                      v(5) v(6) v(3) v(9); ...
                      v(7) v(8) v(9) v(10) ];
                % find the center of the ellipsoid
                center = -A( 1:3, 1:3 ) \ v( 7:9 );
                % form the corresponding translation matrix
                T = eye( 4 );
                T( 4, 1:3 ) = center';
                % translate to the center
                R = T * A * T';
                % solve the eigenproblem
                [ evecs, evals ] = eig( R( 1:3, 1:3 ) / -R( 4, 4 ) );
                radii = sqrt( 1 ./ diag( abs( evals ) ) );
                sgns = sign( diag( evals ) );
                radii = radii .* sgns;
        
                % calculate difference of the fitted points from the actual data normalized by the conic radii
                d = [ x - center(1), y - center(2), z - center(3) ]; % shift data to origin
                d = d * evecs; % rotate to cardinal axes of the conic;
                d = [ d(:,1) / radii(1), d(:,2) / radii(2), d(:,3) / radii(3) ]; % normalize to the conic radii
                chi2 = sum( abs( 1 - sum( d.^2 .* repmat( sgns', size( d, 1 ), 1 ), 2 ) ) );
        
                if abs( v(end) ) > 1e-6
                    v = -v / v(end); % normalize to the more conventional form with constant term = -1
                else
                    v = -sign( v(end) ) * v;
                end        


        end


%------------------------------------------------------------------------------------------------------------------------
        function UpdateCurrentTrackPoint(app)
            
                % Create tiny sphere for trackpoint marker
            
           if isgraphics(app.TrackPointHandle)
                delete(app.TrackPointHandle)
                app.TrackPointHandle = -1;    
           end     
                
            switch app.ShowTrackPointButton.Value
                case false
                    app.ShowTrackPointButton.Text = 'Show Track Point';
                   
                    
                case true
                    hold(app.SingleCellAxisHandle,'on')
                    
                    [xs,ys,zs] = sphere(20);
                    hold(app.SingleCellAxisHandle,'on')
                    
                    app.TrackPointHandle = surf(app.SingleCellAxisHandle,...
                                                app.CurrentTrackPoint.x + 0.75.*xs + app.CellTrackOffset{app.CurrentTrack}(app.CurrentTimePoint,1),...
                                                app.CurrentTrackPoint.y + 0.75.*ys + app.CellTrackOffset{app.CurrentTrack}(app.CurrentTimePoint,2),...
                                                app.CurrentTrackPoint.z + 0.75.*zs + app.CellTrackOffset{app.CurrentTrack}(app.CurrentTimePoint,3));
                                            
                    app.TrackPointHandle.EdgeColor = 'none';
                    app.TrackPointHandle.FaceColor = [1,0,1];
                    material(app.TrackPointHandle,'shiny')  
                    
                    hold(app.SingleCellAxisHandle,'off')
                    app.ShowTrackPointButton.Text = 'Hide Track Point';
                    
                    R3 = app.DataPlaneSizeInput.Value/2 + 10;
                    axis(app.SingleCellAxisHandle,'equal')
                    axis(app.SingleCellAxisHandle, [app.CurrentTrackPoint.x - R3, app.CurrentTrackPoint.x + R3,...
                                                    app.CurrentTrackPoint.y - R3, app.CurrentTrackPoint.y + R3,...
                                                    app.CurrentTrackPoint.z - R3, app.CurrentTrackPoint.z + R3] );
                    
            end  
                
        end
%------------------------------------------------------------------------------------------------------------------------
        function [WSz,WSr,WSc] = LocusMembrane2DImageProcessing(app,V)
        
            sigmaZ = @(xSigma,dp,dz) xSigma*(dp/dz);
            
            %--------------------------------------------------------
            % [WS,EM] = LocusMembrane2DImageProcessing(V)
            %  V = Membrane sub-volume 3d data (uint8)
            %--------------------------------------------------------
            
            [nR,nC,nZ] = size(V);
            WSz = false(nR,nC,nZ);
            WSr = false(nR,nC,nZ);
            WSc = false(nR,nC,nZ);
            
            sigma = app.SigmaSpinner.Value;
                       
            %-----------------------------------------------------------------------------
            IM01 = reshape(V,nR,nC*nZ);
            IM01 = imgaussfilt(IM01, [sigma,sigma]);
            IM01 = IM01./max(IM01(:));
            IM01 = adapthisteq(IM01); 
            WSz  = reshape(~logical(watershed(IM01)),nR,nC,nZ);
            %-----------------------------------------------------------------------------
            IM02 = reshape(permute(V,[3,1,2]),nZ,nC*nR);
            IM02 = imgaussfilt(IM02, [sigmaZ(sigma,app.dp,app.dz),sigma]);
            IM02 = IM02./max(IM02(:));
            IM02 = adapthisteq(IM02); 
            BWr  = ~logical(watershed(IM02));
            WSr  = permute(reshape(BWr,nZ,nR,nC),[2,3,1]);
            %-----------------------------------------------------------------------------
            IM03 = reshape(permute(V,[3,2,1]),nZ,nR*nC);
            IM03 = imgaussfilt(IM03, [sigmaZ(sigma,app.dp,app.dz),sigma]);
            IM03 = IM03./max(IM03(:));
            IM03 = adapthisteq(IM03);
            BWc  = ~logical(watershed(IM03));
            WSc  = permute(reshape(BWc,nZ,nC,nR),[3,2,1]);
            %-----------------------------------------------------------------------------
       
            
%             for z = 1:nZ
%                 IM01 = imgaussfilt(V(:,:,z), [sigma,sigma]);
%                 IM01 = IM01./max(IM01(:));
%                 IM01 = adapthisteq(IM01); 
%                 WSz(:,:,z) = ~logical(watershed(IM01));
%             end
%             %-----------------------------------------------------------------------------
%             for r = 1:nR
%                 IM01 = imgaussfilt(squeeze(V(r,:,:)), [sigmaZ(sigma,app.dp,app.dz),sigma]);
%                 IM01 = IM01./max(IM01(:));
%                 IM01 = adapthisteq(IM01); 
%                 WSr(r,:,:) = ~logical(watershed(IM01));
%             end
%             %-----------------------------------------------------------------------------
%             for c = 1:nC
%                 IM01 = imgaussfilt(squeeze(V(:,c,:)), [sigmaZ(sigma,app.dp,app.dz),sigma]);
%                 IM01 = IM01./max(IM01(:));
%                 IM01 = adapthisteq(IM01); 
%                 WSc(:,c,:) = ~logical(watershed(IM01));
%             end
            
        end
        
%------------------------------------------------------------------------------------------------------------------------         
        function app = CalculateEllipsoidFitAxes(app)
             
                Xpts = mean(app.SingleCellRenderingHandle.XData)';
                Ypts = mean(app.SingleCellRenderingHandle.YData)';
                Zpts = mean(app.SingleCellRenderingHandle.ZData)'; 
                nPts = size(Xpts,1);
                
                if nPts < 9
                    app.EllipsoidAxes = NaN(3,3);
                    app.EllipsoidCenter = NaN(3,1);
                        % warndlg('Too few points to fit ellipse and calculate plane orientation and view')
                else
                    [c,~,ev,~,~] = ellipsoid_fit(app, [Xpts,Ypts,Zpts], '');   
                    app.EllipsoidAxes = ev;
                    app.EllipsoidCenter = mean([Xpts,Ypts,Zpts])';
                end
          
           
        end
%------------------------------------------------------------------------------------------------------------------------ 
        function [WSz,WSr,WSc] = LocusNuclear2DImageProcessing(app,V)
        
            sigmaZ = @(xSigma,dp,dz) xSigma*(dp/dz);
            %--------------------------------------------------------
            % [WS,EM] = LocusMembrane2DImageProcessing(V)
            %  V = Membrane sub-volume 3d data (uint8)
            %--------------------------------------------------------
            
            [nR,nC,nZ] = size(V);
            WSz = false(nR,nC,nZ);
            WSr = false(nR,nC,nZ);
            WSc = false(nR,nC,nZ);
            
            sigma = app.SigmaSpinner.Value;
           
           
            %-----------------------------------------------------------------------------
            IM01 = reshape(V,nR,nC*nZ);
            IM01 = imgaussfilt(IM01, [sigma,sigma]);
            IM01 = IM01./max(IM01(:));
            IM01 = adapthisteq(IM01); 
            WSz  = reshape(~logical(watershed(IM01)),nR,nC,nZ);
            %-----------------------------------------------------------------------------
            IM02 = reshape(permute(V,[3,1,2]),nZ,nC*nR);
            IM02 = imgaussfilt(IM02, [sigmaZ(sigma,app.dp,app.dz),sigma]);
            IM02 = IM02./max(IM02(:));
            IM02 = adapthisteq(IM02); 
            BWr  = ~logical(watershed(IM02));
            WSr  = permute(reshape(BWr,nZ,nR,nC),[2,3,1]);
            %-----------------------------------------------------------------------------
            IM03 = reshape(permute(V,[3,2,1]),nZ,nR*nC);
            IM03 = imgaussfilt(IM03, [sigmaZ(sigma,app.dp,app.dz),sigma]);
            IM03 = IM03./max(IM03(:));
            IM03 = adapthisteq(IM03);
            BWc  = ~logical(watershed(IM03));
            WSc  = permute(reshape(BWc,nZ,nC,nR),[3,2,1]);
            %-----------------------------------------------------------------------------
        end
 

 %------------------------------------------------------------------------------------------------------------------------ -----------------------------------------------------------       
 %------------------------------------------------------------------------------------------------------------------------ -----------------------------------------------------------     

        function RecordTrackPlottingProperties(app,TrackNum)
            
                app.TrackPlottingProperties{TrackNum,1}.ShowTrackOnOff                          = app.ShowTrackCheckBox.Value;
                
                % All time pts panel --------------------------------------------------------------------------------------------------------------------------
                app.TrackPlottingProperties{TrackNum,1}.AllCellSurfaceCheckBox                  = app.AllCellSurfaceCheckBox.Value;
                app.TrackPlottingProperties{TrackNum,1}.AllCellSurfaceColor                     = app.AllCellSurfaceColorDisplay.BackgroundColor;
                app.TrackPlottingProperties{TrackNum,1}.AllCellSurfaceTransparencySpinner       = app.AllCellSurfaceTransparencySpinner.Value;
                app.TrackPlottingProperties{TrackNum,1}.AllCellSurfaceUseColormapCheck          = app.AllCellSurfaceUseColormapCheck.Value;
                app.TrackPlottingProperties{TrackNum,1}.AllCellMeshCheckBox                     = app.AllCellMeshCheckBox.Value;
                app.TrackPlottingProperties{TrackNum,1}.AllCellMeshColor                        = app.AllCellMeshColorDisplay.BackgroundColor;
                app.TrackPlottingProperties{TrackNum,1}.AllCellMeshTransparencySpinner          = app.AllCellMeshTransparencySpinner.Value;  
                app.TrackPlottingProperties{TrackNum,1}.AllCellMeshUseColormapCheck             = app.AllCellMeshUseColormapCheck.Value;
                % Movie panel ----------------------------------------------------------------------------------------------------------------------------------
                app.TrackPlottingProperties{TrackNum,1}.MovieCellSurfaceCheckBox                = app.MovieCellSurfaceCheckBox.Value;
                app.TrackPlottingProperties{TrackNum,1}.MovieCellSurfaceColor                   = app.MovieCellSurfaceColorDisplay.BackgroundColor;
                app.TrackPlottingProperties{TrackNum,1}.MovieCellSurfaceTransparencySpinner     = app.MovieCellSurfaceTransparencySpinner.Value;
                app.TrackPlottingProperties{TrackNum,1}.MovieCellSurfaceUseColormapCheck        = app.MovieCellSurfaceUseColormapCheck.Value;
                app.TrackPlottingProperties{TrackNum,1}.MovieCellMeshCheckBox                   = app.MovieCellMeshCheckBox.Value;
                app.TrackPlottingProperties{TrackNum,1}.MovieCellMeshColor                      = app.MovieCellMeshColorDisplay.BackgroundColor;
                app.TrackPlottingProperties{TrackNum,1}.MovieCellMeshTransparencySpinner        = app.MovieCellMeshTransparencySpinner.Value;
                app.TrackPlottingProperties{TrackNum,1}.MovieCellMeshUseColormapCheck           = app.MovieCellMeshUseColormapCheck.Value;
                app.TrackPlottingProperties{TrackNum,1}.MovieCentroidCheckBox                   = app.MovieCentroidCheckBox.Value;
                app.TrackPlottingProperties{TrackNum,1}.MovieCentroidColor                      = app.MovieCentroidColorDisplay.BackgroundColor;
                app.TrackPlottingProperties{TrackNum,1}.MovieCentroidTransparencySpinner        = app.MovieCentroidTransparencySpinner.Value;
                app.TrackPlottingProperties{TrackNum,1}.MovieCentroidUseColormapCheck           = app.MovieCentroidUseColormapCheck.Value;
                % Track panel -----------------------------------------------------------------------------------------------------------------------------------
                app.TrackPlottingProperties{TrackNum,1}.TrackLineCheckBox                       = app.TrackLineCheckBox.Value;
                app.TrackPlottingProperties{TrackNum,1}.TrackLineColor                          = app.TrackLineColorDisplay.BackgroundColor;
                app.TrackPlottingProperties{TrackNum,1}.TrackLineTransparencySpinner            = app.TrackLineTransparencySpinner.Value;
                app.TrackPlottingProperties{TrackNum,1}.TrackLineUseColormapCheck               = app.TrackLineUseColormapCheck.Value;
                app.TrackPlottingProperties{TrackNum,1}.TrackSmoothingCheck                     = app.TrackSmoothingCheck.Value;
                app.TrackPlottingProperties{TrackNum,1}.TrackSmoothingNPointsDropDown           = app.TrackSmoothingNPointsDropDown.Value;
                app.TrackPlottingProperties{TrackNum,1}.TrackLineWidth                          = app.LineWidthDropDown.Value;
                % Vectors panel ----------------------------------------------------------------------------------------------------------------------------------
                app.TrackPlottingProperties{TrackNum,1}.AllVectorCheckBox                       = app.AllVectorCheckBox.Value;
                app.TrackPlottingProperties{TrackNum,1}.AllVectorColor                          = app.AllVectorColorDisplay.BackgroundColor;
                app.TrackPlottingProperties{TrackNum,1}.AllVectorTransparencySpinner            = app.AllVectorTransparencySpinner.Value;
                app.TrackPlottingProperties{TrackNum,1}.AllVectorUseColormapCheck               = app.AllVectorUseColormapCheck.Value;
                app.TrackPlottingProperties{TrackNum,1}.VectorRadius                            = app.VectorRadiusEditField.Value;
                app.TrackPlottingProperties{TrackNum,1}.VectorLength                            = app.VectorLengthEditField.Value;
                app.TrackPlottingProperties{TrackNum,1}.MovieVectorColorCheckBox                = app.MovieVectorColorCheckBox.Value;
                app.TrackPlottingProperties{TrackNum,1}.MovieVectorColorButton                  = app.MovieVectorColorDisplay.BackgroundColor;
                app.TrackPlottingProperties{TrackNum,1}.MovieVectorTransparencySpinner          = app.MovieVectorTransparencySpinner.Value;
                app.TrackPlottingProperties{TrackNum,1}.MovieVectorUseColormapCheck             = app.MovieVectorUseColormapCheck.Value;
                % Data Plane panel --------------------------------------------------------------------------------------------------------------------------------
%                 app.TrackPlottingProperties{TrackNum,1}.OverlayDataPlaneCheck                   = app.OverlayDataPlaneCheck.Value;
%                 app.TrackPlottingProperties{TrackNum,1}.MovieDataPlaneColormapDropDown          = app.MovieDataPlaneColormapDropDown.Value;
                % Colormap type and map ---------------------------------------------------------------------------------------------------------------------------
                app.TrackPlottingProperties{TrackNum,1}.ColormapTypeDropDown                    = app.ColormapTypeDropDown.Value;
                app.TrackPlottingProperties{TrackNum,1}.MultiCellColormapDropDown               = app.MultiCellColormapDropDown.Value;
                % Figure Propertites ------------------------------------------------------------------------------------------------------------------------------
                app.TrackFigureProperties.FigureBackgroundColor                                 = app.MultiTrackBackgroundColorDisplay.BackgroundColor;
                app.TrackFigureProperties.FigureTextColor                                       = app.TextAxesColorDisplay.BackgroundColor;
                app.TrackFigureProperties.StartingTimePoint                                     = app.StartingTimePoint.Value;
                app.TrackFigureProperties.TimePointIncrement                                    = app.TimePointIncrement.Value;
                
                app.TrackFigureProperties.XminXmaxEditField                                     = cell2mat( textscan( app.XminXmaxEditField.Value,'%f %f','Delimiter',',' ) );
                app.TrackFigureProperties.YminYmaxEditField                                     = cell2mat( textscan( app.YminYmaxEditField.Value,'%f %f','Delimiter',',' ) );
                app.TrackFigureProperties.ZminZmaxEditField                                     = cell2mat( textscan( app.ZminZmaxEditField.Value,'%f %f','Delimiter',',' ) );

        end
        
 %------------------------------------------------------------------------------------------------------------------------ -----------------------------------------------------------       
 %------------------------------------------------------------------------------------------------------------------------ -----------------------------------------------------------  

       function SetTrackPlottingProperties(app,TrackNum)
            
                app.ShowTrackCheckBox.Value                         = app.TrackPlottingProperties{TrackNum,1}.ShowTrackOnOff;                          
                
                % All time pts panel --------------------------------------------------------------------------------------------------------------------------
                app.AllCellSurfaceCheckBox.Value                    = app.TrackPlottingProperties{TrackNum,1}.AllCellSurfaceCheckBox;
                app.AllCellSurfaceColorDisplay.BackgroundColor      = app.TrackPlottingProperties{TrackNum,1}.AllCellSurfaceColor; 
                app.AllCellSurfaceTransparencySpinner.Value         = app.TrackPlottingProperties{TrackNum,1}.AllCellSurfaceTransparencySpinner;
                app.AllCellMeshCheckBox.Value                       = app.TrackPlottingProperties{TrackNum,1}.AllCellMeshCheckBox;                     
                app.AllCellMeshColorDisplay.BackgroundColor         = app.TrackPlottingProperties{TrackNum,1}.AllCellMeshColor;     
                app.AllCellMeshTransparencySpinner.Value            = app.TrackPlottingProperties{TrackNum,1}.AllCellMeshTransparencySpinner;     
                % Movie panel ----------------------------------------------------------------------------------------------------------------------------------
                app.MovieCellSurfaceCheckBox.Value                  = app.TrackPlottingProperties{TrackNum,1}.MovieCellSurfaceCheckBox;                
                app.MovieCellSurfaceColorDisplay.BackgroundColor    = app.TrackPlottingProperties{TrackNum,1}.MovieCellSurfaceColor;                  
                app.MovieCellSurfaceTransparencySpinner.Value       = app.TrackPlottingProperties{TrackNum,1}.MovieCellSurfaceTransparencySpinner;   
                app.MovieCellMeshCheckBox.Value                     = app.TrackPlottingProperties{TrackNum,1}.MovieCellMeshCheckBox;                 
                app.MovieCellMeshColorDisplay.BackgroundColor       = app.TrackPlottingProperties{TrackNum,1}.MovieCellMeshColor;                    
                app.MovieCellMeshTransparencySpinner.Value          = app.TrackPlottingProperties{TrackNum,1}.MovieCellMeshTransparencySpinner;      
                app.MovieCentroidCheckBox.Value                     = app.TrackPlottingProperties{TrackNum,1}.MovieCentroidCheckBox;                  
                app.MovieCentroidColorDisplay.BackgroundColor       = app.TrackPlottingProperties{TrackNum,1}.MovieCentroidColor;                     
                app.MovieCentroidTransparencySpinner.Value          = app.TrackPlottingProperties{TrackNum,1}.MovieCentroidTransparencySpinner;      
                % Track panel -----------------------------------------------------------------------------------------------------------------------------------
                app.TrackLineCheckBox.Value                         = app.TrackPlottingProperties{TrackNum,1}.TrackLineCheckBox;                       
                app.TrackLineColorDisplay.BackgroundColor           = app.TrackPlottingProperties{TrackNum,1}.TrackLineColor;                          
                app.TrackLineTransparencySpinner.Value              = app.TrackPlottingProperties{TrackNum,1}.TrackLineTransparencySpinner;          
                app.TrackSmoothingCheck.Value                       = app.TrackPlottingProperties{TrackNum,1}.TrackSmoothingCheck;                   
                app.TrackSmoothingNPointsDropDown.Value             = app.TrackPlottingProperties{TrackNum,1}.TrackSmoothingNPointsDropDown;   
                app.LineWidthDropDown.Value                         = app.TrackPlottingProperties{TrackNum,1}.TrackLineWidth;                          
                % Vectors panel ----------------------------------------------------------------------------------------------------------------------------------
                app.AllVectorCheckBox.Value                         = app.TrackPlottingProperties{TrackNum,1}.AllVectorCheckBox;                  
                app.AllVectorColorDisplay.BackgroundColor           = app.TrackPlottingProperties{TrackNum,1}.AllVectorColor;                          
                app.AllVectorTransparencySpinner.Value              = app.TrackPlottingProperties{TrackNum,1}.AllVectorTransparencySpinner;            
                app.MovieVectorColorCheckBox.Value                  = app.TrackPlottingProperties{TrackNum,1}.MovieVectorColorCheckBox;                
                app.MovieVectorColorDisplay.BackgroundColor         = app.TrackPlottingProperties{TrackNum,1}.MovieVectorColorButton;                  
                app.MovieVectorTransparencySpinner.Value            = app.TrackPlottingProperties{TrackNum,1}.MovieVectorTransparencySpinner;   
                app.VectorRadiusEditField.Value                     = app.TrackPlottingProperties{TrackNum,1}.VectorRadius;                            
                app.VectorLengthEditField.Value                     = app.TrackPlottingProperties{TrackNum,1}.VectorLength;      
                % Data Plane panel --------------------------------------------------------------------------------------------------------------------------------
%                 app.OverlayDataPlaneCheck.Value                     = app.TrackPlottingProperties{TrackNum,1}.OverlayDataPlaneCheck;                  
%                 app.MovieDataPlaneColormapDropDown.Value            = app.TrackPlottingProperties{TrackNum,1}.MovieDataPlaneColormapDropDown;     
                % Colormap type and map ---------------------------------------------------------------------------------------------------------------------------
                app.ColormapTypeDropDown.Value                      = app.TrackPlottingProperties{TrackNum,1}.ColormapTypeDropDown;
                app.MultiCellColormapDropDown.Value                 = app.TrackPlottingProperties{TrackNum,1}.MultiCellColormapDropDown; 

        end
        
 %------------------------------------------------------------------------------------------------------------------------ -----------------------------------------------------------       
 %------------------------------------------------------------------------------------------------------------------------ -----------------------------------------------------------     

        function EnablePlottingProperties(app,Action)
            
                % Action = true or 'disable'
                
                switch logical(Action)
                    case false; value1 = false; value2 = 'off';
                    case true;  value1 = true;  value2 = 'on';                       
                end
                
                %app.ShowTrackCheckBox.Enable = value1;    
                app.AllCellSurfaceCheckBox.Enable = value1;
                app.AllCellSurfaceColorButton.Enable = value1;
                app.AllCellSurfaceColorDisplay.Enable = value2;
                app.AllCellSurfaceTransparencySpinner.Enable = value1;
                app.AllCellSurfaceUseColormapCheck.Enable = value1;
                app.AllCellMeshUseColormapCheck.Enable = value1;
                app.AllCellMeshCheckBox.Enable = value1;
                app.AllCellMeshColorDisplay.Enable = value2;
                app.AllCellMeshTransparencySpinner.Enable = value1;  
                app.AllCellMeshColorButton.Enable = value1;

                app.MovieCellSurfaceCheckBox.Enable = value1;
                app.MovieCellSurfaceColorDisplay.Enable = value2;
                app.MovieCellSurfaceTransparencySpinner.Enable = value1;
                app.MovieCellSurfaceColorButton.Enable = value1;
                app.MovieCellMeshCheckBox.Enable = value1;
                app.MovieCellMeshColorDisplay.Enable = value2;
                app.MovieCellMeshTransparencySpinner.Enable = value1;
                app.MovieCellMeshColorButton.Enable = value1;
                app.MovieCentroidCheckBox.Enable = value1;
                app.MovieCentroidColorButton.Enable = value1;
                app.MovieCentroidColorDisplay.Enable = value2;
                app.MovieCentroidTransparencySpinner.Enable = value1;

                app.TrackLineCheckBox.Enable = value1;
                app.TrackLineColorButton.Enable = value1;
                app.TrackLineColorDisplay.Enable = value2;
                app.TrackLineUseColormapCheck.Enable = value1;
                app.LineWidthDropDown.Enable = value1;
                app.TrackLineTransparencySpinner.Enable = value1;
                app.TrackSmoothingCheck.Enable = value1;
                app.TrackSmoothingNPointsDropDown.Enable = value1;


                app.TransparencyLabel2.Enable = value2;
                app.TransparencyLabel3.Enable = value2;
                app.TransparencyLabel4.Enable = value2;
                app.TransparencyLabel5.Enable = value2;
                app.TransparencyLabel6.Enable = value2;
                app.TransparencyLabel7.Enable = value2;

                app.AllVectorCheckBox.Enable = value1;
                app.AllVectorColorButton.Enable = value1;
                app.AllVectorColorDisplay.Enable = value2;
                app.AllVectorTransparencySpinner.Enable = value1;
                app.AllVectorUseColormapCheck.Enable = value1;

                app.MovieVectorColorCheckBox.Enable = value1;
                app.VectorColorButton.Enable = value1;
                app.MovieVectorColorDisplay.Enable = value2;
                app.MovieVectorTransparencySpinner.Enable = value1;
                app.MovieCellSurfaceUseColormapCheck.Enable = value1;
                app.MovieCellMeshUseColormapCheck.Enable = value1;
                app.MovieCentroidUseColormapCheck.Enable = value1;
                app.MovieVectorUseColormapCheck.Enable = value1;

                app.TransparencyLabel5_2.Enable = value2;
                app.TransparencyLabel5_3.Enable = value2;

                app.VectorRadiusEditField.Enable = value1;
                app.VectorLengthEditField.Enable = value1;
                
                app.OverlayDataPlaneCheck.Enable = value1;
                app.MovieDataPlaneColormapDropDown.Enable = value1;
                app.DataPlaneImageSaturation.Enable = value1;
                app.DataPlaneAzimuthOffsetInput.Enable = value1;
                app.DataPlaneElevationEditField.Enable = value1;

                app.VelocityFilteringAndRangeButton.Enable = value1;
                app.CurrentVelocityRangeDisplay.Enable = value1;

                nTracksSelected = sum( cell2mat( cellfun( @(x) double(x.ShowTrackOnOff),app.TrackPlottingProperties,'UniformOutput',false)  ) );

                if nTracksSelected > 0
                    app.ColormapTypeDropDown.Enable = true;
                    app.VelocityFilteringAndRangeButton.Enable = true;
                    app.CurrentVelocityRangeDisplay.Enable = true;
                    app.MultiCellColormapDropDown.Enable = true;
                else
                    app.ColormapTypeDropDown.Enable = false;
                    app.VelocityFilteringAndRangeButton.Enable = false;
                    app.CurrentVelocityRangeDisplay.Enable = false;
                    app.MultiCellColormapDropDown.Enable = false;
                end

        end
        
%----------------------------------------------------------------------------------------------------------------------             
        function EnableTrackFigureProperties(app,Action)
            
                % Action = true or 'disable'
                
                switch logical(Action)
                    case false;  value1 = 0; value2 = 'off';
                    case true;   value1 = 1; value2 = 'on';                       
                end
                
                app.MultitrackBackgroundColorButton.Enable  = value1;
                app.TextAxesColorButton.Enable              = value1;
                app.MultiTrackBackgroundColorDisplay.Enable = value2;
                app.TextAxesColorDisplay.Enable             = value2;
                app.StartingTimePoint.Enable                = value1;
                app.MakeDropDownLabel_2.Enable              = value1;
                app.EndingTimePoint.Enable                  = value1;
                app.MakeDropDownLabel_6.Enable              = value1;
                app.MakeDropDownLabel_5.Enable              = value1;
                app.TimePointIncrement.Enable               = value1;
                app.XminXmaxEditField.Enable                = value1;
                app.YminYmaxEditField.Enable                = value1;
                app.ZminZmaxEditField.Enable                = value1;
                app.AutoAxisLimitsCheckBox.Enable           = value1;
                app.UpdateCompositePlotButton.Enable        = value1;
                app.updateplotButton.Enable                 = value1;
                app.StopMovieButton.Enable                  = value1;
                app.RecordMovieButton.Enable                = value1;
                app.AutoAxisLimitsCheckBox.Enable           = value1;
                
                app.SetStartAngleButton.Enable              = value1;
                app.SetEndAngleButton.Enable                = value1;
                app.SetStartAngleDisplay.Enable             = value1;
                app.SetEndAngleDisplay.Enable               = value1;
                app.GoToStartViewButton.Enable              = value1;
                app.GoToEndViewButton.Enable                = value1;
                app.RotationDropDown.Enable                 = value1;
                
                app.UnselectAllTrackButton.Enable           = value1;
                app.TrackSmoothingNPointsDropDown.Enable    = value1;
                app.TrackSmoothingCheck.Enable              = value1;
                app.FramespersecondEditField.Enable         = value1;
                app.ExportFluoRenderFormatedTrack.Enable      = value1;
                
                nTracksSelected = sum( cell2mat(cellfun(@(x) double(x.ShowTrackOnOff),app.TrackPlottingProperties,'UniformOutput',false)) );

                if nTracksSelected == 2
                    app.OverlayDataPlaneCheck.Enable = 1;
                    app.MovieDataPlaneColormapDropDown.Enable = 1;
                    app.ColormapLabel.Enable = 1;
                else
                    app.OverlayDataPlaneCheck.Enable = 0;
                    app.MovieDataPlaneColormapDropDown.Enable = 0;
                    app.ColormapLabel.Enable = 0;
                end

                if logical(Action) && logical(app.AutoAxisLimitsCheckBox.Value)
                    app.updateplotButton.Enable = 0;
                end

        end  
        
 %------------------------------------------------------------------------------------------------------------------------         
        function EnableClosestApproachProperties(app,Action)       
      
                % Action = true or 'disable'
                
                switch logical(Action)
                    case false; value1 = 0; value2 = 'off';
                    case true;  value1 = 1; value2 = 'on';                       
                end
                
                app.SelectTrackADropDown.Enable = value1;
                app.SelectTrackBDropDown.Enable = value1;
                app.XaxisTimeunitsDropDown.Enable = value1;
                app.CalculateandPlotClosestApproachButton.Enable = value1;
                app.ExportasExcelfileButton.Enable = value1;
                app.TrackAStatsButton.Enable = value1;
                app.TrackBStatsButton.Enable = value1;
            
        end
        
 %------------------------------------------------------------------------------------------------------------------------------------------------------------------               
        function SetDefaultPlottingProperties(app,TrackNum)
        
                app.TrackPlottingProperties{TrackNum,1}.ShowTrackOnOff                          = false;
                app.TrackPlottingProperties{TrackNum,1}.AllCellSurfaceCheckBox                  = true;
                app.TrackPlottingProperties{TrackNum,1}.AllCellSurfaceColor                     = [1,0,0]; 
                app.TrackPlottingProperties{TrackNum,1}.AllCellSurfaceTransparencySpinner       = 3;
                app.TrackPlottingProperties{TrackNum,1}.AllCellSurfaceUseColormapCheck          = false;
                app.TrackPlottingProperties{TrackNum,1}.AllCellMeshCheckBox                     = true;                     
                app.TrackPlottingProperties{TrackNum,1}.AllCellMeshColor                        = [0,0,0];     
                app.TrackPlottingProperties{TrackNum,1}.AllCellMeshTransparencySpinner          = 3;     
                app.TrackPlottingProperties{TrackNum,1}.AllCellMeshUseColormapCheck             = false;
                % Movie panel ----------------------------------------------------------------------------------------------------------------------------------
                app.TrackPlottingProperties{TrackNum,1}.MovieCellSurfaceCheckBox                = false;                
                app.TrackPlottingProperties{TrackNum,1}.MovieCellSurfaceColor                   = [1,0,0];                  
                app.TrackPlottingProperties{TrackNum,1}.MovieCellSurfaceTransparencySpinner     = 3;   
                app.TrackPlottingProperties{TrackNum,1}.MovieCellSurfaceUseColormapCheck        = false;
                app.TrackPlottingProperties{TrackNum,1}.MovieCellMeshCheckBox                   = false;                 
                app.TrackPlottingProperties{TrackNum,1}.MovieCellMeshColor                      = [0,0,0];                    
                app.TrackPlottingProperties{TrackNum,1}.MovieCellMeshTransparencySpinner        = 3;       
                app.TrackPlottingProperties{TrackNum,1}.MovieCellMeshUseColormapCheck           = false;
                app.TrackPlottingProperties{TrackNum,1}.MovieCentroidCheckBox                   = false;                  
                app.TrackPlottingProperties{TrackNum,1}.MovieCentroidColor                      = [0,0,0];                     
                app.TrackPlottingProperties{TrackNum,1}.MovieCentroidTransparencySpinner        = 50;   
                app.TrackPlottingProperties{TrackNum,1}.MovieCentroidUseColormapCheck           = false;
                app.TrackPlottingProperties{TrackNum,1}.MovieVectorColorCheckBox                = false;                
                app.TrackPlottingProperties{TrackNum,1}.MovieVectorColorButton                  = [0,0,0];                 
                app.TrackPlottingProperties{TrackNum,1}.MovieVectorTransparencySpinner          = 50;   
                app.TrackPlottingProperties{TrackNum,1}.MovieVectorUseColormapCheck             = false;
                % Track panel -----------------------------------------------------------------------------------------------------------------------------------
                app.TrackPlottingProperties{TrackNum,1}.TrackLineCheckBox                       = false;                       
                app.TrackPlottingProperties{TrackNum,1}.TrackLineColor                          = [0,0,0];                          
                app.TrackPlottingProperties{TrackNum,1}.TrackLineTransparencySpinner            = 100;    
                app.TrackPlottingProperties{TrackNum,1}.TrackLineUseColormapCheck               = false;
                app.TrackPlottingProperties{TrackNum,1}.TrackSmoothingCheck                     = 1;                   
                app.TrackPlottingProperties{TrackNum,1}.TrackSmoothingNPointsDropDown           = 5;    
                app.TrackPlottingProperties{TrackNum,1}.TrackLineWidth                          = 3;
                % Vectors panel ----------------------------------------------------------------------------------------------------------------------------------
                app.TrackPlottingProperties{TrackNum,1}.AllVectorCheckBox                       = false;                  
                app.TrackPlottingProperties{TrackNum,1}.AllVectorColor                          = [0,0,0];                          
                app.TrackPlottingProperties{TrackNum,1}.AllVectorTransparencySpinner            = 50;   
                app.TrackPlottingProperties{TrackNum,1}.AllVectorUseColormapCheck               = false;
                app.TrackPlottingProperties{TrackNum,1}.VectorRadius                            = 0.3;                            
                app.TrackPlottingProperties{TrackNum,1}.VectorLength                            = 15;    
                % Data Plane panel --------------------------------------------------------------------------------------------------------------------------------
%                 app.TrackPlottingProperties{TrackNum,1}.OverlayDataPlaneCheck                   = false;                  
%                 app.TrackPlottingProperties{TrackNum,1}.MovieDataPlaneColormapDropDown          = 1; 
                app.TrackPlottingProperties{TrackNum,1}.ColormapTypeDropDown                    = 1;
                app.TrackPlottingProperties{TrackNum,1}.MultiCellColormapDropDown               = 1;
            
        end
        
%------------------------------------------------------------------------------------------------------------------------------------------------------------------        
        function SetDefaultTrackFigureProperties(app)
        
                app.TrackFigureProperties.FigureBackgroundColor  = [1,1,1];
                app.TrackFigureProperties.FigureTextColor        = [0,0,0];
                app.TrackFigureProperties.StartingTimePoint      = 1;
                app.TrackFigureProperties.TimePointIncrement     = 1;
                app.TrackFigureProperties.XminXmaxEditField      = '0 , 200';
                app.TrackFigureProperties.YminYmaxEditField      = '0 , 200';
                app.TrackFigureProperties.ZminZmaxEditField      = '0 , 200';

        end
 
%------------------------------------------------------------------------------------------------------------------------------------------------------------------        
        function [AzimuthValues,ElevationValues,ProcTracks] = CalculateSmoothAxisRotationForDataPlane(app,Track1,Track2)
                        
                        % calculate camera angle and then smooth the path

                        tStart = app.StartingTimePoint.Value;
                        tEnd   = app.EndingTimePoint.Value;

                        N = tEnd - tStart + 1;

                        theta  = [];
                        Xcen1  = NaN(N,1);
                        Ycen1  = NaN(N,1);
                        Zcen1  = NaN(N,1);
                        Xcen2  = NaN(N,1);
                        Ycen2  = NaN(N,1);
                        Zcen2  = NaN(N,1);

                        for t = tStart:tEnd %1:app.nTime
                                idx = t - tStart + 1;
                                % Update Face color & transparency if turned on -------------------------------------------
                                if ~isempty(app.TimePointData.SurfaceRenderings{Track1,1}{t,1})
                                    Xcen1(idx,1) = mean(app.TimePointData.SurfaceRenderings{Track1,1}{t,1}.vertices(:,1));
                                    Ycen1(idx,1) = mean(app.TimePointData.SurfaceRenderings{Track1,1}{t,1}.vertices(:,2));
                                    Zcen1(idx,1) = mean(app.TimePointData.SurfaceRenderings{Track1,1}{t,1}.vertices(:,3));
                                end
                            
                                if ~isempty(app.TimePointData.SurfaceRenderings{Track2,1}{t,1})
                                    Xcen2(idx,1) = mean(app.TimePointData.SurfaceRenderings{Track2,1}{t,1}.vertices(:,1));
                                    Ycen2(idx,1) = mean(app.TimePointData.SurfaceRenderings{Track2,1}{t,1}.vertices(:,2));
                                    Zcen2(idx,1) = mean(app.TimePointData.SurfaceRenderings{Track2,1}{t,1}.vertices(:,3));
                                end                              
                        end
                        
                        Xcen1 = fillmissing(Xcen1,'linear');
                        Ycen1 = fillmissing(Ycen1,'linear');
                        Zcen1 = fillmissing(Zcen1,'linear');
                        Xcen2 = fillmissing(Xcen2,'linear');
                        Ycen2 = fillmissing(Ycen2,'linear');
                        Zcen2 = fillmissing(Zcen2,'linear');

                        ProcTracks = [Xcen1,Ycen1,Zcen1,Xcen2,Ycen2,Zcen2]; 

                        % calculate camera view angle perpendicular to data plane and add Azimuth offset.
                        theta  = atan2d((Ycen2-Ycen1),(Xcen2-Xcen1)) + app.DataPlaneAzimuthOffsetInput.Value; 
                        theta = mod(theta,360);
                        theta2  = (180/pi)*unwrap(theta*(pi/180)) ;    % fix jumps in angle calculation due to angle going from -180 to 180.

                        AzimuthValues = smooth(theta2,30,'loess'); % smooth camera angle so video isn't so jumpy
                        ElevationValues = linspace(app.DataPlaneElevationEditField.Value,...
                                                   app.DataPlaneElevationEditField.Value,length(AzimuthValues));

        end

%------------------------------------------------------------------------------------------------------------------------------------------------------------------        
        function UpdateCompositePlot(app,DoMovie)
        
                    % Create Figure and Axes (record handles) ----------------------------------------------------------------------------------------------------------
                    if ~isgraphics(app.TrackAndMovieFigureHandle)
                        app.TrackAndMovieFigureHandle = figure('Units','normalized','Color',[1,1,1],'Position',[0.2,0.15,0.6,0.7]);
                    end
                    
                    if isgraphics(app.TrackAndMovieAxesHandle)
                        [az,el] = view(app.TrackAndMovieAxesHandle);
                    else
                        az = 45;
                        el = 12;
                    end

                    set(app.TrackAndMovieFigureHandle,'Resize',"on")

                    app.StopMovieButton.Value = 0;

                    % START: if "Record Movie" button is pressed set up movie name and file ------------------------------------------------------------------------
                    if DoMovie % Create movie file
                            answer = questdlg('Movie output format:','Movie format','tif','mp4','cancel','mp4');
                            if isempty(answer)
                                answer = 'cancel';
                            end

                            switch answer
                                    case 'mp4' %--------------------------------------------------------------------------
                                            MovieFormat = 'mp4';
                                            [File,path] = uiputfile('*.mp4','Movie save name','LocusMovie1.mp4');
                                            if File == 0
                                                    app.StopMovieButton.Value = true;
                                                    DoMovie = false;
                                            else
                                                    app.StopMovieButton.Value = false;
                                                    Params = inputdlg({'Set movie frame rate (frames/sec)','Set movie quality (0-100)'},...
                                                                       'Set movie frame and quality',[1,70],{'10','75'});
                                                    if isempty(Params)
                                                        app.StopMovieButton.Value = true;
                                                        DoMovie = false;
                                                    else
                                                        FrameRate = str2double(Params{1});
                                                        Quality   = str2double(Params{2});
                                                        MovieFileName = fullfile(path,File);
                                                        MH = VideoWriter(MovieFileName,'MPEG-4');
                                                        MH.FrameRate = FrameRate;
                                                        MH.Quality = Quality;
                                                        open(MH)
                                                        % Make figure size unchangelable to frames are all the same size
                                                        set(app.TrackAndMovieFigureHandle,'Resize',"off") 
                                                    end
                                            end
    
                                    case 'tif' %--------------------------------------------------------------------------
                                            MovieFormat = 'tif';
                                            MovieFrameWriteDir = uigetdir('','Select movie frame write directory');
                                            set(app.TrackAndMovieFigureHandle,'Resize',"off") 
                                            if MovieFrameWriteDir == 0
                                                app.StopMovieButton.Value = true;
                                                DoMovie = false;
                                            end

                                    case 'cancel' %-----------------------------------------------------------------------
                                            app.StopMovieButton.Value = true;
                                            DoMovie = false;

                            end

                    end
                    % END: "Record Movie" setup ---------------------------------------------------------------------------------------------------------------------

                    clf(app.TrackAndMovieFigureHandle)

                    app.TrackAndMovieAxesHandle = axes(app.TrackAndMovieFigureHandle);  
                    set(app.TrackAndMovieAxesHandle,'Color', [1,1,1],'DataAspectRatio',[1,1,1],'LineWidth',2,'CameraViewAngleMode','manual',...
                                                    'CameraViewAngle',8,'XGrid','on','YGrid','on','ZGrid','on','XColor',[0,0,0],'YColor',[0,0,0],'ZColor',[0,0,0],...
                                                    'TickDir','both','Box','on','BoxStyle','back','FontSize',14,'Projection','perspective')
                
                    view(app.TrackAndMovieAxesHandle,az,el)

                    xlabel(app.TrackAndMovieAxesHandle,'X(\mum)','FontSize',16)
                    ylabel(app.TrackAndMovieAxesHandle,'Y(\mum)','FontSize',16)
                    zlabel(app.TrackAndMovieAxesHandle,'Z(\mum)','FontSize',16)
            
                    % Grab Start, End, and increments of time steps -----------------------------------------------------------------------------------------------------
                    tStart = app.StartingTimePoint.Value;
                    tEnd   = app.EndingTimePoint.Value;
                    tIncrement = app.TimePointIncrement.Value;
                    TimeVec = tStart:1:tEnd;
                    % nMovieTimePoints = length(TimeVec);
                    dt = app.dt/60; % timestep in minutes

                    nTracks = length(app.CellTrackFilesInfo.names);  
                    CellHandles   = gobjects( length(TimeVec), nTracks );
                    TrackHandles  = gobjects( length(TimeVec), nTracks );
                    VectorHandles = gobjects( length(TimeVec), nTracks );
                    P2 = gobjects(1);

                    % Set up rotation angles ---------------------------------
                    nSteps = numel(TimeVec);
                    switch app.RotationDropDown.Value
                        case 'off'
                                AzimuthStart   = NaN;
                                AzimuthEnd     = NaN;
                                ElevationStart = NaN;
                                ElevationEnd   = NaN;
                                AzimuthValues   = linspace(AzimuthStart,AzimuthEnd,nSteps);
                                ElevationValues = linspace(ElevationStart,ElevationEnd,nSteps);
                        case 'on'
                                AzElStartValues = textscan(app.SetStartAngleDisplay.Value,'%s','delimiter',',');
                                AzimuthStart    = str2double(AzElStartValues{1}{1});
                                ElevationStart  = str2double(AzElStartValues{1}{2});
                                AzElEndValues   = textscan(app.SetEndAngleDisplay.Value,'%s','delimiter',',');
                                AzimuthEnd      = str2double(AzElEndValues{1}{1});
                                ElevationEnd    = str2double(AzElEndValues{1}{2});
                                AzimuthValues   = linspace(AzimuthStart,AzimuthEnd,nSteps);
                                ElevationValues = linspace(ElevationStart,ElevationEnd,nSteps);
                        case 'auto'
                                if strcmp(app.OverlayDataPlaneCheck.Enable,'on')
                                    Tracks = find(cell2mat( cellfun( @(x) double(x.ShowTrackOnOff),app.TrackPlottingProperties,'UniformOutput',false)  ));
                                    [AzimuthValues,ElevationValues,ProcTracks] = CalculateSmoothAxisRotationForDataPlane(app,Tracks(1),Tracks(2));
                                else 
                                    AzElStartValues = textscan(app.SetStartAngleDisplay.Value,'%s','delimiter',',');
                                    AzimuthStart    = str2double(AzElStartValues{1}{1});
                                    ElevationStart  = str2double(AzElStartValues{1}{2});
                                    AzElEndValues   = textscan(app.SetEndAngleDisplay.Value,'%s','delimiter',',');
                                    AzimuthEnd      = str2double(AzElEndValues{1}{1});
                                    ElevationEnd    = str2double(AzElEndValues{1}{2});
                                    AzimuthValues   = linspace(AzimuthStart,AzimuthEnd,nSteps);
                                    ElevationValues = linspace(ElevationStart,ElevationEnd,nSteps);    
                                end
                    end

                    
                    %-----------------------------------------------------------------------------------------------------
                    % Preallocate space for the centroid coordinates of all tracks
                    Xtracks = nan(app.nTime,nTracks);
                    Ytracks = nan(app.nTime,nTracks);
                    Ztracks = nan(app.nTime,nTracks);
                    Xtracksraw    = nan(app.nTime,nTracks);
                    Ytracksraw    = nan(app.nTime,nTracks);
                    Ztracksraw    = nan(app.nTime,nTracks);

                    % Grab X,Y,Z points for cell tracks and applying smoothing if it's selected ..........................
                    for track = 1:nTracks
                            Xtracks(:,track) = app.TimePointData.Measurements{track,1}.Centroid(:,1);                            
                            Ytracks(:,track) = app.TimePointData.Measurements{track,1}.Centroid(:,2);
                            Ztracks(:,track) = app.TimePointData.Measurements{track,1}.Centroid(:,3);
                            Xtracksraw(:,track) = app.TimePointData.Measurements{track,1}.Centroid(:,1);                            
                            Ytracksraw(:,track) = app.TimePointData.Measurements{track,1}.Centroid(:,2);
                            Ztracksraw(:,track) = app.TimePointData.Measurements{track,1}.Centroid(:,3);
                            nPts = size(Xtracks,1);
                            % Smooth X,Y,Z, data if selected
                            if app.TrackSmoothingCheck.Value && nPts > 10
                                N = app.TrackSmoothingNPointsDropDown.Value;
                                [NanIdx,~] = find( isnan( Xtracks(:,track) ) );
                                Xtracks(:,track) = smoothdata(Xtracks(:,track),'gaussian',N); %,'rlowess');
                                Xtracks(NanIdx,track) = NaN;
                                Ytracks(:,track) = smoothdata(Ytracks(:,track),'gaussian',N); %,'rlowess');
                                Ytracks(NanIdx,track) = NaN;
                                Ztracks(:,track) = smoothdata(Ztracks(:,track),'gaussian',N); %,'rlowess');
                                Ztracks(NanIdx,track) = NaN;
                            end
                    end
                    %.....................................................................................................

                    Xmin = nan;
                    Xmax = nan;
                    Ymin = nan;
                    Ymax = nan;
                    Zmin = nan;
                    Zmax = nan;
                    
                    TimeIndices= tStart:tEnd;
                    t2 = tStart:tIncrement:tEnd;
                    nTimePoints = numel(TimeIndices);
                    VelocityOrTimeColorMap = eval(  [lower(app.MultiCellColormapDropDown.Items{app.MultiCellColormapDropDown.Value}),'(app)']  );
                    
                    P2 = gobjects(nTracks,1); 
                    
                    for track = 1:nTracks
                            if AnyColormapsChecked(app) && isequal(app.ColormapTypeDropDown.Value,2)
                                TrackVelocity = CalculateTrackVelocity(app,track,Xtracksraw,Ytracksraw,Ztracksraw);
                            else
                                TrackVelocity = [];
                            end
                            if app.TrackPlottingProperties{track,1}.ShowTrackOnOff
                                    % START looping through time points ==========================================================================
                                    for TimeIndex = 1:nTimePoints
                                            t = TimeIndices(TimeIndex);
                                            %--------------------------------------------------------------------
                                            if ~isempty( app.TimePointData.SurfaceRenderings{track,1}{t,1} )
                                                RenderingAvailable = true;
                                            else
                                                RenderingAvailable = false;
                                            end
                                            %--------------------------------------------------------------------
                                            if any(ismember(t,t2))
                                                RenderThisTimePoint = true;
                                            else
                                                RenderThisTimePoint = false;
                                            end
                                            %--------------------------------------------------------------------
                                            if RenderingAvailable && RenderThisTimePoint  &&  (app.TrackPlottingProperties{track,1}.AllCellSurfaceCheckBox || app.TrackPlottingProperties{track,1}.AllCellMeshCheckBox)
                                                    % Create patch object -----------------------------------------------------------------------------------------
                                                    if app.ShowSmoothedRenderingCheck.Value
                                                        RenderingData = app.TimePointData.SmoothedSurfaceRenderings{track,1}{t,1};
                                                    else
                                                        RenderingData = app.TimePointData.SurfaceRenderings{track,1}{t,1};
                                                    end
                                                    CellHandles(t,track) = patch(app.TrackAndMovieAxesHandle, reducepatch(RenderingData, 0.8) ); 

                                                    % Update Face color & transparency if turned on ---------------------------------------------------------------
                                                    if      app.TrackPlottingProperties{track,1}.AllCellSurfaceCheckBox  &&  ~app.TrackPlottingProperties{track,1}.AllCellSurfaceUseColormapCheck
                                                        CellHandles(t,track).FaceColor = app.TrackPlottingProperties{track,1}.AllCellSurfaceColor; 
                                                    elseif  app.TrackPlottingProperties{track,1}.AllCellSurfaceCheckBox  &&   app.TrackPlottingProperties{track,1}.AllCellSurfaceUseColormapCheck
                                                        switch app.ColormapTypeDropDown.Value
                                                            case 1;   Color = GetTimeOrVelocityColor(app, VelocityOrTimeColorMap, dt.*[tStart-1,tEnd-1], dt*(t-1)); 
                                                            case 2;   Color = GetTimeOrVelocityColor(app, VelocityOrTimeColorMap, app.VelocityColormapRange, TrackVelocity(t)); 
                                                        end
                                                        CellHandles(t,track).FaceColor = Color; 
                                                    else
                                                        CellHandles(t,track).FaceColor = 'none';                                                     
                                                    end
                                                    CellHandles(t,track).FaceAlpha = app.TrackPlottingProperties{track,1}.AllCellSurfaceTransparencySpinner/100;  
        
                                                    % Update Mesh color & transparency if turned on ---------------------------------------------------------------
                                                    if     app.TrackPlottingProperties{track,1}.AllCellMeshCheckBox  &&  ~app.TrackPlottingProperties{track,1}.AllCellMeshUseColormapCheck
                                                        CellHandles(t,track).EdgeColor = app.TrackPlottingProperties{track,1}.AllCellMeshColor; 
                                                        CellHandles(t,track).AlignVertexCenters = 'on'; 
                                                    elseif app.TrackPlottingProperties{track,1}.AllCellMeshCheckBox  &&   app.TrackPlottingProperties{track,1}.AllCellMeshUseColormapCheck
                                                        switch app.ColormapTypeDropDown.Value
                                                            case 1;   Color = GetTimeOrVelocityColor(app, VelocityOrTimeColorMap, dt.*[tStart-1,tEnd-1], dt*(t-1)); 
                                                            case 2;   Color = GetTimeOrVelocityColor(app, VelocityOrTimeColorMap, app.VelocityColormapRange, TrackVelocity(t)); 
                                                        end
                                                        CellHandles(t,track).EdgeColor = Color; 
                                                    else
                                                        CellHandles(t,track).EdgeColor = 'none';
                                                    end
                                                    CellHandles(t,track).EdgeAlpha = app.TrackPlottingProperties{track,1}.AllCellMeshTransparencySpinner/100;
                                            end
    
                                            % Update Vector color & transparency if turned on ---------------------------------------------------------------------
                                            if app.TrackPlottingProperties{track,1}.AllVectorCheckBox  &&  RenderingAvailable  && RenderThisTimePoint
                                                    r = app.VectorRadiusEditField.Value;
                                                    L = app.VectorLengthEditField.Value;
                                                    [Xvec,Yvec,Zvec] = cylinder(r,14);
                                                    [rCyl,cCyl] = size(Xvec);
                                                    Zvec = L*(Zvec-0.5); % Center default position at zero
                                                    % Grab LongAxis vector
                                                    LAvec = app.TimePointData.Measurements{track,1}.EllipsoidFitVectors(t,:,1);
                                                    if isnan(LAvec(1,1))
                                                        [~,~,ev,~,~] = ellipsoid_fit(app, app.TimePointData.SurfaceRenderings{track,1}{t}.vertices, '');       % Calculate best ellipsoid fit, and the three defining vectors: ev !!!!
                                                        LAvec = ev(:,1);
                                                        app.TimePointData.Measurements{track,1}.EllipsoidFitVectors(t,:,:) = ev;
                                                    end
                                                    % Calculate rotation matrix
                                                    [RM, ~] = RotationMatrix3D(app, [0,0,1], LAvec );
                                                    
                                                    M = RM*[Xvec(:)';Yvec(:)';Zvec(:)']; % Rotate default vector to proper cell orientation
                                                    Xvec = reshape(M(1,:),rCyl,cCyl);
                                                    Yvec = reshape(M(2,:),rCyl,cCyl);
                                                    Zvec = reshape(M(3,:),rCyl,cCyl);
        
                                                    Xvec = Xvec + app.TimePointData.Measurements{track,1}.Centroid(t,1);
                                                    Yvec = Yvec + app.TimePointData.Measurements{track,1}.Centroid(t,2);
                                                    Zvec = Zvec + app.TimePointData.Measurements{track,1}.Centroid(t,3);
                                                    
                                                    hold(app.TrackAndMovieAxesHandle,'on')
                                                    VectorHandles(t,track) = surf(app.TrackAndMovieAxesHandle,Xvec,Yvec,Zvec);
    
                                                    if    ~app.TrackPlottingProperties{track,1}.AllVectorUseColormapCheck 
                                                            VectorHandles(t,track).FaceColor = app.TrackPlottingProperties{track,1}.AllVectorColor;
                                                    elseif app.TrackPlottingProperties{track,1}.AllVectorUseColormapCheck 
                                                            switch app.ColormapTypeDropDown.Value
                                                                case 1;   Color = GetTimeOrVelocityColor(app, VelocityOrTimeColorMap, dt.*[tStart-1,tEnd-1], dt*(t-1)); 
                                                                case 2;   Color = GetTimeOrVelocityColor(app, VelocityOrTimeColorMap, app.VelocityColormapRange, TrackVelocity(t)); 
                                                            end
                                                            VectorHandles(t,track).FaceColor = Color;
                                                    end
    
                                                    VectorHandles(t,track).FaceAlpha = app.TrackPlottingProperties{track,1}.AllVectorTransparencySpinner/100;
                                                    VectorHandles(t,track).EdgeColor = 'none';
                                                    hold(app.TrackAndMovieAxesHandle,'off')
                                            end
    
                                            % Update Track line color & transparency if turned on ---------------------------------------------------------------------
                                            if app.TrackPlottingProperties{track,1}.TrackLineCheckBox &&  RenderingAvailable
                                                    if RenderingAvailable
                                                        hold(app.TrackAndMovieAxesHandle,'on')
                                                        if ~isgraphics(P2(track,1))
                                                            P2(track,1) = plot3(app.TrackAndMovieAxesHandle,Xtracks(t,track),Ytracks(t,track),Ztracks(t,track),'.k','MarkerSize',20);
                                                        end
                                                    end
    
                                                    if TimeIndex < nTimePoints-1
                                                        tp2 = TimeIndices(TimeIndex+2);
                                                        if    ~app.TrackPlottingProperties{track,1}.TrackLineUseColormapCheck
                                                                TrackColor = app.TrackPlottingProperties{track,1}.TrackLineColor;
                                                        elseif app.TrackPlottingProperties{track,1}.TrackLineUseColormapCheck
                                                                switch app.ColormapTypeDropDown.Value
                                                                    case 1;   TrackColor = GetTimeOrVelocityColor(app, VelocityOrTimeColorMap, dt.*[tStart-1,tEnd-1], dt*(t-1)); 
                                                                    case 2;   TrackColor = GetTimeOrVelocityColor(app, VelocityOrTimeColorMap, app.VelocityColormapRange, TrackVelocity(t)); 
                                                                end
                                                        end

                                                        TrackHandles(t,track) = surface(app.TrackAndMovieAxesHandle,...
                                                                                        [Xtracks(t:tp2,track)'; Xtracks(t:tp2,track)'],...
                                                                                        [Ytracks(t:tp2,track)'; Ytracks(t:tp2,track)'],...
                                                                                        [Ztracks(t:tp2,track)'; Ztracks(t:tp2,track)'],'facecolor',TrackColor,'edgecolor',TrackColor,'LineWidth',app.TrackPlottingProperties{track,1}.TrackLineWidth);
                                                        
                                                        TrackHandles(t,track).EdgeAlpha = app.TrackPlottingProperties{track,1}.TrackLineTransparencySpinner/100;
                                                        material(TrackHandles(t,track),'dull')
                                                        hold(app.TrackAndMovieAxesHandle,'off')
                                                    end
                                            end

    
                                            % Grab data range for auto axis range -------------------------------------------------------------------------
                                            if ~isempty(app.TimePointData.SurfaceRenderings{track,1}{t,1})  &&  RenderingAvailable
                                                Xmin = min( [Xmin; min(app.TimePointData.SurfaceRenderings{track,1}{t,1}.vertices(:,1))] );
                                                Xmax = max( [Xmax; max(app.TimePointData.SurfaceRenderings{track,1}{t,1}.vertices(:,1))] );
                                                Ymin = min( [Ymin; min(app.TimePointData.SurfaceRenderings{track,1}{t,1}.vertices(:,2))] );
                                                Ymax = max( [Ymax; max(app.TimePointData.SurfaceRenderings{track,1}{t,1}.vertices(:,2))] );
                                                Zmin = min( [Zmin; min(app.TimePointData.SurfaceRenderings{track,1}{t,1}.vertices(:,3))] );
                                                Zmax = max( [Zmax; max(app.TimePointData.SurfaceRenderings{track,1}{t,1}.vertices(:,3))] );
                                            end
    
                                    end % END looping through time points ======================================================================================
                                    R = [10*floor(Xmin/10), 10*ceil(Xmax/10), 10*floor(Ymin/10), 10*ceil(Ymax/10), 10*floor(Zmin/10), 10*ceil(Zmax/10) ];
                                    if R(2)-R(1) < 10 || R(4)-R(3) < 10 || R(6)-R(5) < 10
                                        R = [floor(Xmin), ceil(Xmax), floor(Ymin), ceil(Ymax), floor(Zmin), ceil(Zmax) ];
                                    end
                            end
                    end


                    % ADD colorbar if selected -------------------------------------------------------------------------------------------
                    if AnyColormapsChecked(app)
                        switch app.ColormapTypeDropDown.Items{app.ColormapTypeDropDown.Value}
                            case 'time'
                                colormap(app.TrackAndMovieAxesHandle,VelocityOrTimeColorMap)
                                CB = colorbar;
                                CB.Label.String = 'Time (minutes)';
                                CB.Label.FontSize = 18;
                                CB.Limits = dt.*[tStart-1,tEnd-1];
                                clim(app.TrackAndMovieAxesHandle,dt.*[tStart-1,tEnd-1])
                            case 'velocity'
                                colormap(app.TrackAndMovieAxesHandle, VelocityOrTimeColorMap)
                                CB = colorbar;
                                if app.VelocityUnits == 1
                                    CB.Label.String = 'Velocity (\mum/min)';
                                else
                                    CB.Label.String = 'Velocity (nm/s)';
                                end
                                CB.Label.FontSize = 18;
                                CB.Limits = app.VelocityColormapRange;
                                clim(app.TrackAndMovieAxesHandle, app.VelocityColormapRange)
                        end
                    end

                    %---------------------------------------------------------------------------------------------------------------------
                    % Set axis limits if they're set to manual. Record and update display if it's on auto
                    if logical(app.AutoAxisLimitsCheckBox.Value)
                        % R = axis(app.TrackAndMovieAxesHandle);
                        axis(app.TrackAndMovieAxesHandle, R)
                        app.XminXmaxEditField.Value = [num2str(R(1)),' , ',num2str(R(2))];
                        app.YminYmaxEditField.Value = [num2str(R(3)),' , ',num2str(R(4))];
                        app.ZminZmaxEditField.Value = [num2str(R(5)),' , ',num2str(R(6))];
                    else
                        Xrange = cell2mat( textscan( app.XminXmaxEditField.Value,'%f %f','Delimiter',',' ) );
                        Yrange = cell2mat( textscan( app.YminYmaxEditField.Value,'%f %f','Delimiter',',' ) );
                        Zrange = cell2mat( textscan( app.ZminZmaxEditField.Value,'%f %f','Delimiter',',' ) );
                        axis(app.TrackAndMovieAxesHandle, [Xrange Yrange Zrange])
                    end


                  %|||||||||||||||||||||||||||||||||||||||||||||||||||||||||||||||||||||||||||||||||||||||||||||||||||||||||||||||||||||||||||||||||||||||||||||||||||||||||||||||||| 
                  % NOW OVERLAY/ADD MOVIE OF SELECTED CELL TRACKS(S) ON TOP OF FULL TRACK RENDERING ---------------------------------------------------------------------------------
                  
                  FrameIdx = 0;
                  Tracks = find(cell2mat(cellfun(@(x) double(x.ShowTrackOnOff),app.TrackPlottingProperties,'UniformOutput',false)));
                  nTracksSelected = length(Tracks);  
                  IntersectionLine = gobjects(0,1);
                  S1 = gobjects(1);
                  S2 = gobjects(2);
                  
                  %for TimeIndex = 1:nTimePoints
                  for t = t2
                                    Tindex = t-t2(1)+1;
                                    if any(any(isgraphics(app.MovieObjectHandles)))
                                        delete(app.MovieObjectHandles(:)) 
                                    end
                                    app.MovieObjectHandles = gobjects(nTracks,3);
                                    if any(isgraphics(app.TimeTextHandle))
                                        delete(app.TimeTextHandle)
                                    end
                                    app.TimeTextHandle = gobjects(1,1); 
                                    FrameIdx = FrameIdx + 1;

                                    if ~isnan(AzimuthValues(Tindex)) 
                                        view(app.TrackAndMovieAxesHandle,AzimuthValues(Tindex),ElevationValues(Tindex))
                                    end

                                    if any(isgraphics(app.MovieIntersectionLine))
                                        delete(app.MovieIntersectionLine)
                                    end

                                    CellRendered = false;

                                    for track = 1:nTracks
                                            %--------------------------------------------------------------------
                                            if ~isempty( app.TimePointData.SurfaceRenderings{track,1}{t,1} )
                                                RenderingAvailable = true;
                                            else
                                                RenderingAvailable = false;
                                            end
                                            %--------------------------------------------------------------------
                                            if any(ismember(t,t2))
                                                RenderThisTimePoint = true;
                                            else
                                                RenderThisTimePoint = false;
                                            end
                                            %--------------------------------------------------------------------
                                            if RenderingAvailable && RenderThisTimePoint % START RenderingAvailable --------------------------------------------------------------------

                                                    if AnyColormapsChecked(app) && isequal(app.ColormapTypeDropDown.Value,2)
                                                        TrackVelocity = CalculateTrackVelocity(app,track,Xtracksraw,Ytracksraw,Ztracksraw);
                                                    else
                                                        TrackVelocity = [];
                                                    end
        
                                                    if app.TrackPlottingProperties{track,1}.ShowTrackOnOff 
                                                                    % Create patch object -----------------------------------------------------------------------------------------------
                                                                    if app.TrackPlottingProperties{track,1}.MovieCellSurfaceCheckBox  || app.TrackPlottingProperties{track,1}.MovieCellMeshCheckBox
                                                                            if app.ShowSmoothedRenderingCheck.Value
                                                                                RenderingData = app.TimePointData.SmoothedSurfaceRenderings{track,1}{t,1};
                                                                            else
                                                                                RenderingData = app.TimePointData.SurfaceRenderings{track,1}{t,1};
                                                                            end
                                                                        
                                                                            CellRendered = true;
                                                                            app.MovieObjectHandles(track,1) = patch(app.TrackAndMovieAxesHandle, reducepatch(RenderingData, 0.8)); 
                                                                            % Update Face color & transparency if turned on ---------------------------------------------------------------------
                                                                            if  app.TrackPlottingProperties{track,1}.MovieCellSurfaceCheckBox && ~app.TrackPlottingProperties{track,1}.MovieCellSurfaceUseColormapCheck
                                                                                app.MovieObjectHandles(track,1).FaceColor = app.TrackPlottingProperties{track,1}.MovieCellSurfaceColor; 
                                                                            elseif app.TrackPlottingProperties{track,1}.MovieCellSurfaceCheckBox  &&  app.TrackPlottingProperties{track,1}.MovieCellSurfaceUseColormapCheck
                                                                                switch app.ColormapTypeDropDown.Value
                                                                                    case 1;   Color = GetTimeOrVelocityColor(app, VelocityOrTimeColorMap, dt.*[tStart-1,tEnd-1], dt*(t-1)); 
                                                                                    case 2;   Color = GetTimeOrVelocityColor(app, VelocityOrTimeColorMap, app.VelocityColormapRange, TrackVelocity(t)); 
                                                                                end
                                                                                app.MovieObjectHandles(track,1).FaceColor = Color; 
                                                                            else
                                                                                app.MovieObjectHandles(track,1).FaceColor = 'none';                                                     
                                                                            end
                                                                            app.MovieObjectHandles(track,1).FaceAlpha = app.TrackPlottingProperties{track,1}.MovieCellSurfaceTransparencySpinner/100;  
                                                                            % Update Mesh color & transparency if turned on ---------------------------------------------------------------------
                                                                            if     app.TrackPlottingProperties{track,1}.MovieCellMeshCheckBox && ~app.TrackPlottingProperties{track,1}.AllCellMeshUseColormapCheck
                                                                                app.MovieObjectHandles(track,1).EdgeColor = app.TrackPlottingProperties{track,1}.MovieCellMeshColor; 
                                                                                app.MovieObjectHandles(track,1).AlignVertexCenters = 'on'; 
                                                                            elseif app.TrackPlottingProperties{track,1}.MovieCellMeshCheckBox &&  app.TrackPlottingProperties{track,1}.AllCellMeshUseColormapCheck
                                                                                switch app.ColormapTypeDropDown.Value
                                                                                    case 1;   Color = GetTimeOrVelocityColor(app, VelocityOrTimeColorMap, dt.*[tStart-1,tEnd-1], dt*(t-1)); 
                                                                                    case 2;   Color = GetTimeOrVelocityColor(app, VelocityOrTimeColorMap, app.VelocityColormapRange, TrackVelocity(t)); 
                                                                                end
                                                                                app.MovieObjectHandles(track,1).EdgeColor = Color;
                                                                            else
                                                                                app.MovieObjectHandles(track,1).EdgeColor = 'none';
                                                                            end
                                                                            app.MovieObjectHandles(track,1).EdgeAlpha = app.TrackPlottingProperties{track,1}.MovieCellMeshTransparencySpinner/100;
                                                                    else
                                                                            app.MovieObjectHandles(track,1) = gobjects(1,1);
                                                                    end
                                                                    % Create centroid if turned on --------------------------------------------------------------------------------------
                                                                    if app.TrackPlottingProperties{track,1}.MovieCentroidCheckBox
                                                                            CellRendered = true;
                                                                            Xc = Xtracksraw(t,track);
                                                                            Yc = Ytracksraw(t,track);
                                                                            Zc = Ztracksraw(t,track);
                                                                            [Xs,Ys,Zs] = sphere(6);
                                                                            CenRadius = 0.5;
                                                                            CentroidSurface = surf2patch(CenRadius*Xs + Xc, CenRadius*Ys + Yc, CenRadius*Zs + Zc);
                                                                            app.MovieObjectHandles(track,2) = patch(app.TrackAndMovieAxesHandle, CentroidSurface); 
                                                                            app.MovieObjectHandles(track,2).EdgeColor = 'none';
                                                                            if ~app.TrackPlottingProperties{track,1}.MovieCentroidUseColormapCheck
                                                                                app.MovieObjectHandles(track,2).FaceColor = app.TrackPlottingProperties{track,1}.MovieCentroidColor;
                                                                            elseif app.TrackPlottingProperties{track,1}.MovieCentroidUseColormapCheck
                                                                                switch app.ColormapTypeDropDown.Value
                                                                                    case 1;   Color = GetTimeOrVelocityColor(app, VelocityOrTimeColorMap, dt.*[tStart-1,tEnd-1], dt*(t-1)); 
                                                                                    case 2;   Color = GetTimeOrVelocityColor(app, VelocityOrTimeColorMap, app.VelocityColormapRange, TrackVelocity(t)); 
                                                                                end
                                                                                app.MovieObjectHandles(track,2).FaceColor = Color;
                                                                            end
                                                                    else
                                                                            app.MovieObjectHandles(track,2) = gobjects(1,1);
                                                                    end
                                                                    % Create Vector if turned on --------------------------------------------------------------------------------------
                                                                    if app.TrackPlottingProperties{track,1}.MovieVectorColorCheckBox
                                                                            CellRendered = true;
                                                                            r = app.VectorRadiusEditField.Value;
                                                                            L = app.VectorLengthEditField.Value;
                                                                            [Xvec,Yvec,Zvec] = cylinder(r,14);
                                                                            [rCyl,cCyl] = size(Xvec);
                                                                            Zvec = L*(Zvec-0.5); % Center default position at zero
                                                                            % Grab LongAxis vector
                                                                            LAvec = app.TimePointData.Measurements{track,1}.EllipsoidFitVectors(t,:,1);
                                                                            if isnan(LAvec(1,1))
                                                                                [~,~,ev,~,~] = ellipsoid_fit(app, app.TimePointData.SurfaceRenderings{track,1}{t}.vertices, '');       % Calculate best ellipsoid fit, and the three defining vectors: ev !!!!
                                                                                LAvec = ev(:,1);
                                                                                app.TimePointData.Measurements{track,1}.EllipsoidFitVectors(t,:,:) = ev;
                                                                            end
                                                                            % Calculate rotation matrix
                                                                            [RM, ~] = RotationMatrix3D(app, [0,0,1], LAvec );
                                                                            
                                                                            M = RM*[Xvec(:)';Yvec(:)';Zvec(:)']; % Rotate default vector to proper cell orientation
                                                                            Xvec = reshape(M(1,:),rCyl,cCyl);
                                                                            Yvec = reshape(M(2,:),rCyl,cCyl);
                                                                            Zvec = reshape(M(3,:),rCyl,cCyl);
                                
                                                                            Xvec = Xvec + app.TimePointData.Measurements{track,1}.Centroid(t,1);
                                                                            Yvec = Yvec + app.TimePointData.Measurements{track,1}.Centroid(t,2);
                                                                            Zvec = Zvec + app.TimePointData.Measurements{track,1}.Centroid(t,3);
                                                                            
                                                                            hold(app.TrackAndMovieAxesHandle,'on')  
                                                                            app.MovieObjectHandles(track,3) = surf(app.TrackAndMovieAxesHandle,Xvec,Yvec,Zvec);      
                                                                            app.MovieObjectHandles(track,3).EdgeColor = 'none';
        
                                                                            if ~app.TrackPlottingProperties{track,1}.MovieVectorUseColormapCheck
                                                                                app.MovieObjectHandles(track,3).FaceColor = app.TrackPlottingProperties{track,1}.MovieCentroidColor;
                                                                            elseif app.TrackPlottingProperties{track,1}.MovieVectorUseColormapCheck
                                                                                switch app.ColormapTypeDropDown.Value
                                                                                    case 1;   Color = GetTimeOrVelocityColor(app, VelocityOrTimeColorMap, dt.*[tStart-1,tEnd-1], dt*(t-1)); 
                                                                                    case 2;   Color = GetTimeOrVelocityColor(app, VelocityOrTimeColorMap, app.VelocityColormapRange, TrackVelocity(t)); 
                                                                                end
                                                                                app.MovieObjectHandles(track,3).FaceColor = Color;
                                                                            end
                                                                    else
                                                                            app.MovieObjectHandles(track,3) = gobjects(1,1);
                                                                    end

                                                     end    
                                         
                                            end  % END RenderingAvailable --------------------------------------------------------------------------------------------------
                                    end          % END Looping through Tracks


                                    % START add data plane if there are two tracks selected ------------------------------------------------------------------------------
                                    if nTracksSelected == 2 && app.OverlayDataPlaneCheck.Value
                                            
                                                CellRendered = true;
                                                source = 1;
    
                                                Xcen1 = ProcTracks(Tindex,1); % Xtracks(t,Tracks(1));
                                                Ycen1 = ProcTracks(Tindex,2); % Ytracks(t,Tracks(1));
                                                Zcen1 = ProcTracks(Tindex,3); % Ztracks(t,Tracks(1));
                                                Xcen2 = ProcTracks(Tindex,4); % Xtracks(t,Tracks(2));
                                                Ycen2 = ProcTracks(Tindex,5); % Ytracks(t,Tracks(2));
                                                Zcen2 = ProcTracks(Tindex,6); % Ztracks(t,Tracks(2));
    
                                                Xmid = mean([Xcen1; Xcen2]);
                                                Ymid = mean([Ycen1; Ycen2]);
                                                Zmid = mean([Zcen1; Zcen2]);
    
                                                if ~isnan(Xmid) 
                                                        DATA4 = double( ReadFullImageDataAtTimeT(app,t,source) );
                                                        V_raw = squeeze(DATA4(:,:,1,:));
        
                                                        % Create Meshgrid -------------------------------------------------
                                                        W = sqrt( (Xcen1-Xcen2)^2 + (Ycen1-Ycen2)^2 ) + 40;
                                                        H = 80;
                            
                                                        [xp,yp,~] = meshgrid( linspace( -W/2, W/2, ceil(W/app.dp) ),...  
                                                                              linspace( -H/2, H/2, ceil(H/app.dz) ),...
                                                                              0); 
            
                                                        % move mesh coords around to make plane perpendicular to X-Y plane
                                                        zp = yp; 
                                                        yp = zeros(size(zp));
                                                        [ni,nj] = size(xp); 
                            
                                                        % 2D Plane Rotation -----------------------------------------------------
                                                        theta = atan2d(Ycen2-Ycen1, Xcen2-Xcen1);
                            
                                                        R2 = [ [ cosd(theta) , -sind(theta) , 0] ; ... % 2D Rotation Matrix
                                                               [ sind(theta) ,  cosd(theta) , 0] ; ... 
                                                               [           0 ,            0 , 1] ];
                                        
                                                        M2 = R2*[ xp(:)' ; ...
                                                                  yp(:)' ; ...
                                                                  zp(:)' ]; 
                                            
                                                        xp = reshape(M2(1,:),ni,nj);
                                                        yp = reshape(M2(2,:),ni,nj);
                                                        zp = reshape(M2(3,:),ni,nj);  
                                            
                                                        % 3D Plane Translation --------------------------------------------------  
                                                        xp = xp + Xmid; 
                                                        yp = yp + Ymid; 
                                                        zp = zp + Zmid; 
                                                        
                                                        % Crop DataPlane in Z-direction -----------------------------------------    
                                                        idx = find(zp(:,1) < Zmax & zp(:,1) > Zmin);
                                                        xp = xp(idx(1):idx(end),:);
                                                        yp = yp(idx(1):idx(end),:);
                                                        zp = zp(idx(1):idx(end),:);
                                                        %------------------------------------------------------------------------
        
                                                        if isgraphics(S1)
                                                            delete(S1)
                                                            delete(S2)
                                                        end   
        
                                                        hold(app.TrackAndMovieAxesHandle,'on')
        
                                                        S1 = slice(app.TrackAndMovieAxesHandle,app.X,app.Y,app.Z,V_raw,xp,yp,zp);
                                                        S1.CData(isnan(S1.CData)) = 0;
                                                        if app.DataPlaneImageSaturation.Value
                                                            IMG = uint8(S1.CData);
                                                            p = 1;
                                                            IMG = double( imadjust(IMG,stretchlim(IMG,[p/100 (100-p)/100])) );
                                                            S1.CData = IMG/max(IMG(:));
                                                            %S1.CData = adapthisteq(S1.CData./max(S1.CData(:)));
                                                        else
                                                            S1.CData = S1.CData./max(S1.CData(:));
                                                        end
                                                        S1.FaceAlpha = 1;
                                                        S1.EdgeColor = 'none';
                                                        S1.AmbientStrength  = 0.9;
                                                        S1.DiffuseStrength  = 0;
                                                        S1.SpecularStrength = 0;  
                                                        clim([0,1])
        
                                                        switch app.MovieDataPlaneColormapDropDown.Value
                                                            case 1
                                                                ColorMap = gray;
                                                            case 2
                                                                ColorMap = parula;
                                                            case 3
                                                                ColorMap = thermal(app);
                                                            case 4
                                                                ColorMap = haline(app);
                                                            case 5
                                                                ColorMap = wolfram(app);
                                                            case 6
                                                                ColorMap = turbo;
                                                        end
                                                        colormap( ColorMap ) 
                            
                                                        % Add outline of plane
                                                        pX = [xp(1,1); xp(end,1); xp(end,end); xp(1,end); xp(1,1)];
                                                        pY = [yp(1,1); yp(end,1); yp(end,end); yp(1,end); yp(1,1)];
                                                        pZ = [zp(1,1); zp(end,1); zp(end,end); zp(1,end); zp(1,1)]; % Plane Outline Coords
                                                        S2 = plot3(app.TrackAndMovieAxesHandle,pX,pY,pZ,'-k','LineWidth',2);
                                                        
                                                        for TT = Tracks'
                                                               
                                                                if ~isempty( app.TimePointData.SurfaceRenderings{TT,1}{t,1} )
                                                                    if app.ShowSmoothedRenderingCheck.Value
                                                                        RenderingData = app.TimePointData.SmoothedSurfaceRenderings{TT,1}{t,1};
                                                                    else
                                                                        RenderingData = app.TimePointData.SurfaceRenderings{TT,1}{t,1};
                                                                    end
                                                                    p1 = [xp(1,1),   yp(1,1),   zp(1,1)];
                                                                    p2 = [xp(1,end), yp(1,end), zp(1,end)];
                                                                    p3 = [xp(end,1), yp(end,1), zp(end,1)];
                                                                    N = cross(p2-p1, p3-p1);
                                                                    n = N./norm(N);
                                                                    % lin = intersectPlaneSurf(app,app.TimePointData.SurfaceRenderings{TT,1}{t,1},p1,n);  % Be sure to include mex file: IntersectPlaneTriangle.mex
                                                                    % XYZ = lin{1}';
                                                                    XYZ = PlaneAndSurfaceIntersectionLine(app,RenderingData.faces,RenderingData.vertices,p1,n);
                                                                    PH = plot3(app.TrackAndMovieAxesHandle,XYZ(:,:,1),XYZ(:,:,2),XYZ(:,:,3),'-','LineWidth',2,'Color', [1,1,1]);
                                                                    app.MovieIntersectionLine = [app.MovieIntersectionLine; PH];
                                                                    %IntersectionLine(index,1) = plot3(app.TrackAndMovieAxesHandle,XYZ(:,1),XYZ(:,2),XYZ(:,3),':','LineWidth',2,'Color', [1,1,1]);
                                                                end
                                                        end
        
                                                        drawnow
        
                                                        if app.StopMovieButton.Value == 1
                                                            app.StopMovieButton.Value = 0;
                                                            break
                                                        end
                                                end
                                    end
                                    % END add data plane -------------------------------- ---------------------------------------------------------------------------------------


                                    % If any cells are rendered for the movie at this timepoint, check if stop button is pressed, update timepoint text, pause, then delete old graphics
                                    if any(isgraphics(app.MovieObjectHandles(:))) || CellRendered

                                            if isgraphics(app.TimeTextHandle)
                                                delete(app.TimeTextHandle)
                                            end
                                            app.TimeTextHandle = axes(app.TrackAndMovieFigureHandle,'Position',[0.02,0.96,0.01,0.01],'Visible','off','XLim',[0,1],'YLim',[0,1]);
                                            text(app.TimeTextHandle,0,0, ['timepoint = ',pad(num2str(t),4,'left')],'HorizontalAlignment','left','FontWeight','bold','FontSize',14,'Color',[0,0,0]);   
                                            drawnow
                                            
                                            % START: Write figure image to movie file -----------------------------------------------------------------------------
                                            F = getframe(app.TrackAndMovieFigureHandle);
                                            if DoMovie 
                                                    if strcmp(MovieFormat,'mp4') && exist('MH') %------------------------------------------------------------------
                                                            writeVideo(MH,F.cdata)
                                                            if t == t2(end) %nTimePoints
                                                                set(app.TrackAndMovieFigureHandle,'Resize',"on")
                                                                close(MH)
                                                                break
                                                            elseif app.StopMovieButton.Value == true
                                                                app.StopMovieButton.Value = false;
                                                                set(app.TrackAndMovieFigureHandle,'Resize',"off")
                                                                close(MH)
                                                                break
                                                            end
                                                    end

                                                    if strcmp(MovieFormat,'tif') %---------------------------------------------------------------------------------
                                                            IM = F.cdata;
                                                            ImFileName = fullfile( MovieFrameWriteDir, ['MovieFrame_t',sprintf('%06d',t),'.tif']  );
                                                            imwrite( IM, ImFileName,'tif','WriteMode','overwrite','Compression','none','RowsPerStrip',size(IM,1))
                                                            if t ==  t2(end) %nTimePoints
                                                                set(app.TrackAndMovieFigureHandle,'Resize',"on")
                                                                break
                                                            elseif app.StopMovieButton.Value == true
                                                                app.StopMovieButton.Value = false;
                                                                set(app.TrackAndMovieFigureHandle,'Resize',"off")
                                                                break
                                                            end
                                                    end
                                            end
                                            % END: Write figure image to movie file -------------------------------------------------------------------------------


                                            if app.StopMovieButton.Value == 1
                                                app.StopMovieButton.Value = 0;
                                                break
                                            end
                                            %delete(app.MovieObjectHandles(:))
                                            %delete(app.TimeTextHandle)
                                            %delete(app.IntersectionLine)
                                    end
                                    
                                    drawnow
                                    pause(0.05)
                  end % END looping though time points


        end
%----------------------------------------------------------------------------------------------------------------------------------------------------------    


%------------------------------------------------------------------------------------------------------------------------        
%------------------------------------------------------------------------------------------------------------------------    
%------------------------------------------------------------------------------------------------------------------------             
%------------------------------------------------------------------------------------------------------------------------
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)


                app.RGBZTDirectoryDisplay.Value = '';
                
                app.CellTracksListBox1.Items = cell(0,1); % [{'items'};{'item2'};{'item3'};{'item4'}];
                app.CellTracksListBox1.ItemsData = []; % (1:4)';   
                
                app.CurrentTrackDropDown.Items = {'none'};
                app.CurrentTrackDropDown.ItemsData = 1;
                app.CurrentTrackDropDown.Value = 1;
                
                app.CellTrackFilesInfo.names = cell(0,1);
                app.CellTrackFilesInfo.paths = cell(0,1); 
                app.CellTrackFilesInfo.files = cell(0,1);
                
                app.ChangeImageDirectoryButton.Enable = "off";
                app.SaveAsLocusFileButton.Enable = 'off';
                app.SaveLocusFileButton.Enable = 'off';
                app.SaveLocusFileButton2.Enable = 'off';
                app.LastSavedDisplay.Text = '';

                    % Start with some default values
                    app.PixelSizeField.AllowEmpty = true;
                    app.PixelSizeField.Editable  = false;
                    app.PixelSizeField.Value     = []; %0.59;
                    
                    app.ZStepField.AllowEmpty    = true;
                    app.ZStepField.Editable      = false;
                    app.ZStepField.Value         = []; %1.500;                    
                    
                    app.TimeStepField.AllowEmpty = true;
                    app.TimeStepField.Editable   = false;                    
                    app.TimeStepField.Value      = []; %150;                      

                    app.RowsSubRegionField.Value = 95;
                    app.ColsSubRegionField.Value = 95; 
                    app.ZSubRegionField.Value    = 35;
                    
                    app.LongtrackercelltrackfilesPanel.Visible = "on";
                    app.AddTrackButton.Enable = "off";
                    app.RemoveTrackButton.Enable = "off";
                    app.DataPlaneSizeInput.Value = app.DataPlaneSize;

                    % Preload colormap options for all colormap dropdowns
                    ColorMapNames1 = {'Parula','Thermal','Haline','Wolfram','Turbo'};
                    ColorMapNames2 = {'Gray','Parula','Thermal','Haline','Wolfram','Turbo'};
                    set(app.DataPlaneColormapDropDown,     'Items',ColorMapNames1,'ItemsData',1:length(ColorMapNames1),'Value',2)
                    set(app.MovieDataPlaneColormapDropDown,'Items',ColorMapNames2,'ItemsData',1:length(ColorMapNames2),'Value',1)

                    % Preload colormap types
                    ColorMapTypes = {'time','velocity'};
                    ColorMapNames3 = {'Turbo','Parula','Thermal','Wolfram','Haline'};
                    set(app.ColormapTypeDropDown,     'Items',ColorMapTypes, 'ItemsData',1:length(ColorMapTypes), 'Value',1)
                    set(app.MultiCellColormapDropDown,'Items',ColorMapNames3,'ItemsData',1:length(ColorMapNames3),'Value',2)

                    LineWidths = compose('%1.1f',(1:1:15)');
                    set(app.LineWidthDropDown,'Items',LineWidths,'ItemsData',1:size(LineWidths,1),'Value',1)
                %-------------------------------------------------------------------------------------------
                                   
                    EnableAndDisableRenderControls(app,'disable')
                    EnableAndDisablePlaneControls(app,'disable')

                    app.TimePointSpinner.Enable = false;
                    app.CurrentTrackDropDown.Enable = false;

                    % Disable Rendering Panel ----------------------------
                    app.CellVisibilityButton.Enable  = false;
                    app.CellVisibilityButton.Text = 'Cell Visibility - ON';
                    app.CellVisibilityButton.Value = 1;
                    app.ShowTrackPointButton.Enable  = false;
             
                    app.UseSmoothedForMeasurementsCheckBox.Value = false;
                    app.DataPlaneToggleButton.Text = 'Show Data Plane';
                    app.DataPlaneToggleButton.Enable = false;
                    
                    app.DefaultViewR1.Value = true;
                    app.DefaultViewR1.Enable = false;
                    app.DefaultViewR2.Value = false;
                    app.DefaultViewR2.Enable = false;
                    app.DefaultViewR3.Value = false;
                    app.DefaultViewR3.Enable = false;
                     
                    %------------------------------------------------------
                 
                    app.OPENRenderingFigureButton.Text = {'OPEN Render Figure'};
                             
                    app.CurrentTrackPoint.x = 0;
                    app.CurrentTrackPoint.y = 0;
                    app.CurrentTrackPoint.z = 0;
                   
                %-------------------------------------------------------------------------------------------     
                    app.DataPlaneHandle.main = -1;
                    app.DataPlaneHandle.outline = -1; 
                   
                    app.RenderingSmoothingOnCheckBox.Value = 1;
                    app.GaussianFiltSwitch.Value = 0;
                    app.SigmaSpinner.Value = 1.50;
                    app.GaussFiltLamp.Color = [0.8,0.8,0.8];
                    app.FrangiFilterSwitch.Value = 0;
                    app.FrangiLamp.Color = [0.8,0.8,0.8];
                    app.AdaptHistEqSwitch.Value = 0;
                    app.AdaptHistEqLamp.Color = [0.8,0.8,0.8];
                
                    app.ShowTrackPointButton.Value = true;
                    app.ShowTrackPointButton.Text = 'Hide Track Point';
                    
                    app.StepSizeSpinner.Value = 1;
                    app.StepSizeSpinner.Limits = [0.1,20];
                    app.TimePointSpinner.Value = 1;
                    app.TimePointSpinner.BusyAction = 'cancel';
                    app.MaxDistSpinner.Value = 20;
                   
                    app.XSlider.Value = 0;
                    app.XSliderSpinner.Value = 0;
                    app.XSliderSpinner.Limits = [-20,20];
                    app.XSliderSpinner.Step = app.StepSizeSpinner.Value;
                    app.YSlider.Value = 0;
                    app.YSliderSpinner.Value = 0;
                    app.YSliderSpinner.Limits = [-20,20];
                    app.YSliderSpinner.Step = app.StepSizeSpinner.Value;
                    app.ZSlider.Value = 0;
                    app.ZSliderSpinner.Value = 0;
                    app.ZSliderSpinner.Limits = [-20,20];
                    app.ZSliderSpinner.Step = app.StepSizeSpinner.Value;
                    
                    %app.DataSourceDropDown.Items = [{'Raw'},{'Deconvolved'}];
                    %app.DataSourceDropDown.ItemsData = [1,2];
                    %app.DataSourceDropDown.Value = 1;
                    
                    app.DataPlaneChannel.Items = [{'Membrane Ch'},{'Nuclear Ch'},{'User defined'}];
                    app.DataPlaneChannel.ItemsData = [1,2,3];
                    app.DataPlaneChannel.Value = 1;
                    
                    app.FaceColorDisplay.BackgroundColor = app.FaceColor;
                    app.FaceTransparencySpinner.Value = 100*app.FaceTransparency;
                    app.MeshColorDisplay.BackgroundColor = app.MeshColor;
                    app.MeshTransparencySpinner.Value = 100*app.MeshTransparency;
                    
                    app.EllipsoidTranslationDisplay.Value = '0';
                    app.EllipsoidFitAlertsDisplay.Text = '';
                    
                    app.DefaultRenderingValues.Pad = 22;
                    app.DefaultRenderingValues.ContractionBias = -0.1;
                    app.DefaultRenderingValues.ExtendedMin = 0.1;
                    %app.DefaultRenderingValues.DataSource = 1;
                    
                   % app.TimePointListBox.FontName = 'Courier';
                    
                    app.OPENRenderingFigureButton.Enable = 'off';
                    app.CurrentTrackDropDown.Enable = 'off';
                    
                    % Multi-Cell Analaysis Panel -------------------------------------------------
                        EnablePlottingProperties(app,0)  
                        EnableTrackFigureProperties(app,0)
                        EnableClosestApproachProperties(app,0)   

                        app.UnselectAllTrackButton.Enable = 0;
                        app.UpTrackButton.Enable = 'off';
                        app.DownTrackButton.Enable = 'off';
                        app.SelectTrackDropDown.Items = "empty";
                        app.SelectTrackDropDown.ItemsData = 1;
                        app.SelectTrackDropDown.Value = 1;
                        app.SelectTrackDropDown.Enable = 0;  
                        app.VectorLengthEditField.Value = 15;
                        app.VectorRadiusEditField.Value = 0.3;
                        % Set everything as disabled
                        app.ShowTrackCheckBox.Enable = 0;

                        app.XminXmaxEditField.Value = '0,200';
                        app.YminYmaxEditField.Value = '0,200';
                        app.ZminZmaxEditField.Value = '0,200';
                        
                        app.SetStartAngleDisplay.Value = [num2str(  0,'%1.0f'),' , ',num2str(6,'%1.0f')];
                        app.SetEndAngleDisplay.Value   = [num2str(180,'%1.0f'),' , ',num2str(6,'%1.0f')];

                        app.AutoAxisLimitsCheckBox.Value = 1;
                        app.UnselectAllTrackButton.Enable = 0;
                        app.TrackSmoothingNPointsDropDown.ItemsData = 3:2:21;
                        app.FramespersecondEditField.Value = app.FramesPerSecond;


        end

        % Close request function: LocusUIFigure
        function LocusUIFigureCloseRequest(app, event)

%             selection = uiconfirm(app.LocusUIFigure,'Are you sure you sure you want to quit Locus?', 'Quit Locus?',...
%                                                     'Options', {'Yes','No'}, 'DefaultOption', 2, 'CancelOption', 2, 'Icon','warning');
            
            selection = questdlg('Are you sure you sure you want to quit Locus?','Quit Locus?', 'Yes','No','No');  

            if strcmp(selection,'Yes')
                if isgraphics(app.TrackAndMovieFigureHandle)
                    delete(app.TrackAndMovieFigureHandle)
                end

                if isgraphics(app.SingleCellFigureHandle)
                    delete(app.SingleCellFigureHandle)
                end

                if isgraphics(app.TrackEditorHandle)
                    delete(app.TrackEditorHandle)
                end

                if isgraphics(app.RenderingSummaryHandle)
                    delete(app.RenderingSummaryHandle)
                end
                delete(app)
            end
            

        end

        % Value changed function: CellTracksListBox1
        function CellTracksListBox1ValueChanged(app, event)
        
            %--------------------------------------------------------------------------------    
            
        end

        % Button pushed function: SelectFolderButton
        function SelectFolderButtonPushed(app, event)
          
                dname = uigetdir([],'Select Image Data folder');
                
                if ~isequal(dname,0)

                        F = struct2cell( dir(dname) )';
                        IsFile = ~cell2mat(F(:,5)); 
                        files = F(IsFile,1); % Grab all the files in the selected directory
                            
                        [Selection, ok] = listdlg('ListString',files,'ListSize',[250 400],'PromptString','Select image files','Name','Select image files');

                        if isequal(ok,1)
                                %-----------------------------------------------------------------
                                prompt = {'\fontsize{12}Enter pixel size (\mum):',...
                                          '\fontsize{12}Enter z-step size (\mum):',...
                                          '\fontsize{12}Enter time step (seconds):'};

                                dlgtitle = 'Input image data stats';
                                fieldsize = [ [1 65]; [1 65]; [1 65] ];
                                opts.Interpreter = 'tex';
                                definput = {'0.59','1.5','150'};
                                answer = inputdlg(prompt,dlgtitle,fieldsize,definput,opts);
                                %-----------------------------------------------------------------

                                if ~isempty(answer)

                                        app.RGBZTDirectoryDisplay.Value = dname; % RGBZ timeseries location
                                        app.RGBZT_Directory = dname;

                                        files = files(Selection);
                                        app.nTime = size(files,1);
                                        files = MakeSureImageFilesAreSortedByTimestamp(app,files);
                                        app.RGBZT_ImageFileNames = files;
                
                                        % Set default value for movie timepoint range ---------------------------
                                        app.StartingTimePoint.Value  = 1;
                                        app.EndingTimePoint.Value    = app.nTime;
                                        app.TimePointIncrement.Value = 1;
                                        %------------------------------------------------------------------------
                                        app.SaveAsLocusFileButton.Enable = 'on';
                                        
                                        info = imfinfo( fullfile(app.RGBZT_Directory, files{1,1}) );
                                        app.zDepth  = size(info,1);        % Z-depth
                                        app.cWidth  = info(1,1).Width;     % Full width of image
                                        app.rHeight = info(1,1).Height;    % Full height of image                                
            
                                        app.PixelSizeField.Value = str2double(answer{1});
                                        app.ZStepField.Value     = str2double(answer{2});
                                        app.TimeStepField.Value  = str2double(answer{3});
                                        app.dp = str2double(answer{1}); % Pixel size  (microns)
                                        app.dz = str2double(answer{2}); % Z-step size (microns)
                                        app.dt = str2double(answer{3}); % timestep (seconds)
                                        [app.X, app.Y, app.Z] = meshgrid( app.dp.*( 0:(app.cWidth -1) ),...
                                                                          app.dp.*( 0:(app.rHeight-1) ),...
                                                                          app.dz.*( 0:(app.zDepth -1) ) ); 

                                        % app.LongtrackercelltrackfilesPanel.Visible = "on";
                                        app.ChangeImageDirectoryButton.Enable = "on";
                                        app.AddTrackButton.Enable = "on";
                                        app.RemoveTrackButton.Enable = "on";
                                end
                                

                        end
                        % ---------------------------------------------------------------------------
                end
                
            
        end

        % Button pushed function: RemoveTrackButton
        function RemoveTrackButtonPushed(app, event)
            

                answer = questdlg('Are you sure you want to delete this track and its renderings?','Verify delete track','Yes','No','No');
                if strcmp(answer,'Yes')
                        v = app.CellTracksListBox1.Value;
                        nTracks = length(app.CellTracksListBox1.ItemsData);
                                    
                        if ~isempty(v) %-----------------------------------------------------------
                                app.CellTracksListBox1.Items(v) = []; 
                                app.CellTracksListBox1.ItemsData = (1:length(app.CellTracksListBox1.Items))';
                                app.CellTrackFilesInfo.names(v) = [];
                                app.CellTrackFilesInfo.files(v) = [];
                                app.CellTrackFilesInfo.paths(v) = [];
                                app.CellTrackData(v)   = [];
                                app.CellTrackOffset(v) = [];

                                app.TimePointData.SurfaceRenderings(v,:)                  = [];  
                                app.TimePointData.SolidRenderings(v,:)                    = [];  
                                app.TimePointData.LinearIndexMaskInUnCroppedVolume(v,:)   = [];  
                                app.TimePointData.SmoothedSurfaceRenderings(v,:)          = [];  
                                app.TimePointData.SmoothedSolidRenderings(v,:)            = [];  
                                app.TimePointData.RenderingSettings(v,:)                  = [];
                                app.TimePointData.Measurements(v,:)                       = [];
 
                                app.TrackPlottingProperties(v,:)                          = [];
    
                                app.CurrentTrackDropDown.Items = app.CellTrackFilesInfo.names;
                                app.CurrentTrackDropDown.ItemsData = (1:length(app.CellTrackFilesInfo.names))';
                                app.CurrentTrackDropDown.Value = 1;
                                
                                app.SelectTrackDropDown.Items =  app.CellTrackFilesInfo.names;
                                app.SelectTrackDropDown.ItemsData =  (1:length(app.CellTrackFilesInfo.names))';
                                app.SelectTrackDropDown.Value = 1;
                                
                                app.SelectTrackADropDown.Items = app.CellTrackFilesInfo.names;
                                app.SelectTrackADropDown.ItemsData = (1:length(app.CellTrackFilesInfo.names))';
                                app.SelectTrackADropDown.Value = 1;
                                
                                app.SelectTrackBDropDown.Items = app.CellTrackFilesInfo.names;
                                app.SelectTrackBDropDown.ItemsData = (1:length(app.CellTrackFilesInfo.names))';
                                app.SelectTrackBDropDown.Value = 1;


                                %----------------------------------------------------------------------
                                if v == nTracks && ~isequal(nTracks-1,0)
                                    app.CellTracksListBox1.Value = nTracks - 1;
                                end
                                %----------------------------------------------------------------------
        
                                if nTracks > 1
                                    EnableClosestApproachProperties(app,0) 
                                     app.ShowTrackCheckBox.Enable      = 0;
                                     app.UnselectAllTrackButton.Enable = 0;
                                end
        
                                SetDefaultTrackFigureProperties(app)
                                app.TrackPlottingProperties = cell(0,1);
                                for track = 1:(nTracks-1)
                                    SetDefaultPlottingProperties(app,track)
                                end
                        end
                end

                
        end

        % Value changed function: PixelSizeField
        function PixelSizeFieldValueChanged(app, event)
         
         
            app.dp = app.PixelSizeField.Value;
            
        end

        % Button pushed function: AddTrackButton
        function AddTrackButtonPushed(app, event)
           
                % Get longtracker xyz cell track file -------------------------------------------------------------------
                if isempty(app.CellTrackFilesInfo.paths)   
                    [file,path] = uigetfile('*.txt','Select Longtracker Cell Track');
                else
                    [file,path] = uigetfile([app.CellTrackFilesInfo.paths{end},filesep,'*.txt'],'Select Longtracker Cell Track');
                end
                %--------------------------------------------------------------------------------------------------------
                
                if ~isequal(file,0)
                    [nTracksReadIn,TrackDataReadIn,TrackNamesReadIn] = ReadInLongtrackerTrackFile(app,path,file);
                    for NewTrack = 1:nTracksReadIn
                            % Add track info to app.CellTrackData 
                            nTracks = length(app.CellTrackData);
                            tracknum = nTracks + 1;
                            
                            app.CellTrackData{tracknum,1} = TrackDataReadIn{NewTrack,1};
                            % Add cell to app.TrackPlottingProperties{TrackNum,1} ------
                            SetDefaultPlottingProperties(app,tracknum)
                            if isequal(1,tracknum)
                                app.ShowTrackCheckBox.Enable      = 1;
                                app.UnselectAllTrackButton.Enable = 1;
                                EnablePlottingProperties(app,1)  
                                EnableTrackFigureProperties(app,1)
                                EnablePlottingProperties(app, app.TrackPlottingProperties{1,1}.ShowTrackOnOff)
                            end
        
                            % PROCESS_DATA_FOR_ADDED_TRACK(app,tracknum,TrackNamesReadIn{NewTrack},nTracksReadIn,NewTrack);
                            
                            % Create Default Space for TimePointData ==============================================================
                            % app.TimePointData.Properties{tracknum,1} = [ (0:(nTime-1))'.*app.dt/60 , zeros(nTime,5)];
                            app.TimePointData.SurfaceRenderings{tracknum,1} = cell(app.nTime,1);  
                            app.TimePointData.SmoothedSurfaceRenderings{tracknum,1} = cell(app.nTime,1);  
                            app.TimePointData.SolidRenderings  {tracknum,1} = cell(app.nTime,1);  
                            app.TimePointData.SmoothedSolidRenderings  {tracknum,1} = cell(app.nTime,1); 
                            app.TimePointData.LinearIndexMaskInUnCroppedVolume{tracknum,1} = cell(app.nTime,1);  
                            app.TimePointData.RenderingSettings{tracknum,1}.Pad = nan(app.nTime,1);
                            %app.TimePointData.RenderingSettings{tracknum,1}.ContractBias = nan(app.nTime,1);
                            app.TimePointData.RenderingSettings{tracknum,1}.ExtendedMin  = nan(app.nTime,1);
                            app.TimePointData.RenderingSettings{tracknum,1}.SmoothSegmentationSigma  = nan(app.nTime,1);
                            %app.TimePointData.RenderingSettings{tracknum,1}.nIterations  = nan(app.nTime,1);
                            %app.TimePointData.RenderingSettings{tracknum,1}.DataSource   = nan(app.nTime,1);
                            app.TimePointData.RenderingSettings{tracknum,1}.ConnectionLines = cell(app.nTime,1);
                            app.TimePointData.RenderingSettings{tracknum,1}.SeparationLines = cell(app.nTime,1);
                          
                            app.TimePointData.Measurements{tracknum,1}.Volume    = nan(app.nTime,1);
                            app.TimePointData.Measurements{tracknum,1}.MBSD      = nan(app.nTime,1);
                            app.TimePointData.Measurements{tracknum,1}.MBCD      = nan(app.nTime,1);
                            app.TimePointData.Measurements{tracknum,1}.Centroid  = nan(app.nTime,3);
                            app.TimePointData.Measurements{tracknum,1}.EllipsoidFitVectors = nan(app.nTime,3,3);
                            
                            % Initialize Data Plane Coordinates ====================================================================
                            W = 40;  % Width  of plane in microns
                            L = 40;  % Height of plane in microns
                
                            % Create Cross-section plane, width w and height h, centered at [0,0] 
                            [xp,yp,zp] = meshgrid( linspace( -W/2, W/2, ceil(W/app.dp) ),...  
                                                   linspace( -L/2, L/2, ceil(L/app.dp) ), 0);  
                                                 
                            app.DataPlaneStartArray.xp = xp;
                            app.DataPlaneStartArray.yp = yp;
                            app.DataPlaneStartArray.zp = zp;
                            % =======================================================================================================
                            
                            app.CellTrackFilesInfo.names{tracknum,1} = TrackNamesReadIn{NewTrack};
                            app.CellTrackFilesInfo.files{tracknum,1} = file;
                            app.CellTrackFilesInfo.paths{tracknum,1} = path;
                            app.TrackPlottingProperties  =              [app.TrackPlottingProperties; cell(1,1)];
                            app.CellTracksListBox1.Items =               app.CellTrackFilesInfo.names;
                            app.CellTracksListBox1.ItemsData = (1:length(app.CellTracksListBox1.Items))';
                            app.CellTracksListBox1.Value =        length(app.CellTracksListBox1.Items);
        
                            app.CellTrackOffset = [ app.CellTrackOffset; { zeros( size(TrackDataReadIn{NewTrack,1},1), 3) } ];
                            
                            app.CurrentTrackDropDown.Value = 1;
                            app.CurrentTrackDropDown.Items = app.CellTrackFilesInfo.names;
                            app.CurrentTrackDropDown.ItemsData = (1:length(app.CellTrackFilesInfo.names))';
                            
                            app.IND = [app.IND; cell(1,1)];
                            app.IND{tracknum} = nan(2,3,app.nTime);
                            
                            % If track plotting properties are empty then insert dedault values
                            if nTracks > 1
                                EnableClosestApproachProperties(app,1) 
                            end
                            
                            app.OPENRenderingFigureButton.Enable = 'on';

                            app.SelectTrackDropDown.Enable         = true;
                            app.UpTrackButton.Enable               = true;
                            app.DownTrackButton.Enable             = true;
                            app.ShowTrackCheckBox.Enable           = true;

                            % UPDATE ALL TRACK DROPDOWNS --------------------------------------------
                                app.CellTracksListBox1.Items     = app.CellTrackFilesInfo.names;
                                app.CellTracksListBox1.ItemsData = (1:length(app.CellTrackFilesInfo.names))';
                                app.CellTracksListBox1.Value = 1;
                                
                                app.CurrentTrackDropDown.Items = app.CellTrackFilesInfo.names;
                                app.CurrentTrackDropDown.ItemsData = (1:length(app.CellTrackFilesInfo.names))';
                                app.CurrentTrackDropDown.Value = 1;
                                
                                app.SelectTrackDropDown.Items =  app.CellTrackFilesInfo.names;
                                app.SelectTrackDropDown.ItemsData =  (1:length(app.CellTrackFilesInfo.names))';
                                app.SelectTrackDropDown.Value = 1;
                                
                                app.SelectTrackADropDown.Items = app.CellTrackFilesInfo.names;
                                app.SelectTrackADropDown.ItemsData = (1:length(app.CellTrackFilesInfo.names))';
                                app.SelectTrackADropDown.Value = 1;
                                
                                app.SelectTrackBDropDown.Items = app.CellTrackFilesInfo.names;
                                app.SelectTrackBDropDown.ItemsData = (1:length(app.CellTrackFilesInfo.names))';
                                
                                if length(app.CellTrackFilesInfo.names) == 1
                                    app.SelectTrackBDropDown.Value = 1;
                                else
                                    app.SelectTrackBDropDown.Value = 2;
                                end
                            %------------------------------------------------------------------------

                    end
                    
                    SetDefaultTrackFigureProperties(app)
                    for track = 1:nTracks
                        SetDefaultPlottingProperties(app,track)
                    end
                     
                         
                end


        end

        % Value changed function: ZStepField
        function ZStepFieldValueChanged(app, event)
            
            app.dz = app.ZStepField.Value;
            
        end

        % Value changed function: TimeStepField
        function TimeStepFieldValueChanged(app, event)
            
            app.dt = app.TimeStepField.Value;
            
        end

        % Value changed function: RowsSubRegionField
        function RowsSubRegionFieldValueChanged(app, event)
            
%             app.RowsSubRegionField.Value;
            
            if isgraphics(app.SingleCellFigureHandle)
                TimePointSpinnerValueChanged(app, event)            
            end

        end

        % Value changed function: ColsSubRegionField
        function ColsSubRegionFieldValueChanged(app, event)
            
%             app.ColsSubRegionField.Value; 

            if isgraphics(app.SingleCellFigureHandle)
                TimePointSpinnerValueChanged(app, event)     
            end
            
        end

        % Value changed function: ZSubRegionField
        function ZSubRegionFieldValueChanged(app, event)
            
%             app.ZSubRegionField.Value;

            if isgraphics(app.SingleCellFigureHandle)
                TimePointSpinnerValueChanged(app, event)    
            end
            
            
        end

        % Button pushed function: OPENRenderingFigureButton
        function OPENRenderingFigureButtonPushed(app, event)
            
            
                % Define Button Down/Release Functions for Figure =======================================   
                % These are sub-functions or "OPENRenderingFigureButtonPushed(app, event)"
                        
                        function MainWindowKeyPressFcn(~,event,app)
                                key = lower(event.Key);
                                if ~isequal(app.SingleCellFigureHandle,-1) && isequal('control',key)
                                    set(app.SingleCellFigureHandle,'WindowButtonMotionFcn',{@RotateDataPlaneFcn,app}) 
                                elseif ~isequal(app.SingleCellFigureHandle,-1) && isequal('shift',key)   
                                    set(app.SingleCellFigureHandle,'WindowButtonMotionFcn',{@RotateAxesFcn,app}) 
                                elseif ~isequal(app.SingleCellFigureHandle,-1) && isequal('1',key) && isequal(app.DataPlaneToggleButton.Value, 1) 
                                    Er1ViewButtonPushed(app, event)
                                elseif ~isequal(app.SingleCellFigureHandle,-1) && isequal('2',key) && isequal(app.DataPlaneToggleButton.Value, 1)   
                                    Er2ViewButtonPushed(app, event)   
                                elseif ~isequal(app.SingleCellFigureHandle,-1) && isequal('3',key) && isequal(app.DataPlaneToggleButton.Value, 1)   
                                    Er3ViewButtonPushed(app, event)   
                                elseif ~isequal(app.SingleCellFigureHandle,-1) && isequal('u',key) || isequal('q',key) 
                                    UpdateRenderingButtonPushed(app, event)
                                elseif ~isequal(app.SingleCellFigureHandle,-1) && isequal('v',key)   
                                    app.CellVisibilityButton.Value = ~app.CellVisibilityButton.Value;
                                    CellVisibilityButtonValueChanged(app, event)
                                elseif ~isequal(app.SingleCellFigureHandle,-1) && isequal('w',key)   
                                    app.IntersectionLineVisibilityButton.Value = ~app.IntersectionLineVisibilityButton.Value;
                                    IntersectionLineVisibilityButtonValueChanged(app, event)
                                elseif ~isequal(app.SingleCellFigureHandle,-1) && isequal('e',key)   
                                    if app.DataPlaneChannel.Value == 3; app.DataPlaneChannel.Value = 1;
                                    else; app.DataPlaneChannel.Value = app.DataPlaneChannel.Value + 1;
                                    end
                                    DataPlaneChannelValueChanged2(app, event) 
                                elseif ~isequal(app.SingleCellFigureHandle,-1) && isequal('c',key)  
                                    ConnectionLineSTARTButtonValueChanged(app, event)
                                elseif ~isequal(app.SingleCellFigureHandle,-1) && (isequal('r',key) || isequal('leftarrow',key))
                                    if app.TimePointSpinner.Value > 1
                                        app.TimePointSpinner.Value = app.TimePointSpinner.Value - 1;
                                        TimePointSpinnerValueChanged(app, event)
                                    end
                                elseif ~isequal(app.SingleCellFigureHandle,-1) && (isequal('t',key) || isequal('rightarrow',key))
                                    if app.TimePointSpinner.Value < app.nTime
                                        app.TimePointSpinner.Value = app.TimePointSpinner.Value + 1;
                                        TimePointSpinnerValueChanged(app, event)
                                    end
                                elseif ~isequal(app.SingleCellFigureHandle,-1) && isequal('g',key)
                                    if app.GaussianFiltSwitch.Value == false
                                        app.GaussianFiltSwitch.Value = true;
                                    elseif app.GaussianFiltSwitch.Value == true
                                        app.GaussianFiltSwitch.Value = false;
                                    end
                                    GaussianFiltSwitchValueChanged(app, event) 
                                elseif ~isequal(app.SingleCellFigureHandle,-1) && isequal('f',key)     
                                    if app.FrangiFilterSwitch.Value == false
                                        app.FrangiFilterSwitch.Value = true;
                                    elseif app.FrangiFilterSwitch.Value == true
                                        app.FrangiFilterSwitch.Value = false;
                                    end
                                    FrangiFilterSwitchValueChanged(app, event) 
                                elseif ~isequal(app.SingleCellFigureHandle,-1) && isequal('uparrow',key)   
                                    app.EllipsoidTranslationSlider.Value = app.EllipsoidTranslationSlider.Value + 1;
                                    EllipsoidTranslationSliderValueChanging(app, event)

                                elseif ~isequal(app.SingleCellFigureHandle,-1) && isequal('downarrow',key)   
                                    app.EllipsoidTranslationSlider.Value = app.EllipsoidTranslationSlider.Value - 1;
                                    EllipsoidTranslationSliderValueChanging(app, event)

                                elseif ~isequal(app.SingleCellFigureHandle,-1) && isequal('s',key) 
                                    SeparationLineSTARTButtonValueChanged(app, event)  
                                end 
                        end
                        
                        % Define Figure Release Press Function
                        function FigureKeyReleaseFcn(~,event,app)
                            key = event.Key;
                            if ~isequal(app.SingleCellFigureHandle,-1) && ( isequal('control',key) || isequal('shift',key) )
                                set(app.SingleCellFigureHandle,'WindowButtonMotionFcn',[]) 
                                app.PreviousPoint = [NaN,NaN];
                                tb = axtoolbar(app.SingleCellAxisHandle,{'export','datacursor','rotate','pan','zoomin','zoomout'});
                            end
                        end
                 
                        
                        % Define Figure LocusMainWindowKeyRelease Function
                        function LocusMainWindowKeyRelease(~,event,app)                            
                            set(0,'CurrentFigure',app.SingleCellFigureHandle)
                            set(app.SingleCellFigureHandle,'WindowButtonMotionFcn',[]) 
                            app.PreviousPoint = [NaN,NaN];
                            tb = axtoolbar(app.SingleCellAxisHandle);
                            shg % Show current figure
                        end
                        
                        % Define WindowButtonMotionFcn for Plane Rotation ------------------------------------------------------
                        function RotateDataPlaneFcn(~,eventData,app)
                            
                                if ~isempty(app.DataPlaneHandle)
                                    if isgraphics(app.DataPlaneHandle.main)
                                            ScreenSize = get(0,'ScreenSize');
                                            PointerLocation = get(0,'PointerLocation');
                                            CurrentPoint =  PointerLocation./ScreenSize(3:4);
                                            delX = CurrentPoint(1,1) - app.PreviousPoint(1,1);
                                            delY = CurrentPoint(1,2) - app.PreviousPoint(1,2);
                                            delX(isnan(delX)) = 0;
                                            delY(isnan(delY)) = 0;
                                            app.PreviousPoint = CurrentPoint;
                                            ScaleFactor = 400;
                                            % Translate delX and delY in Pan and Tilt values and adjust knobs their knobs accordingly -------
                                                CurrentTilt = app.TILTslider.Value;
                                                CurrentPan  = app.PANslider.Value;
                                                
                                                if CurrentPan > 0
                                                    CurrentTilt = CurrentTilt + ScaleFactor*delY;
                                                else            
                                                    CurrentTilt = CurrentTilt - ScaleFactor*delY;
                                                end
                                                
                                                CurrentPan  = CurrentPan + 2*ScaleFactor*delX;
                                            
                                                CurrentTilt(CurrentTilt <  -90) = CurrentTilt + 180;
                                                CurrentTilt(CurrentTilt >   90) = CurrentTilt - 180;
                                                CurrentPan(CurrentPan   < -180) = CurrentPan  + 360;
                                                CurrentPan(CurrentPan   >  180) = CurrentPan  - 360;
                                                
                                                app.TILTslider.Value = CurrentTilt;
                                                app.PANslider.Value  = CurrentPan;
                                            %-------------------------------------------------------------------------------------------------
                                            PanTiltTranslatePlane(app);
                                            UpdateDataPlane(app);
                                    end
                                end
                        end
                        
                        % Define WindowButtonMotionFcn for Axis Rotation ------------------------------------------------------
                        function RotateAxesFcn(~,eventData,app)
                                
                                ScreenSize = get(0,'ScreenSize');
                                PointerLocation = get(0,'PointerLocation');
                                CurrentPoint =  PointerLocation./ScreenSize(3:4);
                            
                                delX = CurrentPoint(1,1) - app.PreviousPoint(1,1);
                                delY = CurrentPoint(1,2) - app.PreviousPoint(1,2);
                                delX(isnan(delX)) = 0;
                                delY(isnan(delY)) = 0;
                                
                                app.PreviousPoint = CurrentPoint;
                                [caz,cel] = view(app.SingleCellAxisHandle);
                                ScaleFactor = 400;
                                
                                if ~isnan(app.PreviousPoint(1))
                                    view( app.SingleCellAxisHandle, caz - ScaleFactor*delX, cel - ScaleFactor*delY)
                                end
                                drawnow
                                
                        end
                
                %========================================================================================
            
            
            if ~isgraphics(app.SingleCellFigureHandle)
                
                 % Create figure in middle of screen -----------------------------------------------------------------
                 
                  app.SingleCellFigureHandle = figure('Units','normalized','Color',[1,1,1],'Position',[0.2,0.15,0.6,0.7]);
                  
                  % ASSIGN KEY PRESS/RELEASE FUNCTIONS TO MAIN LOCUS WINDOW AND FIGURE WINDOW ---------------
                  app.SingleCellFigureHandle.WindowKeyPressFcn   = {@MainWindowKeyPressFcn,app};
                  app.SingleCellFigureHandle.WindowKeyReleaseFcn = {@FigureKeyReleaseFcn,app};
                  app.LocusUIFigure.WindowKeyPressFcn            = {@MainWindowKeyPressFcn,app};
                  app.LocusUIFigure.WindowKeyReleaseFcn          = {@LocusMainWindowKeyRelease,app};
                  %-------------------------------------------------------------------------------------------
                  
                  app.SingleCellAxisHandle = axes(app.SingleCellFigureHandle);  
                  set(app.SingleCellAxisHandle,'Color',[1,1,1],'DataAspectRatio',[1,1,1],'LineWidth',1,'CameraViewAngleMode','manual',...
                                               'CameraViewAngle',8.5,'XColor',[0,0,0],'YColor',[0,0,0],'ZColor',[0,0,0],...
                                               'TickDir','both','Box','on','FontSize',12)
                  
                  app.AxesToolBarHandle = axtoolbar(app.SingleCellAxisHandle,'Visible','off');
                  
                  camproj(app.SingleCellAxisHandle,'perspective')
                  view(app.SingleCellAxisHandle,45,12)
                  grid(app.SingleCellAxisHandle,'on')
                  xlabel(app.SingleCellAxisHandle,'X(\mum)','FontSize',14)
                  ylabel(app.SingleCellAxisHandle,'Y(\mum)','FontSize',14)
                  zlabel(app.SingleCellAxisHandle,'Z(\mum)','FontSize',14)
                      
                 % END create figure in middle of screen ------------------------------------------------------------
                 
                 EnableAndDisableRenderControls(app,'enable')
                 
                 app.OPENRenderingFigureButton.FontColor = [0.52,0.98,0.98];
                 app.OPENRenderingFigureButton.BackgroundColor = [0.01,0.36,0.59];
                 app.OPENRenderingFigureButton.Text = 'CLOSE Rendering Figure';
                 app.DataPlaneToggleButton.Value = 0;
                 app.DataPlaneToggleButton.Enable = true; % Enable "Show Data Plane" button

                 app.TimePointSpinner.Enable = true;
                 app.CurrentTrackDropDown.Enable = true;
             
                 app.ShowTrackPointButton.Value   = false;
                 app.ShowTrackPointButton.Text    = 'Show Track Point';   
              
                 app.TrackPointOffset_X.Value = double( app.CellTrackOffset{app.CurrentTrack}(app.CurrentTimePoint,1) );
                 app.TrackPointOffset_Y.Value = double( app.CellTrackOffset{app.CurrentTrack}(app.CurrentTimePoint,2) );
                 app.TrackPointOffset_Z.Value = double( app.CellTrackOffset{app.CurrentTrack}(app.CurrentTimePoint,3) );
                 
                 % !!!!!!! Update axis with the first time point by default (same code as TimePointSpinner)--------
                 TimePointSpinnerValueChanged(app, event)
                 %!!!!!!!!!!!!!!!!!!!!!!!!!!!!!!!!!!!!!!!!!!!!!!!!!!!!!!!!!!!!!!!!!!!!!!!!!!!!!!!!!!!!!!!!!!!!!!!!!!
                 %------------------------------------------------------------------------------------------


            %--------------------------------------------------------------------------------------------------------    
            
            else
                 app.OPENRenderingFigureButton.FontColor = [0.52,0.98,0.98];
                 app.OPENRenderingFigureButton.BackgroundColor = [0.01,0.36,0.59];
                 app.OPENRenderingFigureButton.Text = 'OPEN Rendering Figure';
                 
                 if isgraphics(app.SingleCellFigureHandle)
                    close(app.SingleCellFigureHandle)
                 end
                 
                 app.DataPlaneHandle.main   = -1;
                 app.DataPlaneHandle.outine = -1;
                 app.SingleCellFigureHandle = -1;                
                 
                 EnableAndDisableRenderControls(app,'disable')

                 app.TimePointSpinner.Enable = false;
                 app.CurrentTrackDropDown.Enable = false;

                 app.DataPlaneColormapDropDown.Enable = false;
                
                 app.DataPlaneToggleButton.Text = 'Show Data Plane';
                 app.DataPlaneToggleButton.Enable = false;
                 EnableAndDisablePlaneControls(app,'disable')
                 
                 app.TrackPointOffset_X.Value = 0;
                 app.TrackPointOffset_Y.Value = 0;
                 app.TrackPointOffset_Z.Value = 0;
                 
                 app.ShowTrackPointButton.Enable  = false;
                 app.ShowTrackPointButton.Text = 'Show Track Point';
                 EnableAndDisableRenderControls(app,'disable')
                 
                 app.LocusUIFigure.WindowKeyPressFcn            = [];
                 app.LocusUIFigure.WindowKeyReleaseFcn          = [];
            %--------------------------------------------------------------------------------------------------------          
            end
            
            
        end

        % Value changing function: XSlider
        function XSliderValueChanging(app, event)
            
            changingValue = event.Value;
            app.XSlider.Value = round(changingValue,1);
            app.XSliderSpinner.Value = round(changingValue,1);
            
            if isgraphics(app.SingleCellFigureHandle) % Verify figure is there (in case it was closed without the software knowing)                          
                PanTiltTranslatePlane(app);
                UpdateDataPlane(app)  
                drawnow 
            end
            
        end

        % Value changing function: YSlider
        function YSliderValueChanging(app, event)
            
            changingValue = event.Value;
            app.YSlider.Value = round(changingValue,1);
            app.YSliderSpinner.Value = round(changingValue,1);
            
            if isgraphics(app.SingleCellFigureHandle) % Verify figure is there (in case it was closed without the software knowing)
                PanTiltTranslatePlane(app);
                UpdateDataPlane(app)  
                drawnow 
            end
            
        end

        % Value changing function: ZSlider
        function ZSliderValueChanging(app, event)
            
            changingValue = event.Value;
            app.ZSlider.Value = round(changingValue,1);
            app.ZSliderSpinner.Value = round(changingValue,1);
            
            if isgraphics(app.SingleCellFigureHandle) % Verify figure is there (in case it was closed without the software knowing)
                PanTiltTranslatePlane(app);
                UpdateDataPlane(app)  
                drawnow 
            end            
            
        end

        % Value changed function: PANeditField
        function PANeditFieldValueChanged(app, event)
            
            app.PANslider.Value = app.PANeditField.Value;
            PanTiltTranslatePlane(app);
            UpdateDataPlane(app)  
            drawnow
            
        end

        % Value changed function: FrangiFilterSwitch
        function FrangiFilterSwitchValueChanged(app, event)
                        
            switch app.FrangiFilterSwitch.Value
                case false;  app.FrangiLamp.Color = [0.8,0.8,0.8];
                case true;   app.FrangiLamp.Color = [0.0,1.0,0.0]; 
            end
            UpdateDataPlane(app)  
            
        end

        % Button pushed function: TiltZeroButton
        function TiltZeroButtonPushed(app, event)
            
            app.TILTslider.Value = 0;
            app.TILTeditField.Value = 0;
            PanTiltTranslatePlane(app);
            
            UpdateDataPlane(app)
            
        end

        % Button pushed function: PanZeroButton
        function PanZeroButtonPushed(app, event)
            
            app.PANslider.Value = 0;
            app.PANeditField.Value = 0;
            PanTiltTranslatePlane(app)
            
            UpdateDataPlane(app)
            
        end

        % Value changed function: GaussianFiltSwitch
        function GaussianFiltSwitchValueChanged(app, event)
                        
            if isequal(app.GaussianFiltSwitch.Value,1)
                app.GaussFiltLamp.Color = [0,1,0];
                app.SigmaSpinner.Enable = true;
            else
                app.GaussFiltLamp.Color = [0.8,0.8,0.8];
                app.SigmaSpinner.Enable = false;
            end
            UpdateDataPlane(app)
            
        end

        % Value changed function: AdaptHistEqSwitch
        function AdaptHistEqSwitchValueChanged(app, event)
            
            if app.AdaptHistEqSwitch.Value
                app.AdaptHistEqLamp.Color = [0,1,0];
            else
                app.AdaptHistEqLamp.Color = [0.8,0.8,0.8];
            end
            UpdateDataPlane(app)
            
        end

        % Value changed function: SigmaSpinner
        function SigmaSpinnerValueChanged(app, event)
            
            UpdateDataPlane(app)
            
        end

        % Button pushed function: Tilt45Button
        function Tilt45ButtonPushed(app, event)
            
            app.TILTslider.Value = 45;
            app.TILTeditField.Value = 45;
            PanTiltTranslatePlane(app)
            UpdateDataPlane(app)
            
        end

        % Button pushed function: Pan45Button
        function Pan45ButtonPushed(app, event)
            
            app.PANslider.Value = 45;
            app.PANeditField.Value = 45;
            PanTiltTranslatePlane(app)
            UpdateDataPlane(app)
            
        end

        % Button pushed function: Pan90Button
        function Pan90ButtonPushed(app, event)
            
            app.PANslider.Value = 90;
            app.PANeditField.Value = 90;
            PanTiltTranslatePlane(app)
            UpdateDataPlane(app)
            
        end

        % Button pushed function: Tilt90Button
        function Tilt90ButtonPushed(app, event)
            
            app.TILTslider.Value = 90;
            app.TILTeditField.Value = 90;
            PanTiltTranslatePlane(app)
            UpdateDataPlane(app)
            
        end

        % Value changed function: MaxDistSpinner
        function MaxDistSpinnerValueChanged(app, event)
            
            value = app.MaxDistSpinner.Value;
            
            app.XSlider.Limits = [-value,value];
            app.YSlider.Limits = [-value,value];
            app.ZSlider.Limits = [-value,value];
            app.XSliderSpinner.Limits = [-value,value];
            app.YSliderSpinner.Limits = [-value,value];
            app.ZSliderSpinner.Limits = [-value,value];
            
        end

        % Value changed function: ShowTrackPointButton
        function ShowTrackPointButtonValueChanged(app, event)
                        
            UpdateCurrentTrackPoint(app);
            
        end

        % Button pushed function: XSliderZeroButton
        function XSliderZeroButtonPushed(app, event)
            
            app.XSlider.Value = 0;
            app.XSliderSpinner.Value = 0;
            PanTiltTranslatePlane(app);
            UpdateDataPlane(app)
            
        end

        % Button pushed function: YSliderZeroButton
        function YSliderZeroButtonPushed(app, event)
            
            app.YSlider.Value = 0;
            app.YSliderSpinner.Value = 0;
            PanTiltTranslatePlane(app);
            UpdateDataPlane(app)
            
        end

        % Button pushed function: ZSliderZeroButton
        function ZSliderZeroButtonPushed(app, event)
            
            app.ZSlider.Value = 0;
            app.ZSliderSpinner.Value = 0;
            PanTiltTranslatePlane(app)
            UpdateDataPlane(app)
            
        end

        % Value changed function: XSliderSpinner
        function XSliderSpinnerValueChanged(app, event)
            
            %app.XSliderSpinner.Value = app.XSlider.Value;
            PanTiltTranslatePlane(app)
            UpdateDataPlane(app)
                        
        end

        % Value changed function: YSliderSpinner
        function YSliderSpinnerValueChanged(app, event)
            
            %app.YSlider.Value = app.YSliderSpinner.Value;
            PanTiltTranslatePlane(app);
            UpdateDataPlane(app)            
            
        end

        % Value changed function: ZSliderSpinner
        function ZSliderSpinnerValueChanged(app, event)
            
            %app.ZSlider.Value = app.ZSliderSpinner.Value;
            PanTiltTranslatePlane(app);
            UpdateDataPlane(app)       
            
        end

        % Value changed function: StepSizeSpinner
        function StepSizeSpinnerValueChanged(app, event)
            
            app.XSliderSpinner.Step = app.StepSizeSpinner.Value;
            app.YSliderSpinner.Step = app.StepSizeSpinner.Value;
            app.ZSliderSpinner.Step = app.StepSizeSpinner.Value;
            
        end

        % Value changed function: TimePointSpinner
        function TimePointSpinnerValueChanged(app, event)
            
            if app.TimePointSpinner.Value <= app.nTime || app.TimePointSpinner.Value > 1
                
                app.XSlider.Value = 0;
                app.XSliderSpinner.Value = 0;
                app.YSlider.Value = 0;
                app.YSliderSpinner.Value = 0;
                app.ZSlider.Value = 0;
                app.ZSliderSpinner.Value = 0;
                
                app.CurrentTimePoint = app.TimePointSpinner.Value;
                        
                t  = app.CurrentTimePoint;
                track = app.CurrentTrack;
                % ch = app.DataPlaneChannel.Value;

%                 source = app.DataSourceDropDown.Value;
%                 if ~isnan(app.TimePointData.RenderingSettings{track,1}.DataSource(t,1))
%                     source = app.TimePointData.RenderingSettings{track,1}.DataSource(t,1);
%                 end

                source = 1;
                
                [app.CurrentDataVolumeForTimeT, app.IND{track,1}(:,:,t)] = ReadSubRegionForTimeT(app,t,track,source); 
                
                %------------------------------------------------------

                if isgraphics(app.TimepointTextHandle)
                     delete(app.TimepointTextHandle)
                end
                app.TimepointTextHandle = axes(app.SingleCellFigureHandle,'Position',[0.02,1,0.01,0.01],'Visible','off','XLim',[0,1],'YLim',[0,1]);

                
                %============================================================================================================
                if isempty(app.CurrentDataVolumeForTimeT) % If no image data available for this track point

                        % Remove and turnoff DataPlane ----------------------------------------------------------
                        app.DataPlaneToggleButton.Value = 0;
                        if isgraphics(app.DataPlaneHandle.main);     delete(app.DataPlaneHandle.main);     end
                        if isgraphics(app.DataPlaneHandle.outline);  delete(app.DataPlaneHandle.outline);  end
                        if isgraphics(app.ConnectionLineHandles);    delete(app.ConnectionLineHandles);    end
                        app.DataPlaneHandle.main = -1;
                        app.DataPlaneHandle.outline = -1;
                        EnableAndDisablePlaneControls(app,'disable')
                        app.DataPlaneToggleButton.Text = 'Show Data Plane';
    
                        % Add timepoint text to figure window ---------------------------------------------------------
                        TX1 = text(app.TimepointTextHandle,0,0,[ 'Timepoint = ', num2str(app.TimePointSpinner.Value),...
                                    '      [No track data Available]'], 'VerticalAlignment','top','HorizontalAlignment','left',...
                                    'FontWeight','bold','FontSize',11,'Color',[1,0,0]); 
                        cla(app.SingleCellAxisHandle)
                        axis(app.SingleCellAxisHandle,[-1,0,-1,0,-1,0]);
                        drawnow
                    
                else % If image data IS available for this track point
                    
                        %----------------------------------------------------------------------------------------------
                        % Below, I subtract 1 from the cell track coordinates in order to correct because in Longtracker the exported track
                        % coordinates are given as Array Index Values (start at 1) from the data volume, but in Locus those coordinates start at 0.
                        % See code in Function SELECTandPROCESSDATAButtonPushed:
                        % 
                        % [app.X, app.Y, app.Z] = meshgrid( app.dp.*[0:(app.cWidth -1)],...
                        %                                   app.dp.*[0:(app.rHeight-1)],...
                        %                                   app.dz.*[0:(app.zDepth -1)] ); 
                        %
                        
                        % Convert trackpoint from units of voxels to microns) -------------------------------
                        app.CurrentTrackPoint.x = app.dp*( app.CellTrackData{track}(t,2) - 1 );
                        app.CurrentTrackPoint.y = app.dp*( app.CellTrackData{track}(t,3) - 1 );
                        app.CurrentTrackPoint.z = app.dz*( app.CellTrackData{track}(t,4) - 1 );
                        
                        app.TrackPointOffset_X.Value = app.CellTrackOffset{app.CurrentTrack}(app.CurrentTimePoint,1);
                        app.TrackPointOffset_Y.Value = app.CellTrackOffset{app.CurrentTrack}(app.CurrentTimePoint,2);
                        app.TrackPointOffset_Z.Value = app.CellTrackOffset{app.CurrentTrack}(app.CurrentTimePoint,3);
                        
                        % Add timepoint text to figure window ---------------------------------------------------------
                        if isnan(app.TimePointData.Measurements{track,1}.Volume(t,1))
                            TX1 = text(app.TimepointTextHandle,0,0,['Timepoint = ',num2str(app.TimePointSpinner.Value)],...
                                                                    'VerticalAlignment','top','HorizontalAlignment','left','FontWeight','bold','FontSize',11,'Color',[1,0,0]);   
                        else
                            TX1 = text(app.TimepointTextHandle,0,0,['Timepoint = ',num2str(app.TimePointSpinner.Value),'\color{black}', pad('volume = ',19,'left'),...
                                                                    sprintf('%0.0f',app.TimePointData.Measurements{track,1}.Volume(t,1)),...
                                                                     ' \mum^3         track position [x,y,z] (\mum) = ',...
                                                                     ' [  ',sprintf('%0.2f',app.CurrentTrackPoint.x),...
                                                                     ',  ', sprintf('%0.2f',app.CurrentTrackPoint.y),...
                                                                     ',  ', sprintf('%0.2f',app.CurrentTrackPoint.z),'  ]'],...
                                                                    'VerticalAlignment','top','HorizontalAlignment','left','FontWeight','bold','FontSize',11,'Color',[1,0,0]);                       
                        end
                        drawnow
                        %----------------------------------------------------------------------------------------------  
                        
    
                        % Remove connection lines drawn from previous rendering ---------------------------------------
                        % delete( findall(app.SingleCellAxisHandle,'Type','line' ) )
                        % delete( findall(app.SingleCellAxisHandle,'Type','Light') )
                        
                        if isempty( findall(app.SingleCellAxisHandle,'Type','Light') )
                            camlight(app.SingleCellAxisHandle,0,-85,'infinite'); 
                            camlight(app.SingleCellAxisHandle,180,85,'infinite'); 
                            camlight(app.SingleCellAxisHandle,90,-5,'infinite'); 
                            camlight(app.SingleCellAxisHandle,-90,5,'infinite'); 
                        end
                        % -----------------------------------------------------------------------------------
                        
                        % Set axis limits for timepoint t. --------------------------------------------------
                        axis(app.SingleCellAxisHandle,'equal')
                        axis(app.SingleCellAxisHandle,...
                              [ min(app.X(1, app.IND{track,1}(1,2,t):app.IND{track,1}(2,2,t),1)),...
                                max(app.X(1, app.IND{track,1}(1,2,t):app.IND{track,1}(2,2,t),1)),...
                                min(app.Y(app.IND{track,1}(1,1,t):app.IND{track,1}(2,1,t),1,1)),...
                                max(app.Y(app.IND{track,1}(1,1,t):app.IND{track,1}(2,1,t),1,1)),...
                                min(app.Z(1,1,app.IND{track,1}(1,3,t):app.IND{track,1}(2,3,t))),...
                                max(app.Z(1,1,app.IND{track,1}(1,3,t):app.IND{track,1}(2,3,t))) ] );
                       
                        % Delete cell rendering from previous time point if it exists --------------------------
                            if isgraphics(app.SingleCellRenderingHandle)        
                                delete(app.SingleCellRenderingHandle)
                            end    
                        
                        % Draw cell rendering for this timepoint if it already exists ------------------
                            if ~isempty(app.TimePointData.SurfaceRenderings{track,1}{t,1}) %&& ~isfield('app.TimePointData','SmoothedSurfaceRenderings')
                                if app.CellVisibilityButton.Value
                                   Visible = "on";
                                else
                                   Visible = "off";
                                end
    
                                if app.RenderingSmoothingOnCheckBox.Value && ~isempty(app.TimePointData.SmoothedSurfaceRenderings{track,1}{t,1})
                                    if isfield(app.TimePointData,'SmoothedSurfaceRenderings')
                                        app.SingleCellRenderingHandle = patch( app.SingleCellAxisHandle, app.TimePointData.SmoothedSurfaceRenderings{track,1}{t,1},'Visible',Visible);
                                    else
                                        app.SingleCellRenderingHandle = patch( app.SingleCellAxisHandle, app.TimePointData.SurfaceRenderings{track,1}{t,1},'Visible',Visible);
                                    end
                                else
                                    app.SingleCellRenderingHandle = patch( app.SingleCellAxisHandle, app.TimePointData.SurfaceRenderings{track,1}{t,1},'Visible',Visible);
                                end
                                
                                app.SingleCellRenderingHandle.FaceColor = app.FaceColor;
                                app.SingleCellRenderingHandle.FaceAlpha = app.FaceTransparency;  
                                app.SingleCellRenderingHandle.EdgeColor = app.MeshColor; 
                                app.SingleCellRenderingHandle.EdgeAlpha = app.MeshTransparency;
                                
                                % Update the rendering parameters used ----------------------------------------              
                                app.PadSpinner.Value = app.TimePointData.RenderingSettings{track,1}.Pad(t,1); 
                                %app.ContractionBiasSpinner.Value = app.TimePointData.RenderingSettings{track,1}.ContractBias(t,1); 
                                app.ExtendedMinSpinner.Value =     app.TimePointData.RenderingSettings{track,1}.ExtendedMin(t,1); 
                                drawnow
                                
                            else 
                                % If no rendering exists, use default rendering values --------------------------
                                app.PadSpinner.Value = app.DefaultRenderingValues.Pad; % app.TimePointData.RenderingSettings{track,1}.Pad(t,1); 
                                %app.ContractionBiasSpinner.Value = app.DefaultRenderingValues.ContractionBias; % app.TimePointData.RenderingSettings{track,1}.ContractBias(t,1); 
                                %app.SmoothFactorSpinner.Value = 0; % app.TimePointData.RenderingSettings{track,1}.SmoothFactor(t,1); 
                                app.ExtendedMinSpinner.Value = app.DefaultRenderingValues.ExtendedMin; %app.TimePointData.RenderingSettings{track,1}.ExtendedMin(t,1); 
                                %app.DataSourceDropDown.Value = app.DefaultRenderingValues.DataSource;
                                PanTiltTranslatePlane(app)
                            end
                        
                        % Update data plane and track point if it exists ------------------------------
                            if isgraphics(app.DataPlaneHandle.main)
                                delete(app.DataPlaneHandle.main)
                                delete(app.DataPlaneHandle.outline)
                                %delete(app.CellPlaneIntersectionLineHandle)
                            end
                            
                            if app.DataPlaneToggleButton.Value                                
                                    if app.DefaultViewR1.Value
                                        Er1ViewButtonPushed(app,[])
                                    elseif app.DefaultViewR2.Value
                                        Er2ViewButtonPushed(app,[])
                                    elseif app.DefaultViewR3.Value
                                        Er3ViewButtonPushed(app,[])
                                    end
                                    UpdateDataPlane(app); 
                                    UpdateConnectionLines(app);
                                    UpdateSeparationLines(app);
                            else
                                    if isgraphics(app.CellPlaneIntersectionLineHandle)
                                        delete(app.CellPlaneIntersectionLineHandle)
                                    end
                            end
                            
                            if app.ShowTrackPointButton.Value
                                UpdateCurrentTrackPoint(app)
                            end
                            
                            SendRenderingSummaryToSummaryWindow(app)
                            
                            app.XSlider.Value        = app.TrackPointOffset_X.Value;
                            app.XSliderSpinner.Value = app.TrackPointOffset_X.Value;
                            app.YSlider.Value        = app.TrackPointOffset_Y.Value;
                            app.YSliderSpinner.Value = app.TrackPointOffset_Y.Value;
                            app.ZSlider.Value        = app.TrackPointOffset_Z.Value;
                            app.ZSliderSpinner.Value = app.TrackPointOffset_Z.Value;
                end
            end
            
        end

        % Button pushed function: AutoRenderAllCellsButton
        function AutoRenderAllCellsButtonPushed(app, event)
           
                selection = uiconfirm(app.LocusUIFigure,[{'Are you sure you want to render all cells?'},...
                                                         {'Rendering will start from current timepoint.'}],...
                                                          'Confirm Render all cells');
    
                if isequal('OK',selection)
                        answer = questdlg('Overwrite individual cell rendering settings','Overwrite settings?','No');
                        if strcmp('Yes',answer) | strcmp('No',answer)
                            track = app.CurrentTrack;
                            StartTime = app.TimePointSpinner.Value;
                            d = uiprogressdlg(app.LocusUIFigure,'Title','Rendering cells... please wait','Message','Rendering cells...','Cancelable','on');
                            d.CancelText = 'Stop rendering';
                            %try %-----------------------------------------------------------------------------
                                    nTimePoints = app.nTime - StartTime +1;
                                    index = 0;
                                    for t = StartTime:app.nTime
                                            if d.CancelRequested
                                                close(d)
                                                break
                                            end
                                            index = index + 1;
                                            d.Value = index/nTimePoints;
                                            app.TimePointSpinner.Value = t;
                                            TimePointSpinnerValueChanged(app,event) % This will update rendering settings with any settings previoulsy used ones for this time point
                                            if ~isempty(app.TimePointData.SurfaceRenderings{track,1}{t,1}) && strcmp('Yes',answer)
                                                defaultvaluesButtonPushed(app, event)
                                            end
                                            UpdateRenderingButtonPushed(app,event)
                                    end
                                %------------------------------------------------------------------------------    
                            % catch ME
                            %         rethrow(ME)
                            %         close(d)
                            % end
                        end
                end
            
        end

        % Button pushed function: SaveAsLocusFileButton
        function SaveAsLocusFileButtonPushed(app, event)
            
            try
                if ~isempty(app.LocusFileDirectory) & ~isempty(app.LocusFileName)
                    [file, path, indx] = uiputfile( fullfile(app.LocusFileDirectory,app.LocusFileName) );
                else
                    [file, path, indx] = uiputfile( fullfile(app.LocusFileSaveDirectory, '*.locus') );   
                end
            catch
                [file, path, indx] = uiputfile('*.locus'); 
            end
            
            
            if logical(indx)

                 app.LocusFileName = file;
                 app.LocusFileDirectory = path;

                 LocusSettings.LocusFileName           = app.LocusFileName;
                 LocusSettings.LocusFileDirectory      = app.LocusFileDirectory; 
                 LocusSettings.RGBZT_Directory         = app.RGBZT_Directory;     % 'D:\O15_TL_09042019\RGBZ time series'
                 LocusSettings.RGBZT_ImageFileNames    = app.RGBZT_ImageFileNames;
                 LocusSettings.CellTrackFilesInfo      = app.CellTrackFilesInfo;  % [1×1 struct]
                 LocusSettings.CellTrackData           = app.CellTrackData;       % {[300×3 double]}
                 LocusSettings.CellTrackOffset         = app.CellTrackOffset;     % {[300×3 double]}
                 % LocusSettings.CellRenderingsDirectory = app.CellRenderingsDirectory; % 'D:\O15_TL_09042019\SampleLocusProcessDataFile - renderings\'
                 LocusSettings.dp = app.dp; % 0.5930   
                 LocusSettings.dz = app.dz; % 1
                 LocusSettings.dt = app.dt; % 150
                 LocusSettings.SubVol_nR = app.RowsSubRegionField.Value; % app.SubVol_nR; % 101
                 LocusSettings.SubVol_nC = app.ColsSubRegionField.Value; % app.SubVol_nC; % 103
                 LocusSettings.SubVol_nZ = app.ZSubRegionField.Value;    % app.SubVol_nZ; % 71
                 LocusSettings.rHeight   = app.rHeight; % 384
                 LocusSettings.cWidth    = app.cWidth; % 512
                 LocusSettings.zDepth    = app.zDepth; % 95
                 LocusSettings.nTime     = app.nTime; % 300
                 LocusSettings.TrackPlottingProperties = app.TrackPlottingProperties;
                 LocusSettings.TrackFigureProperties   = app.TrackFigureProperties;
                 LocusSettings.DeconvolvedImageFilesDirectory = app.DeconvolvedImageFilesDirectory;
                 LocusRenderingData = app.TimePointData; % [1×1 struct]
                 
                 % LocusData.DATA5d = app.DATA5d; % {[5-D uint8]}
                 % LocusData.PROC5d = app.PROC5d; % {[5-D single]}
                 
                 LocusData.IND    = app.IND;    % {[2×3×300 uint16]}
                 LocusData.X      = app.X;      % [384×512×95 double]
                 LocusData.Y      = app.Y;      % [384×512×95 double]
                 LocusData.Z      = app.Z;      % [384×512×95 double]
                
                d = uiprogressdlg(app.LocusUIFigure,'Title','Saving Locus File... Please wait a minute.','Indeterminate','on');
                save( fullfile(app.LocusFileDirectory, app.LocusFileName) ,'LocusData','LocusSettings','LocusRenderingData','-v7.3')
                close(d);
                uialert(app.LocusUIFigure,'Locus file saved succesfully!','Saving Locus File','Icon','success');
                app.SaveLocusFileButton.Enable = 'on';
                app.SaveLocusFileButton2.Enable = 'on';
                app.LoadedLocusFileDisplay.Value = fullfile(app.LocusFileDirectory,app.LocusFileName);
                Time = char(datetime('now','TimeZone','local','Format','d-MMM-y   HH:mm:ss'));
                app.LastSavedDisplay.Text = ['Last saved:   ',Time];
            end
            
            
        end

        % Button pushed function: Load_Locus_File_Button
        function Load_Locus_File_ButtonPushed(app, event)
            
            if app.SaveAsLocusFileButton.Enable %  Verify user wants to load in locus file and overwrite current rendering data
                selection = questdlg({'Are you sure you want to load in a new locus file?',...
                                      'Make sure any new rendering data is saved. Loading',...
                                      'in a new locus file will overwrite all current renderings.'},...
                                      'DATA OVERWRITE ALERT!!!', 'Yes','No','No');    
            else
                selection = 'Yes';
            end
             
            
            if strcmp(selection,'Yes')
                
                if isempty(app.LocusFileDirectory)
                    [file, path, indx] = uigetfile( '*.locus' );
                else
                    [file, path, indx] = uigetfile( fullfile(app.LocusFileSaveDirectory,'*.locus') );
                end

                
                if logical(indx)
                     
                     d = uiprogressdlg(app.LocusUIFigure,'Title','Loading Locus File... Please wait a minute.','Indeterminate','on');
                     % Load Locus File ------------------
                     load( fullfile(path,file), '-mat')
                         
                     app.LocusFileName = file;
                     app.LocusFileDirectory = path;
                     app.LocusFileSaveDirectory = path; 
                     
                     app.RGBZT_Directory = LocusSettings.RGBZT_Directory;

                     % LocusSettings.RGBZT_ImageFileNames    = app.RGBZT_ImageFileNames;
                     if ~isfield(LocusSettings,'RGBZT_ImageFileNames')
                         opts = struct('WindowStyle','modal','Interpreter','tex');                    
                         wdfh = warndlg([{'\color{red}\fontsize{11} Locus file is missing Image filenames.'},...
                                         {'\fontsize{11} Re-select RGB-Z Time Series Directory,'},...
                                         {'\fontsize{11} RGB-Z Images, and re-save Locus file.'}],'Image filenames missing',opts);
                         uiwait(wdfh)
                     else
                         app.RGBZT_ImageFileNames = LocusSettings.RGBZT_ImageFileNames;                            
                     end

                     app.CellTrackFilesInfo = LocusSettings.CellTrackFilesInfo;
                     app.CellTrackData      = LocusSettings.CellTrackData;
                     app.CellTrackOffset    = LocusSettings.CellTrackOffset;
                     % app.CellRenderingsDirectory = LocusSettings.CellRenderingsDirectory;
                     app.dp = LocusSettings.dp; 
                     app.dz = LocusSettings.dz;
                     app.dt = LocusSettings.dt;
                     
                     app.RowsSubRegionField.Value = LocusSettings.SubVol_nR;
                     app.ColsSubRegionField.Value = LocusSettings.SubVol_nC;
                     app.ZSubRegionField.Value = LocusSettings.SubVol_nZ;
                     
                     app.rHeight = LocusSettings.rHeight;
                     app.cWidth  = LocusSettings.cWidth;
                     app.zDepth  = LocusSettings.zDepth;
                     app.nTime   = LocusSettings.nTime;
                     
%                    app.TrackPlottingProperties = LocusSettings.TrackPlottingProperties;
%                    app.TrackFigureProperties   = LocusSettings.TrackFigureProperties;
                     
%                    app.DATA5d = LocusData.DATA5d;
%                    app.PROC5d = LocusData.PROC5d;
    
                     app.IND = LocusData.IND;
                     app.X = LocusData.X;
                     app.Y = LocusData.Y;
                     app.Z = LocusData.Z;
                      
                     %====================================================================================================================
                     % START FIX1: This is a fix for updating fields in the recorded Locus file
                     if ~isfield(LocusRenderingData.Measurements{1},'EllipsoidFitVectors')
                                nTracks = size(LocusRenderingData.Measurements,1);
                                for track = 1:nTracks
                                    nTime = size(LocusRenderingData.Measurements{track}.Centroid,1);
                                    LocusRenderingData.Measurements{track}.EllipsoidFitVectors = NaN(nTime,3,3);
                                end
                     end 

                     if ~isfield(LocusRenderingData,'SmoothedSurfaceRenderings') 
                                nTracks = size(LocusRenderingData.Measurements,1);
                                for track = 1:nTracks
                                    nTime = size(LocusRenderingData.Measurements{track}.Centroid,1);
                                    LocusRenderingData.SmoothedSurfaceRenderings{track,1} = cell(nTime,1);  
                                end
                     end  

                     if ~isfield(LocusRenderingData,'SmoothedSolidRenderings')
                                nTracks = size(LocusRenderingData.Measurements,1);
                                for track = 1:nTracks
                                    nTime = size(LocusRenderingData.Measurements{track}.Centroid,1);
                                    LocusRenderingData.SmoothedSolidRenderings  {track,1} = cell(nTime,1); 
                                end
                     end   

                     if ~isfield(LocusRenderingData.RenderingSettings{1},'SmoothSegmentationSigma')
                                nTracks = size(LocusRenderingData.Measurements,1);
                                for track = 1:nTracks
                                    nTime = size(LocusRenderingData.Measurements{track}.Centroid,1);
                                    LocusRenderingData.RenderingSettings{track,1}.SmoothSegmentationSigma  = nan(nTime,1);
                                end
                     end  
                     % END FIX
                     %====================================================================================================================
                     nTracks = size(LocusRenderingData.Measurements,1);

                     app.TimePointData = LocusRenderingData;
                     app.TimePointSpinner.Limits = [1, app.nTime];
                    
                     % Fix D = cell(nTracks,1); for ii = 1:13; D{ii,1} = cell(app.nTime,1); end
                     % app.TimePointData.LinearIndexMaskInUnCroppedVolume = D;

                    close(d)

                    % Check if IMAGE data is still located in the right place -----------------
                    RGBZTdirFound = true;
                    %[files,~] = files_and_folders(app,app.RGBZT_Directory);
                    result = isfolder( app.RGBZT_Directory );
                    
                    if ~result % If first image file is missing ask user to RE-select image data directory
                                RGBZTdirFound = false;
                                % uialert(app.LocusUIFigure,[{'Looks like the image data'}, {'location has changed.'},{'Please, select new image data directory.'}],'Image data missing','Icon',"warning");
                                opts = struct('WindowStyle','modal','Interpreter','tex');                    
                                wdfh = warndlg([{'\color{red}\fontsize{10}Looks like the image data directory'},...
                                                {'\fontsize{10}has changed: Select new RGB-Z timeseries'},...
                                                {'\fontsize{10}directory, RGB-Z images, and re-save Locus file.'}],'Image directory missing',opts);
    
                                uiwait(wdfh)
                                while ~result
                                            dname = uigetdir([],'Select Image Data folder');
                                            if dname ~= 0
                                                    result = isfolder( dname );
                                                    if result
                                                        app.RGBZT_Directory = dname;
                                                        RGBZTdirFound = true;
                                                        if ~isequal(dname,0)
                                                                    % ---------------------------------------------------------------------------
                                                                    F = struct2cell( dir(dname) )';
                                                                    IsFile = ~cell2mat(F(:,5)); 
                                                                    files = F(IsFile,1); % Grab all the files in the selected directory
                                                                        
                                                                    [Selection, ok] = listdlg('ListString',files,'ListSize',[250 400],'PromptString','Select image files','Name','Select image files');
                                            
                                                                    if isequal(ok,1)
                                                                            app.RGBZTDirectoryDisplay.Value = dname; % RGBZ timeseries location
                                                                            app.RGBZT_Directory = dname;
                                            
                                                                            files = files(Selection);
                                                                            app.nTime = size(files,1);
                                                                            files = MakeSureImageFilesAreSortedByTimestamp(app,files);
                                                                            app.RGBZT_ImageFileNames = files;
                                                                    else
                                                                            uialert(app.LocusUIFigure,'Image data still not found, try again.','Image data still missing','Icon',"warning");
                                                                            opts = struct('WindowStyle','modal','Interpreter','tex');                    
                                                                            wdfh = warndlg('\color{red}\fontsize{11}Image data still not found, try again.',...
                                                                                           'Image data missing',opts);
                                                                            uiwait(wdfh)        
                                                                    end
                                                                    % ---------------------------------------------------------------------------
                                                        end
                                                    end
                                            else
                                                    RGBZTdirFound = false;
                                                    result = true;
                                            end    
                                end
                    end
                    %--------------------------------------------------------------------------
                    
                    if RGBZTdirFound

                            uialert(app.LocusUIFigure,'Locus file loaded succesfully!','Locus File Loaded','Icon','success');
                            % app.LongtrackercelltrackfilesPanel.Visible = "on";
                            app.AddTrackButton.Enable = "on";
                            app.RemoveTrackButton.Enable = "on";
                            app.ChangeImageDirectoryButton.Enable = "on";
                            app.SaveAsLocusFileButton.Enable = 'on';
                            app.SaveLocusFileButton.Enable = 'on';
                            app.SaveLocusFileButton2.Enable = 'on';
                            app.LoadedLocusFileDisplay.Value = fullfile(app.LocusFileDirectory,app.LocusFileName);

                            % Update Display Values for Track-Dropdowns and data stats --------------------------------
                            app.RGBZTDirectoryDisplay.Value  = app.RGBZT_Directory;
                            app.CellTracksListBox1.Items     = app.CellTrackFilesInfo.names;
                            app.CellTracksListBox1.ItemsData = (1:length(app.CellTrackFilesInfo.names))';
                            app.CellTracksListBox1.Value = 1;
                            
                            app.CurrentTrackDropDown.Items = app.CellTrackFilesInfo.names;
                            app.CurrentTrackDropDown.ItemsData = (1:length(app.CellTrackFilesInfo.names))';
                            app.CurrentTrackDropDown.Value = 1;
                            
                            app.SelectTrackDropDown.Items =  app.CellTrackFilesInfo.names;
                            app.SelectTrackDropDown.ItemsData =  (1:length(app.CellTrackFilesInfo.names))';
                            app.SelectTrackDropDown.Value = 1;
                            
                            app.SelectTrackADropDown.Items = app.CellTrackFilesInfo.names;
                            app.SelectTrackADropDown.ItemsData = (1:length(app.CellTrackFilesInfo.names))';
                            app.SelectTrackADropDown.Value = 1;
                            
                            app.SelectTrackBDropDown.Items = app.CellTrackFilesInfo.names;
                            app.SelectTrackBDropDown.ItemsData = (1:length(app.CellTrackFilesInfo.names))';
                            app.SelectTrackBDropDown.Value = 1;

                            if length(app.CellTrackFilesInfo.names) == 1
                                app.SelectTrackBDropDown.Value = 1;
                            else
                                app.SelectTrackBDropDown.Value = 2;
                            end
                            
                            app.PixelSizeField.Value = app.dp;
                            app.ZStepField.Value     = app.dz;
                            app.TimeStepField.Value  = app.dt;
                            
                            % Set default value for movie timepoint range --------------------
                            app.StartingTimePoint.Value  = 1;
                            app.EndingTimePoint.Value   = app.nTime;
                            app.TimePointIncrement.Value = 1;
                            %-----------------------------------------------------------------
                        
                             % Initialize Data Plane Coordinates -------------------------------------------------------
                            app.CurrentTrack = 1;
                            app.CurrentTimePoint = 1;
                            
                            W = app.DataPlaneSize;  % Width  of plane in microns
                            L = app.DataPlaneSize;  % Height of plane in microns
                
                            % Create Cross-section plane, width w and height h, centered at [0,0] 
                            [xp,yp,zp] = meshgrid( linspace( -W/2, W/2, ceil(W/app.dp) ),...  
                                                   linspace( -L/2, L/2, ceil(L/app.dp) ), 0 );  
                                                 
                            app.DataPlaneStartArray.xp = xp;
                            app.DataPlaneStartArray.yp = yp;
                            app.DataPlaneStartArray.zp = zp;
                            
                            app.CurrentTrackPoint.x = app.dp*( app.CellTrackData{app.CurrentTrack,1}(app.CurrentTimePoint,2) - 1 ); 
                            app.CurrentTrackPoint.y = app.dp*( app.CellTrackData{app.CurrentTrack,1}(app.CurrentTimePoint,3) - 1 );
                            app.CurrentTrackPoint.z = app.dz*( app.CellTrackData{app.CurrentTrack,1}(app.CurrentTimePoint,4) - 1 );
                            
                            app = InitializeControlValues(app);
                            
                            app.OPENRenderingFigureButton.Enable = 'on';
                            app.CurrentTrackDropDown.Enable = 'on';
                            
                            % Update Multi-Cell Analysis panel -------------------------------------------------------
                            nTracks = length(app.CellTrackFilesInfo.names);  
                           
                            % If track plotting properties are empty then insert dedault values
                            SetDefaultTrackFigureProperties(app)
    
                            for track = 1:nTracks
                                SetDefaultPlottingProperties(app,track)
                            end
                            
                            SetTrackPlottingProperties(app,1)
                            EnablePlottingProperties(app, app.TrackPlottingProperties{1,1}.ShowTrackOnOff )
    
                            app.SelectTrackDropDown.Enable         = true;
                            app.UpTrackButton.Enable               = true;
                            app.DownTrackButton.Enable             = true;
                            app.ShowTrackCheckBox.Enable           = true;
                            
                            if nTracks > 1
                                EnableClosestApproachProperties(app,1)  
                            end
    
                             % Enable Rendering Summary and Track Editor
                            app.TrackEditorButton.Enable  = "on";
                            app.RenderingSummaryButton.Enable = "on";
                            app.ShowSmoothedRenderingCheck.Enable = "on";
                            %app.RenderingSummaryButton2.Enable = "on";
                    else
                            uialert(app.LocusUIFigure,'Locus file failed to load :-(','Locus file did not load properly','Icon','warning');
                    end


                end
            end


        end

        % Value changed function: TrackPointOffset_X
        function TrackPointOffset_XValueChanged(app, event)
            

            app.CellTrackOffset{app.CurrentTrack}(app.CurrentTimePoint,1) = app.TrackPointOffset_X.Value;
            UpdateCurrentTrackPoint(app);
                app.XSlider.Value  = app.TrackPointOffset_X.Value;
                app.XSliderSpinner.Value = app.TrackPointOffset_X.Value;
                UpdateDataPlane(app);

            
        end

        % Value changed function: TrackPointOffset_Y
        function TrackPointOffset_YValueChanged(app, event)
            

             app.CellTrackOffset{app.CurrentTrack}(app.CurrentTimePoint,2) = app.TrackPointOffset_Y.Value;
             UpdateCurrentTrackPoint(app);
                app.YSlider.Value  = app.TrackPointOffset_Y.Value;
                app.YSliderSpinner.Value = app.TrackPointOffset_Y.Value;
                UpdateDataPlane(app);

             
        end

        % Value changed function: TrackPointOffset_Z
        function TrackPointOffset_ZValueChanged(app, event)
            

             app.CellTrackOffset{app.CurrentTrack}(app.CurrentTimePoint,3) = app.TrackPointOffset_Z.Value;
             UpdateCurrentTrackPoint(app);
                app.ZSlider.Value  = app.TrackPointOffset_Z.Value;
                app.ZSliderSpinner.Value = app.TrackPointOffset_Z.Value;
                UpdateDataPlane(app);
             

        end

        % Button pushed function: UpdateRenderingButton
        function UpdateRenderingButtonPushed(app, event)
           

            if ~isempty(app.CurrentDataVolumeForTimeT)  
                 if isgraphics(app.RenderingTextHandle)
                     delete(app.RenderingTextHandle)
                 else
                    app.RenderingTextHandle = axes(app.SingleCellFigureHandle,...
                        'Position',[0.80,0.96,0.01,0.01],...
                        'Visible','off','XLim',[0,1],'YLim',[0,1]);
                    TX = text(app.RenderingTextHandle,0,0,'Rendering...','FontWeight','bold','FontSize',14,'Color',[1,0,0]);   
                    drawnow
                 end
                 
                 %------------------------------------------------------------------------------------------------
                 UpdateCellRendering(app)

                 if isgraphics(app.DataPlaneHandle.main)
                     UpdateDataPlane(app);
                     UpdateConnectionLines(app)
                     UpdateSeparationLines(app)
                 end
                 %------------------------------------------------------------------------------------------------

                 SendRenderingSummaryToSummaryWindow(app)                 
                 
                 delete(app.RenderingTextHandle)
                 app.RenderingTextHandle = -1;
                 drawnow
            end


        end

        % Button pushed function: SaveLocusFileButton2
        function SaveLocusFileButton2Pushed(app, event)


            try
                 LocusSettings.LocusFileName           = app.LocusFileName;
                 LocusSettings.LocusFileDirectory      = app.LocusFileDirectory; 
                 LocusSettings.RGBZT_Directory         = app.RGBZT_Directory;     % 'D:\O15_TL_09042019\RGBZ time series'
                 LocusSettings.RGBZT_ImageFileNames    = app.RGBZT_ImageFileNames;
                 LocusSettings.CellTrackFilesInfo      = app.CellTrackFilesInfo;  % [1×1 struct]
                 LocusSettings.CellTrackData           = app.CellTrackData;       % {[300×3 double]}
                 LocusSettings.CellTrackOffset         = app.CellTrackOffset;     % {[300×3 double]}
                 % LocusSettings.CellRenderingsDirectory = app.CellRenderingsDirectory; % 'D:\O15_TL_09042019\SampleLocusProcessDataFile - renderings\'
                 LocusSettings.dp = app.dp; % 0.5930   
                 LocusSettings.dz = app.dz; % 1
                 LocusSettings.dt = app.dt; % 150
                 LocusSettings.SubVol_nR = app.RowsSubRegionField.Value; % app.SubVol_nR; % 101
                 LocusSettings.SubVol_nC = app.ColsSubRegionField.Value; % app.SubVol_nC; % 103
                 LocusSettings.SubVol_nZ = app.ZSubRegionField.Value;    % app.SubVol_nZ; % 71
                 LocusSettings.rHeight   = app.rHeight; % 384
                 LocusSettings.cWidth    = app.cWidth; % 512
                 LocusSettings.zDepth    = app.zDepth; % 95
                 LocusSettings.nTime     = app.nTime; % 300
                 LocusSettings.TrackPlottingProperties = app.TrackPlottingProperties;
                 LocusSettings.TrackFigureProperties   = app.TrackFigureProperties;
                 LocusSettings.DeconvolvedImageFilesDirectory = app.DeconvolvedImageFilesDirectory;
                 LocusRenderingData = app.TimePointData; % [1×1 struct]
                 
                 % LocusData.DATA5d = app.DATA5d; % {[5-D uint8]}
                 % LocusData.PROC5d = app.PROC5d; % {[5-D single]}
                 
                 LocusData.IND    = app.IND;    % {[2×3×300 uint16]}
                 LocusData.X      = app.X;      % [384×512×95 double]
                 LocusData.Y      = app.Y;      % [384×512×95 double]
                 LocusData.Z      = app.Z;      % [384×512×95 double]
                
                d = uiprogressdlg(app.LocusUIFigure,'Title','Saving Locus File... Please wait a minute.','Indeterminate','on');
                save( fullfile(app.LocusFileDirectory, app.LocusFileName) ,'LocusData','LocusSettings','LocusRenderingData','-v7.3')
                app.LoadedLocusFileDisplay.Value = fullfile(app.LocusFileDirectory,app.LocusFileName);
                Time = char(datetime('now','TimeZone','local','Format','d-MMM-y   HH:mm:ss'));
                app.LastSavedDisplay.Text = ['Last saved:   ',Time];
                close(d);
            catch
                uialert(app.LocusUIFigure,"Error saving locus file.","Saving error");
            end


        end

        % Value changed function: ConnectionLineSTARTButton
        function ConnectionLineSTARTButtonValueChanged(app, event)
            

            app.ConnectionLineSTARTButton.Value = 1;
            app.ConnectionLineSTARTButton.BackgroundColor = [0.39,0.83,0.07]; % [ 0.94,0.94,0.94,];
            
            if isequal(app.ConnectionLineSTARTButton.Value,1)
                rotate3d(app.SingleCellAxisHandle,'off')
                % If there is a current rendering, make it invisible
                if isgraphics(app.SingleCellRenderingHandle)  
                    if isequal(app.SingleCellRenderingHandle.Visible,'on')
                        app.CellVisibilityButton.Text = 'Cell Visibility - OFF';
                        app.SingleCellRenderingHandle.Visible = 'off';
                    end
                end
                UpdateDataPlane(app);
            end
            
            
        end

        % Button pushed function: clearButton
        function clearButtonPushed(app, event)
            

            t = app.CurrentTimePoint;
            track = app.CurrentTrack;
            app.ConnectionLineSTARTButton.Value = 0;
            app.ConnectionLineSTARTButton.BackgroundColor = [0.94,0.94,0.94];
            app.TempConnectionLine = NaN(2,3);
            app.TimePointData.RenderingSettings{track,1}.ConnectionLines{t,1} = [];
            
            if isgraphics(app.ConnectionLineHandles)
                delete(app.ConnectionLineHandles)
                app.ConnectionLineHandles = -1;
            end
            
            if isgraphics(app.DataPlaneHandle.main)
                UpdateDataPlane(app);
            end
            
            % UpdateConnectionLines(app);

            
        end

        % Button pushed function: Er1ViewButton
        function Er1ViewButtonPushed(app, event)
            
               
                CurrObj = gco(app.LocusUIFigure);

                app.ViewButtonPressed = 1;
                app.ELamp1.Color = [0,1,0];
                app.ELamp2.Color = [0.8,0.8,0.8];
                app.ELamp3.Color = [0.8,0.8,0.8];
                
                app.EllipsoidRotationKnob.Enable = false;
                app.EllipsoidRotationDisplay.Enable = false;
                app.EllipsoidRotationZero.Enable = false;
                app.EllipsoidTranslationSlider.Enable = true;
                app.EllipsoidTranslationDisplay.Enable = true;
                app.EllipsoidTranslationZero.Enable = true;
                
                app.EllipsoidTranslationSlider.Value = 0;
                app.EllipsoidTranslationDisplay.Value = '0';
                
                if isgraphics(app.SingleCellRenderingHandle)
                    
                        d = app.EllipsoidTranslationSlider.Value;
                        
                        Xpts = mean(app.SingleCellRenderingHandle.XData)';
                        Ypts = mean(app.SingleCellRenderingHandle.YData)';
                        Zpts = mean(app.SingleCellRenderingHandle.ZData)'; 
                        nPts = size(Xpts,1);
                        
                        if nPts < 9
                            app.EllipsoidFitAlertsDisplay.Text = 'Not enough points for ellipsoid fit!';
                        else
                            app.EllipsoidFitAlertsDisplay.Text = '';
                            [~,~,ev,~,~] = ellipsoid_fit(app,[Xpts,Ypts,Zpts],'');   % Calculate Ellpispod Fit
                            
                            app.EllipsoidAxes = ev;
                            PanTiltTranslatePlane2(app,ev,mean([Xpts,Ypts,Zpts])',d);
                            UpdateDataPlane(app);    
                            UpdateConnectionLines(app);
                            UpdateSeparationLines(app);
                            
                            if ~isempty(event) % Don't change view orientation unless "R1 view, R1 Translation" button was actually pressed
                                if app.AutoUpdateViewCheckBox.Value
                                    if ev(3,1)<0
                                       view(app.SingleCellAxisHandle, -ev(:,1)' )  
                                    else
                                       view(app.SingleCellAxisHandle,  ev(:,1)' )  
                                    end
                                end
                            end
                        end
                end


        end

        % Button pushed function: Er2ViewButton
        function Er2ViewButtonPushed(app, event)
            

                %app.R2Button.Value = true;
                app.ViewButtonPressed  = 2;
                app.ELamp1.Color = [0.8,0.8,0.8];
                app.ELamp2.Color = [0,1,0];
                app.ELamp3.Color = [0.8,0.8,0.8];
                app.EllipsoidRotationKnob.Value = 0;
                
                app.EllipsoidRotationKnob.Enable = true;
                app.EllipsoidRotationDisplay.Enable = true;
                app.EllipsoidRotationZero.Enable = true;
                % app.EllipsoidTranslationSlider.Enable = false;
                % app.EllipsoidTranslationDisplay.Enable = false;
                % app.EllipsoidTranslationZero.Enable = false;
                
                app.EllipsoidTranslationSlider.Value = 0;
                app.EllipsoidTranslationDisplay.Value = '0';
                    
                if isgraphics(app.SingleCellRenderingHandle)
                             
                        d = app.EllipsoidTranslationSlider.Value;

                        Xpts = mean(app.SingleCellRenderingHandle.XData)';
                        Ypts = mean(app.SingleCellRenderingHandle.YData)';
                        Zpts = mean(app.SingleCellRenderingHandle.ZData)'; 
                        nPts = size(Xpts,1);
                        
                        if nPts < 9
                            app.EllipsoidFitAlertsDisplay.Text = 'Not enough points for ellipsoid fit!';
                        else 
                            app.EllipsoidFitAlertsDisplay.Text = '';
                            [~,~,ev,~,~] = ellipsoid_fit(app, [Xpts,Ypts,Zpts], '');   % Calculate Ellpispod Fit
                            
                            app.EllipsoidAxes = ev;
                            PanTiltTranslatePlane3(app,ev,mean([Xpts,Ypts,Zpts])',0,d);
                            UpdateDataPlane(app);  
                            UpdateConnectionLines(app);
                            UpdateSeparationLines(app);
                            
                            if ~isempty(event) % Don't change view orientation unless "R2 view, R2 Translation" button was actually pressed
                                if app.AutoUpdateViewCheckBox.Value
                                    if ev(3,2)<0
                                        view(app.SingleCellAxisHandle, -ev(:,2)')  
                                    else
                                        view(app.SingleCellAxisHandle,  ev(:,2)')  
                                    end
                                end
                            end

                        end
                end    
            
            
        end

        % Button pushed function: Er3ViewButton
        function Er3ViewButtonPushed(app, event)
                  
                app.ViewButtonPressed  = 3;
                app.ELamp1.Color = [0.8,0.8,0.8];
                app.ELamp2.Color = [0.8,0.8,0.8];
                app.ELamp3.Color = [0,1,0];
                
                app.EllipsoidRotationKnob.Value = 0;
                
                app.EllipsoidRotationKnob.Enable = true;
                app.EllipsoidRotationDisplay.Enable = true;
                app.EllipsoidRotationZero.Enable = true;
                % app.EllipsoidTranslationSlider.Enable = false;
                % app.EllipsoidTranslationDisplay.Enable = false;
                % app.EllipsoidTranslationZero.Enable = false;
                
                app.EllipsoidTranslationSlider.Value = 0;
                app.EllipsoidTranslationDisplay.Value = '0';
                
                if isgraphics(app.SingleCellRenderingHandle)

                        d = app.EllipsoidTranslationSlider.Value;
                        Xpts = mean(app.SingleCellRenderingHandle.XData)';
                        Ypts = mean(app.SingleCellRenderingHandle.YData)';
                        Zpts = mean(app.SingleCellRenderingHandle.ZData)'; 
                        nPts = size(Xpts,1);
                        
                        if nPts < 9
                                app.EllipsoidFitAlertsDisplay.Text = 'Not enough points for ellipsoid fit!';
                        else  
                                app.EllipsoidFitAlertsDisplay.Text = '';
                                [~,~,ev,~,~] = ellipsoid_fit(app,[Xpts,Ypts,Zpts],'');   % Calculate Ellipsoid Fit
                                
                                app.EllipsoidAxes = ev;
                                PanTiltTranslatePlane3(app, ev, mean([Xpts,Ypts,Zpts])',0,d);
                                UpdateDataPlane(app); 
                                UpdateConnectionLines(app);
                                UpdateSeparationLines(app);
                                
                                if ~isempty(event) % Don't change view orientation unless "R3 view, R3 Translation" button was actually pressed
                                    if app.AutoUpdateViewCheckBox.Value
                                        if ev(3,3) < 0
                                            view(app.SingleCellAxisHandle, -ev(:,3)')  
                                        else
                                            view(app.SingleCellAxisHandle,  ev(:,3)')  
                                        end
                                    end
                                end
                        end
                    
                end 
            
            
        end

        % Value changed function: CurrentTrackDropDown
        function CurrentTrackDropDownValueChanged(app, event)
            
            value = app.CurrentTrackDropDown.Value;
            app.CurrentTrack = value;
            TimePointSpinnerValueChanged(app, event)            
            
        end

        % Button pushed function: CLEARSepButton
        function CLEARSepButtonPushed(app, event)
            
            t = app.CurrentTimePoint;
            track = app.CurrentTrack;
            app.SeparationLineSTARTButton.Value = 0;
            app.SeparationLineSTARTButton.BackgroundColor = [0.94,0.94,0.94];
            app.TempSeparationLine = zeros(0,3);
            app.TimePointData.RenderingSettings{track,1}.SeparationLines{t,1} = [];
            
            if isgraphics(app.SeparationLineHandles)
                delete(app.SeparationLineHandles)
                app.SeparationLineHandles = -1;
            end
            
            if isgraphics(app.DataPlaneHandle.main)
                UpdateDataPlane(app);
            end
            
        end

        % Value changed function: SeparationLineSTARTButton
        function SeparationLineSTARTButtonValueChanged(app, event)
           
            app.SeparationLineSTARTButton.Value = 1;
            app.SeparationLineSTARTButton.BackgroundColor = [0.39,0.83,0.07]; % [ 0.94,0.94,0.94,];
           
            % Application of recorded Separation Lines are incorporated in the function "ApplySeparationLines"
            
            if isequal(app.SeparationLineSTARTButton.Value,1)
                rotate3d(app.SingleCellAxisHandle,'off')
                % If there is a current rendering, make it invisible
                if isgraphics(app.SingleCellRenderingHandle)  
                    if isequal(app.SingleCellRenderingHandle.Visible,'on')
                        app.CellVisibilityButton.Text = 'Cell Visibility - OFF';
                        app.SingleCellRenderingHandle.Visible = 'off';
                    end
                end
                UpdateDataPlane(app);
            end
            
            
        end

        % Button pushed function: defaultvaluesButton
        function defaultvaluesButtonPushed(app, event)
            
            app.PadSpinner.Value = app.DefaultRenderingValues.Pad;                                 % app.TimePointData.RenderingSettings{track,1}.Pad(t,1); 
            %app.ContractionBiasSpinner.Value = app.DefaultRenderingValues.ContractionBias;         % app.TimePointData.RenderingSettings{track,1}.ContractBias(t,1); 
            app.ExtendedMinSpinner.Value = app.DefaultRenderingValues.ExtendedMin;                 % app.TimePointData.RenderingSettings{track,1}.ExtendedMin(t,1); 
           % app.DataSourceDropDown.Value = app.DefaultRenderingValues.DataSource;

            
        end

        % Key press function: LocusUIFigure
        function LocusUIFigureKeyPress(app, event)


        end

        % Key release function: LocusUIFigure
        function LocusUIFigureKeyRelease(app, event)


        end

        % Value changing function: EllipsoidRotationKnob
        function EllipsoidRotationKnobValueChanging(app, event)
           
            RotationAngle = event.Value;
            d = app.EllipsoidTranslationSlider.Value;
            CalculateEllipsoidFitAxes(app);
            PanTiltTranslatePlane3(app, app.EllipsoidAxes, app.EllipsoidCenter, RotationAngle,d);
            UpdateDataPlane(app);  
            drawnow
            
        end

        % Button pushed function: ShortcutsButton
        function ShortcutsButtonPushed(app, event)


             % Shortcut code is under: OPENRenderingFigureButtonPushed(app, event)
                
             default_color = [0.8 0.8 0.8];

             message = [{'KEYBOARD'};...
                        {'                                  '};...
                        {'    Shift   Rotate axes* '};...
                        {'                                  '};...
                        {'    Cntrl   Rotate data plane*'};...
                        {'                                  '};...
                        {'    1       Data plane defined by vectors R2 & R3'};...
                        {'            (perpendicular to R1**).'};...
                        {'                                  '};...
                        {'    2       Data plane defined by vectors R1 & R2'};...
                        {'            (perpendicular to R3**).'};...
                        {'                                   '};...
                        {'    3       Data plane defined by vectors R1 & R3'};...
                        {'            (perpendicular to R2**).'};...
                        {'                                   '};...
                        {'    U or Q  Update cell rendering.'};...
                        {'                                   '};...
                        {'    V       Change cell visibility.'};...
                        {'                                   '};...
                        {'    W       Change intersection line visibility.'};...
                        {'                                   '};...
                        {'    E       Toggle Membrane/Nuclear channel'};...
                        {'                                  '};...
                        {'    C       Start connection line.'};...
                        {'                                  '};...
                        {'    S       Start separation line.'};...
                        {'                                  '};...
                        {'    T       Advance timepoint by 1.'};...
                        {'                                   '};...
                        {'    R       Reverse timepoint by 1.'};...
                        {'                                   '};...
                        {'    G       Turn on Gaussian filter.'};...
                        {'                                  '};...
                        {'    F       Turn on Frangi filter.'};...
                        {'                                  '};...
                        {'    UP/Down Arrow:  translates data plane '};...
                        {'                    along R1,R2, or R3. '};...
                        {'                                  '};...
                        {'                                  '};...
                        {'       * Holding the mouse button down will allow'};...
                        {'         the pointer to move outside the figure'};...
                        {'         window and still control rotation.'};...
                        {'                                  '};...
                        {'      ** Vectors R1,R2, and R3 are in the direction'};...
                        {'         of the three axes of an ellipsoid best fit'};...
                        {'         to rendered cell.'}];
        
            f = figure('NumberTitle','off','Name','Locus shortcuts','Position',[200 200 430 700],...
                       'Resize','off','Toolbar','none','WindowStyle','normal',...
                       'MenuBar','none');   
            
            uicontrol('Parent',f,'Style','text','FontName','FixedWidth','FontWeight','bold',...
                      'FontSize',9,'String',message,'Position',[15 5 650 670],...
                      'HorizontalAlignment','Left');
            
            
        end

        % Value changing function: PANslider
        function PANsliderValueChanging2(app, event)
            
            changingValue = round(event.Value);
            app.PANeditField.Value = changingValue;
            app.PANslider.Value = changingValue;
            drawnow limitrate
            PanTiltTranslatePlane(app);
            UpdateDataPlane(app)  


        end

        % Value changing function: TILTslider
        function TILTsliderValueChanging2(app, event)
            
            changingValue = round(event.Value);
            app.TILTeditField.Value = changingValue;
            app.TILTslider.Value = changingValue;
            PanTiltTranslatePlane(app);
            UpdateDataPlane(app) 
            drawnow
            
            
        end

        % Button pushed function: FaceColorButton
        function FaceColorButtonPushed(app, event)
                
            c = uisetcolor(app.FaceColor);
            app.FaceColorDisplay.BackgroundColor = c;
            app.FaceColor = c;
            app.SingleCellRenderingHandle.FaceColor = app.FaceColor;
           
        end

        % Button pushed function: MeshColorButton
        function MeshColorButtonPushed(app, event)
           
           c = uisetcolor(app.MeshColor);
           app.MeshColorDisplay.BackgroundColor = c;
           app.MeshColor = c;
           app.SingleCellRenderingHandle.EdgeColor = app.MeshColor; % [0.8,0.8,0.8];
                     
        end

        % Value changing function: FaceTransparencySpinner
        function FaceTransparencySpinnerValueChanging(app, event)
            
            app.FaceTransparency = event.Value/100;
            app.SingleCellRenderingHandle.FaceAlpha = app.FaceTransparency;  
            
        end

        % Value changing function: MeshTransparencySpinner
        function MeshTransparencySpinnerValueChanging(app, event)
            
            app.MeshTransparency = event.Value/100;
            app.SingleCellRenderingHandle.EdgeAlpha = app.MeshTransparency;
            
        end

        % Value changed function: EllipsoidRotationKnob
        function EllipsoidRotationKnobValueChanged(app, event)
            
            value = app.EllipsoidRotationKnob.Value;
            
        end

        % Value changing function: EllipsoidTranslationSlider
        function EllipsoidTranslationSliderValueChanging(app, event)
            
             if isgraphics(app.SingleCellRenderingHandle)
                d = app.EllipsoidTranslationSlider.Value; %event.Value;
                app.EllipsoidTranslationDisplay.Value = sprintf('%0.1f',d);
                Xpts = mean(app.SingleCellRenderingHandle.XData)';
                Ypts = mean(app.SingleCellRenderingHandle.YData)';
                Zpts = mean(app.SingleCellRenderingHandle.ZData)'; 
                nPts = size(Xpts,1);
                if nPts < 9
                        app.EllipsoidFitAlertsDisplay.Text = 'Not enough points for ellipsoid fit!';
                else
                    app.EllipsoidFitAlertsDisplay.Text = '';
                    [~,~,ev,~,~] = ellipsoid_fit(app, [Xpts,Ypts,Zpts], '');   % Calculate Ellpispod Fit
                    if app.ViewButtonPressed == 1
                        PanTiltTranslatePlane2(app,ev,mean([Xpts,Ypts,Zpts])',d);
                    else
                        RotationAngle = app.EllipsoidRotationKnob.Value;
                        PanTiltTranslatePlane3(app,ev,mean([Xpts,Ypts,Zpts])',RotationAngle,d);
                    end
                    UpdateDataPlane(app);    
                    UpdateConnectionLines(app);
                    UpdateSeparationLines(app);
                    %drawnow
                end
             end
            
        end

        % Button pushed function: EllipsoidTranslationZero
        function EllipsoidTranslationZeroButtonPushed(app, event)
            
            app.EllipsoidTranslationSlider.Value = 0;
            
            if isgraphics(app.SingleCellRenderingHandle)
                d = app.EllipsoidTranslationSlider.Value;
                app.EllipsoidTranslationDisplay.Value = sprintf('%0.1f',d);
                Xpts = mean(app.SingleCellRenderingHandle.XData)';
                Ypts = mean(app.SingleCellRenderingHandle.YData)';
                Zpts = mean(app.SingleCellRenderingHandle.ZData)'; 
                nPts = size(Xpts,1);
                if nPts < 9
                        warndlg('Too few points to fit ellipse and calculate plane orientation and view')
                else
                    [~,~,ev,~,~] = ellipsoid_fit(app, [Xpts,Ypts,Zpts], '');   % Calculate Ellpispod Fit

                    if app.ViewButtonPressed == 1
                        PanTiltTranslatePlane2(app,ev,mean([Xpts,Ypts,Zpts])',d);
                    else
                        PanTiltTranslatePlane3(app,ev,mean([Xpts,Ypts,Zpts])',0,d);
                    end
                    UpdateDataPlane(app);    
                    UpdateConnectionLines(app);
                    UpdateSeparationLines(app);
                end
            end
        end

        % Button pushed function: EllipsoidRotationZero
        function EllipsoidRotationZeroButtonPushed(app, event)
             
            app.EllipsoidRotationKnob.Value = 0;
            
            if isgraphics(app.SingleCellRenderingHandle)
                Xpts = mean(app.SingleCellRenderingHandle.XData)';
                Ypts = mean(app.SingleCellRenderingHandle.YData)';
                Zpts = mean(app.SingleCellRenderingHandle.ZData)'; 
                nPts = size(Xpts,1);
                if nPts < 9
                   % warndlg('Too few points to fit ellipse and calculate plane orientation and view')
                else    
                    [~,~,ev,~,~] = ellipsoid_fit(app, [Xpts,Ypts,Zpts], '');   % Calculate Ellpispod Fit
                    d = app.EllipsoidTranslationSlider.Value;
                    PanTiltTranslatePlane3(app,ev,mean([Xpts,Ypts,Zpts])',0,d);
                    UpdateDataPlane(app);
                    UpdateConnectionLines(app); 
                    UpdateSeparationLines(app);
                end
            end    
            
        end

        % Button pushed function: setdefaultsButton
        function setdefaultsButtonPushed(app, event)
            
            app.DefaultRenderingValues.Pad = app.PadSpinner.Value;                              
            %app.DefaultRenderingValues.ContractionBias = app.ContractionBiasSpinner.Value;       
            app.DefaultRenderingValues.ExtendedMin = app.ExtendedMinSpinner.Value;     
            %app.DefaultRenderingValues.DataSource = app.DataSourceDropDown.Value;
            
        end

        % Button pushed function: CalculateandPlotClosestApproachButton
        function CalculateandPlotClosestApproachButtonPushed(app, event)
            
            nT = app.nTime;
            Dist = zeros(nT,1);
            Pt1  = zeros(nT,3);
            Pt2  = zeros(nT,3);
            
            ATrack = app.SelectTrackADropDown.Value;
            BTrack = app.SelectTrackBDropDown.Value;
            
            % START calculate closest point of approach distance -------------------------------------------------------------------------
            for t = 1:nT
                if isfield(app.TimePointData.SurfaceRenderings{ATrack,1}{t},'vertices') && isfield(app.TimePointData.SurfaceRenderings{BTrack,1}{t},'vertices') % perform calculation only where surface data exists
                        p1 = app.TimePointData.SurfaceRenderings{ATrack,1}{t}.vertices; % Grab vertices of cell rendering trackA at time t
                        p2 = app.TimePointData.SurfaceRenderings{BTrack,1}{t}.vertices; % Grab vertices of cell rendering trackB at time t
                        N = size(p1,1);
                        M = size(p2,1);
                        
                        SmallestDist = [];
                        
                        for n = 1:N
                            % Calculate distance of point n in rendering A to all points in rendering B, then find the minimum
                            [d,idx] = min(vecnorm(p2-p1(n,:),2,2)); 
                            % Check if point n in rendering A is closer than the previous calculated minimum, if so record as SmallestDist
                            if isempty(SmallestDist) || (d < SmallestDist)
                                SmallestDist = d;
                                Dist(t,1) = SmallestDist;
                                Pt1(t,:) = p1(n,:);
                                Pt2(t,:) = p2(idx,:);
                            end
                        end

                else
                        Dist(t,1) = nan;
                end
            end
            % END calculate closest point of approach distance ----------------------------------------------------------------------------
            
            
            switch app.XaxisTimeunitsDropDown.Value
                case 'seconds'
                    TimeVec = ((0:app.dt:app.dt*(app.nTime-1)))';
                    XLabelText = 'Time (seconds)';
                case 'minutes'
                    TimeVec = ((0:app.dt:app.dt*(app.nTime-1))./60)';
                    XLabelText = 'Time (minutes)';
                case 'hours'
                    TimeVec = ((0:app.dt:app.dt*(app.nTime-1))./3600)';
                    XLabelText = 'Time (hours)';
                case 'timepoints'
                    TimeVec = (1:1:app.nTime).';
                    XLabelText = 'Time (timepoints)';
            end
            
            FH = figure;
            FH.Color = [1,1,1];
            Tiled = tiledlayout(FH,1,1,'TileSpacing','compact');
            AH1 = nexttile(Tiled,1,[1,1]); %subplot(4,1,1,'Parent',FH);
            Sidx = find( ~isnan(Dist),1,'first');
            Eidx = find( ~isnan(Dist),1,'last');
            Dist(Sidx:Eidx) = smoothdata(Dist(Sidx:Eidx),'movmean',3);
            
            plot(AH1,TimeVec,Dist,'-k')
                xlabel(XLabelText,'FontSize',14)
                ylim1 = ylim(AH1); 
                ylim(AH1,[ylim1(1) ylim1(2)+0.1*ylim1(2)])
                ylabel('Closest Approach (\mum)','FontSize',14)
                xlim(AH1,[min(TimeVec),max(TimeVec)])
                title(AH1,[app.SelectTrackADropDown.Items{ATrack} ,'    vs.    ',...
                           app.SelectTrackBDropDown.Items{BTrack}],'FontSize',10,'Interpreter','none')
%                 
%             AH2 = nexttile(Tiled,2,[1,1]); %subplot(4,1,2,'Parent',FH);  
%             plot(AH2,TimeVec,app.TimePointData.Measurements{ATrack}.Volume,'-b',...
%                      TimeVec,app.TimePointData.Measurements{BTrack}.Volume,'-r')
%                 ylim2 = ylim(AH2); 
%                 ylim(AH2,[ylim2(1) ylim2(2)+0.2*ylim2(2)])
%                 %xlabel(AH2,XLabelText,'FontSize',14)
%                 ylabel(AH2,'Cell volume (\mum^{3})','FontSize',14)
%                 xlim(AH2,[min(TimeVec),max(TimeVec)])
%                 L2 = legend(AH2,{app.SelectTrackADropDown.Items{ATrack},app.SelectTrackBDropDown.Items{BTrack}},...
%                            'FontSize',7,'Interpreter','none','Location','north','Orientation','horizontal');
%                 %L2.Position = [L2.Position(1), L2.Position(2)-0.05*L2.Position(2), L2.Position(3:4)];
%                 
%             AH3 = nexttile(Tiled,3,[1,1]); %subplot(4,1,3,'Parent',FH);  
%             plot(AH3,TimeVec,app.TimePointData.Measurements{ATrack}.MBSD,'-b',...
%                      TimeVec,app.TimePointData.Measurements{BTrack}.MBSD,'-r')
%                 ylim3 = ylim(AH3); 
%                 ylim(AH3,[ylim3(1) ylim3(2)+0.2*ylim3(2)])
%                 %xlabel(AH3,XLabelText,'FontSize',14)
%                 ylabel(AH3,'MBSD (\mum)','FontSize',14)
%                 xlim(AH3,[min(TimeVec),max(TimeVec)])
%                 L3 = legend(AH3,{app.SelectTrackADropDown.Items{ATrack},app.SelectTrackBDropDown.Items{BTrack}},...
%                            'FontSize',7,'Interpreter','none','Location','north','Orientation','horizontal');
%                 %L3.Position = [L3.Position(1), L3.Position(2)-0.05*L3.Position(2), L3.Position(3:4)];
%                 
%             AH4 = nexttile(Tiled,4,[1,1]); %subplot(4,1,4,'Parent',FH);  
%             plot(AH4,TimeVec,app.TimePointData.Measurements{ATrack}.MBCD,'-b',...
%                      TimeVec,app.TimePointData.Measurements{BTrack}.MBCD,'-r')
%                 ylim4 = ylim(AH4); 
%                 ylim(AH4,[ylim4(1) ylim4(2)+0.2*ylim4(2)])
%                 xlabel(AH4,XLabelText,'FontSize',14)
%                 ylabel(AH4,'MBCD (\mum)','FontSize',14)
%                 xlim(AH4,[min(TimeVec),max(TimeVec)])    
%                 L4 = legend(AH4,{app.SelectTrackADropDown.Items{ATrack},app.SelectTrackBDropDown.Items{BTrack}},...
%                             'FontSize',7,'Interpreter','none','Location','north','Orientation','horizontal');
%                 %L4.Position = [L4.Position(1), L4.Position(1)-0.05*L4.Position(2), L4.Position(3:4)];
                
        end

        % Button pushed function: UpdateCompositePlotButton
        function UpdateCompositePlotButtonPushed(app, event)
            
            DoMovie = false;
            UpdateCompositePlot(app,DoMovie)     
          
                
        end

        % Button pushed function: AllCellSurfaceColorButton
        function AllCellSurfaceColorButtonPushed(app, event)
            
            c = uisetcolor(app.AllCellSurfaceColorDisplay.BackgroundColor,'Select a color');   
            if ~isequal(c,0)
                 app.AllCellSurfaceColorDisplay.BackgroundColor = c;
                 TrackNum = app.SelectTrackDropDown.Value;
                 RecordTrackPlottingProperties(app,TrackNum)
            end
            
        end

        % Button pushed function: AllCellMeshColorButton
        function AllCellMeshColorButtonPushed(app, event)
            
            c = uisetcolor(app.AllCellMeshColorDisplay.BackgroundColor,'Select a color');   
            if ~isequal(c,0)
                app.AllCellMeshColorDisplay.BackgroundColor = c;
                TrackNum = app.SelectTrackDropDown.Value;
                RecordTrackPlottingProperties(app,TrackNum)
            end
            
        end

        % Button pushed function: MultitrackBackgroundColorButton
        function MultitrackBackgroundColorButtonPushed(app, event)
            
            TrackNum = app.SelectTrackDropDown.Value;
            RecordTrackPlottingProperties(app,TrackNum)
                                    
        end

        % Value changed function: AllCellSurfaceTransparencySpinner
        function AllCellSurfaceTransparencySpinnerValueChanged(app, event)
            
            TrackNum = app.SelectTrackDropDown.Value;
            RecordTrackPlottingProperties(app,TrackNum)
            
        end

        % Value changed function: AllCellMeshTransparencySpinner
        function AllCellMeshTransparencySpinnerValueChanged(app, event)
            
            TrackNum = app.SelectTrackDropDown.Value;
            RecordTrackPlottingProperties(app,TrackNum)
            
        end

        % Value changed function: SelectTrackDropDown
        function SelectTrackDropDownValueChanged(app, event)
                        
            TrackNum = app.SelectTrackDropDown.Value;
            
            if app.TrackPlottingProperties{TrackNum,1}.ShowTrackOnOff
                app.ShowTrackCheckBox.Value = 1;
                EnablePlottingProperties(app,1)
            else
                app.ShowTrackCheckBox.Value = 0;
                EnablePlottingProperties(app,0)
            end
            SetTrackPlottingProperties(app,TrackNum)

            
        end

        % Button pushed function: updateplotButton
        function updateplotButtonPushed(app, event)
            
            if isgraphics(app.TrackAndMovieAxesHandle) && ~logical(app.AutoAxisLimitsCheckBox.Value)
                Xrange = cell2mat( textscan(app.XminXmaxEditField.Value,'%f %f','Delimiter',',') );
                Yrange = cell2mat( textscan(app.YminYmaxEditField.Value,'%f %f','Delimiter',',') );
                Zrange = cell2mat( textscan(app.ZminZmaxEditField.Value,'%f %f','Delimiter',',') );
                axis(app.TrackAndMovieAxesHandle, [Xrange Yrange Zrange])
            end
            
        end

        % Button pushed function: ExportasExcelfileButton
        function ExportasExcelfileButtonPushed(app, event)
            
                d = uiprogressdlg(app.LocusUIFigure,'Title','Calculating...','Indeterminate','on');
                drawnow
                pause(1)
                
                nT = app.nTime;
                Dist = zeros(nT,1);
                Pt1  = zeros(nT,3);
                Pt2  = zeros(nT,3);
                
                ATrack = app.SelectTrackADropDown.Value;
                BTrack = app.SelectTrackBDropDown.Value;
                
                for t = 1:nT
                    p1 = app.TimePointData.Renderings{ATrack,1}{t}.vertices;
                    p2 = app.TimePointData.Renderings{BTrack,1}{t}.vertices;
                    N = size(p1,1);
                    M = size(p2,1);
                    SmallestDist = [];
                    for n = 1:N
                        [d,idx] = min(vecnorm(p2-p1(n,:),2,2));
                        if isempty(SmallestDist) || (d < SmallestDist)
                            SmallestDist = d;
                            Dist(t,1) = SmallestDist;
                            Pt1(t,:) = p1(n,:);
                            Pt2(t,:) = p2(idx,:);
                        end
                    end
                end
                
                %---------------------------------------------------------------------------- 
                TimeVec = ( (0:app.dt:app.dt*(app.nTime-1)) )';
                TimePoints = (1:1:app.nTime).';
                
                [file,path] = uiputfile('*.xlsx*','Save Distance Meaurments','Closest_Approach_Measurments.xlsx');
                if ~isequal(file,0)
                    Header = [{'Timepoint'},{'Time (seconds)'},{'Closest Approach (microns)'}];
                    Array = num2cell([TimePoints,TimeVec,Dist]);
                    writecell( [Header;Array], fullfile(path,file) )
                end
                
                if isgraphics(d)
                    close(d)
                end
            
        end

        % Button pushed function: TextAxesColorButton
        function TextAxesColorButtonPushed(app, event)
            
            TrackNum = app.SelectTrackDropDown.Value;
            RecordTrackPlottingProperties(app,TrackNum);
            
        end

        % Value changed function: StartingTimePoint
        function StartingTimePointValueChanged(app, event)
            
            SV = app.StartingTimePoint.Value;

            if SV >= app.EndingTimePoint.Value
                app.StartingTimePoint.Value = 1;
                warndlg('Start timepoint must be less than End timepoint!')
            % elseif SV > app.nTime
            %     app.StartingTimePoint.Value = 1;
            %     %warndlg('Start timepoint must be less than max timepoint!')
            % elseif SV < 1
            %     app.StartingTimePoint.Value = 1;
                %warndlg('Start timepoint must be greater than 1!')
            end


        end

        % Value changed function: TimePointIncrement
        function TimePointIncrementValueChanged(app, event)
            
            TrackNum = app.SelectTrackDropDown.Value;
            RecordTrackPlottingProperties(app,TrackNum);
            
        end

        % Value changed function: XminXmaxEditField
        function XminXmaxEditFieldValueChanged(app, event)
               
            TrackNum = app.SelectTrackDropDown.Value;
            RecordTrackPlottingProperties(app,TrackNum);
            
        end

        % Value changed function: YminYmaxEditField
        function YminYmaxEditFieldValueChanged(app, event)
            
            TrackNum = app.SelectTrackDropDown.Value;
            RecordTrackPlottingProperties(app,TrackNum);
            
        end

        % Value changed function: ZminZmaxEditField
        function ZminZmaxEditFieldValueChanged(app, event)
            
            TrackNum = app.SelectTrackDropDown.Value;
            RecordTrackPlottingProperties(app,TrackNum);
            
        end

        % Value changed function: ShowTrackCheckBox
        function ShowTrackCheckBoxValueChanged(app, event)
           

                TrackNum = app.SelectTrackDropDown.Value;
                RecordTrackPlottingProperties(app,TrackNum);
                EnablePlottingProperties(app, app.TrackPlottingProperties{TrackNum,1}.ShowTrackOnOff )
    
                nTracksSelected = sum( cell2mat( cellfun( @(x) double(x.ShowTrackOnOff),app.TrackPlottingProperties,'UniformOutput',false)  ) );
                % Tracks = find(cell2mat( cellfun( @(x) double(x.ShowTrackOnOff),app.TrackPlottingProperties,'UniformOutput',false)  ));
                
                if nTracksSelected > 0 
                    EnableTrackFigureProperties(app,1)
                else                  
                    EnableTrackFigureProperties(app,0)
                end
    
                if nTracksSelected~=2 && app.OverlayDataPlaneCheck.Value
                    app.OverlayDataPlaneCheck.Value = false;
                end
    
                if nTracksSelected==2
                    app.OverlayDataPlaneCheck.Enable = "on";
                    app.DataPlaneImageSaturation.Enable = "on";
                    app.MovieDataPlaneColormapDropDown.Enable = "on";
                    app.DataPlaneAzimuthOffsetInput.Enable = "on";
                    app.DataPlaneElevationEditField.Enable = "on";
                else
                    app.OverlayDataPlaneCheck.Enable = "off";
                    app.DataPlaneImageSaturation.Enable = "off";
                    app.MovieDataPlaneColormapDropDown.Enable = "off";
                    app.DataPlaneAzimuthOffsetInput.Enable = "off";
                    app.DataPlaneElevationEditField.Enable = "off";
                end


        end

        % Value changed function: AllCellSurfaceCheckBox
        function AllCellSurfaceCheckBoxValueChanged(app, event)
           
            TrackNum = app.SelectTrackDropDown.Value;
            RecordTrackPlottingProperties(app,TrackNum);
            
        end

        % Value changed function: AllCellMeshCheckBox
        function AllCellMeshCheckBoxValueChanged(app, event)
            
            TrackNum = app.SelectTrackDropDown.Value;
            RecordTrackPlottingProperties(app,TrackNum);
            
        end

        % Value changed function: TrackLineCheckBox
        function TrackLineCheckBoxValueChanged(app, event)
            
            TrackNum = app.SelectTrackDropDown.Value;
            RecordTrackPlottingProperties(app,TrackNum);
            
        end

        % Value changed function: MovieCellSurfaceCheckBox
        function MovieCellSurfaceCheckBoxValueChanged(app, event)
            
            TrackNum = app.SelectTrackDropDown.Value;
            RecordTrackPlottingProperties(app,TrackNum);
            
        end

        % Button pushed function: TrackLineColorButton
        function TrackLineColorButtonPushed(app, event)
            
            c = uisetcolor(app.TrackLineColorDisplay.BackgroundColor,'Select a color');   
            if ~isequal(c,0)
                app.TrackLineColorDisplay.BackgroundColor = c;
                TrackNum = app.SelectTrackDropDown.Value;
                RecordTrackPlottingProperties(app,TrackNum);
            end
            
        end

        % Value changed function: TrackLineTransparencySpinner
        function TrackLineTransparencySpinnerValueChanged(app, event)
            
            TrackNum = app.SelectTrackDropDown.Value;
            RecordTrackPlottingProperties(app,TrackNum);
            
        end

        % Button pushed function: MovieCellSurfaceColorButton
        function MovieCellSurfaceColorButtonPushed(app, event)
            
            c = uisetcolor(app.MovieCellSurfaceColorDisplay.BackgroundColor,'Select a color');   
            if ~isequal(c,0)
                app.MovieCellSurfaceColorDisplay.BackgroundColor = c;
                TrackNum = app.SelectTrackDropDown.Value;
                RecordTrackPlottingProperties(app,TrackNum);
            end
            
        end

        % Button pushed function: MovieCellMeshColorButton
        function MovieCellMeshColorButtonPushed(app, event)
            
            c = uisetcolor(app.MovieCellMeshColorDisplay.BackgroundColor,'Select a color');   
            if ~isequal(c,0)
                app.MovieCellMeshColorDisplay.BackgroundColor = c;
                TrackNum = app.SelectTrackDropDown.Value;
                RecordTrackPlottingProperties(app,TrackNum);
            end
            
        end

        % Value changed function: MovieCellMeshCheckBox
        function MovieCellMeshCheckBoxValueChanged(app, event)
            
            TrackNum = app.SelectTrackDropDown.Value;
            RecordTrackPlottingProperties(app,TrackNum);
            
        end

        % Value changed function: MovieCellMeshTransparencySpinner
        function MovieCellMeshTransparencySpinnerValueChanged(app, event)
           
            TrackNum = app.SelectTrackDropDown.Value;
            RecordTrackPlottingProperties(app,TrackNum);
            
        end

        % Value changed function: MovieCentroidCheckBox
        function MovieCentroidCheckBoxValueChanged(app, event)
            
            TrackNum = app.SelectTrackDropDown.Value;
            RecordTrackPlottingProperties(app,TrackNum);
            
        end

        % Button pushed function: MovieCentroidColorButton
        function MovieCentroidColorButtonPushed(app, event)
            
            c = uisetcolor(app.MovieCentroidColorDisplay.BackgroundColor,'Select a color');   
            if ~isequal(c,0)
                app.MovieCentroidColorDisplay.BackgroundColor = c;
                TrackNum = app.SelectTrackDropDown.Value;
                RecordTrackPlottingProperties(app,TrackNum);
            end
            
        end

        % Value changed function: MovieCentroidTransparencySpinner
        function MovieCentroidTransparencySpinnerValueChanged(app, event)
            
            TrackNum = app.SelectTrackDropDown.Value;
            RecordTrackPlottingProperties(app,TrackNum);
            
        end

        % Value changed function: MovieCellSurfaceTransparencySpinner
        function MovieCellSurfaceTransparencySpinnerValueChanged(app, event)
            
            TrackNum = app.SelectTrackDropDown.Value;
            RecordTrackPlottingProperties(app,TrackNum);
            
        end

        % Value changed function: AutoAxisLimitsCheckBox
        function AutoAxisLimitsCheckBoxValueChanged(app, event)
            
            if logical(app.AutoAxisLimitsCheckBox.Value)
                app.updateplotButton.Enable = 0;
            else
                app.updateplotButton.Enable = 1;
            end
            
            if isgraphics(app.TrackAndMovieAxesHandle) && logical(app.AutoAxisLimitsCheckBox.Value)
                app.updateplotButton.Enable = 0;
                %axis(app.TrackAndMovieAxesHandle,'normal')
            end            
            
        end

        % Value changed function: DataPlaneSizeInput
        function DataPlaneSizeInputValueChanged(app, event)
            
            value = app.DataPlaneSizeInput.Value;
            
            if value >= 10 && value <= 100
                app.DataPlaneSize = value;
                app.DataPlaneSizeInput.Value = value;
                
                W = app.DataPlaneSize;  % Width  of plane in microns
                L = app.DataPlaneSize;  % Height of plane in microns
    
                % Create Cross-section plane, width w and height h, centered at [0,0] 
                [xp,yp,zp] = meshgrid( linspace( -W/2, W/2, ceil(W/app.dp) ),...  
                                       linspace( -L/2, L/2, ceil(L/app.dp) ), 0 );  
                                     
                app.DataPlaneStartArray.xp = xp;
                app.DataPlaneStartArray.yp = yp;
                app.DataPlaneStartArray.zp = zp;
                
                PanTiltTranslatePlane(app)
                UpdateDataPlane(app)
                UpdateConnectionLines(app)
                UpdateSeparationLines(app)
            else
                app.DataPlaneSizeInput.Value = app.DataPlaneSize;
            end
            
            
        end

        % Value changed function: XSlider
        function XSliderValueChanged(app, event)
            
            value = app.XSlider.Value;
            
        end

        % Button pushed function: IntersectionlinecolorButton
        function IntersectionlinecolorButtonPushed(app, event)
            
            c = uisetcolor(app.CellPlaneIntersectionColor);
            app.IntersectionColorDisplay.BackgroundColor = c;
            app.CellPlaneIntersectionColor = c;
            UpdateDataPlane(app)
            
        end

        % Value changed function: DataPlaneToggleButton
        function DataPlaneToggleButtonValueChanged(app, event)
            
            
            if app.DataPlaneToggleButton.Value  &&  ~isempty(app.CurrentDataVolumeForTimeT)  
                    app.DataPlaneToggleButton.Text = 'Hide Data Plane';

                    EnableAndDisablePlaneControls(app,'enable')

                    PanTiltTranslatePlane(app); 
                    UpdateDataPlane(app);
                    UpdateConnectionLines(app)
                    UpdateSeparationLines(app)
                    
                    app.ViewButtonPressed = 1;
                    app.ELamp1.Color = [0,1,0];
                    app.ELamp2.Color = [0.8,0.8,0.8];
                    app.ELamp3.Color = [0.8,0.8,0.8];
                    
                    app.EllipsoidRotationKnob.Enable = false;
                    app.EllipsoidRotationDisplay.Enable = false;
                    app.EllipsoidRotationZero.Enable = false;
                    app.EllipsoidTranslationSlider.Enable = true;
                    app.EllipsoidTranslationDisplay.Enable = true;
                    app.EllipsoidTranslationZero.Enable = true;
                    
                    app.EllipsoidTranslationSlider.Value = 0;
                    app.EllipsoidTranslationDisplay.Value = '0';
                    
            elseif app.DataPlaneToggleButton.Value
                    
                    uialert(app.LocusUIFigure,{'No trackpoint available', 'at this timepoint: Unable to calculate','image data subregion.'},"warning","Icon","warning");
                    if isgraphics(app.DataPlaneHandle.main);     delete(app.DataPlaneHandle.main);     end
                    if isgraphics(app.DataPlaneHandle.outline);  delete(app.DataPlaneHandle.outline);  end
                    if isgraphics(app.ConnectionLineHandles);    delete(app.ConnectionLineHandles);    end
                    app.DataPlaneHandle.main = -1;
                    app.DataPlaneHandle.outline = -1;
                    EnableAndDisablePlaneControls(app,'disable')
                    app.DataPlaneToggleButton.Text = 'Show Data Plane';
                    app.DataPlaneToggleButton.Value = false;
            else

                    if isgraphics(app.DataPlaneHandle.main);     delete(app.DataPlaneHandle.main);     end
                    if isgraphics(app.DataPlaneHandle.outline);  delete(app.DataPlaneHandle.outline);  end
                    if isgraphics(app.ConnectionLineHandles);    delete(app.ConnectionLineHandles);    end
                    if isgraphics(app.CellPlaneIntersectionLineHandle);   delete(app.CellPlaneIntersectionLineHandle);   end

                    app.DataPlaneHandle.main = -1;
                    app.DataPlaneHandle.outline = -1;
                    EnableAndDisablePlaneControls(app,'disable')
                    app.DataPlaneToggleButton.Text = 'Show Data Plane';
                    app.DataPlaneToggleButton.Value = false;

            end
            
            
        end

        % Button pushed function: TrackEditorButton
        function TrackEditorButtonPushed(app, event)
            
              app.TrackEditorHandle = TrackEditor(app, app.CellTrackData, app.CellTrackFilesInfo, app.dp, app.dz);
                
              waitfor(app.TrackEditorHandle)
              app.TrackEditorButton.Enable = true;
              
              if ~isempty(app.DATA1) % If user requested to SAVE edits then overwrite CellTrackData with new version in app.DATA1
                  app.CellTrackData = app.DATA1;
                  app.TrackEditorHandle = -1;
              end
            
        end

        % Value changed function: DefaultViewR1
        function DefaultViewR1ValueChanged(app, event)
            
            app.DefaultViewR1.Value = true;
            app.DefaultViewR2.Value = false;
            app.DefaultViewR3.Value = false;
            
        end

        % Value changed function: DefaultViewR2
        function DefaultViewR2ValueChanged(app, event)
            
            app.DefaultViewR1.Value = false;
            app.DefaultViewR2.Value = true;
            app.DefaultViewR3.Value = false;
            
        end

        % Value changed function: DefaultViewR3
        function DefaultViewR3ValueChanged(app, event)
            
            app.DefaultViewR1.Value = false;
            app.DefaultViewR2.Value = false;
            app.DefaultViewR3.Value = true;
            
        end

        % Value changed function: DataPlaneColormapDropDown
        function DataPlaneColormapDropDownValueChanged(app, event)
            

            % Update data plane and track point if it exists ------------------------------
            if isgraphics(app.DataPlaneHandle.main)
                delete(app.DataPlaneHandle.main)
                delete(app.DataPlaneHandle.outline)
            end
            
            if app.DataPlaneToggleButton.Value == 1
                if app.DefaultViewR1.Value
                    Er1ViewButtonPushed(app,[])
                elseif app.DefaultViewR2.Value
                    Er2ViewButtonPushed(app,[])
                elseif app.DefaultViewR3.Value
                    Er3ViewButtonPushed(app,[])
                end
                % PanTiltTranslatePlane(app);
                UpdateDataPlane(app); 
                UpdateConnectionLines(app);
                UpdateSeparationLines(app);
               % UpdateCurrentTrackPoint(app)
            end
            
            if app.ShowTrackPointButton.Value == 1
                UpdateCurrentTrackPoint(app)
            end

            
        end

        % Button pushed function: TrackAStatsButton
        function TrackAStatsButtonPushed(app, event)
            

            FH = figure;
            FH.Color = [1,1,1];
            Tiled = tiledlayout(FH,3,1,'TileSpacing','compact');

            ATrack = app.SelectTrackADropDown.Value;
            %BTrack = app.SelectTrackBDropDown.Value;

            switch app.XaxisTimeunitsDropDown.Value
                case 'seconds'
                    TimeVec = ((0:app.dt:app.dt*(app.nTime-1)))';
                    XLabelText = 'Time (seconds)';
                case 'minutes'
                    TimeVec = ((0:app.dt:app.dt*(app.nTime-1))./60)';
                    XLabelText = 'Time (minutes)';
                case 'hours'
                    TimeVec = ((0:app.dt:app.dt*(app.nTime-1))./3600)';
                    XLabelText = 'Time (hours)';
                case 'timepoints'
                    TimeVec = (1:1:app.nTime).';
                    XLabelText = 'Time (timepoints)';
            end
            

            Yfilt1 = app.TimePointData.Measurements{ATrack}.Volume;
            Sidx = find( ~isnan(Yfilt1),1,'first');
            Eidx = find( ~isnan(Yfilt1),1,'last');
            Yfilt1(Sidx:Eidx) = fillmissing(Yfilt1(Sidx:Eidx),'linear');   

            AH1 = nexttile(Tiled,1,[1,1]); 
            plot(AH1,TimeVec,app.TimePointData.Measurements{ATrack}.Volume,'-b')
                ylabel(AH1,'Cell volume (\mum^{3})','FontSize',14)
                xlim(AH1,[min(TimeVec),max(TimeVec)])
                title(AH1,['Track: ',app.SelectTrackADropDown.Items{ATrack}],'FontSize',12,'Interpreter','none')            

            Yfilt2 = app.TimePointData.Measurements{ATrack}.MBSD;
            Yfilt2(Sidx:Eidx) = fillmissing(Yfilt2(Sidx:Eidx),'linear');  
            AH2 = nexttile(Tiled,2,[1,1]); 
            plot(AH2,TimeVec,app.TimePointData.Measurements{ATrack}.MBSD,'-b')
                ylabel(AH2,'MBSD (\mum)','FontSize',14)
                xlim(AH2,[min(TimeVec),max(TimeVec)])

            Yfilt3 = app.TimePointData.Measurements{ATrack}.MBCD;
            Yfilt3(Sidx:Eidx) = fillmissing(Yfilt3(Sidx:Eidx),'linear');     

            AH3 = nexttile(Tiled,3,[1,1]); 
            plot(AH3,TimeVec,app.TimePointData.Measurements{ATrack}.MBCD,'-b')
                xlabel(AH3,XLabelText,'FontSize',14)
                ylabel(AH3,'MBCD (\mum)','FontSize',14)
                xlim(AH3,[min(TimeVec),max(TimeVec)])    


        end

        % Button pushed function: TrackBStatsButton
        function TrackBStatsButtonPushed(app, event)


            FH = figure;
            FH.Color = [1,1,1];
            Tiled = tiledlayout(FH,3,1,'TileSpacing','compact');

            %ATrack = app.SelectTrackADropDown.Value;
            BTrack = app.SelectTrackBDropDown.Value;

            switch app.XaxisTimeunitsDropDown.Value
                case 'seconds'
                    TimeVec = ((0:app.dt:app.dt*(app.nTime-1)))';
                    XLabelText = 'Time (seconds)';
                case 'minutes'
                    TimeVec = ((0:app.dt:app.dt*(app.nTime-1))./60)';
                    XLabelText = 'Time (minutes)';
                case 'hours'
                    TimeVec = ((0:app.dt:app.dt*(app.nTime-1))./3600)';
                    XLabelText = 'Time (hours)';
                case 'timepoints'
                    TimeVec = (1:1:app.nTime).';
                    XLabelText = 'Time (timepoints)';
            end


            Yfilt1 = app.TimePointData.Measurements{BTrack}.Volume;
            Sidx = find( ~isnan(Yfilt1),1,'first');
            Eidx = find( ~isnan(Yfilt1),1,'last');
            Yfilt1(Sidx:Eidx) = fillmissing(Yfilt1(Sidx:Eidx),'linear');   

            AH1 = nexttile(Tiled,1,[1,1]); 
            plot(AH1,TimeVec,app.TimePointData.Measurements{BTrack}.Volume,'-b',TimeVec,Yfilt1,'-r')
                ylabel(AH1,'Cell volume (\mum^{3})','FontSize',14)
                xlim(AH1,[min(TimeVec),max(TimeVec)])
                title(AH1,['Track: ',app.SelectTrackADropDown.Items{BTrack}],'FontSize',12,'Interpreter','none')
            

            Yfilt2 = app.TimePointData.Measurements{BTrack}.MBSD;
            AH2 = nexttile(Tiled,2,[1,1]); 
            plot(AH2,TimeVec,app.TimePointData.Measurements{BTrack}.MBSD,'-b',TimeVec,Yfilt2,'-r')
                ylabel(AH2,'MBSD (\mum)','FontSize',14)
                xlim(AH2,[min(TimeVec),max(TimeVec)])

            Yfilt3 = app.TimePointData.Measurements{BTrack}.MBCD; 

            AH3 = nexttile(Tiled,3,[1,1]); 
            plot(AH3,TimeVec,app.TimePointData.Measurements{BTrack}.MBCD,'-b',TimeVec,Yfilt3,'-r')
                xlabel(AH3,XLabelText,'FontSize',14)
                ylabel(AH3,'MBCD (\mum)','FontSize',14)
                xlim(AH3,[min(TimeVec),max(TimeVec)])    


        end

        % Button pushed function: UnselectAllTrackButton
        function UnselectAllTrackButtonPushed(app, event)
            
            nTracks = length(app.CellTrackFilesInfo.names);  
            for TrackNum = 1:nTracks
                app.TrackPlottingProperties{TrackNum,1}.ShowTrackOnOff = false;
                EnablePlottingProperties(app,0)
            end
            app.ShowTrackCheckBox.Value = false;
            EnableTrackFigureProperties(app,0)


        end

        % Value changed function: RGBZTDirectoryDisplay
        function RGBZTDirectoryDisplayValueChanged(app, event)
           
            value = app.RGBZTDirectoryDisplay.Value;
            
        end

        % Value changed function: EndingTimePoint
        function EndingTimePointValueChanged(app, event)
                
            EV = app.EndingTimePoint.Value;
            if EV > app.nTime
                app.EndingTimePoint.Value = app.nTime;
                warndlg('End timepoint must be less than or equal to max timepoint!')
            elseif  EV <= app.StartingTimePoint.Value 
                app.EndingTimePoint.Value = app.StartingTimePoint.Value + 1; %app.nTime;
                warndlg('End timepoint must be greater than Start timepoint!')
            end

        end

        % Button pushed function: SetStartAngleButton
        function SetStartAngleButtonPushed(app, event)
        
            if isgraphics(app.TrackAndMovieAxesHandle)
                [caz,cel] = view(app.TrackAndMovieAxesHandle);
                 app.SetStartAngleDisplay.Value = [num2str(caz,'%1.0f'),' , ',num2str(cel,'%1.0f')];
            else
                warndlg('Press CreateComposite Plot button first!')
            end


        end

        % Button pushed function: SetEndAngleButton
        function SetEndAngleButtonPushed(app, event)
            
            if isgraphics(app.TrackAndMovieAxesHandle)
                [caz,cel] = view(app.TrackAndMovieAxesHandle);
                 app.SetEndAngleDisplay.Value = [num2str(caz,'%1.0f'),' , ',num2str(cel,'%1.0f')];
            else
                warndlg('Press CreateComposite Plot button first!')
            end
            

        end

        % Value changed function: OverlayDataPlaneCheck
        function OverlayDataPlaneCheckValueChanged(app, event)
            
            value = app.OverlayDataPlaneCheck.Value;
            % Check how many tracks are selected to be shown     
            if value == 1
                    nTracksSelected = sum(cell2mat(cellfun(@(x) double(x.ShowTrackOnOff),app.TrackPlottingProperties,'UniformOutput',false)));
                    if nTracksSelected ~= 2
                        app.OverlayDataPlaneCheck.Value = false;  
                        uialert(app.LocusUIFigure,'Exactly 2 tracks must be selected in order to use "Overlay data plane" feature.',...
                                                  'Select 2 tracks!','Icon','warning');
                    end
            end


        end

        % Button pushed function: ExportFluoRenderFormatedTrack
        function ExportFluoRenderFormatedTrackPushed(app, event)
            
            TrackNum = app.SelectTrackDropDown.Value;
            Path = uigetdir('C:\','Select write location');   
            answer = inputdlg({'Enter longaxis length (microns):'},'Input longaxis length',[1 65],{'120'});
            if ~isempty(answer)
                LongAxisLength = str2double(answer{1});
            else
                LongAxisLength = [];
            end

            if Path ~= 0 & ~isempty(LongAxisLength) && ~isnan(LongAxisLength)
                % Ask user for longaxis length in microns
                d = uiprogressdlg(app.LocusUIFigure,'Title','Writing files','Message','Writing 3D image files.','Cancelable','on');
                d.CancelText = 'Stop';
                for t = 1:app.nTime
                            d.Value = t/app.nTime;
                            nZ = app.zDepth;
    %-------------------------------------------------------------------------------------------------------------------------------------------------------------------------                            
                            % METHOD 1 ==============================================================================================
                            % if Method1
                                    % LI are the linear indices of the 3D voxel mask used to create a smooth rendering
                                    LI = app.TimePointData.LinearIndexMaskInUnCroppedVolume{TrackNum,1}{t};
                                    Image3D = zeros(size(app.X),'uint8'); % Create blank 3D volume the same size as original data set
                                    Image3D(LI) = uint8(250); % Create binary mask of cell using original segmented voxels
                                    % LineMaskIdx are the linear indices of a 3D line mask representing the Long axis vector of the cell
                                    LineMaskIdx = GetLinearIndicesForLongAxisLine(app,TrackNum,t,LongAxisLength/2); % L = line half length in microns
                                    Line3D = zeros(size(app.X),'uint8'); % Create blank 3D volume the same size as original data set
                                    Line3D(LineMaskIdx) = uint8(250);   
                                    BlankMask = zeros(size(app.X),'uint8');
    
                            % METHOD 2 ==============================================================================================
                            % elseif Method2
                                    % CellSurface = app.TimePointData.SurfaceRenderings{TrackNum}{t};
                                    % PreviousPoints = [];
                                    % Image3D = zeros(size(app.X),'uint8'); % Create blank 3D volume the same size as original data set
                                    % LineMaskIdx = GetLinearIndicesForLongAxisLine(app,TrackNum,t,LongAxisLength/2); % L = line half length in microns
                                    % Line3D = zeros(size(app.X),'uint8'); % Create blank 3D volume the same size as original data set
                                    % Line3D(LineMaskIdx) = uint8(250);  
                                    % BlankMask = zeros(size(app.X),'uint8');
                                    % 
                                    % if ~isempty(CellSurface) & isfield(CellSurface,'faces')
                                    %         for z = 1:nZ
                                    %                 p1 = [app.X(1,1,z), app.Y(1,1,z), app.Z(1,1,z)]; % Corner 1 of Data Plane
                                    %                 p2 = [app.X(1,end,z), app.Y(1,end,z), app.Z(1,end,z)]; % Corner 2 of Data Plane
                                    %                 p3 = [app.X(end,1,z), app.Y(end,1,z), app.Z(end,1,z)]; % Corner 3 of Data Plane
                                    %                 N = cross(p2-p1, p3-p1); % Create vectors p1-p1 and p3-p1. They're cross-product is the vector perpendicular to the data plane
                                    %                 n = N./norm(N); % Normalize perpendicular vector
                                    % 
                                    %                 try
                                    %                       PTS = PlaneAndSurfaceIntersectionLine(app,CellSurface.faces,CellSurface.vertices,p1,n);
                                    %                       Zsection_xyz = PTS;
                                    %                 catch ME
                                    %                       rethrow(ME)
                                    %                       keyboard
                                    %                       disp('Intersection points calculation failed')
                                    %                       if ~isempty(PreviousPoints)
                                    %                           disp('Points from previous Z-slice used')
                                    %                           Zsection_xyz = PreviousPoints;
                                    % 
                                    %                       else
                                    %                           Zsection_xyz = [];
                                    %                       end
                                    %                 end 
                                    % 
                                    %                 if ~isempty(Zsection_xyz)
                                    %                     Image3D(:,:,z) = poly2mask( Zsection_xyz(:,1), Zsection_xyz(:,2), size(app.X,1), size(app.X,2));
                                    %                 end
                                    %                 PreviousPoints = Zsection_xyz;                                                        
                                    %         end
                                    % 
                                    % else
                                    %         Zsection_xyz = [];
                                    % end
                            %end
    %--------------------------------------------------------------------------------------------------------------------------------------------------------------------------
    
                            TimePointText = sprintf('%05d',t); % create timepoint text to be used in filename
                            TrackName = app.SelectTrackDropDown.Items{TrackNum};
                            FileName = [TrackName,'_T',TimePointText,'.tif'];
        
                            for z = 1:nZ % Save Mask volume (Image3D) one z-slice at a time
                                RGB = cat(3, Image3D(:,:,z), Line3D(:,:,z), BlankMask(:,:,z)); % Concatenate the cell mask into the red channel, and the line mask into the green channel
                                if d.CancelRequested; close(d); break;  end
                                if z == 1
                                    imwrite( RGB, fullfile(Path,FileName),'Compression','none','WriteMode','overwrite','RowsPerStrip',size(Image3D,1) )
                                else
                                    imwrite( RGB, fullfile(Path,FileName),'Compression','none','WriteMode','append','RowsPerStrip',size(Image3D,1) )
                                end
                            end
                            if d.CancelRequested; close(d); break; end
                end
                X = app.X;
                Y = app.Y;
                Z = app.Z;
                XYZDataFileName = [TrackName,'__XYZarrays.mat'];
                save( fullfile(Path,XYZDataFileName),'X','Y','Z', '-v7.3' )

                close(d)
            end


        end

        % Button pushed function: UpTrackButton
        function UpTrackButtonPushed(app, event)
            
            Track = app.SelectTrackDropDown.Value;
            if Track > 1
                app.SelectTrackDropDown.Value = Track - 1;
            end

            SelectTrackDropDownValueChanged(app, event)


        end

        % Button pushed function: DownTrackButton
        function DownTrackButtonPushed(app, event)
            
            nTracks = length(app.CellTrackFilesInfo.names); 
            Track = app.SelectTrackDropDown.Value;
            if Track < nTracks
                app.SelectTrackDropDown.Value = Track + 1;
            end

            SelectTrackDropDownValueChanged(app, event)
            
            
        end

        % Value changed function: EllipsoidTranslationSlider
        function EllipsoidTranslationSliderValueChanged(app, event)
            
            value = app.EllipsoidTranslationSlider.Value;
            
        end

        % Value changed function: RotationDropDown
        function RotationDropDownValueChanged(app, event)
            
            value = app.RotationDropDown.Value;
            if strcmp('auto',value)
                app.SetStartAngleDisplay.Value = [num2str(0,'%1.0f'),' , ',num2str(6,'%1.0f')];
                app.SetEndAngleDisplay.Value =  [num2str(180,'%1.0f'),' , ',num2str(6,'%1.0f')];
            end

        end

        % Value changed function: SetStartAngleDisplay
        function SetStartAngleDisplayValueChanged(app, event)
            
            value = app.SetStartAngleDisplay.Value;
            
        end

        % Value changed function: SetEndAngleDisplay
        function SetEndAngleDisplayValueChanged(app, event)
            
            value = app.SetEndAngleDisplay.Value;
            
        end

        % Button pushed function: GoToStartViewButton
        function GoToStartViewButtonPushed(app, event)
            

            if isgraphics(app.TrackAndMovieFigureHandle)
                AzElStartValues = textscan(app.SetStartAngleDisplay.Value,'%s','delimiter',',');
                AzimuthStart    = str2double(AzElStartValues{1}{1});
                ElevationStart  = str2double(AzElStartValues{1}{2});
                view(app.TrackAndMovieAxesHandle,AzimuthStart,ElevationStart)
            end


        end

        % Button pushed function: GoToEndViewButton
        function GoToEndViewButtonPushed(app, event)
            
            if isgraphics(app.TrackAndMovieFigureHandle)
                AzElEndValues   = textscan(app.SetEndAngleDisplay.Value,'%s','delimiter',',');
                AzimuthEnd      = str2double(AzElEndValues{1}{1});
                ElevationEnd    = str2double(AzElEndValues{1}{2});
                view(app.TrackAndMovieAxesHandle,AzimuthEnd,ElevationEnd)
            end


        end

        % Value changed function: VectorLengthEditField
        function VectorLengthEditFieldValueChanged(app, event)
            
            value = app.VectorLengthEditField.Value;
            if value < 2 || value > 200
                 app.VectorLengthEditField.Value = 15;
            end
            TrackNum = app.SelectTrackDropDown.Value;
            RecordTrackPlottingProperties(app,TrackNum);
            
        end

        % Value changed function: VectorRadiusEditField
        function VectorRadiusEditFieldValueChanged(app, event)
            
            value = app.VectorRadiusEditField.Value;
            if value < 0.001 || value > 20
                app.VectorRadiusEditField.Value = 0.3;
            end
            TrackNum = app.SelectTrackDropDown.Value;
            RecordTrackPlottingProperties(app,TrackNum);

        end

        % Value changed function: AllVectorCheckBox
        function AllVectorCheckBoxValueChanged(app, event)
            
            TrackNum = app.SelectTrackDropDown.Value;
            RecordTrackPlottingProperties(app,TrackNum);
            
        end

        % Button pushed function: AllVectorColorButton
        function AllVectorColorButtonPushed(app, event)
            
            TrackNum = app.SelectTrackDropDown.Value;
            c = uisetcolor;
            if ~isequal(c,0)
                app.AllVectorColorDisplay.BackgroundColor = c; 
                RecordTrackPlottingProperties(app,TrackNum);
            end

        end

        % Value changed function: AllVectorTransparencySpinner
        function AllVectorTransparencySpinnerValueChanged(app, event)
            
            TrackNum = app.SelectTrackDropDown.Value;
            RecordTrackPlottingProperties(app,TrackNum);
            
        end

        % Value changed function: MovieVectorColorCheckBox
        function MovieVectorColorCheckBoxValueChanged(app, event)
            
            TrackNum = app.SelectTrackDropDown.Value;
            RecordTrackPlottingProperties(app,TrackNum);
            
        end

        % Button pushed function: VectorColorButton
        function VectorColorButtonPushed(app, event)
            
            TrackNum = app.SelectTrackDropDown.Value;
            c = uisetcolor;
            if ~isequal(c(1),0)
                app.MovieVectorColorDisplay.BackgroundColor = c;
                RecordTrackPlottingProperties(app,TrackNum);
            end

        end

        % Value changed function: MovieVectorTransparencySpinner
        function MovieVectorTransparencySpinnerValueChanged(app, event)
            
            TrackNum = app.SelectTrackDropDown.Value;
            RecordTrackPlottingProperties(app,TrackNum);
            
        end

        % Value changed function: MovieDataPlaneColormapDropDown
        function DataPlaneColormapDropDown_2ValueChanged(app, event)
            
            TrackNum = app.SelectTrackDropDown.Value;
            RecordTrackPlottingProperties(app,TrackNum);
            
        end

        % Value changed function: ColormapTypeDropDown
        function ColormapTypeDropDownValueChanged(app, event)
            
            TrackNum = app.SelectTrackDropDown.Value;
            RecordTrackPlottingProperties(app,TrackNum);

        end

        % Value changed function: MultiCellColormapDropDown
        function MultiCellColormapDropDownValueChanged(app, event)
           
            TrackNum = app.SelectTrackDropDown.Value;
            RecordTrackPlottingProperties(app,TrackNum);
            
        end

        % Value changed function: TrackSmoothingCheck
        function TrackSmoothingCheckValueChanged(app, event)
            
            TrackNum = app.SelectTrackDropDown.Value;
            RecordTrackPlottingProperties(app,TrackNum);
            
        end

        % Value changed function: TrackSmoothingNPointsDropDown
        function TrackSmoothingNPointsDropDownValueChanged(app, event)
            
            %value = app.TrackSmoothingNPointsDropDown.Value;
            TrackNum = app.SelectTrackDropDown.Value;
            RecordTrackPlottingProperties(app,TrackNum);
            
        end

        % Value changed function: FramespersecondEditField
        function FramespersecondEditFieldValueChanged(app, event)
            
            app.FramesPerSecond = app.FramespersecondEditField.Value;

        end

        % Value changed function: StopMovieButton
        function StopMovieButtonValueChanged(app, event)
            
            value = app.StopMovieButton.Value;            
            
        end

        % Value changed function: AllCellSurfaceUseColormapCheck
        function AllCellSurfaceUseColormapCheckValueChanged(app, event)
            
            TrackNum = app.SelectTrackDropDown.Value;
            RecordTrackPlottingProperties(app,TrackNum);
            
        end

        % Value changed function: AllCellMeshUseColormapCheck
        function AllCellMeshUseColormapCheckValueChanged(app, event)
            
            TrackNum = app.SelectTrackDropDown.Value;
            RecordTrackPlottingProperties(app,TrackNum);
            
        end

        % Value changed function: TrackLineUseColormapCheck
        function TrackLineUseColormapCheckValueChanged(app, event)
            
            TrackNum = app.SelectTrackDropDown.Value;
            RecordTrackPlottingProperties(app,TrackNum);
            
        end

        % Value changed function: AllVectorUseColormapCheck
        function AllVectorUseColormapCheckValueChanged(app, event)
            
            TrackNum = app.SelectTrackDropDown.Value;
            RecordTrackPlottingProperties(app,TrackNum);
            
        end

        % Value changed function: MovieCellSurfaceUseColormapCheck
        function MovieCellSurfaceUseColormapCheckValueChanged(app, event)
            
            TrackNum = app.SelectTrackDropDown.Value;
            RecordTrackPlottingProperties(app,TrackNum);
            
        end

        % Value changed function: MovieCellMeshUseColormapCheck
        function MovieCellMeshUseColormapCheckValueChanged(app, event)
            
            TrackNum = app.SelectTrackDropDown.Value;
            RecordTrackPlottingProperties(app,TrackNum);
            
        end

        % Value changed function: MovieCentroidUseColormapCheck
        function MovieCentroidUseColormapCheckValueChanged(app, event)
            
            TrackNum = app.SelectTrackDropDown.Value;
            RecordTrackPlottingProperties(app,TrackNum);
            
        end

        % Value changed function: MovieVectorUseColormapCheck
        function MovieVectorUseColormapCheckValueChanged(app, event)
            
            TrackNum = app.SelectTrackDropDown.Value;
            RecordTrackPlottingProperties(app,TrackNum);
            
        end

        % Value changed function: LineWidthDropDown
        function LineWidthDropDownValueChanged(app, event)
            
            TrackNum = app.SelectTrackDropDown.Value;
            RecordTrackPlottingProperties(app,TrackNum);
            
        end

        % Value changed function: PANslider
        function PANsliderValueChanged(app, event)
            
            value = app.PANslider.Value;
            
        end

        % Button pushed function: VelocityFilteringAndRangeButton
        function VelocityFilteringAndRangeButtonPushed(app, event)
            

            VFAhandle = SetVelocityFilteringParam(app, app.TimePointData, app.VelocityColormapRange, app.VelocityUnits, app.nVelocitySmoothingPoints); % Ask user how they want to filter
            uiwait(VFAhandle.VFAmain)

            app.nVelocitySmoothingPoints = app.DATA1;
            app.VelocityColormapRange = app.DATA2; 
            app.VelocityUnits = app.DATA3;
            
            switch app.VelocityUnits
                case 1; units = [char(956),'m/min'];
                case 2; units = 'nm/s';
            end

            app.CurrentVelocityRangeDisplay.Text = { ['Current velocity range: ',sprintf('%2.2f',app.VelocityColormapRange(1)),' - ',...
                                                       sprintf('%2.2f',app.VelocityColormapRange(2)),' ',units],...
                                                     ['nPts for velocity smoothing: ', sprintf('%2.2f',app.nVelocitySmoothingPoints)] };


        end

        % Button pushed function: RecordMovieButton
        function RecordMovieButtonPushed(app, event)
            
            DoMovie = true;
            UpdateCompositePlot(app,DoMovie)    

        end

        % Button pushed function: RenderingSummaryButton
        function RenderingSummaryButtonPushed(app, event)
             

            GraphicsHandle = RenderingSummary(app, app.TimePointData.SurfaceRenderings);      
            app.RenderingSummaryHandle = GraphicsHandle.RenderingSummaryTable; % Grab graphics handle of table in RenderingSummary window
            app.TrackEditorButton.Enable = true;   
            SendRenderingSummaryToSummaryWindow(app)

            
        end

        % Value changed function: IntersectionLineVisibilityButton
        function IntersectionLineVisibilityButtonValueChanged(app, event)
            
                               
                    if app.IntersectionLineVisibilityButton.Value
                        app.IntersectionLineVisibilityButton.Text = 'Intersection line: off';
                        if isgraphics(app.CellPlaneIntersectionLineHandle)  
                            set(app.CellPlaneIntersectionLineHandle,'Visible','off')
                        end
                    else
                        app.IntersectionLineVisibilityButton.Text = 'Intersection line: on';
                        if isgraphics(app.CellPlaneIntersectionLineHandle)  
                            set(app.CellPlaneIntersectionLineHandle,'Visible','on')
                        else
                            UpdateDataPlane(app)
                        end
                    end


        end

        % Value changed function: RenderingSmoothingOnCheckBox
        function RenderingSmoothingOnCheckBoxValueChanged(app, event)
       

                if app.RenderingSmoothingOnCheckBox.Value
                    app.SegSmoothSigmaSpinner.Enable = "on";
                    app.UseSmoothedForMeasurementsCheckBox.Enable = "on";
                else
                    app.SegSmoothSigmaSpinner.Enable = "off";
                    app.UseSmoothedForMeasurementsCheckBox.Enable = "off";
                    app.UseSmoothedForMeasurementsCheckBox.Value = false;
                end
                
                
                %TimePointSpinnerValueChanged(app, event)

                t  = app.CurrentTimePoint;
                track = app.CurrentTrack;
                ch = app.DataPlaneChannel.Value;
                source = 1;
                %============================================================================================================   
                       
                        % Delete cell rendering from previous time point if it exists --------------------------
                            if isgraphics(app.SingleCellRenderingHandle)        
                                delete(app.SingleCellRenderingHandle)
                            end    
                        
                        % Draw cell rendering for this timepoint if it already exists ------------------
                            if ~isempty(app.TimePointData.SurfaceRenderings{track,1}{t,1}) %&& ~isfield('app.TimePointData','SmoothedSurfaceRenderings')
                                if app.CellVisibilityButton.Value
                                   Visible = "on";
                                else
                                   Visible = "off";
                                end
    
                                if app.RenderingSmoothingOnCheckBox.Value && ~isempty(app.TimePointData.SmoothedSurfaceRenderings{track,1}{t,1})
                                    if isfield(app.TimePointData,'SmoothedSurfaceRenderings')
                                        app.SingleCellRenderingHandle = patch( app.SingleCellAxisHandle, app.TimePointData.SmoothedSurfaceRenderings{track,1}{t,1},'Visible',Visible);
                                    else
                                        app.SingleCellRenderingHandle = patch( app.SingleCellAxisHandle, app.TimePointData.SurfaceRenderings{track,1}{t,1},'Visible',Visible);
                                    end
                                else
                                    app.SingleCellRenderingHandle = patch( app.SingleCellAxisHandle, app.TimePointData.SurfaceRenderings{track,1}{t,1},'Visible',Visible);
                                end
                                
                                app.SingleCellRenderingHandle.FaceColor = app.FaceColor;
                                app.SingleCellRenderingHandle.FaceAlpha = app.FaceTransparency;  
                                app.SingleCellRenderingHandle.EdgeColor = app.MeshColor; 
                                app.SingleCellRenderingHandle.EdgeAlpha = app.MeshTransparency;
                                
                                % Update the rendering parameters used ----------------------------------------              
                                app.PadSpinner.Value = app.TimePointData.RenderingSettings{track,1}.Pad(t,1); 
                                %app.ContractionBiasSpinner.Value = app.TimePointData.RenderingSettings{track,1}.ContractBias(t,1); 
                                app.ExtendedMinSpinner.Value =     app.TimePointData.RenderingSettings{track,1}.ExtendedMin(t,1); 
                                drawnow
                                
                            else 
                                % If no rendering exists, use default rendering values --------------------------
                                app.PadSpinner.Value = app.DefaultRenderingValues.Pad; % app.TimePointData.RenderingSettings{track,1}.Pad(t,1); 
                                %app.ContractionBiasSpinner.Value = app.DefaultRenderingValues.ContractionBias; % app.TimePointData.RenderingSettings{track,1}.ContractBias(t,1); 
                                %app.SmoothFactorSpinner.Value = 0; % app.TimePointData.RenderingSettings{track,1}.SmoothFactor(t,1); 
                                app.ExtendedMinSpinner.Value = app.DefaultRenderingValues.ExtendedMin; %app.TimePointData.RenderingSettings{track,1}.ExtendedMin(t,1); 
                                %app.DataSourceDropDown.Value = app.DefaultRenderingValues.DataSource;
                                PanTiltTranslatePlane(app)
                            end
                        
                        % Update data plane and track point if it exists ------------------------------
                            if isgraphics(app.DataPlaneHandle.main)
                                delete(app.DataPlaneHandle.main)
                                delete(app.DataPlaneHandle.outline)
                            end
                            
                            if app.DataPlaneToggleButton.Value == 1                                
                                UpdateDataPlane(app); 
                            end


        end

        % Value changed function: CellVisibilityButton
        function CellVisibilityButtonValueChanged(app, event)
            
                                        
                    if ~app.CellVisibilityButton.Value
                        app.CellVisibilityButton.Text = 'Cell Visibility - OFF';
                        if isgraphics(app.SingleCellRenderingHandle)  
                            app.SingleCellRenderingHandle.Visible = 'off';
                        end
                    else
                        app.CellVisibilityButton.Text = 'Cell Visibility - ON';
                        if isgraphics(app.SingleCellRenderingHandle)  
                            app.SingleCellRenderingHandle.Visible = 'on';
                        end
                    end


        end

        % Value changed function: AutoUpdateDataPlanePositionCheckBox
        function AutoUpdateDataPlanePositionCheckBoxValueChanged(app, event)
                       
            value = app.AutoUpdateDataPlanePositionCheckBox.Value;
            
        end

        % Button pushed function: ChangeImageDirectoryButton
        function ChangeImageDirectoryButtonPushed(app, event)
            

            fig = app.LocusUIFigure;           
            msg = '\fontname{helvetica}\fontsize{10}You may select a new image data directory: File names may be different, but all data measurements must be the same. After directory selection, resave locus file.';
            selection = uiconfirm(fig,msg,"Confirm changing image data directory","Options",["Continue","Cancel"],"Icon","warning","Interpreter","tex");

            if strcmp(selection,'Continue')
                dname = uigetdir([],'Select new image data directory');                
                if ~isequal(dname,0)
                        % ---------------------------------------------------------------------------
                        F = struct2cell( dir(dname) )';
                        IsFile = ~cell2mat(F(:,5)); 
                        files = F(IsFile,1); % Grab all the files in the selected directory
                            
                        [Selection, ok] = listdlg('ListString',files,'ListSize',[250 400],'PromptString','Select image files','Name','Select image files');

                        if isequal(ok,1)
                                app.RGBZTDirectoryDisplay.Value = dname; % RGBZ timeseries location
                                app.RGBZT_Directory = dname;

                                files = files(Selection);
                                app.nTime = size(files,1);
                                files = MakeSureImageFilesAreSortedByTimestamp(app,files);
                                app.RGBZT_ImageFileNames = files;
                        end
                        % ---------------------------------------------------------------------------
                end
            end


        end

        % Value changed function: EllipsoidTranslationDisplay
        function EllipsoidTranslationDisplayValueChanged(app, event)
            
            value = app.EllipsoidTranslationDisplay.Value;
            
        end

        % Button pushed function: AboutButton
        function AboutButtonPushed(app, event)
            

                      default_color = [0.8 0.8 0.8];
            
                      message = [{' Locus (2024)'};...
                                 {'                    '};...
                                 {'    Programming and Design:'};...
                                 {'                    '};...
                                 {'             Keith R. Carney'};...
                                 {'             Senior Software Engineer,'};...
                                 {'             University of Utah'};
                                 {'                    '};...
                                 {'                    '};...
                                 {'    Contact:        '}; ...
                                 {'                    '};...
                                 {'             Kristen M. Kwan, Ph.D. '};...
                                 {'             kmkwan@genetics.utah.edu'};...
                                 {'             Department of Human Genetics, '};...
                                 {'             University of Utah'};...
                                 {'                    '};...
                                 {'                    '}];
            
                     f = figure('NumberTitle','off','Name','About Locus','Position',[200,200,420,300],...
                          'Resize','off','Toolbar','none','WindowStyle','normal','MenuBar','none');
            
                     uicontrol('Parent',f,'Style','text','FontName','FixedWidth','FontWeight','bold',...
                          'FontSize',10,'String',message,'Position',[15,15,400,260],'HorizontalAlignment','Left');


        end

        % Button pushed function: SaveLocusFileButton
        function SaveLocusFileButtonPushed(app, event)
            

            try
                 LocusSettings.LocusFileName           = app.LocusFileName;
                 LocusSettings.LocusFileDirectory      = app.LocusFileDirectory; 
                 LocusSettings.RGBZT_Directory         = app.RGBZT_Directory;     % 'D:\O15_TL_09042019\RGBZ time series'
                 LocusSettings.RGBZT_ImageFileNames    = app.RGBZT_ImageFileNames;
                 LocusSettings.CellTrackFilesInfo      = app.CellTrackFilesInfo;  % [1×1 struct]
                 LocusSettings.CellTrackData           = app.CellTrackData;       % {[300×3 double]}
                 LocusSettings.CellTrackOffset         = app.CellTrackOffset;     % {[300×3 double]}
                 % LocusSettings.CellRenderingsDirectory = app.CellRenderingsDirectory; % 'D:\O15_TL_09042019\SampleLocusProcessDataFile - renderings\'
                 LocusSettings.dp = app.dp; % 0.5930   
                 LocusSettings.dz = app.dz; % 1
                 LocusSettings.dt = app.dt; % 150
                 LocusSettings.SubVol_nR = app.RowsSubRegionField.Value; % app.SubVol_nR; % 101
                 LocusSettings.SubVol_nC = app.ColsSubRegionField.Value; % app.SubVol_nC; % 103
                 LocusSettings.SubVol_nZ = app.ZSubRegionField.Value;    % app.SubVol_nZ; % 71
                 LocusSettings.rHeight   = app.rHeight; % 384
                 LocusSettings.cWidth    = app.cWidth; % 512
                 LocusSettings.zDepth    = app.zDepth; % 95
                 LocusSettings.nTime     = app.nTime; % 300
                 LocusSettings.TrackPlottingProperties = app.TrackPlottingProperties;
                 LocusSettings.TrackFigureProperties   = app.TrackFigureProperties;
                 LocusSettings.DeconvolvedImageFilesDirectory = app.DeconvolvedImageFilesDirectory;
                 LocusRenderingData = app.TimePointData; % [1×1 struct]
                 
                 % LocusData.DATA5d = app.DATA5d; % {[5-D uint8]}
                 % LocusData.PROC5d = app.PROC5d; % {[5-D single]}
                 
                 LocusData.IND    = app.IND;    % {[2×3×300 uint16]}
                 LocusData.X      = app.X;      % [384×512×95 double]
                 LocusData.Y      = app.Y;      % [384×512×95 double]
                 LocusData.Z      = app.Z;      % [384×512×95 double]
                
                d = uiprogressdlg(app.LocusUIFigure,'Title','Saving Locus File... Please wait a minute.','Indeterminate','on');
                save( fullfile(app.LocusFileDirectory, app.LocusFileName) ,'LocusData','LocusSettings','LocusRenderingData','-v7.3')
                app.LoadedLocusFileDisplay.Value = fullfile(app.LocusFileDirectory,app.LocusFileName);
                Time = char(datetime('now','TimeZone','local','Format','d-MMM-y   HH:mm:ss'));
                app.LastSavedDisplay.Text = ['Last saved:   ',Time];
                close(d);
            catch
                uialert(app.LocusUIFigure,"Error saving locus file.","Saving error");
            end


        end

        % Value changed function: DataPlaneChannel
        function DataPlaneChannelValueChanged2(app, event)
                
            
            value = app.DataPlaneChannel.Value;
            t  = app.CurrentTimePoint;
            track = app.CurrentTrack;
            ch = app.DataPlaneChannel.Value;
            source = 1; % app.DataSourceDropDown.Value;

            [app.CurrentDataVolumeForTimeT, ~] = ReadSubRegionForTimeT(app,t,track,source); 
            UpdateDataPlane(app);     


        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.LocusUIFigure = uifigure('Visible', 'off');
            app.LocusUIFigure.Name = 'Locus';
            app.LocusUIFigure.CloseRequestFcn = createCallbackFcn(app, @LocusUIFigureCloseRequest, true);
            app.LocusUIFigure.KeyPressFcn = createCallbackFcn(app, @LocusUIFigureKeyPress, true);
            app.LocusUIFigure.KeyReleaseFcn = createCallbackFcn(app, @LocusUIFigureKeyRelease, true);
            app.TabGroup = uitabgroup(app.LocusUIFigure);
            app.DataTab = uitab(app.TabGroup);
            app.DataTab.Title = 'Data';
            app.LongtrackercelltrackfilesPanel = uipanel(app.DataTab);
            app.LongtrackercelltrackfilesPanel.Title = 'Longtracker cell track file(s):';
            app.AddTrackButton = uibutton(app.LongtrackercelltrackfilesPanel, 'push');
            app.AddTrackButton.ButtonPushedFcn = createCallbackFcn(app, @AddTrackButtonPushed, true);
            app.AddTrackButton.Text = '+';
            app.RemoveTrackButton = uibutton(app.LongtrackercelltrackfilesPanel, 'push');
            app.RemoveTrackButton.ButtonPushedFcn = createCallbackFcn(app, @RemoveTrackButtonPushed, true);
            app.RemoveTrackButton.Text = '-';
            app.CellTracksListBox1 = uilistbox(app.LongtrackercelltrackfilesPanel);
            app.CellTracksListBox1.Items = {'Item 2', 'Item 3', 'Item 4'};
            app.CellTracksListBox1.ValueChangedFcn = createCallbackFcn(app, @CellTracksListBox1ValueChanged, true);
            app.CellTracksListBox1.Value = 'Item 2';
            app.RGBZtimeseriesdirectoryPanel = uipanel(app.DataTab);
            app.RGBZtimeseriesdirectoryPanel.Title = 'RGB-Z time series directory:';
            app.RGBZTDirectoryDisplay = uitextarea(app.RGBZtimeseriesdirectoryPanel);
            app.RGBZTDirectoryDisplay.ValueChangedFcn = createCallbackFcn(app, @RGBZTDirectoryDisplayValueChanged, true);
            app.RGBZTDirectoryDisplay.Value = {'D:\O15_TL_09042019\RGBZ time series'};
            app.SelectFolderButton = uibutton(app.RGBZtimeseriesdirectoryPanel, 'push');
            app.SelectFolderButton.ButtonPushedFcn = createCallbackFcn(app, @SelectFolderButtonPushed, true);
            app.SelectFolderButton.Text = {'Select'; 'Folder'};
            app.ChangeImageDirectoryButton = uibutton(app.RGBZtimeseriesdirectoryPanel, 'push');
            app.ChangeImageDirectoryButton.ButtonPushedFcn = createCallbackFcn(app, @ChangeImageDirectoryButtonPushed, true);
            app.ChangeImageDirectoryButton.Text = 'Change';
            app.DatameasurementsPanel = uipanel(app.DataTab);
            app.DatameasurementsPanel.Title = 'Data measurements:';
            app.PixelSizemicronsEditFieldLabel = uilabel(app.DatameasurementsPanel);
            app.PixelSizemicronsEditFieldLabel.Text = 'Pixel Size (microns)';
            app.PixelSizeField = uieditfield(app.DatameasurementsPanel, 'numeric');
            app.PixelSizeField.ValueDisplayFormat = '%1.3f';
            app.PixelSizeField.ValueChangedFcn = createCallbackFcn(app, @PixelSizeFieldValueChanged, true);
            app.ZstepmicronsLabel = uilabel(app.DatameasurementsPanel);
            app.ZstepmicronsLabel.Text = 'Z-step (microns)';
            app.ZStepField = uieditfield(app.DatameasurementsPanel, 'numeric');
            app.ZStepField.ValueDisplayFormat = '%1.3f';
            app.ZStepField.ValueChangedFcn = createCallbackFcn(app, @ZStepFieldValueChanged, true);
            app.TimeStepsecondsLabel = uilabel(app.DatameasurementsPanel);
            app.TimeStepsecondsLabel.Text = 'Time Step (seconds)';
            app.TimeStepField = uieditfield(app.DatameasurementsPanel, 'numeric');
            app.TimeStepField.ValueDisplayFormat = '%4.1f';
            app.TimeStepField.ValueChangedFcn = createCallbackFcn(app, @TimeStepFieldValueChanged, true);
            app.DatavolumesubregionsizePanel = uipanel(app.DataTab);
            app.DatavolumesubregionsizePanel.Title = 'Data volume sub-region size:';
            app.RowsvoxelsLabel = uilabel(app.DatavolumesubregionsizePanel);
            app.RowsvoxelsLabel.Text = 'Rows (voxels)';
            app.RowsSubRegionField = uieditfield(app.DatavolumesubregionsizePanel, 'numeric');
            app.RowsSubRegionField.Limits = [20 1000];
            app.RowsSubRegionField.ValueDisplayFormat = '%4d';
            app.RowsSubRegionField.ValueChangedFcn = createCallbackFcn(app, @RowsSubRegionFieldValueChanged, true);
            app.RowsSubRegionField.Tag = 'RowsInput';
            app.RowsSubRegionField.Value = 20;
            app.ColumnsvoxelsLabel = uilabel(app.DatavolumesubregionsizePanel);
            app.ColumnsvoxelsLabel.Text = 'Columns (voxels)';
            app.ColsSubRegionField = uieditfield(app.DatavolumesubregionsizePanel, 'numeric');
            app.ColsSubRegionField.Limits = [20 1000];
            app.ColsSubRegionField.ValueDisplayFormat = '%4d';
            app.ColsSubRegionField.ValueChangedFcn = createCallbackFcn(app, @ColsSubRegionFieldValueChanged, true);
            app.ColsSubRegionField.Tag = 'CollInput';
            app.ColsSubRegionField.Value = 20;
            app.ZvoxelsLabel = uilabel(app.DatavolumesubregionsizePanel);
            app.ZvoxelsLabel.Text = 'Z (voxels)';
            app.ZSubRegionField = uieditfield(app.DatavolumesubregionsizePanel, 'numeric');
            app.ZSubRegionField.Limits = [20 1000];
            app.ZSubRegionField.ValueDisplayFormat = '%4d';
            app.ZSubRegionField.ValueChangedFcn = createCallbackFcn(app, @ZSubRegionFieldValueChanged, true);
            app.ZSubRegionField.Tag = 'ZInput';
            app.ZSubRegionField.Value = 20;
            app.InputvaluesusingkeypadtoshortcutconflictsLabel = uilabel(app.DatavolumesubregionsizePanel);
            app.InputvaluesusingkeypadtoshortcutconflictsLabel.Text = 'Input values using keypad to avoid conflicts with keyboard shortcuts.';
            app.AboutButton = uibutton(app.DataTab, 'push');
            app.AboutButton.ButtonPushedFcn = createCallbackFcn(app, @AboutButtonPushed, true);
            app.AboutButton.Text = 'About';
            app.Load_Locus_File_Button = uibutton(app.DataTab, 'push');
            app.Load_Locus_File_Button.ButtonPushedFcn = createCallbackFcn(app, @Load_Locus_File_ButtonPushed, true);
            app.Load_Locus_File_Button.Text = 'LOAD Locus File';
            app.ShortcutsButton = uibutton(app.DataTab, 'push');
            app.ShortcutsButton.ButtonPushedFcn = createCallbackFcn(app, @ShortcutsButtonPushed, true);
            app.ShortcutsButton.Text = 'Shortcuts';
            app.SavelocusfilePanel = uipanel(app.DataTab);
            app.SavelocusfilePanel.Title = 'Save locus file:';
            app.LoadedLocusFileDisplay = uitextarea(app.SavelocusfilePanel);
            app.SaveAsLocusFileButton = uibutton(app.SavelocusfilePanel, 'push');
            app.SaveAsLocusFileButton.ButtonPushedFcn = createCallbackFcn(app, @SaveAsLocusFileButtonPushed, true);
            app.SaveAsLocusFileButton.Text = 'Save As';
            app.SaveLocusFileButton = uibutton(app.SavelocusfilePanel, 'push');
            app.SaveLocusFileButton.ButtonPushedFcn = createCallbackFcn(app, @SaveLocusFileButtonPushed, true);
            app.SaveLocusFileButton.Text = 'Save';
            app.LastSavedDisplay = uilabel(app.SavelocusfilePanel);
            app.LastSavedDisplay.Text = 'Last saved:';
            app.SingleCellRenderingTab = uitab(app.TabGroup);
            app.SingleCellRenderingTab.Title = 'Single Cell Rendering';
            app.CellRenderingandTrackPointControlPanel = uipanel(app.SingleCellRenderingTab);
            app.CellRenderingandTrackPointControlPanel.Title = 'Cell Rendering and Track Point Control';
            app.SaveLocusFileButton2 = uibutton(app.CellRenderingandTrackPointControlPanel, 'push');
            app.SaveLocusFileButton2.ButtonPushedFcn = createCallbackFcn(app, @SaveLocusFileButton2Pushed, true);
            app.SaveLocusFileButton2.Text = {'SAVE '; 'LOCUS FILE'};
            app.ConnectionLinePanel = uipanel(app.CellRenderingandTrackPointControlPanel);
            app.ConnectionLinePanel.Title = 'Connection Line';
            app.clearButton = uibutton(app.ConnectionLinePanel, 'push');
            app.clearButton.ButtonPushedFcn = createCallbackFcn(app, @clearButtonPushed, true);
            app.clearButton.Text = {'clear'; ''};
            app.ConnectionLineSTARTButton = uibutton(app.ConnectionLinePanel, 'state');
            app.ConnectionLineSTARTButton.ValueChangedFcn = createCallbackFcn(app, @ConnectionLineSTARTButtonValueChanged, true);
            app.ConnectionLineSTARTButton.Text = 'START';
            app.ShowTrackPointButton = uibutton(app.CellRenderingandTrackPointControlPanel, 'state');
            app.ShowTrackPointButton.ValueChangedFcn = createCallbackFcn(app, @ShowTrackPointButtonValueChanged, true);
            app.ShowTrackPointButton.Text = 'Hide Track Point';
            app.UpdateRenderingButton = uibutton(app.CellRenderingandTrackPointControlPanel, 'push');
            app.UpdateRenderingButton.ButtonPushedFcn = createCallbackFcn(app, @UpdateRenderingButtonPushed, true);
            app.UpdateRenderingButton.Text = {'UPDATE'; 'Rendering'; ''};
            app.SeparationLineBox = uipanel(app.CellRenderingandTrackPointControlPanel);
            app.SeparationLineBox.Title = 'Separation Line';
            app.CLEARSepButton = uibutton(app.SeparationLineBox, 'push');
            app.CLEARSepButton.ButtonPushedFcn = createCallbackFcn(app, @CLEARSepButtonPushed, true);
            app.CLEARSepButton.Text = {'clear'; ''};
            app.SeparationLineSTARTButton = uibutton(app.SeparationLineBox, 'state');
            app.SeparationLineSTARTButton.ValueChangedFcn = createCallbackFcn(app, @SeparationLineSTARTButtonValueChanged, true);
            app.SeparationLineSTARTButton.Text = 'START';
            app.ColorsPanel = uipanel(app.CellRenderingandTrackPointControlPanel);
            app.ColorsPanel.Title = 'Colors';
            app.FaceColorButton = uibutton(app.ColorsPanel, 'push');
            app.FaceColorButton.ButtonPushedFcn = createCallbackFcn(app, @FaceColorButtonPushed, true);
            app.FaceColorButton.Text = 'Surface';
            app.FaceColorDisplay = uipanel(app.ColorsPanel);
            app.MeshColorButton = uibutton(app.ColorsPanel, 'push');
            app.MeshColorButton.ButtonPushedFcn = createCallbackFcn(app, @MeshColorButtonPushed, true);
            app.MeshColorButton.Text = 'Mesh';
            app.MeshColorDisplay = uipanel(app.ColorsPanel);
            app.TransparencyLabel = uilabel(app.ColorsPanel);
            app.TransparencyLabel.Text = 'Transparency';
            app.FaceTransparencySpinner = uispinner(app.ColorsPanel);
            app.FaceTransparencySpinner.Step = 5;
            app.FaceTransparencySpinner.ValueChangingFcn = createCallbackFcn(app, @FaceTransparencySpinnerValueChanging, true);
            app.FaceTransparencySpinner.Limits = [0 100];
            app.FaceTransparencySpinner.Value = 100;
            app.TransparencySpinner_2Label = uilabel(app.ColorsPanel);
            app.TransparencySpinner_2Label.Text = 'Transparency';
            app.MeshTransparencySpinner = uispinner(app.ColorsPanel);
            app.MeshTransparencySpinner.Step = 5;
            app.MeshTransparencySpinner.ValueChangingFcn = createCallbackFcn(app, @MeshTransparencySpinnerValueChanging, true);
            app.MeshTransparencySpinner.Limits = [0 100];
            app.MeshTransparencySpinner.Value = 100;
            app.RenderingParametersPanel = uipanel(app.CellRenderingandTrackPointControlPanel);
            app.RenderingParametersPanel.Title = 'Rendering Parameters';
            app.ExtendedMinimumLabel = uilabel(app.RenderingParametersPanel);
            app.ExtendedMinimumLabel.Text = {'Extended'; 'Minimum'};
            app.ExtendedMinSpinner = uispinner(app.RenderingParametersPanel);
            app.ExtendedMinSpinner.Step = 0.01;
            app.ExtendedMinSpinner.Limits = [0 0.2];
            app.ExtendedMinSpinner.Value = 0.1;
            app.PadSpinnerLabel = uilabel(app.RenderingParametersPanel);
            app.PadSpinnerLabel.Text = 'Pad(';
            app.PadSpinner = uispinner(app.RenderingParametersPanel);
            app.PadSpinner.Step = 2;
            app.PadSpinner.Limits = [10 50];
            app.PadSpinner.Value = 15;
            app.mLabel = uilabel(app.RenderingParametersPanel);
            app.mLabel.Text = ' m';
            app.mLabel_2 = uilabel(app.RenderingParametersPanel);
            app.mLabel_2.Text = ' m)';
            app.defaultvaluesButton = uibutton(app.RenderingParametersPanel, 'push');
            app.defaultvaluesButton.ButtonPushedFcn = createCallbackFcn(app, @defaultvaluesButtonPushed, true);
            app.defaultvaluesButton.Text = {'default'; 'values'};
            app.setdefaultsButton = uibutton(app.RenderingParametersPanel, 'push');
            app.setdefaultsButton.ButtonPushedFcn = createCallbackFcn(app, @setdefaultsButtonPushed, true);
            app.setdefaultsButton.Text = {'set'; 'defaults'};
            app.RenderingsmoothingPanel = uipanel(app.CellRenderingandTrackPointControlPanel);
            app.RenderingsmoothingPanel.Title = 'Rendering smoothing';
            app.RenderingSmoothingOnCheckBox = uicheckbox(app.RenderingsmoothingPanel);
            app.RenderingSmoothingOnCheckBox.ValueChangedFcn = createCallbackFcn(app, @RenderingSmoothingOnCheckBoxValueChanged, true);
            app.RenderingSmoothingOnCheckBox.Text = 'on';
            app.RenderingSmoothingOnCheckBox.Value = true;
            app.sigmaSpinnerLabel_2 = uilabel(app.RenderingsmoothingPanel);
            app.sigmaSpinnerLabel_2.Text = 'sigma:';
            app.SegSmoothSigmaSpinner = uispinner(app.RenderingsmoothingPanel);
            app.SegSmoothSigmaSpinner.Step = 0.25;
            app.SegSmoothSigmaSpinner.Limits = [0.5 4];
            app.SegSmoothSigmaSpinner.ValueDisplayFormat = '%0.2f';
            app.SegSmoothSigmaSpinner.Value = 1;
            app.UseSmoothedForMeasurementsCheckBox = uicheckbox(app.RenderingsmoothingPanel);
            app.UseSmoothedForMeasurementsCheckBox.Text = {'Use smoothed rendering'; 'for measurements'};
            app.UseSmoothedForMeasurementsCheckBox.Value = true;
            app.TrackOffsetPanel = uipanel(app.CellRenderingandTrackPointControlPanel);
            app.TrackOffsetPanel.Title = 'Track Offset';
            app.XSpinnerLabel_2 = uilabel(app.TrackOffsetPanel);
            app.XSpinnerLabel_2.Text = 'X:';
            app.TrackPointOffset_X = uispinner(app.TrackOffsetPanel);
            app.TrackPointOffset_X.Step = 0.5;
            app.TrackPointOffset_X.Limits = [-200 200];
            app.TrackPointOffset_X.ValueDisplayFormat = '%3.1f';
            app.TrackPointOffset_X.ValueChangedFcn = createCallbackFcn(app, @TrackPointOffset_XValueChanged, true);
            app.TrackPointOffset_X.Value = 25.2;
            app.YLabel = uilabel(app.TrackOffsetPanel);
            app.YLabel.Text = 'Y:';
            app.TrackPointOffset_Y = uispinner(app.TrackOffsetPanel);
            app.TrackPointOffset_Y.Step = 0.5;
            app.TrackPointOffset_Y.Limits = [-200 200];
            app.TrackPointOffset_Y.ValueDisplayFormat = '%3.1f';
            app.TrackPointOffset_Y.ValueChangedFcn = createCallbackFcn(app, @TrackPointOffset_YValueChanged, true);
            app.ZLabel = uilabel(app.TrackOffsetPanel);
            app.ZLabel.Text = 'Z:';
            app.TrackPointOffset_Z = uispinner(app.TrackOffsetPanel);
            app.TrackPointOffset_Z.Step = 0.5;
            app.TrackPointOffset_Z.Limits = [-200 200];
            app.TrackPointOffset_Z.ValueDisplayFormat = '%3.1f';
            app.TrackPointOffset_Z.ValueChangedFcn = createCallbackFcn(app, @TrackPointOffset_ZValueChanged, true);
            app.TrackPointResetButton = uibutton(app.TrackOffsetPanel, 'push');
            app.TrackPointResetButton.Text = {'0'; ''};
            app.RenderingSummaryButton = uibutton(app.TrackOffsetPanel, 'push');
            app.RenderingSummaryButton.ButtonPushedFcn = createCallbackFcn(app, @RenderingSummaryButtonPushed, true);
            app.RenderingSummaryButton.Text = {'Rendering'; 'Summary'};
            app.TrackEditorButton = uibutton(app.TrackOffsetPanel, 'push');
            app.TrackEditorButton.ButtonPushedFcn = createCallbackFcn(app, @TrackEditorButtonPushed, true);
            app.TrackEditorButton.Text = 'Track Editor';
            app.CellVisibilityButton = uibutton(app.CellRenderingandTrackPointControlPanel, 'state');
            app.CellVisibilityButton.ValueChangedFcn = createCallbackFcn(app, @CellVisibilityButtonValueChanged, true);
            app.CellVisibilityButton.Text = 'Cell Visibility';
            app.CurrentTimepointAndTrackPanel = uipanel(app.SingleCellRenderingTab);
            app.OPENRenderingFigureButton = uibutton(app.CurrentTimepointAndTrackPanel, 'push');
            app.OPENRenderingFigureButton.ButtonPushedFcn = createCallbackFcn(app, @OPENRenderingFigureButtonPushed, true);
            app.OPENRenderingFigureButton.Text = 'OPEN Rendering Figure';
            app.AutoRenderAllCellsButton = uibutton(app.CurrentTimepointAndTrackPanel, 'push');
            app.AutoRenderAllCellsButton.ButtonPushedFcn = createCallbackFcn(app, @AutoRenderAllCellsButtonPushed, true);
            app.AutoRenderAllCellsButton.Text = 'Render ALL Cells';
            app.CurrentTrackLabel = uilabel(app.CurrentTimepointAndTrackPanel);
            app.CurrentTrackLabel.Text = 'Current Track:';
            app.CurrentTrackDropDown = uidropdown(app.CurrentTimepointAndTrackPanel);
            app.CurrentTrackDropDown.Items = {'Track 1', 'Track 2', 'Track 3', 'Track 4'};
            app.CurrentTrackDropDown.ItemsData = {'1,2,3,4'};
            app.CurrentTrackDropDown.ValueChangedFcn = createCallbackFcn(app, @CurrentTrackDropDownValueChanged, true);
            app.CurrentTrackDropDown.Value = '1,2,3,4';
            app.TimePointSpinner = uispinner(app.CurrentTimepointAndTrackPanel);
            app.TimePointSpinner.Limits = [1 9999];
            app.TimePointSpinner.ValueDisplayFormat = '%04d';
            app.TimePointSpinner.ValueChangedFcn = createCallbackFcn(app, @TimePointSpinnerValueChanged, true);
            app.TimePointSpinner.Value = 2;
            app.DataPlaneManipulationPanel = uipanel(app.SingleCellRenderingTab);
            app.DataPlaneManipulationPanel.Title = 'Data Plane Manipulation';
            app.Panel_11 = uipanel(app.DataPlaneManipulationPanel);
            app.GaussianFiltSwitchLabel = uilabel(app.Panel_11);
            app.GaussianFiltSwitchLabel.Text = 'Gaussian Filt:';
            app.GaussianFiltSwitch = uiswitch(app.Panel_11, 'slider');
            app.GaussianFiltSwitch.ItemsData = {'false', 'true'};
            app.GaussianFiltSwitch.ValueChangedFcn = createCallbackFcn(app, @GaussianFiltSwitchValueChanged, true);
            app.GaussianFiltSwitch.Value = 'false';
            app.GaussFiltLamp = uilamp(app.Panel_11);
            app.sigmaSpinnerLabel = uilabel(app.Panel_11);
            app.sigmaSpinnerLabel.Text = 'sigma:';
            app.SigmaSpinner = uispinner(app.Panel_11);
            app.SigmaSpinner.Step = 0.25;
            app.SigmaSpinner.Limits = [0.5 4];
            app.SigmaSpinner.ValueDisplayFormat = '%0.2f';
            app.SigmaSpinner.ValueChangedFcn = createCallbackFcn(app, @SigmaSpinnerValueChanged, true);
            app.SigmaSpinner.Value = 4;
            app.Panel_9 = uipanel(app.DataPlaneManipulationPanel);
            app.AdaptHistEqSwitchLabel = uilabel(app.Panel_9);
            app.AdaptHistEqSwitchLabel.Text = 'AdaptHistEq:';
            app.AdaptHistEqSwitch = uiswitch(app.Panel_9, 'slider');
            app.AdaptHistEqSwitch.ItemsData = {'false', 'true'};
            app.AdaptHistEqSwitch.ValueChangedFcn = createCallbackFcn(app, @AdaptHistEqSwitchValueChanged, true);
            app.AdaptHistEqSwitch.Value = 'false';
            app.AdaptHistEqLamp = uilamp(app.Panel_9);
            app.Panel_10 = uipanel(app.DataPlaneManipulationPanel);
            app.FrangiFilterLabel = uilabel(app.Panel_10);
            app.FrangiFilterLabel.Text = 'Frangi Filter:';
            app.FrangiFilterSwitch = uiswitch(app.Panel_10, 'slider');
            app.FrangiFilterSwitch.ItemsData = {'false', 'true'};
            app.FrangiFilterSwitch.ValueChangedFcn = createCallbackFcn(app, @FrangiFilterSwitchValueChanged, true);
            app.FrangiFilterSwitch.Value = 'false';
            app.FrangiLamp = uilamp(app.Panel_10);
            app.TranslationRotationTabGroup = uitabgroup(app.DataPlaneManipulationPanel);
            app.EllipsoidTranslationRotationTab = uitab(app.TranslationRotationTabGroup);
            app.EllipsoidTranslationRotationTab.Title = 'Ellipsoid Translation & Rotation';
            app.Er3ViewButton = uibutton(app.EllipsoidTranslationRotationTab, 'push');
            app.Er3ViewButton.ButtonPushedFcn = createCallbackFcn(app, @Er3ViewButtonPushed, true);
            app.Er3ViewButton.Text = {'R3 view '; 'R1 rotation'};
            app.Er2ViewButton = uibutton(app.EllipsoidTranslationRotationTab, 'push');
            app.Er2ViewButton.ButtonPushedFcn = createCallbackFcn(app, @Er2ViewButtonPushed, true);
            app.Er2ViewButton.Text = {'R2 view'; 'R1 rotation'; ''};
            app.Er1ViewButton = uibutton(app.EllipsoidTranslationRotationTab, 'push');
            app.Er1ViewButton.ButtonPushedFcn = createCallbackFcn(app, @Er1ViewButtonPushed, true);
            app.Er1ViewButton.Text = {'R1 view '; 'R1 translation'};
            app.EllipsoidRotationKnob = uiknob(app.EllipsoidTranslationRotationTab, 'continuous');
            app.EllipsoidRotationKnob.Limits = [-90 90];
            app.EllipsoidRotationKnob.MajorTicks = [-90 -75 -60 -45 -30 -15 0 15 30 45 60 75 90];
            app.EllipsoidRotationKnob.ValueChangedFcn = createCallbackFcn(app, @EllipsoidRotationKnobValueChanged, true);
            app.EllipsoidRotationKnob.ValueChangingFcn = createCallbackFcn(app, @EllipsoidRotationKnobValueChanging, true);
            app.RotationLabel = uilabel(app.EllipsoidTranslationRotationTab);
            app.RotationLabel.Text = {'Rotation'; ''};
            app.EllipsoidRotationDisplay = uieditfield(app.EllipsoidTranslationRotationTab, 'numeric');
            app.EllipsoidRotationDisplay.Value = -180;
            app.EllipsoidTranslationSlider = uislider(app.EllipsoidTranslationRotationTab);
            app.EllipsoidTranslationSlider.Limits = [-20 20];
            app.EllipsoidTranslationSlider.MajorTicks = [-50 -40 -30 -20 -10 0 10 20 30 40 50];
            app.EllipsoidTranslationSlider.ValueChangedFcn = createCallbackFcn(app, @EllipsoidTranslationSliderValueChanged, true);
            app.EllipsoidTranslationSlider.ValueChangingFcn = createCallbackFcn(app, @EllipsoidTranslationSliderValueChanging, true);
            app.TranslationEditFieldLabel = uilabel(app.EllipsoidTranslationRotationTab);
            app.TranslationEditFieldLabel.Text = 'Translation';
            app.EllipsoidTranslationDisplay = uieditfield(app.EllipsoidTranslationRotationTab, 'text');
            app.EllipsoidTranslationDisplay.ValueChangedFcn = createCallbackFcn(app, @EllipsoidTranslationDisplayValueChanged, true);
            app.EllipsoidRotationZero = uibutton(app.EllipsoidTranslationRotationTab, 'push');
            app.EllipsoidRotationZero.ButtonPushedFcn = createCallbackFcn(app, @EllipsoidRotationZeroButtonPushed, true);
            app.EllipsoidRotationZero.Text = '0';
            app.EllipsoidTranslationZero = uibutton(app.EllipsoidTranslationRotationTab, 'push');
            app.EllipsoidTranslationZero.ButtonPushedFcn = createCallbackFcn(app, @EllipsoidTranslationZeroButtonPushed, true);
            app.EllipsoidTranslationZero.Text = '0';
            app.ELamp1 = uilamp(app.EllipsoidTranslationRotationTab);
            app.ELamp2 = uilamp(app.EllipsoidTranslationRotationTab);
            app.ELamp3 = uilamp(app.EllipsoidTranslationRotationTab);
            app.EllipsoidFitAlertsDisplay = uilabel(app.EllipsoidTranslationRotationTab);
            app.EllipsoidFitAlertsDisplay.Text = 'Not enough points for ellipsoid fit!';
            app.DefaultViewR1 = uicheckbox(app.EllipsoidTranslationRotationTab);
            app.DefaultViewR1.ValueChangedFcn = createCallbackFcn(app, @DefaultViewR1ValueChanged, true);
            app.DefaultViewR1.Text = '';
            app.DefaultViewR2 = uicheckbox(app.EllipsoidTranslationRotationTab);
            app.DefaultViewR2.ValueChangedFcn = createCallbackFcn(app, @DefaultViewR2ValueChanged, true);
            app.DefaultViewR2.Text = '';
            app.DefaultViewR3 = uicheckbox(app.EllipsoidTranslationRotationTab);
            app.DefaultViewR3.ValueChangedFcn = createCallbackFcn(app, @DefaultViewR3ValueChanged, true);
            app.DefaultViewR3.Text = '';
            app.AutoUpdateDataPlanePositionCheckBox = uicheckbox(app.EllipsoidTranslationRotationTab);
            app.AutoUpdateDataPlanePositionCheckBox.ValueChangedFcn = createCallbackFcn(app, @AutoUpdateDataPlanePositionCheckBoxValueChanged, true);
            app.AutoUpdateDataPlanePositionCheckBox.Text = 'Auto update position';
            app.AutoUpdateDataPlanePositionCheckBox.Value = true;
            app.AutoUpdateViewCheckBox = uicheckbox(app.EllipsoidTranslationRotationTab);
            app.AutoUpdateViewCheckBox.Text = 'Auto update view';
            app.AutoUpdateViewCheckBox.Value = true;
            app.GeneralTranslationRotationTab = uitab(app.TranslationRotationTabGroup);
            app.GeneralTranslationRotationTab.Title = 'General Translation & Rotation';
            app.TranslationPanel = uipanel(app.GeneralTranslationRotationTab);
            app.TranslationPanel.Title = 'Translation';
            app.XSlider = uislider(app.TranslationPanel);
            app.XSlider.Limits = [-20 20];
            app.XSlider.ValueChangedFcn = createCallbackFcn(app, @XSliderValueChanged, true);
            app.XSlider.ValueChangingFcn = createCallbackFcn(app, @XSliderValueChanging, true);
            app.XSlider.MinorTicks = [];
            app.YSlider = uislider(app.TranslationPanel);
            app.YSlider.Limits = [-20 20];
            app.YSlider.ValueChangingFcn = createCallbackFcn(app, @YSliderValueChanging, true);
            app.YSlider.MinorTicks = [];
            app.ZSlider = uislider(app.TranslationPanel);
            app.ZSlider.Limits = [-20 20];
            app.ZSlider.ValueChangingFcn = createCallbackFcn(app, @ZSliderValueChanging, true);
            app.ZSlider.MinorTicks = [];
            app.XSpinnerLabel = uilabel(app.TranslationPanel);
            app.XSpinnerLabel.Text = 'X:';
            app.XSliderSpinner = uispinner(app.TranslationPanel);
            app.XSliderSpinner.Limits = [-200 200];
            app.XSliderSpinner.ValueDisplayFormat = '%3.1f';
            app.XSliderSpinner.ValueChangedFcn = createCallbackFcn(app, @XSliderSpinnerValueChanged, true);
            app.XSliderSpinner.Value = 100.2;
            app.YSpinnerLabel = uilabel(app.TranslationPanel);
            app.YSpinnerLabel.Text = 'Y:';
            app.YSliderSpinner = uispinner(app.TranslationPanel);
            app.YSliderSpinner.Limits = [-200 200];
            app.YSliderSpinner.ValueDisplayFormat = '%3.1f';
            app.YSliderSpinner.ValueChangedFcn = createCallbackFcn(app, @YSliderSpinnerValueChanged, true);
            app.YSliderSpinner.Value = 200;
            app.ZSpinnerLabel = uilabel(app.TranslationPanel);
            app.ZSpinnerLabel.Text = 'Z:';
            app.ZSliderSpinner = uispinner(app.TranslationPanel);
            app.ZSliderSpinner.Limits = [-200 200];
            app.ZSliderSpinner.ValueDisplayFormat = '%3.1f';
            app.ZSliderSpinner.ValueChangedFcn = createCallbackFcn(app, @ZSliderSpinnerValueChanged, true);
            app.ZSliderSpinner.Value = 200;
            app.XSliderZeroButton = uibutton(app.TranslationPanel, 'push');
            app.XSliderZeroButton.ButtonPushedFcn = createCallbackFcn(app, @XSliderZeroButtonPushed, true);
            app.XSliderZeroButton.Text = {'0X'; ''};
            app.YSliderZeroButton = uibutton(app.TranslationPanel, 'push');
            app.YSliderZeroButton.ButtonPushedFcn = createCallbackFcn(app, @YSliderZeroButtonPushed, true);
            app.YSliderZeroButton.Text = '0Y';
            app.ZSliderZeroButton = uibutton(app.TranslationPanel, 'push');
            app.ZSliderZeroButton.ButtonPushedFcn = createCallbackFcn(app, @ZSliderZeroButtonPushed, true);
            app.ZSliderZeroButton.Text = '0Z';
            app.MaxDistSpinner = uispinner(app.TranslationPanel);
            app.MaxDistSpinner.Limits = [10 200];
            app.MaxDistSpinner.ValueChangedFcn = createCallbackFcn(app, @MaxDistSpinnerValueChanged, true);
            app.MaxDistSpinner.Value = 20;
            app.MaxDistLabel = uilabel(app.TranslationPanel);
            app.MaxDistLabel.Text = 'Max Dist:';
            app.StepSizeSpinner = uispinner(app.TranslationPanel);
            app.StepSizeSpinner.Limits = [0.1 20];
            app.StepSizeSpinner.RoundFractionalValues = 'on';
            app.StepSizeSpinner.ValueDisplayFormat = '%0.0f';
            app.StepSizeSpinner.ValueChangedFcn = createCallbackFcn(app, @StepSizeSpinnerValueChanged, true);
            app.StepSizeSpinner.Value = 1;
            app.StepSizeLabel = uilabel(app.TranslationPanel);
            app.StepSizeLabel.Text = 'Step Size:';
            app.RotationPanel = uipanel(app.GeneralTranslationRotationTab);
            app.RotationPanel.Title = 'Rotation';
            app.PANEditFieldLabel = uilabel(app.RotationPanel);
            app.PANEditFieldLabel.Text = 'PAN:';
            app.PANeditField = uieditfield(app.RotationPanel, 'numeric');
            app.PANeditField.ValueDisplayFormat = '%.0f';
            app.PANeditField.ValueChangedFcn = createCallbackFcn(app, @PANeditFieldValueChanged, true);
            app.PANeditField.Value = -180;
            app.TILTLabel = uilabel(app.RotationPanel);
            app.TILTLabel.Text = 'TILT :';
            app.TILTeditField = uieditfield(app.RotationPanel, 'numeric');
            app.TILTeditField.ValueDisplayFormat = '%.0f';
            app.TILTeditField.Value = -90;
            app.TiltZeroButton = uibutton(app.RotationPanel, 'push');
            app.TiltZeroButton.ButtonPushedFcn = createCallbackFcn(app, @TiltZeroButtonPushed, true);
            app.TiltZeroButton.Text = '0';
            app.PanZeroButton = uibutton(app.RotationPanel, 'push');
            app.PanZeroButton.ButtonPushedFcn = createCallbackFcn(app, @PanZeroButtonPushed, true);
            app.PanZeroButton.Text = '0';
            app.Pan45Button = uibutton(app.RotationPanel, 'push');
            app.Pan45Button.ButtonPushedFcn = createCallbackFcn(app, @Pan45ButtonPushed, true);
            app.Pan45Button.Text = '45';
            app.Pan90Button = uibutton(app.RotationPanel, 'push');
            app.Pan90Button.ButtonPushedFcn = createCallbackFcn(app, @Pan90ButtonPushed, true);
            app.Pan90Button.Text = {'90'; ''};
            app.Tilt45Button = uibutton(app.RotationPanel, 'push');
            app.Tilt45Button.ButtonPushedFcn = createCallbackFcn(app, @Tilt45ButtonPushed, true);
            app.Tilt45Button.Text = '45';
            app.Tilt90Button = uibutton(app.RotationPanel, 'push');
            app.Tilt90Button.ButtonPushedFcn = createCallbackFcn(app, @Tilt90ButtonPushed, true);
            app.Tilt90Button.Text = {'90'; ''};
            app.TILTslider = uislider(app.RotationPanel);
            app.TILTslider.Limits = [-90 90];
            app.TILTslider.MajorTicks = [-90 -60 -30 0 30 60 90];
            app.TILTslider.MajorTickLabels = {'-90', '-60', '-30', '0', '30', '60', '90'};
            app.TILTslider.ValueChangingFcn = createCallbackFcn(app, @TILTsliderValueChanging2, true);
            app.PANslider = uislider(app.RotationPanel);
            app.PANslider.Limits = [-180 180];
            app.PANslider.MajorTicks = [-180 -120 -60 0 60 120 180];
            app.PANslider.ValueChangedFcn = createCallbackFcn(app, @PANsliderValueChanged, true);
            app.PANslider.ValueChangingFcn = createCallbackFcn(app, @PANsliderValueChanging2, true);
            app.Panel_17 = uipanel(app.DataPlaneManipulationPanel);
            app.IntersectionlinecolorButton = uibutton(app.Panel_17, 'push');
            app.IntersectionlinecolorButton.ButtonPushedFcn = createCallbackFcn(app, @IntersectionlinecolorButtonPushed, true);
            app.IntersectionlinecolorButton.Text = 'Intersection line color';
            app.IntersectionColorDisplay = uipanel(app.Panel_17);
            app.IntersectionLineVisibilityButton = uibutton(app.Panel_17, 'state');
            app.IntersectionLineVisibilityButton.ValueChangedFcn = createCallbackFcn(app, @IntersectionLineVisibilityButtonValueChanged, true);
            app.IntersectionLineVisibilityButton.Text = 'Intersection line: on';
            app.Panel_12 = uipanel(app.DataPlaneManipulationPanel);
            app.SizeumEditFieldLabel = uilabel(app.Panel_12);
            app.SizeumEditFieldLabel.Text = 'Size (um)';
            app.DataPlaneSizeInput = uieditfield(app.Panel_12, 'numeric');
            app.DataPlaneSizeInput.ValueChangedFcn = createCallbackFcn(app, @DataPlaneSizeInputValueChanged, true);
            app.DataPlaneToggleButton = uibutton(app.Panel_12, 'state');
            app.DataPlaneToggleButton.ValueChangedFcn = createCallbackFcn(app, @DataPlaneToggleButtonValueChanged, true);
            app.DataPlaneToggleButton.Text = 'Show Data Plane';
            app.DataPlaneChannel = uidropdown(app.Panel_12);
            app.DataPlaneChannel.Items = {'Membrane Ch', 'Nuclear Ch', 'Cellpose seg'};
            app.DataPlaneChannel.ItemsData = [1 2 3];
            app.DataPlaneChannel.ValueChangedFcn = createCallbackFcn(app, @DataPlaneChannelValueChanged2, true);
            app.DataPlaneChannel.Value = 1;
            app.DataplancolormapLabel = uilabel(app.DataPlaneManipulationPanel);
            app.DataplancolormapLabel.Text = 'Data plan colormap:';
            app.DataPlaneColormapDropDown = uidropdown(app.DataPlaneManipulationPanel);
            app.DataPlaneColormapDropDown.ValueChangedFcn = createCallbackFcn(app, @DataPlaneColormapDropDownValueChanged, true);
            app.MultiCellAnalysisTab = uitab(app.TabGroup);
            app.MultiCellAnalysisTab.Title = 'Multi-Cell Analysis';
            app.PlotCellRenderingsOverAllTimePointsPanel = uipanel(app.MultiCellAnalysisTab);
            app.PlotCellRenderingsOverAllTimePointsPanel.Title = 'Plot Cell Renderings Over All Time Points';
            app.AxesRangePanel = uipanel(app.PlotCellRenderingsOverAllTimePointsPanel);
            app.AxesRangePanel.Title = 'Axes Range';
            app.XminXmaxLabel = uilabel(app.AxesRangePanel);
            app.XminXmaxLabel.Text = 'Xmin, Xmax';
            app.XminXmaxEditField = uieditfield(app.AxesRangePanel, 'text');
            app.XminXmaxEditField.ValueChangedFcn = createCallbackFcn(app, @XminXmaxEditFieldValueChanged, true);
            app.XminXmaxEditField.Value = '0,100';
            app.YminYmaxEditFieldLabel = uilabel(app.AxesRangePanel);
            app.YminYmaxEditFieldLabel.Text = 'Ymin, Ymax';
            app.YminYmaxEditField = uieditfield(app.AxesRangePanel, 'text');
            app.YminYmaxEditField.ValueChangedFcn = createCallbackFcn(app, @YminYmaxEditFieldValueChanged, true);
            app.YminYmaxEditField.Value = '0,100';
            app.ZminZmaxEditFieldLabel = uilabel(app.AxesRangePanel);
            app.ZminZmaxEditFieldLabel.Text = 'Zmin, Zmax';
            app.ZminZmaxEditField = uieditfield(app.AxesRangePanel, 'text');
            app.ZminZmaxEditField.ValueChangedFcn = createCallbackFcn(app, @ZminZmaxEditFieldValueChanged, true);
            app.ZminZmaxEditField.Value = '0,100';
            app.updateplotButton = uibutton(app.AxesRangePanel, 'push');
            app.updateplotButton.ButtonPushedFcn = createCallbackFcn(app, @updateplotButtonPushed, true);
            app.updateplotButton.Text = 'update plot';
            app.AutoAxisLimitsCheckBox = uicheckbox(app.AxesRangePanel);
            app.AutoAxisLimitsCheckBox.ValueChangedFcn = createCallbackFcn(app, @AutoAxisLimitsCheckBoxValueChanged, true);
            app.AutoAxisLimitsCheckBox.Text = 'auto';
            app.Panel_18 = uipanel(app.PlotCellRenderingsOverAllTimePointsPanel);
            app.MakeDropDownLabel_2 = uilabel(app.Panel_18);
            app.MakeDropDownLabel_2.Text = 'start @ t =';
            app.StartingTimePoint = uieditfield(app.Panel_18, 'numeric');
            app.StartingTimePoint.ValueDisplayFormat = '%.0f';
            app.StartingTimePoint.ValueChangedFcn = createCallbackFcn(app, @StartingTimePointValueChanged, true);
            app.StartingTimePoint.Value = 1;
            app.TimePointIncrement = uieditfield(app.Panel_18, 'numeric');
            app.TimePointIncrement.ValueDisplayFormat = '%.0f';
            app.TimePointIncrement.ValueChangedFcn = createCallbackFcn(app, @TimePointIncrementValueChanged, true);
            app.TimePointIncrement.Value = 1;
            app.MakeDropDownLabel_5 = uilabel(app.Panel_18);
            app.MakeDropDownLabel_5.Text = 't increment';
            app.MakeDropDownLabel_6 = uilabel(app.Panel_18);
            app.MakeDropDownLabel_6.Text = 'end @ t =';
            app.EndingTimePoint = uieditfield(app.Panel_18, 'numeric');
            app.EndingTimePoint.ValueDisplayFormat = '%.0f';
            app.EndingTimePoint.ValueChangedFcn = createCallbackFcn(app, @EndingTimePointValueChanged, true);
            app.EndingTimePoint.Value = 1000;
            app.Panel_19 = uipanel(app.PlotCellRenderingsOverAllTimePointsPanel);
            app.MultitrackBackgroundColorButton = uibutton(app.Panel_19, 'push');
            app.MultitrackBackgroundColorButton.ButtonPushedFcn = createCallbackFcn(app, @MultitrackBackgroundColorButtonPushed, true);
            app.MultitrackBackgroundColorButton.Text = 'Figure Background';
            app.MultiTrackBackgroundColorDisplay = uipanel(app.Panel_19);
            app.MultiTrackBackgroundColorDisplay.Title = ';';
            app.TextAxesColorButton = uibutton(app.Panel_19, 'push');
            app.TextAxesColorButton.ButtonPushedFcn = createCallbackFcn(app, @TextAxesColorButtonPushed, true);
            app.TextAxesColorButton.Text = 'Text/Axes';
            app.TextAxesColorDisplay = uipanel(app.Panel_19);
            app.Panel_20 = uipanel(app.PlotCellRenderingsOverAllTimePointsPanel);
            app.UpdateCompositePlotButton = uibutton(app.Panel_20, 'push');
            app.UpdateCompositePlotButton.ButtonPushedFcn = createCallbackFcn(app, @UpdateCompositePlotButtonPushed, true);
            app.UpdateCompositePlotButton.Text = 'Update Composite Plot';
            app.StopMovieButton = uibutton(app.Panel_20, 'state');
            app.StopMovieButton.ValueChangedFcn = createCallbackFcn(app, @StopMovieButtonValueChanged, true);
            app.StopMovieButton.Text = {'Stop '; 'Movie'};
            app.RecordMovieButton = uibutton(app.Panel_20, 'push');
            app.RecordMovieButton.ButtonPushedFcn = createCallbackFcn(app, @RecordMovieButtonPushed, true);
            app.RecordMovieButton.Text = 'Record Movie';
            app.Panel_21 = uipanel(app.PlotCellRenderingsOverAllTimePointsPanel);
            app.SelectTrackDropDownLabel = uilabel(app.Panel_21);
            app.SelectTrackDropDownLabel.Text = 'Select Track:';
            app.SelectTrackDropDown = uidropdown(app.Panel_21);
            app.SelectTrackDropDown.Items = {'Track 1', 'Track 2', 'Track 3', 'Track 4'};
            app.SelectTrackDropDown.ItemsData = {'1,2,3,4'};
            app.SelectTrackDropDown.ValueChangedFcn = createCallbackFcn(app, @SelectTrackDropDownValueChanged, true);
            app.SelectTrackDropDown.Value = '1,2,3,4';
            app.ShowTrackCheckBox = uicheckbox(app.Panel_21);
            app.ShowTrackCheckBox.ValueChangedFcn = createCallbackFcn(app, @ShowTrackCheckBoxValueChanged, true);
            app.ShowTrackCheckBox.Text = 'Show Track';
            app.UnselectAllTrackButton = uibutton(app.Panel_21, 'push');
            app.UnselectAllTrackButton.ButtonPushedFcn = createCallbackFcn(app, @UnselectAllTrackButtonPushed, true);
            app.UnselectAllTrackButton.Text = 'Unshow ALL tracks';
            app.ExportFluoRenderFormatedTrack = uibutton(app.Panel_21, 'push');
            app.ExportFluoRenderFormatedTrack.ButtonPushedFcn = createCallbackFcn(app, @ExportFluoRenderFormatedTrackPushed, true);
            app.ExportFluoRenderFormatedTrack.Text = 'Export FluoRender Track';
            app.UpTrackButton = uibutton(app.Panel_21, 'push');
            app.UpTrackButton.ButtonPushedFcn = createCallbackFcn(app, @UpTrackButtonPushed, true);
            app.UpTrackButton.Text = 'Up';
            app.DownTrackButton = uibutton(app.Panel_21, 'push');
            app.DownTrackButton.ButtonPushedFcn = createCallbackFcn(app, @DownTrackButtonPushed, true);
            app.DownTrackButton.Text = 'Down';
            app.ShowSmoothedRenderingCheck = uicheckbox(app.Panel_21);
            app.ShowSmoothedRenderingCheck.Text = {'Show smoothed '; 'rendering'};
            app.ShowSmoothedRenderingCheck.Value = true;
            app.Panel_23 = uipanel(app.PlotCellRenderingsOverAllTimePointsPanel);
            app.SetStartAngleButton = uibutton(app.Panel_23, 'push');
            app.SetStartAngleButton.ButtonPushedFcn = createCallbackFcn(app, @SetStartAngleButtonPushed, true);
            app.SetStartAngleButton.Text = 'Set START angle , elevation';
            app.SetEndAngleButton = uibutton(app.Panel_23, 'push');
            app.SetEndAngleButton.ButtonPushedFcn = createCallbackFcn(app, @SetEndAngleButtonPushed, true);
            app.SetEndAngleButton.Text = 'Set END angle , elevation';
            app.SetStartAngleDisplay = uieditfield(app.Panel_23, 'text');
            app.SetStartAngleDisplay.ValueChangedFcn = createCallbackFcn(app, @SetStartAngleDisplayValueChanged, true);
            app.SetStartAngleDisplay.Editable = 'off';
            app.SetStartAngleDisplay.Value = '-360, -90';
            app.SetEndAngleDisplay = uieditfield(app.Panel_23, 'text');
            app.SetEndAngleDisplay.ValueChangedFcn = createCallbackFcn(app, @SetEndAngleDisplayValueChanged, true);
            app.SetEndAngleDisplay.Editable = 'off';
            app.SetEndAngleDisplay.Value = '-360, -90';
            app.RotationLabel_2 = uilabel(app.Panel_23);
            app.RotationLabel_2.Text = 'Rotation:';
            app.RotationDropDown = uidropdown(app.Panel_23);
            app.RotationDropDown.Items = {'off', 'on', 'auto'};
            app.RotationDropDown.ValueChangedFcn = createCallbackFcn(app, @RotationDropDownValueChanged, true);
            app.RotationDropDown.Value = 'off';
            app.GoToStartViewButton = uibutton(app.Panel_23, 'push');
            app.GoToStartViewButton.ButtonPushedFcn = createCallbackFcn(app, @GoToStartViewButtonPushed, true);
            app.GoToStartViewButton.Text = 'Go';
            app.GoToEndViewButton = uibutton(app.Panel_23, 'push');
            app.GoToEndViewButton.ButtonPushedFcn = createCallbackFcn(app, @GoToEndViewButtonPushed, true);
            app.GoToEndViewButton.Text = 'Go';
            app.VisualPropertiesTabGroup = uitabgroup(app.PlotCellRenderingsOverAllTimePointsPanel);
            app.AlltimeptsTab = uitab(app.VisualPropertiesTabGroup);
            app.AlltimeptsTab.Title = 'All time pts';
            app.AllCellSurfaceColorButton = uibutton(app.AlltimeptsTab, 'push');
            app.AllCellSurfaceColorButton.ButtonPushedFcn = createCallbackFcn(app, @AllCellSurfaceColorButtonPushed, true);
            app.AllCellSurfaceColorButton.Text = 'Surf Color';
            app.AllCellSurfaceColorDisplay = uipanel(app.AlltimeptsTab);
            app.TransparencyLabel2 = uilabel(app.AlltimeptsTab);
            app.TransparencyLabel2.Text = '%T';
            app.AllCellMeshColorButton = uibutton(app.AlltimeptsTab, 'push');
            app.AllCellMeshColorButton.ButtonPushedFcn = createCallbackFcn(app, @AllCellMeshColorButtonPushed, true);
            app.AllCellMeshColorButton.Text = 'Mesh Color';
            app.AllCellMeshColorDisplay = uipanel(app.AlltimeptsTab);
            app.AllCellMeshTransparencySpinner = uispinner(app.AlltimeptsTab);
            app.AllCellMeshTransparencySpinner.Limits = [0 100];
            app.AllCellMeshTransparencySpinner.ValueChangedFcn = createCallbackFcn(app, @AllCellMeshTransparencySpinnerValueChanged, true);
            app.TransparencyLabel3 = uilabel(app.AlltimeptsTab);
            app.TransparencyLabel3.Text = '%T';
            app.AllCellSurfaceCheckBox = uicheckbox(app.AlltimeptsTab);
            app.AllCellSurfaceCheckBox.ValueChangedFcn = createCallbackFcn(app, @AllCellSurfaceCheckBoxValueChanged, true);
            app.AllCellSurfaceCheckBox.Text = '';
            app.AllCellMeshCheckBox = uicheckbox(app.AlltimeptsTab);
            app.AllCellMeshCheckBox.ValueChangedFcn = createCallbackFcn(app, @AllCellMeshCheckBoxValueChanged, true);
            app.AllCellMeshCheckBox.Text = '';
            app.AllCellSurfaceTransparencySpinner = uispinner(app.AlltimeptsTab);
            app.AllCellSurfaceTransparencySpinner.Limits = [0 100];
            app.AllCellSurfaceTransparencySpinner.ValueChangedFcn = createCallbackFcn(app, @AllCellSurfaceTransparencySpinnerValueChanged, true);
            app.AllCellSurfaceUseColormapCheck = uicheckbox(app.AlltimeptsTab);
            app.AllCellSurfaceUseColormapCheck.ValueChangedFcn = createCallbackFcn(app, @AllCellSurfaceUseColormapCheckValueChanged, true);
            app.AllCellSurfaceUseColormapCheck.Text = 'Use colormap';
            app.AllCellMeshUseColormapCheck = uicheckbox(app.AlltimeptsTab);
            app.AllCellMeshUseColormapCheck.ValueChangedFcn = createCallbackFcn(app, @AllCellMeshUseColormapCheckValueChanged, true);
            app.AllCellMeshUseColormapCheck.Text = 'Use colormap';
            app.TrackTab = uitab(app.VisualPropertiesTabGroup);
            app.TrackTab.Title = 'Track';
            app.TrackLineColorButton = uibutton(app.TrackTab, 'push');
            app.TrackLineColorButton.ButtonPushedFcn = createCallbackFcn(app, @TrackLineColorButtonPushed, true);
            app.TrackLineColorButton.Text = 'Track Color';
            app.TrackLineColorDisplay = uipanel(app.TrackTab);
            app.TrackLineTransparencySpinner = uispinner(app.TrackTab);
            app.TrackLineTransparencySpinner.Limits = [0 100];
            app.TrackLineTransparencySpinner.ValueChangedFcn = createCallbackFcn(app, @TrackLineTransparencySpinnerValueChanged, true);
            app.TransparencyLabel4 = uilabel(app.TrackTab);
            app.TransparencyLabel4.Text = '%T';
            app.TrackLineCheckBox = uicheckbox(app.TrackTab);
            app.TrackLineCheckBox.ValueChangedFcn = createCallbackFcn(app, @TrackLineCheckBoxValueChanged, true);
            app.TrackLineCheckBox.Text = '';
            app.TrackSmoothingCheck = uicheckbox(app.TrackTab);
            app.TrackSmoothingCheck.ValueChangedFcn = createCallbackFcn(app, @TrackSmoothingCheckValueChanged, true);
            app.TrackSmoothingCheck.Text = 'Track moving average';
            app.TrackSmoothingNPointsDropDown = uidropdown(app.TrackTab);
            app.TrackSmoothingNPointsDropDown.Items = {'3', '5', '7', '9', '11', '13', '15', '17', '19', '21'};
            app.TrackSmoothingNPointsDropDown.ValueChangedFcn = createCallbackFcn(app, @TrackSmoothingNPointsDropDownValueChanged, true);
            app.TrackSmoothingNPointsDropDown.Value = '5';
            app.nPtsLabel = uilabel(app.TrackTab);
            app.nPtsLabel.Text = 'nPts:';
            app.SphericalmarkeristrackstartingpointLabel = uilabel(app.TrackTab);
            app.SphericalmarkeristrackstartingpointLabel.Text = 'Spherical marker is track starting point.';
            app.TrackLineUseColormapCheck = uicheckbox(app.TrackTab);
            app.TrackLineUseColormapCheck.ValueChangedFcn = createCallbackFcn(app, @TrackLineUseColormapCheckValueChanged, true);
            app.TrackLineUseColormapCheck.Text = 'Use colormap';
            app.LinewidthDropDownLabel = uilabel(app.TrackTab);
            app.LinewidthDropDownLabel.Text = 'Line width:';
            app.LineWidthDropDown = uidropdown(app.TrackTab);
            app.LineWidthDropDown.Items = {'1.0', '1.5', '2.0', '2.5', '3.0', '3.5', '4.0', '4.5', '5.0', '5.5', '6.0'};
            app.LineWidthDropDown.ValueChangedFcn = createCallbackFcn(app, @LineWidthDropDownValueChanged, true);
            app.LineWidthDropDown.Value = '1.0';
            app.VectorsTab = uitab(app.VisualPropertiesTabGroup);
            app.VectorsTab.Title = 'Vectors';
            app.AllVectorColorButton = uibutton(app.VectorsTab, 'push');
            app.AllVectorColorButton.ButtonPushedFcn = createCallbackFcn(app, @AllVectorColorButtonPushed, true);
            app.AllVectorColorButton.Text = 'All Vector Color';
            app.AllVectorColorDisplay = uipanel(app.VectorsTab);
            app.AllVectorTransparencySpinner = uispinner(app.VectorsTab);
            app.AllVectorTransparencySpinner.Limits = [0 100];
            app.AllVectorTransparencySpinner.ValueChangedFcn = createCallbackFcn(app, @AllVectorTransparencySpinnerValueChanged, true);
            app.TransparencyLabel5_2 = uilabel(app.VectorsTab);
            app.TransparencyLabel5_2.Text = '%T';
            app.AllVectorCheckBox = uicheckbox(app.VectorsTab);
            app.AllVectorCheckBox.ValueChangedFcn = createCallbackFcn(app, @AllVectorCheckBoxValueChanged, true);
            app.AllVectorCheckBox.Text = '';
            app.VectorradiusLabel = uilabel(app.VectorsTab);
            app.VectorradiusLabel.Text = 'Vector radius:';
            app.VectorRadiusEditField = uieditfield(app.VectorsTab, 'numeric');
            app.VectorRadiusEditField.ValueChangedFcn = createCallbackFcn(app, @VectorRadiusEditFieldValueChanged, true);
            app.VectorRadiusEditField.Value = 0.3;
            app.VectorlengthLabel = uilabel(app.VectorsTab);
            app.VectorlengthLabel.Text = 'Vector length:';
            app.VectorLengthEditField = uieditfield(app.VectorsTab, 'numeric');
            app.VectorLengthEditField.ValueChangedFcn = createCallbackFcn(app, @VectorLengthEditFieldValueChanged, true);
            app.VectorLengthEditField.Value = 20;
            app.AllVectorUseColormapCheck = uicheckbox(app.VectorsTab);
            app.AllVectorUseColormapCheck.ValueChangedFcn = createCallbackFcn(app, @AllVectorUseColormapCheckValueChanged, true);
            app.AllVectorUseColormapCheck.Text = 'Use colormap';
            app.DataPlaneTab = uitab(app.VisualPropertiesTabGroup);
            app.DataPlaneTab.Title = 'DataPlane';
            app.OverlayDataPlaneCheck = uicheckbox(app.DataPlaneTab);
            app.OverlayDataPlaneCheck.ValueChangedFcn = createCallbackFcn(app, @OverlayDataPlaneCheckValueChanged, true);
            app.OverlayDataPlaneCheck.Text = 'Overlay data plane (works only for 2 tracks)';
            app.MovieDataPlaneColormapDropDown = uidropdown(app.DataPlaneTab);
            app.MovieDataPlaneColormapDropDown.ValueChangedFcn = createCallbackFcn(app, @DataPlaneColormapDropDown_2ValueChanged, true);
            app.ColormapLabel = uilabel(app.DataPlaneTab);
            app.ColormapLabel.Text = 'Colormap:';
            app.AzimuthoffsetLabel = uilabel(app.DataPlaneTab);
            app.AzimuthoffsetLabel.Text = 'Azimuth offset:';
            app.DataPlaneAzimuthOffsetInput = uieditfield(app.DataPlaneTab, 'numeric');
            app.DataPlaneAzimuthOffsetInput.Limits = [-360 360];
            app.DataPlaneAzimuthOffsetInput.Value = 20;
            app.ElevationEditFieldLabel = uilabel(app.DataPlaneTab);
            app.ElevationEditFieldLabel.Text = 'Elevation:';
            app.DataPlaneElevationEditField = uieditfield(app.DataPlaneTab, 'numeric');
            app.DataPlaneElevationEditField.Limits = [-360 360];
            app.DataPlaneElevationEditField.Value = 10;
            app.DataPlaneImageSaturation = uicheckbox(app.DataPlaneTab);
            app.DataPlaneImageSaturation.Text = 'Image saturation (1%)';
            app.MovieTab = uitab(app.VisualPropertiesTabGroup);
            app.MovieTab.Title = ' Movie';
            app.MovieCellSurfaceColorButton = uibutton(app.MovieTab, 'push');
            app.MovieCellSurfaceColorButton.ButtonPushedFcn = createCallbackFcn(app, @MovieCellSurfaceColorButtonPushed, true);
            app.MovieCellSurfaceColorButton.Text = 'Surf Color';
            app.MovieCellSurfaceColorDisplay = uipanel(app.MovieTab);
            app.MovieCellSurfaceTransparencySpinner = uispinner(app.MovieTab);
            app.MovieCellSurfaceTransparencySpinner.Limits = [0 100];
            app.MovieCellSurfaceTransparencySpinner.ValueChangedFcn = createCallbackFcn(app, @MovieCellSurfaceTransparencySpinnerValueChanged, true);
            app.MovieCellSurfaceTransparencySpinner.Value = 100;
            app.TransparencyLabel5 = uilabel(app.MovieTab);
            app.TransparencyLabel5.Text = '%T';
            app.MovieCellMeshColorButton = uibutton(app.MovieTab, 'push');
            app.MovieCellMeshColorButton.ButtonPushedFcn = createCallbackFcn(app, @MovieCellMeshColorButtonPushed, true);
            app.MovieCellMeshColorButton.Text = 'Mesh Color';
            app.MovieCellMeshColorDisplay = uipanel(app.MovieTab);
            app.MovieCellMeshTransparencySpinner = uispinner(app.MovieTab);
            app.MovieCellMeshTransparencySpinner.Limits = [0 100];
            app.MovieCellMeshTransparencySpinner.ValueChangedFcn = createCallbackFcn(app, @MovieCellMeshTransparencySpinnerValueChanged, true);
            app.TransparencyLabel6 = uilabel(app.MovieTab);
            app.TransparencyLabel6.Text = '%T';
            app.MovieCellSurfaceCheckBox = uicheckbox(app.MovieTab);
            app.MovieCellSurfaceCheckBox.ValueChangedFcn = createCallbackFcn(app, @MovieCellSurfaceCheckBoxValueChanged, true);
            app.MovieCellSurfaceCheckBox.Text = '';
            app.MovieCellMeshCheckBox = uicheckbox(app.MovieTab);
            app.MovieCellMeshCheckBox.ValueChangedFcn = createCallbackFcn(app, @MovieCellMeshCheckBoxValueChanged, true);
            app.MovieCellMeshCheckBox.Text = '';
            app.MovieCentroidColorButton = uibutton(app.MovieTab, 'push');
            app.MovieCentroidColorButton.ButtonPushedFcn = createCallbackFcn(app, @MovieCentroidColorButtonPushed, true);
            app.MovieCentroidColorButton.Text = 'Centroid Color';
            app.MovieCentroidColorDisplay = uipanel(app.MovieTab);
            app.MovieCentroidTransparencySpinner = uispinner(app.MovieTab);
            app.MovieCentroidTransparencySpinner.Limits = [0 100];
            app.MovieCentroidTransparencySpinner.ValueChangedFcn = createCallbackFcn(app, @MovieCentroidTransparencySpinnerValueChanged, true);
            app.TransparencyLabel7 = uilabel(app.MovieTab);
            app.TransparencyLabel7.Text = '%T';
            app.MovieCentroidCheckBox = uicheckbox(app.MovieTab);
            app.MovieCentroidCheckBox.ValueChangedFcn = createCallbackFcn(app, @MovieCentroidCheckBoxValueChanged, true);
            app.MovieCentroidCheckBox.Text = '';
            app.VectorColorButton = uibutton(app.MovieTab, 'push');
            app.VectorColorButton.ButtonPushedFcn = createCallbackFcn(app, @VectorColorButtonPushed, true);
            app.VectorColorButton.Text = 'Vector Color';
            app.MovieVectorColorDisplay = uipanel(app.MovieTab);
            app.MovieVectorTransparencySpinner = uispinner(app.MovieTab);
            app.MovieVectorTransparencySpinner.Limits = [0 100];
            app.MovieVectorTransparencySpinner.ValueChangedFcn = createCallbackFcn(app, @MovieVectorTransparencySpinnerValueChanged, true);
            app.TransparencyLabel5_3 = uilabel(app.MovieTab);
            app.TransparencyLabel5_3.Text = '%T';
            app.MovieVectorColorCheckBox = uicheckbox(app.MovieTab);
            app.MovieVectorColorCheckBox.ValueChangedFcn = createCallbackFcn(app, @MovieVectorColorCheckBoxValueChanged, true);
            app.MovieVectorColorCheckBox.Text = '';
            app.MovieCellSurfaceUseColormapCheck = uicheckbox(app.MovieTab);
            app.MovieCellSurfaceUseColormapCheck.ValueChangedFcn = createCallbackFcn(app, @MovieCellSurfaceUseColormapCheckValueChanged, true);
            app.MovieCellSurfaceUseColormapCheck.Text = 'Use colormap';
            app.MovieCellMeshUseColormapCheck = uicheckbox(app.MovieTab);
            app.MovieCellMeshUseColormapCheck.ValueChangedFcn = createCallbackFcn(app, @MovieCellMeshUseColormapCheckValueChanged, true);
            app.MovieCellMeshUseColormapCheck.Text = 'Use colormap';
            app.MovieCentroidUseColormapCheck = uicheckbox(app.MovieTab);
            app.MovieCentroidUseColormapCheck.ValueChangedFcn = createCallbackFcn(app, @MovieCentroidUseColormapCheckValueChanged, true);
            app.MovieCentroidUseColormapCheck.Text = 'Use colormap';
            app.MovieVectorUseColormapCheck = uicheckbox(app.MovieTab);
            app.MovieVectorUseColormapCheck.ValueChangedFcn = createCallbackFcn(app, @MovieVectorUseColormapCheckValueChanged, true);
            app.MovieVectorUseColormapCheck.Text = 'Use colormap';
            app.Panel_24 = uipanel(app.PlotCellRenderingsOverAllTimePointsPanel);
            app.FPSLabel = uilabel(app.Panel_24);
            app.FPSLabel.Text = 'Frames per second:';
            app.FramespersecondEditField = uieditfield(app.Panel_24, 'numeric');
            app.FramespersecondEditField.Limits = [0.1 100];
            app.FramespersecondEditField.ValueDisplayFormat = '%4.1f';
            app.FramespersecondEditField.ValueChangedFcn = createCallbackFcn(app, @FramespersecondEditFieldValueChanged, true);
            app.FramespersecondEditField.Value = 5;
            app.MultiCellColormapDropDown = uidropdown(app.PlotCellRenderingsOverAllTimePointsPanel);
            app.MultiCellColormapDropDown.ValueChangedFcn = createCallbackFcn(app, @MultiCellColormapDropDownValueChanged, true);
            app.ColormapTypeDropDown = uidropdown(app.PlotCellRenderingsOverAllTimePointsPanel);
            app.ColormapTypeDropDown.Items = {'colormap off', 'time', 'velocity'};
            app.ColormapTypeDropDown.ValueChangedFcn = createCallbackFcn(app, @ColormapTypeDropDownValueChanged, true);
            app.ColormapTypeDropDown.Value = 'colormap off';
            app.ColormaptypeLabel = uilabel(app.PlotCellRenderingsOverAllTimePointsPanel);
            app.ColormaptypeLabel.Text = 'Colormap type:';
            app.ColormapLabel_2 = uilabel(app.PlotCellRenderingsOverAllTimePointsPanel);
            app.ColormapLabel_2.Text = 'Colormap:';
            app.VelocityFilteringAndRangeButton = uibutton(app.PlotCellRenderingsOverAllTimePointsPanel, 'push');
            app.VelocityFilteringAndRangeButton.ButtonPushedFcn = createCallbackFcn(app, @VelocityFilteringAndRangeButtonPushed, true);
            app.VelocityFilteringAndRangeButton.Text = 'Velocity filtering and range';
            app.CurrentVelocityRangeDisplay = uilabel(app.PlotCellRenderingsOverAllTimePointsPanel);
            app.CurrentVelocityRangeDisplay.Text = {'Velocity colormap range: 15.00 - 25.00 nm/s'; 'nPts for velocity smoothing: 25'};
            app.ClosestPointofApproachCalculationPanel = uipanel(app.MultiCellAnalysisTab);
            app.ClosestPointofApproachCalculationPanel.Title = 'Closest Point of Approach Calculation';
            app.CalculateandPlotClosestApproachButton = uibutton(app.ClosestPointofApproachCalculationPanel, 'push');
            app.CalculateandPlotClosestApproachButton.ButtonPushedFcn = createCallbackFcn(app, @CalculateandPlotClosestApproachButtonPushed, true);
            app.CalculateandPlotClosestApproachButton.Text = {'Calculate and Plot Closest Approach'; ''};
            app.SelectTrackADropDownLabel = uilabel(app.ClosestPointofApproachCalculationPanel);
            app.SelectTrackADropDownLabel.Text = 'Select Track A:';
            app.SelectTrackADropDown = uidropdown(app.ClosestPointofApproachCalculationPanel);
            app.SelectTrackADropDown.Items = {'Track 1', 'Track 2', 'Track 3', 'Track 4'};
            app.SelectTrackADropDown.ItemsData = {'1,2,3,4'};
            app.SelectTrackADropDown.Value = '1,2,3,4';
            app.SelectTrackBDropDownLabel = uilabel(app.ClosestPointofApproachCalculationPanel);
            app.SelectTrackBDropDownLabel.Text = 'Select Track B:';
            app.SelectTrackBDropDown = uidropdown(app.ClosestPointofApproachCalculationPanel);
            app.SelectTrackBDropDown.Items = {'Track 1', 'Track 2', 'Track 3', 'Track 4'};
            app.SelectTrackBDropDown.ItemsData = {'1,2,3,4'};
            app.SelectTrackBDropDown.Value = '1,2,3,4';
            app.ExportasExcelfileButton = uibutton(app.ClosestPointofApproachCalculationPanel, 'push');
            app.ExportasExcelfileButton.ButtonPushedFcn = createCallbackFcn(app, @ExportasExcelfileButtonPushed, true);
            app.ExportasExcelfileButton.Text = 'Export as Excel file';
            app.XaxisTimeunitsDropDownLabel = uilabel(app.ClosestPointofApproachCalculationPanel);
            app.XaxisTimeunitsDropDownLabel.Text = '(X-axis) Time units:';
            app.XaxisTimeunitsDropDown = uidropdown(app.ClosestPointofApproachCalculationPanel);
            app.XaxisTimeunitsDropDown.Items = {'seconds', 'minutes', 'hours', 'timepoints'};
            app.XaxisTimeunitsDropDown.Value = 'hours';
            app.TrackAStatsButton = uibutton(app.ClosestPointofApproachCalculationPanel, 'push');
            app.TrackAStatsButton.ButtonPushedFcn = createCallbackFcn(app, @TrackAStatsButtonPushed, true);
            app.TrackAStatsButton.Text = 'Track A Stats';
            app.TrackBStatsButton = uibutton(app.ClosestPointofApproachCalculationPanel, 'push');
            app.TrackBStatsButton.ButtonPushedFcn = createCallbackFcn(app, @TrackBStatsButtonPushed, true);
            app.TrackBStatsButton.Text = 'Track B Stats';
            % Show the figure after all components are created
            app.LocusUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = locus

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.LocusUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.LocusUIFigure)
        end
    end
end